classdef BRANCHGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MarkBranchUIFigure              matlab.ui.Figure
        BetweenStenosisEntryandPeakCheckBox_10  matlab.ui.control.CheckBox
        BetweenStenosisEntryandPeakCheckBox_9  matlab.ui.control.CheckBox
        BetweenStenosisEntryandPeakCheckBox_8  matlab.ui.control.CheckBox
        BetweenStenosisEntryandPeakCheckBox_7  matlab.ui.control.CheckBox
        BetweenStenosisEntryandPeakCheckBox_6  matlab.ui.control.CheckBox
        BetweenStenosisEntryandPeakCheckBox_5  matlab.ui.control.CheckBox
        BetweenStenosisEntryandPeakCheckBox_4  matlab.ui.control.CheckBox
        BetweenStenosisEntryandPeakCheckBox_3  matlab.ui.control.CheckBox
        BetweenStenosisEntryandPeakCheckBox_2  matlab.ui.control.CheckBox
        BetweenStenosisEntryandPeakCheckBox  matlab.ui.control.CheckBox
        NumberofbranchbetweenprelesionandpostlesionsitesLabel  matlab.ui.control.Label
        BranchradiuscmEditField_10      matlab.ui.control.NumericEditField
        BranchradiuscmEditField_10Label  matlab.ui.control.Label
        PostbranchradiuscmEditField_10  matlab.ui.control.NumericEditField
        PostbranchradiuscmEditField_10Label  matlab.ui.control.Label
        SliceNumberEditField_10         matlab.ui.control.NumericEditField
        SliceNumberEditField_10Label    matlab.ui.control.Label
        Branch10CheckBox                matlab.ui.control.CheckBox
        BranchradiuscmEditField_9       matlab.ui.control.NumericEditField
        BranchradiuscmEditField_9Label  matlab.ui.control.Label
        PostbranchradiuscmEditField_9   matlab.ui.control.NumericEditField
        PostbranchradiuscmEditField_9Label  matlab.ui.control.Label
        SliceNumberEditField_9          matlab.ui.control.NumericEditField
        SliceNumberEditField_9Label     matlab.ui.control.Label
        Branch9CheckBox                 matlab.ui.control.CheckBox
        BranchradiuscmEditField_8       matlab.ui.control.NumericEditField
        BranchradiuscmEditField_8Label  matlab.ui.control.Label
        PostbranchradiuscmEditField_8   matlab.ui.control.NumericEditField
        PostbranchradiuscmEditField_8Label  matlab.ui.control.Label
        SliceNumberEditField_8          matlab.ui.control.NumericEditField
        SliceNumberEditField_8Label     matlab.ui.control.Label
        Branch8CheckBox                 matlab.ui.control.CheckBox
        BranchradiuscmEditField_7       matlab.ui.control.NumericEditField
        BranchradiuscmEditField_7Label  matlab.ui.control.Label
        PostbranchradiuscmEditField_7   matlab.ui.control.NumericEditField
        PostbranchradiuscmEditField_7Label  matlab.ui.control.Label
        SliceNumberEditField_7          matlab.ui.control.NumericEditField
        SliceNumberEditField_7Label     matlab.ui.control.Label
        Branch7CheckBox                 matlab.ui.control.CheckBox
        BranchradiuscmEditField_6       matlab.ui.control.NumericEditField
        BranchradiuscmEditField_6Label  matlab.ui.control.Label
        PostbranchradiuscmEditField_6   matlab.ui.control.NumericEditField
        PostbranchradiuscmEditField_6Label  matlab.ui.control.Label
        SliceNumberEditField_6          matlab.ui.control.NumericEditField
        SliceNumberEditField_6Label     matlab.ui.control.Label
        Branch6CheckBox                 matlab.ui.control.CheckBox
        BranchradiuscmEditField_5       matlab.ui.control.NumericEditField
        BranchradiuscmEditField_5Label  matlab.ui.control.Label
        PostbranchradiuscmEditField_5   matlab.ui.control.NumericEditField
        PostbranchradiuscmEditField_5Label  matlab.ui.control.Label
        SliceNumberEditField_5          matlab.ui.control.NumericEditField
        SliceNumberEditField_5Label     matlab.ui.control.Label
        Branch5CheckBox                 matlab.ui.control.CheckBox
        BranchradiuscmEditField_4       matlab.ui.control.NumericEditField
        BranchradiuscmEditField_4Label  matlab.ui.control.Label
        PostbranchradiuscmEditField_4   matlab.ui.control.NumericEditField
        PostbranchradiuscmEditField_4Label  matlab.ui.control.Label
        SliceNumberEditField_4          matlab.ui.control.NumericEditField
        SliceNumberEditField_4Label     matlab.ui.control.Label
        Branch4CheckBox                 matlab.ui.control.CheckBox
        BranchradiuscmEditField_3       matlab.ui.control.NumericEditField
        BranchradiuscmEditField_3Label  matlab.ui.control.Label
        PostbranchradiuscmEditField_3   matlab.ui.control.NumericEditField
        PostbranchradiuscmEditField_3Label  matlab.ui.control.Label
        SliceNumberEditField_3          matlab.ui.control.NumericEditField
        SliceNumberEditField_3Label     matlab.ui.control.Label
        Branch3CheckBox                 matlab.ui.control.CheckBox
        BranchradiuscmEditField_2       matlab.ui.control.NumericEditField
        BranchradiuscmEditField_2Label  matlab.ui.control.Label
        PostbranchradiuscmEditField_2   matlab.ui.control.NumericEditField
        PostbranchradiuscmEditField_2Label  matlab.ui.control.Label
        SliceNumberEditField_2          matlab.ui.control.NumericEditField
        SliceNumberLabel                matlab.ui.control.Label
        Branch2CheckBox                 matlab.ui.control.CheckBox
        BranchradiuscmEditField         matlab.ui.control.NumericEditField
        BranchradiuscmEditFieldLabel    matlab.ui.control.Label
        PostbranchradiuscmEditField     matlab.ui.control.NumericEditField
        PostbranchradiuscmEditFieldLabel  matlab.ui.control.Label
        SliceNumberEditField            matlab.ui.control.NumericEditField
        BranchSliceNumberLabel          matlab.ui.control.Label
        Branch1CheckBox                 matlab.ui.control.CheckBox
        EnterButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        DCTFFRGUI;
        BRANCHCURVEData;
        
    end
    
    events
        AppClosedEvent;
    end
    
    properties (Access = public)
        branchesCheckedOn = [false, false, false, false, false, false, false, false, false, false];
        branches = struct('radiusOfSideBranch', [], 'sliceAtSideBranch', [], 'radiusAtTrunkAfterSideBranch', [], 'aucOfSideBranch', []);
        colorBackgroundNormalMode = [0.0 0.0 0.0];
        colorBackgroundDarkMode = [0.0 0.0 0.0];
        colorTextFieldNormalMode = [0.07,0.07,0.07];
        colorTextFieldDarkMode = [0.07,0.07,0.07];
        colorRegularFontDarkMode = [1.0 1.0 1.0];
        colorRegularFontNormalMode = [1.0 1.0 1.0];
        branchNumber;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DCTFFRGUI)
            % Center the app
            screenSize = get(0, 'ScreenSize');
            appWidth = 725;
            appHeight =  420;
            appX = (screenSize(3) - appWidth) / 2;
            appY = (screenSize(4) - appHeight) / 2;
            app.MarkBranchUIFigure.Position = [appX, appY, appWidth, appHeight];
            
            % Connecting with Main App
            app.DCTFFRGUI = DCTFFRGUI;
            % Change View Mode
            
            % bChangeViewMode(app, app.DCTFFRGUI.viewMode); %RENABLE BEFORE
        end

        % Value changed function: Branch1CheckBox
        function Branch1CheckBoxValueChanged(app, event)
            value = app.Branch1CheckBox.Value;
            if (value == true)
                app.BranchSliceNumberLabel.Enable = true;
                app.SliceNumberEditField.Enable = true;
                app.PostbranchradiuscmEditFieldLabel.Enable = true;
                app.PostbranchradiuscmEditField.Enable = true;
                app.BranchradiuscmEditFieldLabel.Enable = true;
                app.BranchradiuscmEditField.Enable = true;
                app.branchesCheckedOn(1) = true;
                app.BetweenStenosisEntryandPeakCheckBox.Enable= true;
            else
                app.Branch1CheckBox.Value = false;
                app.BranchSliceNumberLabel.Enable = false;
                app.SliceNumberEditField.Enable = false;
                app.PostbranchradiuscmEditFieldLabel.Enable = false;
                app.PostbranchradiuscmEditField.Enable = false;
                app.BranchradiuscmEditFieldLabel.Enable = false;
                app.BranchradiuscmEditField.Enable = false;
                app.branchesCheckedOn(1) = false;
                app.BetweenStenosisEntryandPeakCheckBox.Enable= false;
            end
        end

        % Value changed function: Branch2CheckBox
        function Branch2CheckBoxValueChanged(app, event)
            value = app.Branch2CheckBox.Value;
            if (value == true)
                app.SliceNumberLabel.Enable = true;
                app.SliceNumberEditField_2.Enable = true;
                app.PostbranchradiuscmEditField_2Label.Enable = true;
                app.PostbranchradiuscmEditField_2.Enable = true;
                app.BranchradiuscmEditField_2Label.Enable = true;
                app.BranchradiuscmEditField_2.Enable = true;
                app.branchesCheckedOn(2) = true;
                app.BetweenStenosisEntryandPeakCheckBox_2.Enable= true;
            else
                app.Branch2CheckBox.Value = false;
                app.SliceNumberLabel.Enable = false;
                app.SliceNumberEditField_2.Enable = false;
                app.PostbranchradiuscmEditField_2Label.Enable = false;
                app.PostbranchradiuscmEditField_2.Enable = false;
                app.BranchradiuscmEditField_2Label.Enable = false;
                app.BranchradiuscmEditField_2.Enable = false;
                app.branchesCheckedOn(2) = false;
                app.BetweenStenosisEntryandPeakCheckBox_2.Enable= false;
            end
        end

        % Close request function: MarkBranchUIFigure
        function MarkBranchUIFigureCloseRequest(app, event)
            notify(app, 'AppClosedEvent');
            delete(app);
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            
            
            app.branches(1).radiusOfSideBranch = app.BranchradiuscmEditField.Value;
            app.branches(1).sliceAtSideBranch = app.SliceNumberEditField.Value;
            app.branches(1).radiusAtTrunkAfterSideBranch = app.PostbranchradiuscmEditField.Value;
            
            app.branches(2).radiusOfSideBranch = app.BranchradiuscmEditField_2.Value;
            app.branches(2).sliceAtSideBranch = app.SliceNumberEditField_2.Value;
            app.branches(2).radiusAtTrunkAfterSideBranch = app.PostbranchradiuscmEditField_2.Value;
            
            app.branches(3).radiusOfSideBranch = app.BranchradiuscmEditField_3.Value;
            app.branches(3).sliceAtSideBranch = app.SliceNumberEditField_3.Value;
            app.branches(3).radiusAtTrunkAfterSideBranch = app.PostbranchradiuscmEditField_3.Value;
            
            app.branches(4).radiusOfSideBranch = app.BranchradiuscmEditField_4.Value;
            app.branches(4).sliceAtSideBranch = app.SliceNumberEditField_4.Value;
            app.branches(4).radiusAtTrunkAfterSideBranch = app.PostbranchradiuscmEditField_4.Value;
            
            app.branches(5).radiusOfSideBranch = app.BranchradiuscmEditField_5.Value;
            app.branches(5).sliceAtSideBranch = app.SliceNumberEditField_5.Value;
            app.branches(5).radiusAtTrunkAfterSideBranch = app.PostbranchradiuscmEditField_5.Value;
            
            app.branches(6).radiusOfSideBranch = app.BranchradiuscmEditField_6.Value;
            app.branches(6).sliceAtSideBranch = app.SliceNumberEditField_6.Value;
            app.branches(6).radiusAtTrunkAfterSideBranch = app.PostbranchradiuscmEditField_6.Value;
            
            app.branches(7).radiusOfSideBranch = app.BranchradiuscmEditField_7.Value;
            app.branches(7).sliceAtSideBranch = app.SliceNumberEditField_7.Value;
            app.branches(7).radiusAtTrunkAfterSideBranch = app.PostbranchradiuscmEditField_7.Value;
            
            app.branches(8).radiusOfSideBranch = app.BranchradiuscmEditField_8.Value;
            app.branches(8).sliceAtSideBranch = app.SliceNumberEditField_8.Value;
            app.branches(8).radiusAtTrunkAfterSideBranch = app.PostbranchradiuscmEditField_8.Value;
            
            app.branches(9).radiusOfSideBranch = app.BranchradiuscmEditField_9.Value;
            app.branches(9).sliceAtSideBranch = app.SliceNumberEditField_9.Value;
            app.branches(9).radiusAtTrunkAfterSideBranch = app.PostbranchradiuscmEditField_9.Value;
            
            app.branches(10).radiusOfSideBranch = app.BranchradiuscmEditField_10.Value;
            app.branches(10).sliceAtSideBranch = app.SliceNumberEditField_10.Value;
            app.branches(10).radiusAtTrunkAfterSideBranch = app.PostbranchradiuscmEditField_10.Value;
            
            getBranchDataFromBranchGUI(app.DCTFFRGUI);
        end

        % Value changed function: Branch3CheckBox
        function Branch3CheckBoxValueChanged(app, event)
            value = app.Branch3CheckBox.Value;
            if (value == true)
                app.SliceNumberEditField_3Label.Enable = true;
                app.SliceNumberEditField_3.Enable = true;
                app.PostbranchradiuscmEditField_3Label.Enable = true;
                app.PostbranchradiuscmEditField_3.Enable = true;
                app.BranchradiuscmEditField_3Label.Enable = true;
                app.BranchradiuscmEditField_3.Enable = true;
                app.branchesCheckedOn(3) = true;
                app.BetweenStenosisEntryandPeakCheckBox_3.Enable= true;
            else
                app.Branch3CheckBox.Value = false;
                app.SliceNumberEditField_3Label.Enable = false;
                app.SliceNumberEditField_3.Enable = false;
                app.PostbranchradiuscmEditField_3Label.Enable = false;
                app.PostbranchradiuscmEditField_3.Enable = false;
                app.BranchradiuscmEditField_3Label.Enable = false;
                app.BranchradiuscmEditField_3.Enable = false;
                app.branchesCheckedOn(3) = false;
                app.BetweenStenosisEntryandPeakCheckBox_3.Enable= false;
            end
        end

        % Value changed function: Branch4CheckBox
        function Branch4CheckBoxValueChanged(app, event)
            value = app.Branch4CheckBox.Value;
            if (value == true)
                app.SliceNumberEditField_4Label.Enable = true;
                app.SliceNumberEditField_4.Enable = true;
                app.PostbranchradiuscmEditField_4Label.Enable = true;
                app.PostbranchradiuscmEditField_4.Enable = true;
                app.BranchradiuscmEditField_4Label.Enable = true;
                app.BranchradiuscmEditField_4.Enable = true;
                app.branchesCheckedOn(4) = true;
                app.BetweenStenosisEntryandPeakCheckBox_4.Enable= true;
            else
                app.Branch4CheckBox.Value = false;
                app.SliceNumberEditField_4Label.Enable = false;
                app.SliceNumberEditField_4.Enable = false;
                app.PostbranchradiuscmEditField_4Label.Enable = false;
                app.PostbranchradiuscmEditField_4.Enable = false;
                app.BranchradiuscmEditField_4Label.Enable = false;
                app.BranchradiuscmEditField_4.Enable = false;
                app.branchesCheckedOn(4) = false;
                app.BetweenStenosisEntryandPeakCheckBox_4.Enable= false;
            end
        end

        % Value changed function: Branch5CheckBox
        function Branch5CheckBoxValueChanged(app, event)
            value = app.Branch5CheckBox.Value;
            if (value == true)
                app.SliceNumberEditField_5Label.Enable = true;
                app.SliceNumberEditField_5.Enable = true;
                app.PostbranchradiuscmEditField_5Label.Enable = true;
                app.PostbranchradiuscmEditField_5.Enable = true;
                app.BranchradiuscmEditField_5Label.Enable = true;
                app.BranchradiuscmEditField_5.Enable = true;
                app.branchesCheckedOn(5) = true;  
                app.BetweenStenosisEntryandPeakCheckBox_5.Enable= true;
            else
                app.Branch5CheckBox.Value = false;
                app.SliceNumberEditField_5Label.Enable = false;
                app.SliceNumberEditField_5.Enable = false;
                app.PostbranchradiuscmEditField_5Label.Enable = false;
                app.PostbranchradiuscmEditField_5.Enable = false;
                app.BranchradiuscmEditField_5Label.Enable = false;
                app.BranchradiuscmEditField_5.Enable = false;
                app.branchesCheckedOn(6) = false; 
                app.BetweenStenosisEntryandPeakCheckBox_5.Enable= false;
            end
        end

        % Value changed function: Branch6CheckBox
        function Branch6CheckBoxValueChanged(app, event)
            value = app.Branch6CheckBox.Value;
            if (value == true)
                app.SliceNumberEditField_6Label.Enable = true;
                app.SliceNumberEditField_6.Enable = true;
                app.PostbranchradiuscmEditField_6Label.Enable = true;
                app.PostbranchradiuscmEditField_6.Enable = true;
                app.BranchradiuscmEditField_6Label.Enable = true;
                app.BranchradiuscmEditField_6.Enable = true;
                app.branchesCheckedOn(6) = true;
                app.BetweenStenosisEntryandPeakCheckBox_6.Enable= true;
                
            else
                app.Branch6CheckBox.Value = false;
                app.SliceNumberEditField_6Label.Enable = false;
                app.SliceNumberEditField_6.Enable = false;
                app.PostbranchradiuscmEditField_6Label.Enable = false;
                app.PostbranchradiuscmEditField_6.Enable = false;
                app.BranchradiuscmEditField_6Label.Enable = false;
                app.BranchradiuscmEditField_6.Enable = false;
                app.branchesCheckedOn(6) = false; 
                app.BetweenStenosisEntryandPeakCheckBox_6.Enable= false;
            end
        end

        % Value changed function: Branch7CheckBox
        function Branch7CheckBoxValueChanged(app, event)
            value = app.Branch7CheckBox.Value;
            if (value == true)
                app.SliceNumberEditField_7Label.Enable = true;
                app.SliceNumberEditField_7.Enable = true;
                app.PostbranchradiuscmEditField_7Label.Enable = true;
                app.PostbranchradiuscmEditField_7.Enable = true;
                app.BranchradiuscmEditField_7Label.Enable = true;
                app.BranchradiuscmEditField_7.Enable = true;
                app.branchesCheckedOn(7) = true;
                app.BetweenStenosisEntryandPeakCheckBox_7.Enable= true;
            else
                app.Branch7CheckBox.Value = false;
                app.SliceNumberEditField_7Label.Enable = false;
                app.SliceNumberEditField_7.Enable = false;
                app.PostbranchradiuscmEditField_7Label.Enable = false;
                app.PostbranchradiuscmEditField_7.Enable = false;
                app.BranchradiuscmEditField_7Label.Enable = false;
                app.BranchradiuscmEditField_7.Enable = false;
                app.branchesCheckedOn(7) = false;
                app.BetweenStenosisEntryandPeakCheckBox_7.Enable= false;
            end
        end

        % Value changed function: Branch8CheckBox
        function Branch8CheckBoxValueChanged(app, event)
            value = app.Branch8CheckBox.Value;
            if (value == true)
                app.SliceNumberEditField_8Label.Enable = true;
                app.SliceNumberEditField_8.Enable = true;
                app.PostbranchradiuscmEditField_8Label.Enable = true;
                app.PostbranchradiuscmEditField_8.Enable = true;
                app.BranchradiuscmEditField_8Label.Enable = true;
                app.BranchradiuscmEditField_8.Enable = true;
                app.branchesCheckedOn(8) = true;
                app.BetweenStenosisEntryandPeakCheckBox_8.Enable= true;
            else
                app.Branch8CheckBox.Value = false;
                app.SliceNumberEditField_8Label.Enable = false;
                app.SliceNumberEditField_8.Enable = false;
                app.PostbranchradiuscmEditField_8Label.Enable = false;
                app.PostbranchradiuscmEditField_8.Enable = false;
                app.BranchradiuscmEditField_8Label.Enable = false;
                app.BranchradiuscmEditField_8.Enable = false;
                app.branchesCheckedOn(8) = false;
                app.BetweenStenosisEntryandPeakCheckBox_8.Enable= false;
            end
        end

        % Value changed function: Branch9CheckBox
        function Branch9CheckBoxValueChanged(app, event)
            value = app.Branch9CheckBox.Value;
            if (value == true)
                app.SliceNumberEditField_9Label.Enable = true;
                app.SliceNumberEditField_9.Enable = true;
                app.PostbranchradiuscmEditField_9Label.Enable = true;
                app.PostbranchradiuscmEditField_9.Enable = true;
                app.BranchradiuscmEditField_9Label.Enable = true;
                app.BranchradiuscmEditField_9.Enable = true;
                app.branchesCheckedOn(9) = true; 
                app.BetweenStenosisEntryandPeakCheckBox_9.Enable= true;
            else
                app.Branch9CheckBox.Value = false;
                app.SliceNumberEditField_9Label.Enable = false;
                app.SliceNumberEditField_9.Enable = false;
                app.PostbranchradiuscmEditField_9Label.Enable = false;
                app.PostbranchradiuscmEditField_9.Enable = false;
                app.BranchradiuscmEditField_9Label.Enable = false;
                app.BranchradiuscmEditField_9.Enable = false;
                app.branchesCheckedOn(9) = false;
                app.BetweenStenosisEntryandPeakCheckBox_9.Enable= false;
            end
        end

        % Value changed function: Branch10CheckBox
        function Branch10CheckBoxValueChanged(app, event)
            value = app.Branch10CheckBox.Value;
            if (value == true)
                app.SliceNumberEditField_10Label.Enable = true;
                app.SliceNumberEditField_10.Enable = true;
                app.PostbranchradiuscmEditField_10Label.Enable = true;
                app.PostbranchradiuscmEditField_10.Enable = true;
                app.BranchradiuscmEditField_10Label.Enable = true;
                app.BranchradiuscmEditField_10.Enable = true;
                app.branchesCheckedOn(10) = true; 
                app.BetweenStenosisEntryandPeakCheckBox_10.Enable= true;
            else
                app.Branch10CheckBox.Value = false;
                app.SliceNumberEditField_10Label.Enable = false;
                app.SliceNumberEditField_10.Enable = false;
                app.PostbranchradiuscmEditField_10Label.Enable = false;
                app.PostbranchradiuscmEditField_10.Enable = false;
                app.BranchradiuscmEditField_10Label.Enable = false;
                app.BranchradiuscmEditField_10.Enable = false;
                app.branchesCheckedOn(10) = false;
                app.BetweenStenosisEntryandPeakCheckBox_10.Enable= false;
            end
        end

        % Value changed function: BetweenStenosisEntryandPeakCheckBox
        function BetweenStenosisEntryandPeakCheckBoxValueChanged(app, event)
            value = app.BetweenStenosisEntryandPeakCheckBox.Value;
            if value == 1 
                app.branchNumber= 1;
                app.branches(app.branchNumber).sliceAtSideBranch=app.SliceNumberEditField.Value; 
                app.BRANCHCURVEData=BRANCHCURVE(app.DCTFFRGUI, app);
                app.BRANCHCURVEData.addlistener('AppClosedEvent', @app.onBRANCHCURVEDGuiClosed);
                
            else
                app.branches(app.branchNumber).aucOfSideBranch=0;  
            end
        end

        % Value changed function: BetweenStenosisEntryandPeakCheckBox_2
        function BetweenStenosisEntryandPeakCheckBox_2ValueChanged(app, event)
            value = app.BetweenStenosisEntryandPeakCheckBox_2.Value;
            if value == 1 
                app.branchNumber= 2;
                app.branches(app.branchNumber).sliceAtSideBranch=app.SliceNumberEditField_2.Value; 
                app.BRANCHCURVEData=BRANCHCURVE(app.DCTFFRGUI, app);
                app.BRANCHCURVEData.addlistener('AppClosedEvent', @app.onBRANCHCURVEDGuiClosed);
                
            else
                app.branches(app.branchNumber).aucOfSideBranch=0;  
            end
        end

        % Value changed function: BetweenStenosisEntryandPeakCheckBox_3
        function BetweenStenosisEntryandPeakCheckBox_3ValueChanged(app, event)
            value = app.BetweenStenosisEntryandPeakCheckBox_3.Value;
            if value == 1 
                app.branchNumber= 3;
                app.branches(app.branchNumber).sliceAtSideBranch=app.SliceNumberEditField_3.Value; 
                app.BRANCHCURVEData=BRANCHCURVE(app.DCTFFRGUI, app);
                app.BRANCHCURVEData.addlistener('AppClosedEvent', @app.onBRANCHCURVEDGuiClosed);
                
            else
                app.branches(app.branchNumber).aucOfSideBranch=0;  
            end
            
        end

        % Value changed function: BetweenStenosisEntryandPeakCheckBox_4
        function BetweenStenosisEntryandPeakCheckBox_4ValueChanged(app, event)
            value = app.BetweenStenosisEntryandPeakCheckBox_4.Value;
            if value == 1 
                app.branchNumber= 4;
                app.branches(app.branchNumber).sliceAtSideBranch=app.SliceNumberEditField_4.Value; 
                app.BRANCHCURVEData=BRANCHCURVE(app.DCTFFRGUI, app);
                app.BRANCHCURVEData.addlistener('AppClosedEvent', @app.onBRANCHCURVEDGuiClosed);
                
            else
                app.branches(app.branchNumber).aucOfSideBranch=0;
            end
            
        end

        % Value changed function: BetweenStenosisEntryandPeakCheckBox_5
        function BetweenStenosisEntryandPeakCheckBox_5ValueChanged(app, event)
            value = app.BetweenStenosisEntryandPeakCheckBox_5.Value;
            if value == 1 
                app.branchNumber= 5;
                app.branches(app.branchNumber).sliceAtSideBranch=app.SliceNumberEditField_5.Value; 
                app.BRANCHCURVEData=BRANCHCURVE(app.DCTFFRGUI, app);
                app.BRANCHCURVEData.addlistener('AppClosedEvent', @app.onBRANCHCURVEDGuiClosed);
                
            else
                app.branches(app.branchNumber).aucOfSideBranch=0;  
            end
        end

        % Value changed function: BetweenStenosisEntryandPeakCheckBox_6
        function BetweenStenosisEntryandPeakCheckBox_6ValueChanged(app, event)
            value = app.BetweenStenosisEntryandPeakCheckBox_6.Value;
            if value == 1 
                app.branchNumber= 6;
                app.branches(app.branchNumber).sliceAtSideBranch=app.SliceNumberEditField_3.Value; 
                app.BRANCHCURVEData=BRANCHCURVE(app.DCTFFRGUI, app);
                app.BRANCHCURVEData.addlistener('AppClosedEvent', @app.onBRANCHCURVEDGuiClosed);
                
            else
                app.branches(app.branchNumber).aucOfSideBranch=0;  
            end
        end

        % Value changed function: BetweenStenosisEntryandPeakCheckBox_7
        function BetweenStenosisEntryandPeakCheckBox_7ValueChanged(app, event)
            value = app.BetweenStenosisEntryandPeakCheckBox_7.Value;
            if value == 1 
                app.branchNumber= 7;
                app.branches(app.branchNumber).sliceAtSideBranch=app.SliceNumberEditField_7.Value; 
                app.BRANCHCURVEData=BRANCHCURVE(app.DCTFFRGUI, app);
                app.BRANCHCURVEData.addlistener('AppClosedEvent', @app.onBRANCHCURVEDGuiClosed);
                
            else
                app.branches(app.branchNumber).aucOfSideBranch=0;  
            end
        end

        % Value changed function: BetweenStenosisEntryandPeakCheckBox_8
        function BetweenStenosisEntryandPeakCheckBox_8ValueChanged(app, event)
            value = app.BetweenStenosisEntryandPeakCheckBox_8.Value;
            if value == 1 
                app.branchNumber= 8;
                app.branches(app.branchNumber).sliceAtSideBranch=app.SliceNumberEditField_8.Value; 
                app.BRANCHCURVEData=BRANCHCURVE(app.DCTFFRGUI, app);
                app.BRANCHCURVEData.addlistener('AppClosedEvent', @app.onBRANCHCURVEDGuiClosed);
                
            else
                app.branches(app.branchNumber).aucOfSideBranch=0;  
            end
        end

        % Value changed function: BetweenStenosisEntryandPeakCheckBox_9
        function BetweenStenosisEntryandPeakCheckBox_9ValueChanged(app, event)
            value = app.BetweenStenosisEntryandPeakCheckBox_9.Value;
            if value == 1 
                app.branchNumber= 9;
                app.branches(app.branchNumber).sliceAtSideBranch=app.SliceNumberEditField_9.Value; 
                app.BRANCHCURVEData=BRANCHCURVE(app.DCTFFRGUI, app);
                app.BRANCHCURVEData.addlistener('AppClosedEvent', @app.onBRANCHCURVEDGuiClosed);
                
            else
                app.branches(app.branchNumber).aucOfSideBranch=0;  
            end
        end

        % Value changed function: BetweenStenosisEntryandPeakCheckBox_10
        function BetweenStenosisEntryandPeakCheckBox_10ValueChanged(app, event)
            value = app.BetweenStenosisEntryandPeakCheckBox_10.Value;
            if value == 1 
                app.branchNumber= 10;
                app.branches(app.branchNumber).sliceAtSideBranch=app.SliceNumberEditField_10.Value; 
                app.BRANCHCURVEData=BRANCHCURVE(app.DCTFFRGUI, app);
                app.BRANCHCURVEData.addlistener('AppClosedEvent', @app.onBRANCHCURVEDGuiClosed);
                
            else
                app.branches(app.branchNumber).aucOfSideBranch=0;  
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MarkBranchUIFigure = uifigure('Visible', 'off');
            app.MarkBranchUIFigure.Name = 'Mark Branch';
            app.MarkBranchUIFigure.CloseRequestFcn = createCallbackFcn(app, @MarkBranchUIFigureCloseRequest, true);
            app.EnterButton = uibutton(app.MarkBranchUIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.Branch1CheckBox = uicheckbox(app.MarkBranchUIFigure);
            app.Branch1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Branch1CheckBoxValueChanged, true);
            app.Branch1CheckBox.Text = 'Branch 1';
            app.BranchSliceNumberLabel = uilabel(app.MarkBranchUIFigure);
            app.BranchSliceNumberLabel.Enable = 'off';
            app.BranchSliceNumberLabel.Text = {'Slice '; 'Number'};
            app.SliceNumberEditField = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.SliceNumberEditField.RoundFractionalValues = 'on';
            app.SliceNumberEditField.Enable = 'off';
            app.SliceNumberEditField.Value = 1;
            app.PostbranchradiuscmEditFieldLabel = uilabel(app.MarkBranchUIFigure);
            app.PostbranchradiuscmEditFieldLabel.Enable = 'off';
            app.PostbranchradiuscmEditFieldLabel.Text = {'Post branch '; 'radius (cm)'};
            app.PostbranchradiuscmEditField = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.PostbranchradiuscmEditField.Enable = 'off';
            app.BranchradiuscmEditFieldLabel = uilabel(app.MarkBranchUIFigure);
            app.BranchradiuscmEditFieldLabel.Enable = 'off';
            app.BranchradiuscmEditFieldLabel.Text = {'Branch '; 'radius (cm)'};
            app.BranchradiuscmEditField = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.BranchradiuscmEditField.Enable = 'off';
            app.Branch2CheckBox = uicheckbox(app.MarkBranchUIFigure);
            app.Branch2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Branch2CheckBoxValueChanged, true);
            app.Branch2CheckBox.Text = 'Branch 2';
            app.SliceNumberLabel = uilabel(app.MarkBranchUIFigure);
            app.SliceNumberLabel.Enable = 'off';
            app.SliceNumberLabel.Text = {'Slice '; 'Number'};
            app.SliceNumberEditField_2 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.SliceNumberEditField_2.Enable = 'off';
            app.PostbranchradiuscmEditField_2Label = uilabel(app.MarkBranchUIFigure);
            app.PostbranchradiuscmEditField_2Label.Enable = 'off';
            app.PostbranchradiuscmEditField_2Label.Text = {'Post branch '; 'radius (cm)'};
            app.PostbranchradiuscmEditField_2 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.PostbranchradiuscmEditField_2.Enable = 'off';
            app.BranchradiuscmEditField_2Label = uilabel(app.MarkBranchUIFigure);
            app.BranchradiuscmEditField_2Label.Enable = 'off';
            app.BranchradiuscmEditField_2Label.Text = {'Branch '; 'radius (cm)'};
            app.BranchradiuscmEditField_2 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.BranchradiuscmEditField_2.Enable = 'off';
            app.Branch3CheckBox = uicheckbox(app.MarkBranchUIFigure);
            app.Branch3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Branch3CheckBoxValueChanged, true);
            app.Branch3CheckBox.Text = 'Branch 3';
            app.SliceNumberEditField_3Label = uilabel(app.MarkBranchUIFigure);
            app.SliceNumberEditField_3Label.Enable = 'off';
            app.SliceNumberEditField_3Label.Text = {'Slice '; 'Number'};
            app.SliceNumberEditField_3 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.SliceNumberEditField_3.Enable = 'off';
            app.PostbranchradiuscmEditField_3Label = uilabel(app.MarkBranchUIFigure);
            app.PostbranchradiuscmEditField_3Label.Enable = 'off';
            app.PostbranchradiuscmEditField_3Label.Text = {'Post branch '; 'radius (cm)'};
            app.PostbranchradiuscmEditField_3 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.PostbranchradiuscmEditField_3.Enable = 'off';
            app.BranchradiuscmEditField_3Label = uilabel(app.MarkBranchUIFigure);
            app.BranchradiuscmEditField_3Label.Enable = 'off';
            app.BranchradiuscmEditField_3Label.Text = {'Branch '; 'radius (cm)'};
            app.BranchradiuscmEditField_3 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.BranchradiuscmEditField_3.Enable = 'off';
            app.Branch4CheckBox = uicheckbox(app.MarkBranchUIFigure);
            app.Branch4CheckBox.ValueChangedFcn = createCallbackFcn(app, @Branch4CheckBoxValueChanged, true);
            app.Branch4CheckBox.Text = 'Branch 4';
            app.SliceNumberEditField_4Label = uilabel(app.MarkBranchUIFigure);
            app.SliceNumberEditField_4Label.Enable = 'off';
            app.SliceNumberEditField_4Label.Text = {'Slice '; 'Number'};
            app.SliceNumberEditField_4 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.SliceNumberEditField_4.Enable = 'off';
            app.PostbranchradiuscmEditField_4Label = uilabel(app.MarkBranchUIFigure);
            app.PostbranchradiuscmEditField_4Label.Enable = 'off';
            app.PostbranchradiuscmEditField_4Label.Text = {'Post branch '; 'radius (cm)'};
            app.PostbranchradiuscmEditField_4 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.PostbranchradiuscmEditField_4.Enable = 'off';
            app.BranchradiuscmEditField_4Label = uilabel(app.MarkBranchUIFigure);
            app.BranchradiuscmEditField_4Label.Enable = 'off';
            app.BranchradiuscmEditField_4Label.Text = {'Branch '; 'radius (cm)'};
            app.BranchradiuscmEditField_4 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.BranchradiuscmEditField_4.Enable = 'off';
            app.Branch5CheckBox = uicheckbox(app.MarkBranchUIFigure);
            app.Branch5CheckBox.ValueChangedFcn = createCallbackFcn(app, @Branch5CheckBoxValueChanged, true);
            app.Branch5CheckBox.Text = 'Branch 5';
            app.SliceNumberEditField_5Label = uilabel(app.MarkBranchUIFigure);
            app.SliceNumberEditField_5Label.Enable = 'off';
            app.SliceNumberEditField_5Label.Text = {'Slice '; 'Number'};
            app.SliceNumberEditField_5 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.SliceNumberEditField_5.Enable = 'off';
            app.PostbranchradiuscmEditField_5Label = uilabel(app.MarkBranchUIFigure);
            app.PostbranchradiuscmEditField_5Label.Enable = 'off';
            app.PostbranchradiuscmEditField_5Label.Text = {'Post branch '; 'radius (cm)'};
            app.PostbranchradiuscmEditField_5 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.PostbranchradiuscmEditField_5.Enable = 'off';
            app.BranchradiuscmEditField_5Label = uilabel(app.MarkBranchUIFigure);
            app.BranchradiuscmEditField_5Label.Enable = 'off';
            app.BranchradiuscmEditField_5Label.Text = {'Branch '; 'radius (cm)'};
            app.BranchradiuscmEditField_5 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.BranchradiuscmEditField_5.Enable = 'off';
            app.Branch6CheckBox = uicheckbox(app.MarkBranchUIFigure);
            app.Branch6CheckBox.ValueChangedFcn = createCallbackFcn(app, @Branch6CheckBoxValueChanged, true);
            app.Branch6CheckBox.Text = 'Branch 6';
            app.SliceNumberEditField_6Label = uilabel(app.MarkBranchUIFigure);
            app.SliceNumberEditField_6Label.Enable = 'off';
            app.SliceNumberEditField_6Label.Text = {'Slice '; 'Number'};
            app.SliceNumberEditField_6 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.SliceNumberEditField_6.Enable = 'off';
            app.PostbranchradiuscmEditField_6Label = uilabel(app.MarkBranchUIFigure);
            app.PostbranchradiuscmEditField_6Label.Enable = 'off';
            app.PostbranchradiuscmEditField_6Label.Text = {'Post branch '; 'radius (cm)'};
            app.PostbranchradiuscmEditField_6 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.PostbranchradiuscmEditField_6.Enable = 'off';
            app.BranchradiuscmEditField_6Label = uilabel(app.MarkBranchUIFigure);
            app.BranchradiuscmEditField_6Label.Enable = 'off';
            app.BranchradiuscmEditField_6Label.Text = {'Branch '; 'radius (cm)'};
            app.BranchradiuscmEditField_6 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.BranchradiuscmEditField_6.Enable = 'off';
            app.Branch7CheckBox = uicheckbox(app.MarkBranchUIFigure);
            app.Branch7CheckBox.ValueChangedFcn = createCallbackFcn(app, @Branch7CheckBoxValueChanged, true);
            app.Branch7CheckBox.Text = 'Branch 7';
            app.SliceNumberEditField_7Label = uilabel(app.MarkBranchUIFigure);
            app.SliceNumberEditField_7Label.Enable = 'off';
            app.SliceNumberEditField_7Label.Text = {'Slice '; 'Number'};
            app.SliceNumberEditField_7 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.SliceNumberEditField_7.Enable = 'off';
            app.PostbranchradiuscmEditField_7Label = uilabel(app.MarkBranchUIFigure);
            app.PostbranchradiuscmEditField_7Label.Enable = 'off';
            app.PostbranchradiuscmEditField_7Label.Text = {'Post branch '; 'radius (cm)'};
            app.PostbranchradiuscmEditField_7 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.PostbranchradiuscmEditField_7.Enable = 'off';
            app.BranchradiuscmEditField_7Label = uilabel(app.MarkBranchUIFigure);
            app.BranchradiuscmEditField_7Label.Enable = 'off';
            app.BranchradiuscmEditField_7Label.Text = {'Branch '; 'radius (cm)'};
            app.BranchradiuscmEditField_7 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.BranchradiuscmEditField_7.Enable = 'off';
            app.Branch8CheckBox = uicheckbox(app.MarkBranchUIFigure);
            app.Branch8CheckBox.ValueChangedFcn = createCallbackFcn(app, @Branch8CheckBoxValueChanged, true);
            app.Branch8CheckBox.Text = 'Branch 8';
            app.SliceNumberEditField_8Label = uilabel(app.MarkBranchUIFigure);
            app.SliceNumberEditField_8Label.Enable = 'off';
            app.SliceNumberEditField_8Label.Text = {'Slice '; 'Number'};
            app.SliceNumberEditField_8 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.SliceNumberEditField_8.Enable = 'off';
            app.PostbranchradiuscmEditField_8Label = uilabel(app.MarkBranchUIFigure);
            app.PostbranchradiuscmEditField_8Label.Enable = 'off';
            app.PostbranchradiuscmEditField_8Label.Text = {'Post branch '; 'radius (cm)'};
            app.PostbranchradiuscmEditField_8 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.PostbranchradiuscmEditField_8.Enable = 'off';
            app.BranchradiuscmEditField_8Label = uilabel(app.MarkBranchUIFigure);
            app.BranchradiuscmEditField_8Label.Enable = 'off';
            app.BranchradiuscmEditField_8Label.Text = {'Branch '; 'radius (cm)'};
            app.BranchradiuscmEditField_8 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.BranchradiuscmEditField_8.Enable = 'off';
            app.Branch9CheckBox = uicheckbox(app.MarkBranchUIFigure);
            app.Branch9CheckBox.ValueChangedFcn = createCallbackFcn(app, @Branch9CheckBoxValueChanged, true);
            app.Branch9CheckBox.Text = 'Branch 9';
            app.SliceNumberEditField_9Label = uilabel(app.MarkBranchUIFigure);
            app.SliceNumberEditField_9Label.Enable = 'off';
            app.SliceNumberEditField_9Label.Text = {'Slice '; 'Number'};
            app.SliceNumberEditField_9 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.SliceNumberEditField_9.Enable = 'off';
            app.PostbranchradiuscmEditField_9Label = uilabel(app.MarkBranchUIFigure);
            app.PostbranchradiuscmEditField_9Label.Enable = 'off';
            app.PostbranchradiuscmEditField_9Label.Text = {'Post branch '; 'radius (cm)'};
            app.PostbranchradiuscmEditField_9 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.PostbranchradiuscmEditField_9.Enable = 'off';
            app.BranchradiuscmEditField_9Label = uilabel(app.MarkBranchUIFigure);
            app.BranchradiuscmEditField_9Label.Enable = 'off';
            app.BranchradiuscmEditField_9Label.Text = {'Branch '; 'radius (cm)'};
            app.BranchradiuscmEditField_9 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.BranchradiuscmEditField_9.Enable = 'off';
            app.Branch10CheckBox = uicheckbox(app.MarkBranchUIFigure);
            app.Branch10CheckBox.ValueChangedFcn = createCallbackFcn(app, @Branch10CheckBoxValueChanged, true);
            app.Branch10CheckBox.Text = 'Branch 10';
            app.SliceNumberEditField_10Label = uilabel(app.MarkBranchUIFigure);
            app.SliceNumberEditField_10Label.Enable = 'off';
            app.SliceNumberEditField_10Label.Text = {'Slice '; 'Number'};
            app.SliceNumberEditField_10 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.SliceNumberEditField_10.Enable = 'off';
            app.PostbranchradiuscmEditField_10Label = uilabel(app.MarkBranchUIFigure);
            app.PostbranchradiuscmEditField_10Label.Enable = 'off';
            app.PostbranchradiuscmEditField_10Label.Text = {'Post branch '; 'radius (cm)'};
            app.PostbranchradiuscmEditField_10 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.PostbranchradiuscmEditField_10.Enable = 'off';
            app.BranchradiuscmEditField_10Label = uilabel(app.MarkBranchUIFigure);
            app.BranchradiuscmEditField_10Label.Enable = 'off';
            app.BranchradiuscmEditField_10Label.Text = {'Branch '; 'radius (cm)'};
            app.BranchradiuscmEditField_10 = uieditfield(app.MarkBranchUIFigure, 'numeric');
            app.BranchradiuscmEditField_10.Enable = 'off';
            app.NumberofbranchbetweenprelesionandpostlesionsitesLabel = uilabel(app.MarkBranchUIFigure);
            app.NumberofbranchbetweenprelesionandpostlesionsitesLabel.Text = 'Number of branch between pre-lesion and post-lesion sites';
            app.BetweenStenosisEntryandPeakCheckBox = uicheckbox(app.MarkBranchUIFigure);
            app.BetweenStenosisEntryandPeakCheckBox.ValueChangedFcn = createCallbackFcn(app, @BetweenStenosisEntryandPeakCheckBoxValueChanged, true);
            app.BetweenStenosisEntryandPeakCheckBox.Enable = 'off';
            app.BetweenStenosisEntryandPeakCheckBox.Text = {'Between Stenosis'; 'Entry and Peak'};
            app.BetweenStenosisEntryandPeakCheckBox_2 = uicheckbox(app.MarkBranchUIFigure);
            app.BetweenStenosisEntryandPeakCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @BetweenStenosisEntryandPeakCheckBox_2ValueChanged, true);
            app.BetweenStenosisEntryandPeakCheckBox_2.Enable = 'off';
            app.BetweenStenosisEntryandPeakCheckBox_2.Text = {'Between Stenosis'; 'Entry and Peak'};
            app.BetweenStenosisEntryandPeakCheckBox_3 = uicheckbox(app.MarkBranchUIFigure);
            app.BetweenStenosisEntryandPeakCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @BetweenStenosisEntryandPeakCheckBox_3ValueChanged, true);
            app.BetweenStenosisEntryandPeakCheckBox_3.Enable = 'off';
            app.BetweenStenosisEntryandPeakCheckBox_3.Text = {'Between Stenosis'; 'Entry and Peak'};
            app.BetweenStenosisEntryandPeakCheckBox_4 = uicheckbox(app.MarkBranchUIFigure);
            app.BetweenStenosisEntryandPeakCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @BetweenStenosisEntryandPeakCheckBox_4ValueChanged, true);
            app.BetweenStenosisEntryandPeakCheckBox_4.Enable = 'off';
            app.BetweenStenosisEntryandPeakCheckBox_4.Text = {'Between Stenosis'; 'Entry and Peak'};
            app.BetweenStenosisEntryandPeakCheckBox_5 = uicheckbox(app.MarkBranchUIFigure);
            app.BetweenStenosisEntryandPeakCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @BetweenStenosisEntryandPeakCheckBox_5ValueChanged, true);
            app.BetweenStenosisEntryandPeakCheckBox_5.Enable = 'off';
            app.BetweenStenosisEntryandPeakCheckBox_5.Text = {'Between Stenosis'; 'Entry and Peak'};
            app.BetweenStenosisEntryandPeakCheckBox_6 = uicheckbox(app.MarkBranchUIFigure);
            app.BetweenStenosisEntryandPeakCheckBox_6.ValueChangedFcn = createCallbackFcn(app, @BetweenStenosisEntryandPeakCheckBox_6ValueChanged, true);
            app.BetweenStenosisEntryandPeakCheckBox_6.Enable = 'off';
            app.BetweenStenosisEntryandPeakCheckBox_6.Text = {'Between Stenosis'; 'Entry and Peak'};
            app.BetweenStenosisEntryandPeakCheckBox_7 = uicheckbox(app.MarkBranchUIFigure);
            app.BetweenStenosisEntryandPeakCheckBox_7.ValueChangedFcn = createCallbackFcn(app, @BetweenStenosisEntryandPeakCheckBox_7ValueChanged, true);
            app.BetweenStenosisEntryandPeakCheckBox_7.Enable = 'off';
            app.BetweenStenosisEntryandPeakCheckBox_7.Text = {'Between Stenosis'; 'Entry and Peak'};
            app.BetweenStenosisEntryandPeakCheckBox_8 = uicheckbox(app.MarkBranchUIFigure);
            app.BetweenStenosisEntryandPeakCheckBox_8.ValueChangedFcn = createCallbackFcn(app, @BetweenStenosisEntryandPeakCheckBox_8ValueChanged, true);
            app.BetweenStenosisEntryandPeakCheckBox_8.Enable = 'off';
            app.BetweenStenosisEntryandPeakCheckBox_8.Text = {'Between Stenosis'; 'Entry and Peak'};
            app.BetweenStenosisEntryandPeakCheckBox_9 = uicheckbox(app.MarkBranchUIFigure);
            app.BetweenStenosisEntryandPeakCheckBox_9.ValueChangedFcn = createCallbackFcn(app, @BetweenStenosisEntryandPeakCheckBox_9ValueChanged, true);
            app.BetweenStenosisEntryandPeakCheckBox_9.Enable = 'off';
            app.BetweenStenosisEntryandPeakCheckBox_9.Text = {'Between Stenosis'; 'Entry and Peak'};
            app.BetweenStenosisEntryandPeakCheckBox_10 = uicheckbox(app.MarkBranchUIFigure);
            app.BetweenStenosisEntryandPeakCheckBox_10.ValueChangedFcn = createCallbackFcn(app, @BetweenStenosisEntryandPeakCheckBox_10ValueChanged, true);
            app.BetweenStenosisEntryandPeakCheckBox_10.Enable = 'off';
            app.BetweenStenosisEntryandPeakCheckBox_10.Text = {'Between Stenosis'; 'Entry and Peak'};
            % Show the figure after all components are created
            app.MarkBranchUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BRANCHGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MarkBranchUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MarkBranchUIFigure)
        end
    end
end