classdef BREAKERGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DefineBreakerUIFigure          matlab.ui.Figure
        MarkbreakerpointsButton        matlab.ui.control.Button
        NumberofbreakersDropDown       matlab.ui.control.DropDown
        NumberofbreakersDropDownLabel  matlab.ui.control.Label
        Slicenumberforbreaker6EditField  matlab.ui.control.NumericEditField
        Slicenumberforbreaker6EditFieldLabel  matlab.ui.control.Label
        Slicenumberforbreaker5EditField  matlab.ui.control.NumericEditField
        Slicenumberforbreaker5EditFieldLabel  matlab.ui.control.Label
        Slicenumberforbreaker4EditField  matlab.ui.control.NumericEditField
        Slicenumberforbreaker4EditFieldLabel  matlab.ui.control.Label
        Slicenumberforbreaker3EditField  matlab.ui.control.NumericEditField
        Slicenumberforbreaker3EditFieldLabel  matlab.ui.control.Label
        Slicenumberforbreaker2EditField  matlab.ui.control.NumericEditField
        Slicenumberforbreaker2EditFieldLabel  matlab.ui.control.Label
        Slicenumberforbreaker1EditField  matlab.ui.control.NumericEditField
        Slicenumberforbreaker1EditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        DCTFFRGUI;
    end
    
    properties (Access = public)
        numberOfBreakers;
        breakerSliceLocations = zeros(6,1);
        colorBackgroundNormalMode = [0.0 0.0 0.0];
        colorBackgroundDarkMode = [0.0 0.0 0.0];
        colorTextFieldNormalMode = [0.07,0.07,0.07];
        colorTextFieldDarkMode = [0.07,0.07,0.07];
        colorRegularFontDarkMode = [1.0 1.0 1.0];
        colorRegularFontNormalMode = [1.0 1.0 1.0];
    end
    
    events
        AppClosedEvent;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DCTFFRGUI)
            % Center the app
            screenSize = get(0, 'ScreenSize');
            appWidth = 243;
            appHeight = 243;
            appX = (screenSize(3) - appWidth) / 2;
            appY = (screenSize(4) - appHeight) / 2;
            app.DefineBreakerUIFigure.Position = [appX, appY, appWidth, appHeight];

            % Connecting with Main App
            app.DCTFFRGUI = DCTFFRGUI;
            % Change View Mode
            brChangeViewMode(app, app.DCTFFRGUI.viewMode);
        end

        % Value changed function: NumberofbreakersDropDown
        function NumberofbreakersDropDownValueChanged(app, event)
            value = str2double(app.NumberofbreakersDropDown.Value);
            app.numberOfBreakers = value;
            switch value
                case 0
                    app.Slicenumberforbreaker1EditField.Enable = false;
                    app.Slicenumberforbreaker1EditFieldLabel.Enable = false;
                    app.Slicenumberforbreaker2EditField.Enable = false;
                    app.Slicenumberforbreaker2EditFieldLabel.Enable = false;
                    app.Slicenumberforbreaker3EditField.Enable = false;
                    app.Slicenumberforbreaker3EditFieldLabel.Enable = false;
                    app.Slicenumberforbreaker4EditField.Enable = false;
                    app.Slicenumberforbreaker4EditFieldLabel.Enable = false;
                    app.Slicenumberforbreaker5EditField.Enable = false;
                    app.Slicenumberforbreaker5EditFieldLabel.Enable = false;
                    app.Slicenumberforbreaker6EditField.Enable = false;
                    app.Slicenumberforbreaker6EditFieldLabel.Enable = false;
                    
                    app.MarkbreakerpointsButton.Enable = false;
                case 1
                    app.Slicenumberforbreaker1EditField.Enable = true;
                    app.Slicenumberforbreaker1EditFieldLabel.Enable = true;

                    app.Slicenumberforbreaker2EditField.Enable = false;
                    app.Slicenumberforbreaker2EditFieldLabel.Enable = false;
                    app.Slicenumberforbreaker3EditField.Enable = false;
                    app.Slicenumberforbreaker3EditFieldLabel.Enable = false;
                    app.Slicenumberforbreaker4EditField.Enable = false;
                    app.Slicenumberforbreaker4EditFieldLabel.Enable = false;
                    app.Slicenumberforbreaker5EditField.Enable = false;
                    app.Slicenumberforbreaker5EditFieldLabel.Enable = false;
                    app.Slicenumberforbreaker6EditField.Enable = false;
                    app.Slicenumberforbreaker6EditFieldLabel.Enable = false;
                    
                    app.MarkbreakerpointsButton.Enable = true;
                case 2
                    app.Slicenumberforbreaker1EditField.Enable = true;
                    app.Slicenumberforbreaker1EditFieldLabel.Enable = true;
                    app.Slicenumberforbreaker2EditField.Enable = true;
                    app.Slicenumberforbreaker2EditFieldLabel.Enable = true;

                    app.Slicenumberforbreaker3EditField.Enable = false;
                    app.Slicenumberforbreaker3EditFieldLabel.Enable = false;
                    app.Slicenumberforbreaker4EditField.Enable = false;
                    app.Slicenumberforbreaker4EditFieldLabel.Enable = false;
                    app.Slicenumberforbreaker5EditField.Enable = false;
                    app.Slicenumberforbreaker5EditFieldLabel.Enable = false;
                    app.Slicenumberforbreaker6EditField.Enable = false;
                    app.Slicenumberforbreaker6EditFieldLabel.Enable = false;
                    
                    app.MarkbreakerpointsButton.Enable = true;
                case 3
                    app.Slicenumberforbreaker1EditField.Enable = true;
                    app.Slicenumberforbreaker1EditFieldLabel.Enable = true;
                    app.Slicenumberforbreaker2EditField.Enable = true;
                    app.Slicenumberforbreaker2EditFieldLabel.Enable = true;
                    app.Slicenumberforbreaker3EditField.Enable = true;
                    app.Slicenumberforbreaker3EditFieldLabel.Enable = true;

                    app.Slicenumberforbreaker4EditField.Enable = false;
                    app.Slicenumberforbreaker4EditFieldLabel.Enable = false;
                    app.Slicenumberforbreaker5EditField.Enable = false;
                    app.Slicenumberforbreaker5EditFieldLabel.Enable = false;
                    app.Slicenumberforbreaker6EditField.Enable = false;
                    app.Slicenumberforbreaker6EditFieldLabel.Enable = false;
                    
                    app.MarkbreakerpointsButton.Enable = true;
                case 4
                    app.Slicenumberforbreaker1EditField.Enable = true;
                    app.Slicenumberforbreaker1EditFieldLabel.Enable = true;
                    app.Slicenumberforbreaker2EditField.Enable = true;
                    app.Slicenumberforbreaker2EditFieldLabel.Enable = true;
                    app.Slicenumberforbreaker3EditField.Enable = true;
                    app.Slicenumberforbreaker3EditFieldLabel.Enable = true;
                    app.Slicenumberforbreaker4EditField.Enable = true;
                    app.Slicenumberforbreaker4EditFieldLabel.Enable = true;

                    app.Slicenumberforbreaker5EditField.Enable = false;
                    app.Slicenumberforbreaker5EditFieldLabel.Enable = false;
                    app.Slicenumberforbreaker6EditField.Enable = false;
                    app.Slicenumberforbreaker6EditFieldLabel.Enable = false;
                    
                    app.MarkbreakerpointsButton.Enable = true;
                case 5
                    app.Slicenumberforbreaker1EditField.Enable = true;
                    app.Slicenumberforbreaker1EditFieldLabel.Enable = true;
                    app.Slicenumberforbreaker2EditField.Enable = true;
                    app.Slicenumberforbreaker2EditFieldLabel.Enable = true;
                    app.Slicenumberforbreaker3EditField.Enable = true;
                    app.Slicenumberforbreaker3EditFieldLabel.Enable = true;
                    app.Slicenumberforbreaker4EditField.Enable = true;
                    app.Slicenumberforbreaker4EditFieldLabel.Enable = true;
                    app.Slicenumberforbreaker5EditField.Enable = true;
                    app.Slicenumberforbreaker5EditFieldLabel.Enable = true;

                    app.Slicenumberforbreaker6EditField.Enable = false;
                    app.Slicenumberforbreaker6EditFieldLabel.Enable = false;
                    
                    app.MarkbreakerpointsButton.Enable = true;
                case 6
                    app.Slicenumberforbreaker1EditField.Enable = true;
                    app.Slicenumberforbreaker1EditFieldLabel.Enable = true;
                    app.Slicenumberforbreaker2EditField.Enable = true;
                    app.Slicenumberforbreaker2EditFieldLabel.Enable = true;
                    app.Slicenumberforbreaker3EditField.Enable = true;
                    app.Slicenumberforbreaker3EditFieldLabel.Enable = true;
                    app.Slicenumberforbreaker4EditField.Enable = true;
                    app.Slicenumberforbreaker4EditFieldLabel.Enable = true;
                    app.Slicenumberforbreaker5EditField.Enable = true;
                    app.Slicenumberforbreaker5EditFieldLabel.Enable = true;
                    app.Slicenumberforbreaker6EditField.Enable = true;
                    app.Slicenumberforbreaker6EditFieldLabel.Enable = true;
                    
                    app.MarkbreakerpointsButton.Enable = true;
            end
        end

        % Button pushed function: MarkbreakerpointsButton
        function MarkbreakerpointsButtonPushed(app, event)
            if (app.numberOfBreakers > 0)
                switch app.numberOfBreakers
                    case 1
                        app.breakerSliceLocations(1) = app.Slicenumberforbreaker1EditField.Value;
                    case 2
                        app.breakerSliceLocations(1) = app.Slicenumberforbreaker1EditField.Value;
                        app.breakerSliceLocations(2) = app.Slicenumberforbreaker2EditField.Value;
                    case 3
                        app.breakerSliceLocations(1) = app.Slicenumberforbreaker1EditField.Value;
                        app.breakerSliceLocations(2) = app.Slicenumberforbreaker2EditField.Value;
                        app.breakerSliceLocations(3) = app.Slicenumberforbreaker3EditField.Value;
                    case 4
                        app.breakerSliceLocations(1) = app.Slicenumberforbreaker1EditField.Value;
                        app.breakerSliceLocations(2) = app.Slicenumberforbreaker2EditField.Value;
                        app.breakerSliceLocations(3) = app.Slicenumberforbreaker3EditField.Value;
                        app.breakerSliceLocations(4) = app.Slicenumberforbreaker4EditField.Value;
                    case 5
                        app.breakerSliceLocations(1) = app.Slicenumberforbreaker1EditField.Value;
                        app.breakerSliceLocations(2) = app.Slicenumberforbreaker2EditField.Value;
                        app.breakerSliceLocations(3) = app.Slicenumberforbreaker3EditField.Value;
                        app.breakerSliceLocations(4) = app.Slicenumberforbreaker4EditField.Value;
                        app.breakerSliceLocations(5) = app.Slicenumberforbreaker5EditField.Value;
                    case 6
                        app.breakerSliceLocations(1) = app.Slicenumberforbreaker1EditField.Value;
                        app.breakerSliceLocations(2) = app.Slicenumberforbreaker2EditField.Value;
                        app.breakerSliceLocations(3) = app.Slicenumberforbreaker3EditField.Value;
                        app.breakerSliceLocations(4) = app.Slicenumberforbreaker4EditField.Value;
                        app.breakerSliceLocations(5) = app.Slicenumberforbreaker5EditField.Value;
                        app.breakerSliceLocations(6) = app.Slicenumberforbreaker6EditField.Value;
                end
            end
            getBreakerDataFromBreakerGUI(app.DCTFFRGUI);
        end

        % Close request function: DefineBreakerUIFigure
        function DefineBreakerUIFigureCloseRequest(app, event)
            notify(app, 'AppClosedEvent');
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DefineBreakerUIFigure = uifigure('Visible', 'off');
            app.DefineBreakerUIFigure.Name = 'Mark Breaker';
            app.DefineBreakerUIFigure.CloseRequestFcn = createCallbackFcn(app, @DefineBreakerUIFigureCloseRequest, true);
            app.Slicenumberforbreaker1EditFieldLabel = uilabel(app.DefineBreakerUIFigure);
            app.Slicenumberforbreaker1EditFieldLabel.Enable = 'off';
            app.Slicenumberforbreaker1EditFieldLabel.Text = 'Slice number for breaker # 1';
            app.Slicenumberforbreaker1EditField = uieditfield(app.DefineBreakerUIFigure, 'numeric');
            app.Slicenumberforbreaker1EditField.Enable = 'off';
            app.Slicenumberforbreaker2EditFieldLabel = uilabel(app.DefineBreakerUIFigure);
            app.Slicenumberforbreaker2EditFieldLabel.Enable = 'off';
            app.Slicenumberforbreaker2EditFieldLabel.Text = 'Slice number for breaker # 2';
            app.Slicenumberforbreaker2EditField = uieditfield(app.DefineBreakerUIFigure, 'numeric');
            app.Slicenumberforbreaker2EditField.Enable = 'off';
            app.Slicenumberforbreaker3EditFieldLabel = uilabel(app.DefineBreakerUIFigure);
            app.Slicenumberforbreaker3EditFieldLabel.Enable = 'off';
            app.Slicenumberforbreaker3EditFieldLabel.Text = 'Slice number for breaker # 3';
            app.Slicenumberforbreaker3EditField = uieditfield(app.DefineBreakerUIFigure, 'numeric');
            app.Slicenumberforbreaker3EditField.Enable = 'off';
            app.Slicenumberforbreaker4EditFieldLabel = uilabel(app.DefineBreakerUIFigure);
            app.Slicenumberforbreaker4EditFieldLabel.Enable = 'off';
            app.Slicenumberforbreaker4EditFieldLabel.Text = 'Slice number for breaker # 4';
            app.Slicenumberforbreaker4EditField = uieditfield(app.DefineBreakerUIFigure, 'numeric');
            app.Slicenumberforbreaker4EditField.Enable = 'off';
            app.Slicenumberforbreaker5EditFieldLabel = uilabel(app.DefineBreakerUIFigure);
            app.Slicenumberforbreaker5EditFieldLabel.Enable = 'off';
            app.Slicenumberforbreaker5EditFieldLabel.Text = 'Slice number for breaker # 5';
            app.Slicenumberforbreaker5EditField = uieditfield(app.DefineBreakerUIFigure, 'numeric');
            app.Slicenumberforbreaker5EditField.Enable = 'off';
            app.Slicenumberforbreaker6EditFieldLabel = uilabel(app.DefineBreakerUIFigure);
            app.Slicenumberforbreaker6EditFieldLabel.Enable = 'off';
            app.Slicenumberforbreaker6EditFieldLabel.Text = 'Slice number for breaker # 6';
            app.Slicenumberforbreaker6EditField = uieditfield(app.DefineBreakerUIFigure, 'numeric');
            app.Slicenumberforbreaker6EditField.Enable = 'off';
            app.NumberofbreakersDropDownLabel = uilabel(app.DefineBreakerUIFigure);
            app.NumberofbreakersDropDownLabel.Text = 'Number of breakers';
            app.NumberofbreakersDropDown = uidropdown(app.DefineBreakerUIFigure);
            app.NumberofbreakersDropDown.Items = {'0', '1', '2', '3', '4', '5', '6'};
            app.NumberofbreakersDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofbreakersDropDownValueChanged, true);
            app.NumberofbreakersDropDown.Value = '0';
            app.MarkbreakerpointsButton = uibutton(app.DefineBreakerUIFigure, 'push');
            app.MarkbreakerpointsButton.ButtonPushedFcn = createCallbackFcn(app, @MarkbreakerpointsButtonPushed, true);
            app.MarkbreakerpointsButton.Enable = 'off';
            app.MarkbreakerpointsButton.Text = 'Mark breaker points';
            % Show the figure after all components are created
            app.DefineBreakerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BREAKERGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DefineBreakerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DefineBreakerUIFigure)
        end
    end
end