classdef DCTFFRGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        DCECTAnalysisMenu               matlab.ui.container.Menu
        DCECTImagesMenu                 matlab.ui.container.Menu
        ScannerModelMenu                matlab.ui.container.Menu
        SiemensMenu_3                   matlab.ui.container.Menu
        CanonMenu_3                     matlab.ui.container.Menu
        GEMenu_3                        matlab.ui.container.Menu
        CTAImagesMenu                   matlab.ui.container.Menu
        PreLesionCurveMenu              matlab.ui.container.Menu
        PostLesionCurveMenu             matlab.ui.container.Menu
        SampleROIMenu                   matlab.ui.container.Menu
        CTPTimeInfoMenu                 matlab.ui.container.Menu
        ApplyCTPTimeInfoMenu            matlab.ui.container.Menu
        StressandRestAnalysisMenu       matlab.ui.container.Menu
        StressImagesMenu_4              matlab.ui.container.Menu
        ScannerModelMenu_2              matlab.ui.container.Menu
        SiemensMenu_4                   matlab.ui.container.Menu
        CanonMenu_4                     matlab.ui.container.Menu
        GEMenu_4                        matlab.ui.container.Menu
        TimeInfoStudyStressMenu         matlab.ui.container.Menu
        RestImagesMenu_2                matlab.ui.container.Menu
        ScannerModelMenu_3              matlab.ui.container.Menu
        SiemensMenu_5                   matlab.ui.container.Menu
        CanonMenu_5                     matlab.ui.container.Menu
        GEMenu_5                        matlab.ui.container.Menu
        TimeInfoStudyRestMenu           matlab.ui.container.Menu
        PreLesionCurveMenu_2            matlab.ui.container.Menu
        PostLesionCurveMenu_2           matlab.ui.container.Menu
        SupervisedLearningMenu_2        matlab.ui.container.Menu
        DCTAVolumeMenu                  matlab.ui.container.Menu
        TrainingMenu_2                  matlab.ui.container.Menu
        TrainingDataMenu                matlab.ui.container.Menu
        ValidationDataMenu              matlab.ui.container.Menu
        TestingMenu_2                   matlab.ui.container.Menu
        TestDataMenu                    matlab.ui.container.Menu
        SavedNetworkMenu                matlab.ui.container.Menu
        SaveMenu                        matlab.ui.container.Menu
        DCECTAnalysisMenu_2             matlab.ui.container.Menu
        ResultsMenu                     matlab.ui.container.Menu
        StressandRestAnalysisMenu_2     matlab.ui.container.Menu
        ResultsMenu_2                   matlab.ui.container.Menu
        SupervisedLearningMenu          matlab.ui.container.Menu
        LabelledDataMenu                matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        DCECTAnalysisMenu_3             matlab.ui.container.Menu
        DCECTImagesMenu_2               matlab.ui.container.Menu
        CTAImagesMenu_2                 matlab.ui.container.Menu
        FittedCurvesMenu                matlab.ui.container.Menu
        FFRValuesMenu                   matlab.ui.container.Menu
        StressandRestAnalysisMenu_3     matlab.ui.container.Menu
        StressImagesMenu_3              matlab.ui.container.Menu
        RestImagesMenu                  matlab.ui.container.Menu
        FittedCurveMenu_2               matlab.ui.container.Menu
        WorkflowMenu                    matlab.ui.container.Menu
        SupervisedLearningMenu_4        matlab.ui.container.Menu
        TrainingMenu                    matlab.ui.container.Menu
        NetworkOptionMenu               matlab.ui.container.Menu
        CNNMenu                         matlab.ui.container.Menu
        UNETMenu                        matlab.ui.container.Menu
        StartTrainingMenu               matlab.ui.container.Menu
        TestingMenu                     matlab.ui.container.Menu
        ClassifyMenu                    matlab.ui.container.Menu
        DataLabelingMenu                matlab.ui.container.Menu
        SetApexPointMenu                matlab.ui.container.Menu
        SetLADPointMenu                 matlab.ui.container.Menu
        SetLCxPointMenu                 matlab.ui.container.Menu
        SetRCAPointMenu                 matlab.ui.container.Menu
        RemoveLastPointMenu             matlab.ui.container.Menu
        ScreenCaptureMenu               matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        DCECTAnalysisMenu_4             matlab.ui.container.Menu
        SetLevelWidthforStressTestMenu  matlab.ui.container.Menu
        SetLevelWidthforCTATestMenu     matlab.ui.container.Menu
        YaxisScalePreLesionMenu         matlab.ui.container.Menu
        AutoMenu                        matlab.ui.container.Menu
        ManualMenu                      matlab.ui.container.Menu
        YaxisScalePostLesionMenu        matlab.ui.container.Menu
        AutoMenu_2                      matlab.ui.container.Menu
        ManualMenu_2                    matlab.ui.container.Menu
        StressandRestAnalysisMenu_4     matlab.ui.container.Menu
        SetLevelWidthforStressTestMenu_2  matlab.ui.container.Menu
        SetLevelWidthforRestTestMenu    matlab.ui.container.Menu
        YaxisScalePreLesionMenu_2       matlab.ui.container.Menu
        AutoMenu_3                      matlab.ui.container.Menu
        ManualMenu_3                    matlab.ui.container.Menu
        YaxisScalePostLesionMenu_2      matlab.ui.container.Menu
        AutoMenu_4                      matlab.ui.container.Menu
        ManualMenu_4                    matlab.ui.container.Menu
        SupervisedLearningMenu_3        matlab.ui.container.Menu
        SetWindowLevelMenu              matlab.ui.container.Menu
        Menu_2                          matlab.ui.container.Menu
        ViewMenu                        matlab.ui.container.Menu
        OptionMenu                      matlab.ui.container.Menu
        BasicMenu                       matlab.ui.container.Menu
        FullMenu                        matlab.ui.container.Menu
        BackgroundMenu                  matlab.ui.container.Menu
        BrightMenu                      matlab.ui.container.Menu
        DarkMenu                        matlab.ui.container.Menu
        FontMenu                        matlab.ui.container.Menu
        ArialMenu                       matlab.ui.container.Menu
        PalatinoLinotypeMenu            matlab.ui.container.Menu
        TimesMenu                       matlab.ui.container.Menu
        Menu_3                          matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutDAIVeraMenu                matlab.ui.container.Menu
        UserGuideMenu                   matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        StressOnly                      matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        WidthSlider                     matlab.ui.control.Slider
        WidthSliderCTA                  matlab.ui.control.Slider
        LevelSliderCTA                  matlab.ui.control.Slider
        HUTextArea_4                    matlab.ui.control.TextArea
        ImportLesionButton              matlab.ui.control.Button
        SetTranslationButton            matlab.ui.control.Button
        LevelSlider                     matlab.ui.control.Slider
        FlipSliceNumbersCheckBox        matlab.ui.control.CheckBox
        ClearButton                     matlab.ui.control.Button
        GraphAppearanceDropDown         matlab.ui.control.DropDown
        GraphAppearanceDropDownLabel    matlab.ui.control.Label
        OutputParametersPanelStressOnly  matlab.ui.container.Panel
        ClearOutputsButtonStressOnly    matlab.ui.control.Button
        PostlesionShearStressPaEditFieldStressOnly  matlab.ui.control.EditField
        PostlesionShearStressPaEditField_2Label  matlab.ui.control.Label
        PrelesionShearStressPaEditFieldStressOnly  matlab.ui.control.EditField
        PrelesionShearStressPaEditField_2Label  matlab.ui.control.Label
        PostlesionFlowVelocitycmsEditFieldStressOnly  matlab.ui.control.EditField
        PostlesionFlowVelocitycmsEditField_2Label  matlab.ui.control.Label
        PrelesionFlowVelocitycmsEditFieldStressOnly  matlab.ui.control.EditField
        PrelesionFlowVelocitycmsEditField_2Label  matlab.ui.control.Label
        FFREditFieldStressOnly          matlab.ui.control.EditField
        FFREditField_2Label             matlab.ui.control.Label
        StressLabelStressOnly           matlab.ui.control.Label
        InputParametersforStressStudyPanelStressOnly  matlab.ui.container.Panel
        IntermediatebranchDropDown      matlab.ui.control.DropDown
        IntermediatebranchDropDownLabel  matlab.ui.control.Label
        MarkBreakerCheckBox             matlab.ui.control.CheckBox
        PostlesionLumenRadiuscmEditFieldStressOnly  matlab.ui.control.EditField
        PostlesionLumenRadiuscmEditField_2Label  matlab.ui.control.Label
        MarkStenosisCheckBox            matlab.ui.control.CheckBox
        PrelesionLumenRadiuscmEditFieldStressOnly  matlab.ui.control.EditField
        PrelesionLumenRadiuscmLabel_2   matlab.ui.control.Label
        MarkBranchCheckBox              matlab.ui.control.CheckBox
        ContrastConcentrationmglmLEditFieldStressOnly  matlab.ui.control.EditField
        ContrastConcentrationmgmLLabel  matlab.ui.control.Label
        ContrastVolumemLEditFieldStressOnly  matlab.ui.control.EditField
        ContrastVolumemLEditField_2Label  matlab.ui.control.Label
        ArteryTypeButtonGroupStressOnly  matlab.ui.container.ButtonGroup
        AortaButtonStressOnly           matlab.ui.control.RadioButton
        CoronaryButtonStressOnly        matlab.ui.control.RadioButton
        SystolicBloodPressuremmHgEditFieldStressOnly  matlab.ui.control.EditField
        ArterialBloodPressuremmHgLabel  matlab.ui.control.Label
        XrayTubeVoltagekVDropDown       matlab.ui.control.DropDown
        XrayTubeVoltagekVDropDownLabel  matlab.ui.control.Label
        FrictionFactorButtonGroupStressOnly  matlab.ui.container.ButtonGroup
        ColebrookButtonStressOnly       matlab.ui.control.RadioButton
        ChurchillButtonStressOnly       matlab.ui.control.RadioButton
        RestorStressConditionDropDown   matlab.ui.control.DropDown
        RestorStressConditionDropDownLabel  matlab.ui.control.Label
        ClearInputsButtonStressOnly     matlab.ui.control.Button
        CoronaryDominanceDropDown       matlab.ui.control.DropDown
        CoronaryDominanceDropDownLabel  matlab.ui.control.Label
        OutputButtonStressOnly          matlab.ui.control.Button
        CoronaryArteryDropDown          matlab.ui.control.DropDown
        CoronaryArteryDropDownLabel     matlab.ui.control.Label
        InterpolateCurrentSliceWithDropDownStressOnly  matlab.ui.control.DropDown
        InterpolateCurrentSliceWithDropDown_2Label_2  matlab.ui.control.Label
        SearchROIDropDownStressOnly     matlab.ui.control.DropDown
        SearchROIDropDown_2Label_2      matlab.ui.control.Label
        SamplingROIDropDownStressOnly   matlab.ui.control.DropDown
        SampleROILabel                  matlab.ui.control.Label
        MButton                         matlab.ui.control.Button
        veHeightCheckBoxStressOnly      matlab.ui.control.CheckBox
        SetPostLesionStressOnly         matlab.ui.control.Button
        SetPreLesionButtonStressOnly    matlab.ui.control.Button
        SetWindowButtonStressOnly       matlab.ui.control.Button
        SetWindowWidthStressOnly        matlab.ui.control.TextArea
        SetWindowLevelStressOnly        matlab.ui.control.TextArea
        SetWindowButtonCTAStressOnly    matlab.ui.control.Button
        SetWindowWidthCTAStressOnly     matlab.ui.control.TextArea
        SetWindowLevelCTAStressOnly     matlab.ui.control.TextArea
        TimePointsSliderStressOnlyLabel  matlab.ui.control.Label
        TimePointsSliderStressOnly      matlab.ui.control.Slider
        SlicesSliderStressOnlyLabel     matlab.ui.control.Label
        SlicesSliderCTAStressOnlyLabel  matlab.ui.control.Label
        HUTextArea_2                    matlab.ui.control.TextArea
        RemovePointPostCheckBoxStressOnly  matlab.ui.control.CheckBox
        ManualWashoutInputStressOnly_2  matlab.ui.control.TextArea
        SetLabel_2                      matlab.ui.control.Label
        ManualBaselineInputStressOnly_2  matlab.ui.control.TextArea
        FitCurvesButtonStressOnly_2     matlab.ui.control.Button
        ClearButton_3                   matlab.ui.control.Button
        EditPointCheckBox_2             matlab.ui.control.CheckBox
        WashoutLabel_2                  matlab.ui.control.Label
        BaselineLabel_2                 matlab.ui.control.Label
        SlicesSliderCTAStressOnly       matlab.ui.control.Slider
        SlicesSliderStressOnly          matlab.ui.control.Slider
        ManualZoomingCheckBoxStressOnly  matlab.ui.control.CheckBox
        CTAStudyLabelStressOnly         matlab.ui.control.Label
        StressStudyLabelStressOnly      matlab.ui.control.Label
        RemovePointPreCheckBoxStressOnly  matlab.ui.control.CheckBox
        ManualWashoutInputStressOnly    matlab.ui.control.TextArea
        SetLabel                        matlab.ui.control.Label
        ManualBaselineInputStressOnly   matlab.ui.control.TextArea
        FitCurvesButtonStressOnly       matlab.ui.control.Button
        ClearButton_2                   matlab.ui.control.Button
        DynamicAngiographicImagingDAIVeraPanel_2  matlab.ui.container.Panel
        UnauthorizedUseisProhibitedLabelStressOnly  matlab.ui.control.Label
        EditPointCheckBox               matlab.ui.control.CheckBox
        WashoutLabel                    matlab.ui.control.Label
        BaselineLabel                   matlab.ui.control.Label
        pdPlotAreaStressOnly            matlab.ui.control.UIAxes
        ImageAxesCTAStressOnly          matlab.ui.control.UIAxes
        paPlotAreaStressOnly            matlab.ui.control.UIAxes
        ImageAxesStressOnly             matlab.ui.control.UIAxes
        FFRVsSliceLocation              matlab.ui.control.UIAxes
        Tab                             matlab.ui.container.Tab
        ContextMenu                     matlab.ui.container.ContextMenu
        SetYaxisMaxMenu                 matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        SetYaxisMaxMenu_2               matlab.ui.container.Menu
    end

    % Private Variables
    properties (Access = private)
        STENOSISGUI;
        stenosisGuiData;
        BRANCHGUI;
        branchGuiData;
        BREAKERGUI;
        breakerGuiData;
        BRANCHCURVE;
        branchcurveData;
    end

    % Global Variables
    properties (Access = public)
        entrySlicerNum;
        exitSlicerNum;
        entrySliceThk;
        exitSliceThk;
        narowRatio;

        allDicomInfo;
        allDicomInfoRest;
        allDicomInfoStressOnly;
        allExtraPointsForDistanceInStressOnly = [];
        allFFRStressOnly = [];
        allFFRStressOnlySliceLocations = [];
        allFFRStressOnlyDicomSliceNumbers = [];
        allFlowVelocitiesStressOnly = [];
        allFlowVelocitiesSliceLocationsStressOnly = [];
        allResultsForCSVWrite = 'TimeStamp,RestOrStress,PreLesionSlice,PostLesionSlice,PreLesionCooridnate,PostLesionCoordinate,Prelesion AUC,Postlesion AUC,Prelesion Alpha,Prelesion Beta,Prelesion K,Postlesion Alpha,Postlesion Beta,Postlesion K,PreVolumetricFlowRate(mL/s),PostVolumetricFlowRate(mL/s),PreVelocity(cm/s),PostVelocity(cm/s),PreShearStress(Pa),PostShearStress(Pa),pLE(mmHg),pLF(mmHg),Re,Darcy Friction Factor(f),pB(mmHg),Vs1(cm/s),Vs2(cm/s),Vs3(cm/s),pLC(mmHg),pLS(mmHg),FFR\n';
        allStenosisData = struct();
        alphaA;
        alphaARest;
        alphaAStressOnly;
        alphaB;
        alphaBRest;
        alphaBStressOnly;
        arterySelections = ["RCA", "LM", "LAD", "LCX","LM-LAD","LM-LCX","IB","LM-IB"];
        arterySelectedIndex = 1;
        arteryType = 'Coronary';
        arteryTypeRest = 'Coronary';
        arteryTypeStressOnly = 'Coronary';
        aucA;
        aucARest;
        aucAEntryStressOnly = [];
        aucAStressOnly;
        aucB;
        aucBRest;
        aucBExitStressOnly = [];
        aucBStressOnly;
        betaA;
        betaARest;
        betaAStressOnly;
        betaB;
        betaBRest;
        betaBStressOnly;
        branchData = struct();
        breakerData;
        checkedStenosis = [];
        colorBackgroundNormalMode = [0.94 0.94 0.94];
        colorBackgroundDarkMode = [0.30 0.30 0.30];
        colorTextFieldNormalMode = [1.0 1.0 1.0];
        colorTextFieldDarkMode = [0.50 0.50 0.50];
        colorPlotAreaNormalMode = [1.0 1.0 1.0];
        colorPlotAreaDarkMode = [0.50 0.50 0.50];
        colorRegularFontDarkMode = [1.0 1.0 1.0];
        colorRegularFontNormalMode = [0.0 0.0 0.0];
        colorRegularFontHiddenMode = [0.80 0.80 0.80];
        colorTitleFontDarkMode = [0.65 0.81 0.92];
        colorTitleFontNormalMode = [0.00 0.45 0.74];
        colorUnauthorizedUseDarkMode = [1.00 0.67 0.67];
        colorUnauthorizedUseNormalMode = [1.00 0.00 0.00];
        concentrationofContrast;
        concentrationofContrastRest;
        coOrdinatesForCSVWrite = 'X,Y,Z,T\n';
        coOrdinatesForCSVWriteRest = 'X,Y,Z,T\n';
        coOrdinatesForCSVWriteStressOnly = 'X,Y,Z,T\n';
        ctaVolume;
        ctaInfo;
        currentSlice = 1;
        currentSliceCTA = 1;
        currentSlice3D = 1;
        currentSliceStressOnly = 1;
        currentSliceRest = 1;
        currentStenosisNumber = -1;
        currentStenosisID = 1;
        currentTimePoint = 1;
        currentTimePointRest = 1;
        currentTimePointStressOnly = 1;
        defaultThresholdCheck = 0;
        dicomInfo;
        dicomInfoRest;
        dicomInfoStressOnly;
        dicomFolder;
        dominanceTypes = ["Right", "Left", "Co"];
        dominanceType = "Right";
        entryCurveStressOnly;
        entryTimePointStressOnly;
        entrySliceLocations = [];
        entrySxStressOnly;
        entrySyStressOnly;
        entryQ = [];
        entryRoiObjectStressOnly;
        exitCurveStressOnly;
        exitTimePointStressOnly;
        exitSliceLocations = [];
        exitSxStressOnly;
        exitSyStressOnly;
        exitQ = [];
        exitRoiObjectStressOnly;
        f = 1;
        FAStressOnly;
        FBStressOnly;
        FFR;
        FFRRest;
        FFRStressOnly;
        FFRlastCalculated;
        fittedCurveEntryStressOnly;
        fittedCurveExitStressOnly;
        fittedCurvePa;
        fittedCurvePaRest;
        fittedCurvePaStressOnly;
        fittedCurvePd;
        fittedCurvePdRest;
        fittedCurvePdStressOnly;
        fittedTimeEntryStressOnly;
        fittedTimeExitStressOnly;
        fittedTimePa;
        fittedTimePaRest;
        fittedTimePaStressOnly;
        fittedTimePd;
        fittedTimePdRest;
        fittedTimePdStressOnly;
        fMethod = 'Colebrook';
        fMethodRest = 'Colebrook';
        fMethodStressOnly = 'Colebrook';
        frictionFactor;
        frictionFactorRest;
        frictionFactorStressOnly;
        fontName = 'Arial';
        fourDImageSet;
        fourDImageSetStressOnly;
        hAhBDiffNegative = true;
        hAhBRestDiffNegative = true;
        hAhBDiffNegativeStressOnly = true;
        hB;
        hBRest;
        hBStressOnly;
        hBText;
        hBTextStressOnly;
        im;
        imCTA;
        imJPEG;
        imRest;
        imStressOnly;
        interpolatedSampledPointsPa;
        interpolatedSampledPointsPaRest;
        interpolatedSampledPointsPaStressOnly;
        interpolatedSampledPointsPd;
        interpolatedSampledPointsPdRest;
        interpolatedSampledPointsPdStressOnly;
        interpolatedTimePoints;
        interpolatedTimePointsRest;
        interpolatedTimePointsStressOnly;
        kA;
        kARest;
        kAStressOnly;
        kB;
        kBRest;
        kBStressOnly;
        L;
        LRest;
        LStressOnly;
        LText;
        LTextStressOnly;
        labelImageDirectory;
        manualBaselineCheck;
        manualBaselineCheckRest;
        manualBaselineCheckStressOnly;
        manualBaselineValue = 0;
        manualBaselineValueRest = 0;
        manualBaselineValueStressOnly = 0;
        manualWashoutValue = 0;
        manualWashoutValueRest = 0;
        manualWashoutValueStressOnly = 0;
        maxHUCorrectionSupported = 3;
        maxStenosisSupported = 5;
        maxWindow3D;
        maxWindow;
        maxWindowCTA;
        maxWindowRest;
        maxWindowStressOnly;
        maxYAxisInterval = 50;
        minWindow3D;
        minWindow;
        minWindowCTA;
        minWindowRest;
        minWindowStressOnly;
        movieStop = false;
        movieFrameDuration = 0.20;
        mTotal;
        numberOfBranches = 0;
        numberOfStenosis = 0;
        pa;
        paPlotAreaPointModifyCounter = 0;
        paStressOnly;
        paCurve;
        paCurveRest;
        paCurveStressOnly;
        paRest;
        paSlice;
        paSliceRest;
        paSliceStressOnly;
        paTimePoint;
        paTimePointRest;
        paTimePointsStressOnly;
        paCurrentTimePointStressOnly;
        pB;
        pBRest;
        pBStressOnly;
        pd;
        pdPlotAreaPointModifyCounter = 0;
        pdStressOnly;
        pdCurve;
        pdCurveRest;
        pdCurveStressOnly;
        pdRest;
        pdSlice;
        pdSliceRest;
        pdSliceStressOnly;
        pdTimePoint;
        pdTimePointRest;
        pdTimePointsStressOnly;
        pdCurrentTimePointStressOnly;
        pEntryStressOnly;
        pExitStressOnly;
        pLC;
        pLCRest;
        pLCStressOnly;
        pLE;
        pLERest;
        pLEStressOnly;
        pLF;
        pLFRest;
        pLFStresOnly;
        pLS;
        pLSRest;
        pLSStressOnly;
        plotData = struct();
        plotIDsToRemoveFromPlotArea;
        plotToRemovePoint;
        plotX = [1:10];
        plotXRest = [1:10];
        plotXEntryStressOnly = [1:10];
        plotXExitStressOnly = [1:10];
        plotXPreStressOnly = [1:10];
        plotXPostStressOnly = [1:10];
        postCFR;
        postRoiObjectStressOnly;
        postQ;
        postQRest;
        postQStressOnly;
        postTextRead = false;
        postTextReadStressOnly = false;
        postTracerPercentage;
        postTracerPercentageRest;
        preCFR;
        preRoiObjectStressOnly;
        preQ;
        preQRest;
        preQStressOnly;
        postQinitial;
        preTextRead = false;
        preTextReadStressOnly = false;
        preTracerPercentage;
        preTracerPercentageRest;
        previousStenosisInfo = struct();
        ptsA;
        ptsApex = [];
        ptsARest;
        ptsAStressOnly;
        ptsB;
        ptsBRest;
        ptsBStressOnly;
        ptsLAD = [];
        ptsLCx = [];
        ptsRCA = [];
        Re;
        ReRest;
        ReStressOnly;
        restImageSet;
        reversedALLFFRStressOnly = false;
        rmseA;
        rmseARest;
        rmseAStressOnly;
        rmseB;
        rmseBRest;
        rmseBStressOnly;
        roiM = 2;
        roiMRest = 2;
        roiMStressOnly = 2;
        roiN = 2;
        roiNRest = 2;
        roiNStressOnly = 2;
        roiObjectStressOnly = struct();
        rs1 = [];
        rs2 = [];
        rs3 = [];
        rxPost = [];
        rxPostRest = [];
        rxPostStressOnly = [];
        rxPre = [];
        rxPreRest = [];
        rxPreStressOnly = [];
        ryPost = [];
        ryPostRest = [];
        ryPostStressOnly = [];
        ryPre = [];
        ryPreRest = [];
        ryPreStressOnly = [];
        savedNetwork;
        scannerType = 'Siemens';
        scannerTypeRest = 'Siemens';
        scannerTypeStressOnly = 'Siemens';
        scannerName = ["Siemens", "Canon", "GE"];
        searchROIM = 6;
        searchROIMRest = 6;
        searchROIMStressOnly = 6;
        searchROIN = 6;
        searchROINRest = 6;
        searchROINStressOnly = 6;
        selectedTrainingOption = 'CNN';
        shearStressA;
        shearStressARest;
        shearStressAStressOnly;
        shearStressB;
        shearStressBRest;
        shearStressBStressOnly;
        sliceToBeInterpolatedWith;
        sliceToBeInterpolatedWithRest;
        sliceToBeInterpolatedWithStressOnly;
        stenosisData = struct();
        stenosisPercentage = [];
        supervisedLearningLabeledPoints;
        supervisedLearningSaveCount = 1;
        sxPost = [];
        sxPostRest = [];
        sxPostStressOnly = [];
        sxPre = [];
        sxPreRest = [];
        sxPreStressOnly = [];
        syPost = [];
        syPostRest = [];
        syPostStressOnly = [];
        syPre = [];
        syPreRest = [];
        syPreStressOnly = [];
        testDirectory;
        threeDVolume;
        thresholdSelection = 'Coronary Arteries';
        timeToCopy;
        timePoints;
        timePointsRest;
        timePointsStressOnly;
        timeUnit = 's';
        timeUnitRest = 's';
        timeUnitStressOnly = 's';
        tracerMassAtBranches;
        tracerMassAtTrunkAfterEachSideBranch;
        trainDirectory;
        trainedNetwork;
        trainingDS_LAD;
        trainingDS_RCA;
        trainingOptions = ["CNN", "UNET"];
        VA;
        validationDirectory;
        validationDS_LAD;
        validationDS_RCA;
        VARest;
        VAStressOnly;
        VB;
        VBRest;
        VBStressOnly;
        vesselThresholdMax;
        vesselThresholdMin;
        versionNumberDAI = 'a.11.10';
        viewMode = 'Normal';
        volumeJPEG;
        volumeofContrast;
        volumeofContrastRest;
        Vs1 = [];
        Vs2 = [];
        Vs3 = [];
        Vs1Rest = [];
        Vs2Rest = [];
        Vs3Rest = [];
        Vs1StressOnly = [];
        Vs2StressOnly = [];
        Vs3StressOnly = [];
        xDataEntryStressOnly;
        xDataExitStressOnly;
        xDataPreStressOnly;
        xDataPostStressOnly;
        xRayTubeVoltages = ["80", "100", "120"];
        xRayTubeVoltageIndex = 1;
        yAxisLimitMin = -50;
        yAxisLimitMax = 500;

        %variables added by Carlos's Mendes
        StenosisInfo= struct('stenosisLenght',[],'entrySlice',[], 'exitSlice',[], 'xEntry',[],'yEntry',[],'xExit',[],'yExit',[]);
        
    end

    methods (Access = private)

        function updateimage(app,imagefile)

            % Try displaying the image
            try
                app.im = imagefile;
            catch ME
                % Error
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end

            % Try showing the plots
            try
                imagesc(app.ImageAxes, app.im, [app.minWindow, app.maxWindow]);
                enableDefaultInteractivity(app.ImageAxes);
            catch ME
                % Error
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end

        end

        function updateimageStressOnly(app,imagefile)

            % Try displaying the image
            try
                app.imStressOnly = imagefile;
            catch ME
                % Error
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end

            % Try showing the plots
            try
                imagesc(app.ImageAxesStressOnly, app.imStressOnly, [app.minWindowStressOnly, app.maxWindowStressOnly]);
                enableDefaultInteractivity(app.ImageAxesStressOnly);
            catch ME
                % Error
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end

        end

        function updateImageRest(app,imagefile)

            % Try displaying the image
            try
                app.imRest = imagefile;
            catch ME
                % Error
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end


            % Try showing the plots
            try
                imagesc(app.ImageAxesRest, app.imRest, [app.minWindowRest, app.maxWindowRest]);
                enableDefaultInteractivity(app.ImageAxesRest);
            catch ME
                % Error
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end

        end

        function updateimage3D(app,imagefile)

            % Try displaying the image
            try
                app.im = imagefile;
            catch ME
                % Error
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end

            % Try showing the plots
            try
                imagesc(app.ImageAxes3D, app.im, [app.minWindow3D, app.maxWindow3D]);
            catch ME
                % Error
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end

        end

        function updateimage3DJPEG(app,imagefile)

            % Try displaying the image
            try
                app.imJPEG = imagefile;
            catch ME
                % Error
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end

            % Try showing the plots
            try
                imagesc(app.ImageAxes3DJPEG, app.imJPEG);
            catch ME
                % Error
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end

        end

        function updateimageCTA(app,imagefile)

            % Try displaying the image
            try
                app.imCTA = imagefile;
            catch ME
                % Error
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end

            % Try showing the plots
            try
                imagesc(app.ImageAxesCTAStressOnly, app.imCTA, [app.minWindowCTA, app.maxWindowCTA]);
            catch ME
                % Error
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end

        end

        function drawPreLesionCurve(app)
            drawCurve(app, 'pre', 'stress');
        end

        function drawPostLesionCurve(app)
            drawCurve(app, 'post', 'stress');
        end

        function drawPreLesionCurveRest(app)
            drawCurve(app, 'pre', 'rest');
        end

        function drawPostLesionCurveRest(app)
            drawCurve(app, 'post', 'rest');
        end

        function drawPreLesionCurveStressOnly(app)
            drawCurve(app, 'pre', 'stressOnly');
            app.xDataPreStressOnly = app.plotData.pre.data(1).xData;
        end

        function drawPostLesionCurveStressOnly(app)
            drawCurve(app, 'post', 'stressOnly');
            app.xDataPostStressOnly = app.plotData.post.data(1).xData;
        end

        function drawEntryCurveStressOnly(app)
            drawCurve(app, 'entry', 'stressOnly');
            % The xData for currentStenosisID will stay in 2 index further
            index = app.currentStenosisID * 2 + 1;
            app.xDataEntryStressOnly = app.plotData.pre.data(index).xData;
        end

        function drawExitCurveStressOnly(app)
            drawCurve(app, 'exit', 'stressOnly');
            % The xData for currentStenosisID will stay in 2 index further
            index = app.currentStenosisID * 2 + 1;
            app.xDataExitStressOnly = app.plotData.post.data(index).xData;
        end

        function onStenosisGuiClosed(app, ~, ~)
            app.MarkStenosisCheckBox.Value = false;
            app.numberOfStenosis = 0;

            app.entryCurveStressOnly = [];
            app.fittedCurveEntryStressOnly = [];
            app.exitCurveStressOnly = [];
            app.fittedCurveExitStressOnly = [];
            plotIDsToRemove = find(app.checkedStenosis);
            for i = 1:max(size(plotIDsToRemove))
                app.plotIDsToRemoveFromPlotArea(end+1) = plotIDsToRemove(i) * 2 + 1;
                app.plotIDsToRemoveFromPlotArea(end+1) = plotIDsToRemove(i) * 2 + 2;
            end
            app.plotIDsToRemoveFromPlotArea = unique(app.plotIDsToRemoveFromPlotArea);
            showPlot(app);
        end

        function onBranchGuiClosed(app, ~, ~)
            app.MarkBranchCheckBox.Value = false;
        end

        function onBreakerGuiClosed(app, ~, ~)
            app.MarkBreakerCheckBox.Value = false;
        end

        function StressImagesMenuLoader(app, choice)

            if (nargin < 2)
                choice = false;
            end

            % Display uigetfile dialog
            p = uigetdir;
            app.dicomFolder=p;
            if (p == 0)
                % Do Nothing
                figure(app.UIFigure);
            else
                set(gcf, 'Pointer','watch');
                drawnow;
                figure(app.UIFigure);

                try
                    % Read dicom files:
                    [app.fourDImageSetStressOnly, app.timePointsStressOnly, app.timeUnitStressOnly, app.allDicomInfoStressOnly] = readAndSplitImages(p, app.scannerTypeStressOnly, choice);
                    app.dicomInfoStressOnly = app.allDicomInfoStressOnly{1};
                    app.interpolatedTimePointsStressOnly = app.timePointsStressOnly;
                    app.paTimePointsStressOnly = app.timePointsStressOnly;
                    app.pdTimePointsStressOnly = app.timePointsStressOnly;
                    app.entryTimePointStressOnly = app.timePointsStressOnly;
                    app.exitTimePointStressOnly = app.timePointsStressOnly;

                    set(gcf, 'Pointer','arrow');

                    % Make sure user didn't cancel uigetfile dialog
                    if (ischar(p))
                        % Generate the First Image, update sliders' positions and Send it for displaying
                        
                        firstImage(1:512,1:512) = app.fourDImageSetStressOnly(1,1,1:512,1:512);
                        app.SlicesSliderStressOnly.Limits = [1 size(app.fourDImageSetStressOnly,1)];
                        tickValues = getSliderTicks(1, size(app.fourDImageSetStressOnly,1));
                        app.SlicesSliderStressOnly.MajorTicks = tickValues;
                        app.TimePointsSliderStressOnly.Limits = [1 size(app.fourDImageSetStressOnly,2)];
                        app.TimePointsSliderStressOnly.MajorTicks = getSliderTicks(1, size(app.fourDImageSetStressOnly,2));
                        app.TimePointsSliderStressOnly.MajorTickLabels = arrayfun(@num2str, app.TimePointsSliderStressOnly.MajorTicks, 'UniformOutput', 0);
                        app.SlicesSliderStressOnly.MinorTicks = [1:size(app.fourDImageSetStressOnly,1)];
                        app.FFRVsSliceLocation.XLim = [1 size(app.fourDImageSetStressOnly,1)];
                        minValue = min(app.fourDImageSetStressOnly(:));
                        maxValue = max(app.fourDImageSetStressOnly(:));
                        width = maxValue - minValue;
                        level = (maxValue + minValue) / 2;
                        app.minWindowStressOnly = level - width / 2;
                        app.maxWindowStressOnly = level + width / 2;
                        app.currentSliceStressOnly = 1;
                        app.currentTimePointStressOnly = 1;
                        app.SlicesSliderStressOnly.Value = 1;
                        app.SlicesSliderStressOnlyLabel.Text = sprintf('Slice %d/%d', app.currentSliceStressOnly, size(app.fourDImageSetStressOnly, 1));
                        app.TimePointsSliderStressOnly.Value = 1;
                        updateimageStressOnly(app, firstImage);

                        
                    end

                    % Set Initial y axes limits
                    app.paPlotArea.YLim = [-50 500];
                    app.pdPlotArea.YLim = [-50 500];

                catch ME
                    set(gcf, 'Pointer','arrow');
                    msg = strcat('The DAI did not fail, there seems to be an issue with this dataset in particular: ', ME.message);
                    errordlg(msg);
                end

            end
        end

        function StreesImageLoaderInStressRestTab(app)
            % Display uigetfile dialog
            p = uigetdir;
            if (p == 0)
                % Do nothing
                figure(app.UIFigure);
            else
                set(gcf, 'Pointer','watch');
                drawnow;
                figure(app.UIFigure);

                try
                    % Read dicom files:
                    [app.fourDImageSet, app.timePoints, app.timeUnit, app.allDicomInfo] = readAndSplitImages(p, app.scannerType);
                    app.dicomInfo = app.allDicomInfo{1};
                    app.interpolatedTimePoints = app.timePoints;
                    set(gcf, 'Pointer','arrow');

                    % Make sure user didn't cancel uigetfile dialog
                    if (ischar(p))
                        % Generate the First Image, update sliders' positions and Send it for displaying
                        firstImage(1:512,1:512) = app.fourDImageSet(1,1,1:512,1:512);
                        app.SlicesSlider.Limits = [1 size(app.fourDImageSet,1)];
                        tickValues = getSliderTicks(1, size(app.fourDImageSet,1));
                        app.SlicesSlider.MajorTicks = tickValues;
                        app.TimePointsSlider.Limits = [1 size(app.fourDImageSet,2)];
                        app.TimePointsSlider.MajorTicks = [1:size(app.fourDImageSet,2)];
                        app.TimePointsSlider.MajorTickLabels = arrayfun(@num2str, app.TimePointsSlider.MajorTicks, 'UniformOutput', 0);
                        app.SlicesSlider.MinorTicks = [1:size(app.fourDImageSet,1)];
                        level = 500;
                        width = 1500;
                        app.minWindow = level - width / 2;
                        app.maxWindow = level + width / 2;
                        app.currentSlice = 1;
                        app.currentTimePoint = 1;
                        app.SlicesSlider.Value = 1;
                        app.SlicesSlider_2Label.Text = sprintf('Slice %d/%d', app.currentSlice, size(app.fourDImageSet, 1));
                        app.TimePointsSlider.Value = 1;
                        updateimage(app, firstImage);
                    end

                    % Set Initial y axes limits
                    app.paPlotArea.YLim = [-50 500];
                    app.pdPlotArea.YLim = [-50 500];
                catch ME
                    set(gcf, 'Pointer','arrow');
                    msg = strcat('The DAI did not fail, there seems to be an issue with this dataset in particular: ', ME.message);
                    errordlg(msg);
                end
            end
        end

        function RestImageLoaderInStressRestTab(app)
            % Display uigetfile dialog
            p = uigetdir;
            if (p == 0)
                % Do nothing
                figure(app.UIFigure);
            else
                set(gcf, 'Pointer','watch');
                drawnow;
                figure(app.UIFigure);

                try
                    % Read dicom files:
                    [app.restImageSet, app.timePointsRest, app.timeUnitRest, app.allDicomInfoRest] = readAndSplitImages(p, app.scannerTypeRest);
                    app.dicomInfoRest = app.allDicomInfoRest{1};
                    app.interpolatedTimePointsRest = app.timePointsRest;
                    set(gcf, 'Pointer','arrow');

                    % Make sure user didn't cancel uigetfile dialog
                    if (ischar(p))
                        % Generate the First Image, update sliders' positions and Send it for displaying
                        firstImage(1:512,1:512) = app.restImageSet(1,1,1:512,1:512);
                        app.SlicesSliderRest.Limits = [1 size(app.restImageSet,1)];
                        tickValues = getSliderTicks(1, size(app.restImageSet,1));
                        app.SlicesSliderRest.MajorTicks = tickValues;
                        app.TimePointsSliderRest.Limits = [1 size(app.restImageSet,2)];
                        app.TimePointsSliderRest.MajorTicks = [1:size(app.restImageSet,2)];
                        app.TimePointsSliderRest.MajorTickLabels = arrayfun(@num2str, app.TimePointsSliderRest.MajorTicks, 'UniformOutput', 0);
                        app.SlicesSliderRest.MinorTicks = [1:size(app.restImageSet,1)];
                        level = 500;
                        width = 1500;
                        app.minWindowRest = level - width / 2;
                        app.maxWindowRest = level + width / 2;
                        app.currentSliceRest = 1;
                        app.currentTimePointRest = 1;
                        app.SlicesSliderRest.Value = 1;
                        app.SlicesSliderLabelRest.Text = sprintf('Slice %d/%d', app.currentSliceRest, size(app.restImageSet, 1));
                        app.TimePointsSliderRest.Value = 1;
                        updateImageRest(app, firstImage);
                    end

                    % Set Initial y axes limits
                    app.paPlotArea.YLim = [-50 500];
                    app.pdPlotArea.YLim = [-50 500];
                catch ME
                    set(gcf, 'Pointer','arrow');
                    msg = strcat('The DAI did not fail, there seems to be an issue with this dataset in particular: ', ME.message);
                    errordlg(msg);
                end
            end
        end

        function updateCoordinates(app, axis)
            if (strcmp(axis,'pre'))
                app.HUTextArea.Value = sprintf('HU = %.2f',app.paPlotAreaStressOnly.CurrentPoint(1,2));
            end
            if (strcmp(axis,'post'))
                app.HUTextArea_2.Value = sprintf('HU = %.2f',app.pdPlotAreaStressOnly.CurrentPoint(1,2));
            end
        end
    end

    methods (Access = public)

        function getBreakerDataFromBreakerGUI(app)
            app.breakerData = app.breakerGuiData;
            app.allExtraPointsForDistanceInStressOnly = []; % Modification done by Carlos Mendes : This resets the variable that stores the extrapoints.
            if (app.breakerData.numberOfBreakers > 0)
                for slice = 1 : app.breakerData.numberOfBreakers
                    sliceLocation = app.breakerData.breakerSliceLocations(slice);
                    app.SlicesSliderStressOnly.Value = sliceLocation;
                    event = struct('Value', 1, 'PreviousValue', 0);
                    SlicesSliderStressOnlyValueChanging(app, event);
                    app.SlicesSliderStressOnlyLabel.Text = sprintf('Slice %d/%d', sliceLocation, size(app.fourDImageSetStressOnly, 1));

                    app.currentSliceStressOnly = sliceLocation;
                    imageData(1:512, 1:512) = app.fourDImageSetStressOnly(app.currentSliceStressOnly,app.currentTimePointStressOnly,1:512,1:512);
                    updateimageStressOnly(app,imageData);

                    pts = drawpoint(app.ImageAxesStressOnly);
                    pointsOnSlice = [app.currentSliceStressOnly, app.currentTimePointStressOnly, round(pts.Position(2)), round(pts.Position(1))];
                    app.allExtraPointsForDistanceInStressOnly(end+1) = pointsOnSlice(3);
                    app.allExtraPointsForDistanceInStressOnly(end+1) = pointsOnSlice(4);
                    app.allExtraPointsForDistanceInStressOnly(end+1) = app.currentSliceStressOnly;
                end
            end
            disp(app.allExtraPointsForDistanceInStressOnly);
        end

        function getBranchDataFromBranchGUI(app)
            % app.branchGuiData holds all the values from the BRANCHGUI.
            app.branchData = app.branchGuiData;           
            app.numberOfBranches = sum(app.branchData.branchesCheckedOn);            
            % app.tracerMassAtBranches = getTracerMassAtEverySideBranch(app);
        end

        function getStenosisDataFromStenosisGUI(app)

            app.checkedStenosis = [false, false, false, false, false];
            changedStenosis = [false, false, false, false, false];
            changedEntryExitSlice = [false, false, false, false, false];
            plotIDsToRemove = app.stenosisGuiData.plotToRemove;

            if (~isempty(plotIDsToRemove))
                for i = 1:max(size(plotIDsToRemove))
                    app.plotIDsToRemoveFromPlotArea(end+1) = plotIDsToRemove(i) * 2 + 1;
                    app.plotIDsToRemoveFromPlotArea(end+1) = plotIDsToRemove(i) * 2 + 2;
                end
                app.plotIDsToRemoveFromPlotArea = unique(app.plotIDsToRemoveFromPlotArea);
                showPlot(app);
            else
                app.plotIDsToRemoveFromPlotArea = [];
            end

            app.previousStenosisInfo.entryQ = app.entryQ;
            app.previousStenosisInfo.exitQ = app.exitQ;
            app.previousStenosisInfo.entrySliceLocations = app.entrySliceLocations;
            app.previousStenosisInfo.exitSliceLocations = app.exitSliceLocations;
            app.previousStenosisInfo.stenosisPercentage = app.stenosisPercentage;
            app.previousStenosisInfo.aucAEntryStressOnly = app.aucAEntryStressOnly;
            app.previousStenosisInfo.aucBExitStressOnly = app.aucBExitStressOnly;
            app.previousStenosisInfo.rs1 = app.rs1;
            app.previousStenosisInfo.rs2 = app.rs2;
            app.previousStenosisInfo.rs3 = app.rs3;

            app.entryQ = [];
            app.exitQ = [];
            app.entrySliceLocations = [];
            app.exitSliceLocations = [];
            app.stenosisPercentage = [];
            app.aucAEntryStressOnly = [];
            app.aucBExitStressOnly = [];
            app.rs1 = [];
            app.rs2 = [];
            app.rs3 = [];

            app.allStenosisData = app.stenosisGuiData;

            if (app.stenosisGuiData.Stenosis1CheckBox.Value == true)
                app.stenosisData(1).NumberOfBranchBeforeEntry = app.stenosisGuiData.NumberofbranchbetweenprelesionandstenosisEditField.Value;
                app.stenosisData(1).NumberOfBranchBeforeExit = app.stenosisGuiData.EditField.Value;
                app.stenosisData(1).Lumenradiusatimmediateexitcm = app.stenosisGuiData.ExitLumenRadiuscmEditField.Value;
                app.stenosisData(1).Lumenradiusatimmediateentrycm = app.stenosisGuiData.EntryLumenRadiuscmEditField.Value;
                app.stenosisData(1).Stenosis = (app.stenosisGuiData.LumenNarrowingEditField.Value +10)/2 ;
                if (isfield(app.stenosisData(1), 'Slicenumberforimmediateexit') && ~isempty(app.stenosisData(1).Slicenumberforimmediateentry) && app.stenosisData(1).Slicenumberforimmediateentry > 0)
                    if (app.stenosisData(1).Slicenumberforimmediateexit ~= app.stenosisGuiData.ExitSliceNumberEditField.Value)
                        app.stenosisData(1).Slicenumberforimmediateexit = app.stenosisGuiData.ExitSliceNumberEditField.Value;
                        changedStenosis(1) = true;
                        changedEntryExitSlice(1) = true;
                    end
                    if (app.stenosisData(1).Slicenumberforimmediateentry ~= app.stenosisGuiData.EntrySliceNumberEditField.Value)
                        app.stenosisData(1).Slicenumberforimmediateentry = app.stenosisGuiData.EntrySliceNumberEditField.Value;
                        changedStenosis(1) = true;
                        changedEntryExitSlice(1) = true;
                    end
                else
                    app.stenosisData(1).Slicenumberforimmediateexit = app.stenosisGuiData.ExitSliceNumberEditField.Value;
                    app.stenosisData(1).Slicenumberforimmediateentry = app.stenosisGuiData.EntrySliceNumberEditField.Value;
                    changedStenosis(1) = true;
                    changedEntryExitSlice(1) = true;
                end

                app.checkedStenosis(1) = true;
            else
                app.stenosisData(1).Lumenradiusatimmediateexitcm = 0;
                app.stenosisData(1).Lumenradiusatimmediateentrycm = 0;
                app.stenosisData(1).Stenosis = 0;
                app.stenosisData(1).Slicenumberforimmediateexit = 0;
                app.stenosisData(1).Slicenumberforimmediateentry = 0;
                app.stenosisData(1).NumberOfBranchBeforeEntry = 0;
                app.stenosisData(1).NumberOfBranchBeforeExit = 0;
            end

            if (app.stenosisGuiData.Stenosis2CheckBox.Value == true)
                app.stenosisData(2).NumberOfBranchBeforeEntry = app.stenosisGuiData.NumberofbranchbetweenprelesionandstenosisEditField_2.Value;
                app.stenosisData(2).NumberOfBranchBeforeExit = app.stenosisGuiData.EditField_2.Value;
                app.stenosisData(2).Lumenradiusatimmediateexitcm = app.stenosisGuiData.ExitLumenRadiuscmEditField_2.Value;
                app.stenosisData(2).Lumenradiusatimmediateentrycm = app.stenosisGuiData.EntryLumenRadiuscmEditField_2.Value;
                app.stenosisData(2).Stenosis = (app.stenosisGuiData.LumenNarrowingEditField_2.Value+10)/2;
                if (isfield(app.stenosisData(2), 'Slicenumberforimmediateexit') && ~isempty(app.stenosisData(2).Slicenumberforimmediateentry) && app.stenosisData(2).Slicenumberforimmediateentry > 0)
                    if (app.stenosisData(2).Slicenumberforimmediateexit ~= app.stenosisGuiData.ExitSliceNumberEditField_2.Value)
                        app.stenosisData(2).Slicenumberforimmediateexit = app.stenosisGuiData.ExitSliceNumberEditField_2.Value;
                        changedStenosis(2) = true;
                        changedEntryExitSlice(2) = true;
                    end
                    if (app.stenosisData(2).Slicenumberforimmediateentry ~= app.stenosisGuiData.EntrySliceNumberEditField_2.Value)
                        app.stenosisData(2).Slicenumberforimmediateentry = app.stenosisGuiData.EntrySliceNumberEditField_2.Value;
                        changedStenosis(2) = true;
                        changedEntryExitSlice(2) = true;
                    end
                else
                    app.stenosisData(2).Slicenumberforimmediateexit = app.stenosisGuiData.ExitSliceNumberEditField_2.Value;
                    app.stenosisData(2).Slicenumberforimmediateentry = app.stenosisGuiData.EntrySliceNumberEditField_2.Value;
                    changedStenosis(2) = true;
                    changedEntryExitSlice(2) = true;
                end
                app.checkedStenosis(2) = true;
            else
                app.stenosisData(2).Tracerpercentageatimmediateexit = 0;
                app.stenosisData(2).Tracerpercentageatimmediateentry = 0;
                app.stenosisData(2).Lumenradiusatimmediateexitcm = 0;
                app.stenosisData(2).Lumenradiusatimmediateentrycm = 0;
                app.stenosisData(2).Stenosis = 0;
                app.stenosisData(2).Slicenumberforimmediateexit = 0;
                app.stenosisData(2).Slicenumberforimmediateentry = 0;
                app.stenosisData(2).NumberOfBranchBeforeEntry = 0;
                app.stenosisData(2).NumberOfBranchBeforeExit = 0;
            end

            if (app.stenosisGuiData.Stenosis3CheckBox.Value == true)
                app.stenosisData(3).NumberOfBranchBeforeEntry = app.stenosisGuiData.NumberofbranchbetweenprelesionandstenosisEditField_3.Value;
                app.stenosisData(3).NumberOfBranchBeforeExit = app.stenosisGuiData.EditField_3.Value;
                app.stenosisData(3).Lumenradiusatimmediateexitcm = app.stenosisGuiData.ExitLumenRadiuscmEditField_3.Value;
                app.stenosisData(3).Lumenradiusatimmediateentrycm = app.stenosisGuiData.EntryLumenRadiuscmEditField_3.Value;
                app.stenosisData(3).Stenosis = (app.stenosisGuiData.LumenNarrowingEditField_3.Value+10)/2;
                if (isfield(app.stenosisData(3), 'Slicenumberforimmediateexit') && ~isempty(app.stenosisData(3).Slicenumberforimmediateentry) && app.stenosisData(3).Slicenumberforimmediateentry > 0)
                    if (app.stenosisData(3).Slicenumberforimmediateexit ~= app.stenosisGuiData.ExitSliceNumberEditField_3.Value)
                        app.stenosisData(3).Slicenumberforimmediateexit = app.stenosisGuiData.ExitSliceNumberEditField_3.Value;
                        changedStenosis(3) = true;
                        changedEntryExitSlice(3) = true;
                    end
                    if (app.stenosisData(3).Slicenumberforimmediateentry ~= app.stenosisGuiData.EntrySliceNumberEditField_3.Value)
                        app.stenosisData(3).Slicenumberforimmediateentry = app.stenosisGuiData.EntrySliceNumberEditField_3.Value;
                        changedStenosis(3) = true;
                        changedEntryExitSlice(3) = true;
                    end
                else
                    app.stenosisData(3).Slicenumberforimmediateexit = app.stenosisGuiData.ExitSliceNumberEditField_3.Value;
                    app.stenosisData(3).Slicenumberforimmediateentry = app.stenosisGuiData.EntrySliceNumberEditField_3.Value;
                    changedStenosis(3) = true;
                    changedEntryExitSlice(3) = true;
                end
                app.checkedStenosis(3) = true;
            else
                app.stenosisData(3).NumberOfBranchBeforeEntry = 0;
                app.stenosisData(3).NumberOfBranchBeforeExit = 0;
                app.stenosisData(3).Lumenradiusatimmediateexitcm = 0;
                app.stenosisData(3).Lumenradiusatimmediateentrycm = 0;
                app.stenosisData(3).Stenosis = 0;
                app.stenosisData(3).Slicenumberforimmediateexit = 0;
                app.stenosisData(3).Slicenumberforimmediateentry = 0;
            end

            if (app.stenosisGuiData.Stenosis4CheckBox.Value == true)
                app.stenosisData(4).NumberOfBranchBeforeEntry = app.stenosisGuiData.NumberofbranchbetweenprelesionandstenosisEditField_4.Value;
                app.stenosisData(4).NumberOfBranchBeforeExit = app.stenosisGuiData.EditField_4.Value;
                app.stenosisData(4).Lumenradiusatimmediateexitcm = app.stenosisGuiData.ExitLumenRadiuscmEditField_4.Value;
                app.stenosisData(4).Lumenradiusatimmediateentrycm = app.stenosisGuiData.EntryLumenRadiuscmEditField_4.Value;
                app.stenosisData(4).Stenosis = (app.stenosisGuiData.LumenNarrowingEditField_4.Value+10)/2;
                if (isfield(app.stenosisData(4), 'Slicenumberforimmediateexit') && ~isempty(app.stenosisData(4).Slicenumberforimmediateentry) && app.stenosisData(4).Slicenumberforimmediateentry > 0)
                    if (app.stenosisData(4).Slicenumberforimmediateexit ~= app.stenosisGuiData.ExitSliceNumberEditField_4.Value)
                        app.stenosisData(4).Slicenumberforimmediateexit = app.stenosisGuiData.ExitSliceNumberEditField_4.Value;
                        changedStenosis(4) = true;
                        changedEntryExitSlice(4) = true;
                    end
                    if(app.stenosisData(4).Slicenumberforimmediateentry ~= app.stenosisGuiData.EntrySliceNumberEditField_4.Value)
                        app.stenosisData(4).Slicenumberforimmediateentry = app.stenosisGuiData.EntrySliceNumberEditField_4.Value;
                        changedStenosis(4) = true;
                        changedEntryExitSlice(4) = true;
                    end
                else
                    app.stenosisData(4).Slicenumberforimmediateexit = app.stenosisGuiData.ExitSliceNumberEditField_4.Value;
                    app.stenosisData(4).Slicenumberforimmediateentry = app.stenosisGuiData.EntrySliceNumberEditField_4.Value;
                    changedStenosis(4) = true;
                    changedEntryExitSlice(4) = true;
                end
                app.checkedStenosis(4) = true;
            else
                app.stenosisData(4).NumberOfBranchBeforeEntry = 0;
                app.stenosisData(4).NumberOfBranchBeforeExit = 0;
                app.stenosisData(4).Lumenradiusatimmediateexitcm = 0;
                app.stenosisData(4).Lumenradiusatimmediateentrycm = 0;
                app.stenosisData(4).Stenosis = 0;
                app.stenosisData(4).Slicenumberforimmediateexit = 0;
                app.stenosisData(4).Slicenumberforimmediateentry = 0;
            end

            if (app.stenosisGuiData.Stenosis5CheckBox.Value == true)
                app.stenosisData(5).NumberOfBranchBeforeEntry = app.stenosisGuiData.NumberofbranchbetweenprelesionandstenosisEditField_5.Value;
                app.stenosisData(5).NumberOfBranchBeforeExit = app.stenosisGuiData.EditField_5.Value;
                app.stenosisData(5).Lumenradiusatimmediateexitcm = app.stenosisGuiData.ExitLumenRadiuscmEditField_5.Value;
                app.stenosisData(5).Lumenradiusatimmediateentrycm = app.stenosisGuiData.EntryLumenRadiuscmEditField_5.Value;
                app.stenosisData(5).Stenosis = (app.stenosisGuiData.LumenNarrowingEditField_5.Value+10)/2;
                if (isfield(app.stenosisData(5), 'Slicenumberforimmediateexit') && ~isempty(app.stenosisData(5).Slicenumberforimmediateentry) && app.stenosisData(5).Slicenumberforimmediateentry > 0)
                    if (app.stenosisData(5).Slicenumberforimmediateexit ~= app.stenosisGuiData.ExitSliceNumberEditField_5.Value)
                        app.stenosisData(5).Slicenumberforimmediateexit = app.stenosisGuiData.ExitSliceNumberEditField_5.Value;
                        changedStenosis(5) = true;
                        changedEntryExitSlice(5) = true;
                    end
                    if (app.stenosisData(5).Slicenumberforimmediateentry ~= app.stenosisGuiData.EntrySliceNumberEditField_5.Value)
                        app.stenosisData(5).Slicenumberforimmediateentry = app.stenosisGuiData.EntrySliceNumberEditField_5.Value;
                        changedStenosis(5) = true;
                        changedEntryExitSlice(5) = true;
                    end
                else
                    app.stenosisData(5).Slicenumberforimmediateexit = app.stenosisGuiData.ExitSliceNumberEditField_5.Value;
                    app.stenosisData(5).Slicenumberforimmediateentry = app.stenosisGuiData.EntrySliceNumberEditField_5.Value;
                    changedStenosis(5) = true;
                    changedEntryExitSlice(5) = true;
                end
                app.checkedStenosis(5) = true;
            else
                app.stenosisData(5).NumberOfBranchBeforeEntry = 0;
                app.stenosisData(5).NumberOfBranchBeforeExit = 0;
                app.stenosisData(5).Lumenradiusatimmediateexitcm = 0;
                app.stenosisData(5).Lumenradiusatimmediateentrycm = 0;
                app.stenosisData(5).Stenosis = 0;
                app.stenosisData(5).Slicenumberforimmediateexit = 0;
                app.stenosisData(5).Slicenumberforimmediateentry = 0;
            end

            % selectedIndices = find(checkedStenosis);
            % app.stenosisData = app.stenosisData(selectedIndices);
            % changedStenosis = changedStenosis(selectedIndices);

            app.numberOfStenosis = app.stenosisGuiData.numberOfStenosis;

            % At this point, changedStenosis contain a boolean vector, 1
            % representing if stenosis entry and exit location at that index
            % has been modified, 0 representing otherwise. If none of them
            % are modified, then no need to redraw the curves. Also
            % "plotIDsToRemoveFromPlotArea" contains plotIDs that need to
            % be removed.

            % Updating stenosis info into app data.
            if (app.numberOfStenosis > 0)
                for stenosis = 1 : app.maxStenosisSupported
                    entrySlice(stenosis) = app.stenosisData(stenosis).Slicenumberforimmediateentry;
                    app.entrySliceLocations(stenosis) = app.stenosisData(stenosis).Slicenumberforimmediateentry;
                    exitSlice(stenosis) = app.stenosisData(stenosis).Slicenumberforimmediateexit;
                    app.exitSliceLocations(stenosis) = app.stenosisData(stenosis).Slicenumberforimmediateexit;
                    percentage(stenosis) = app.stenosisData(stenosis).Stenosis/100;
                    app.stenosisPercentage(stenosis) = app.stenosisData(stenosis).Stenosis/100;
                    app.rs1(stenosis) = app.stenosisData(stenosis).Lumenradiusatimmediateentrycm;
                    app.rs1(stenosis) = app.stenosisData(stenosis).Lumenradiusatimmediateentrycm;
                    app.rs2(stenosis) = (1.0 - percentage(stenosis)) * app.rs1(stenosis);
                    app.rs2(stenosis) = (1.0 - percentage(stenosis)) * app.rs1(stenosis);
                    app.rs3(stenosis) = app.stenosisData(stenosis).Lumenradiusatimmediateexitcm;
                    app.rs3(stenosis) = app.stenosisData(stenosis).Lumenradiusatimmediateexitcm;
                    app.entryQ(stenosis) = 0;
                    app.exitQ(stenosis) = 0;
                end
            end

            % Based on the stenosis info, asking user to draw stenosis ROI
            if (~isempty(fieldnames(app.stenosisData)))
                for s = 1 : numel(entrySlice)
                    if (changedEntryExitSlice(s) == true)
                        app.currentStenosisID = s;

                        entrySliceNumber = entrySlice(s);
                        exitSliceNumber = exitSlice(s);

                        app.entryTimePointStressOnly = app.timePointsStressOnly;
                        app.exitTimePointStressOnly = app.timePointsStressOnly;

                        app.SlicesSliderStressOnly.Value = entrySliceNumber;
                        event = struct('Value', entrySliceNumber);
                        SlicesSliderStressOnlyValueChanging(app, event);
                        app.SlicesSliderStressOnlyLabel.Text = sprintf('Slice %d/%d', entrySliceNumber, size(app.fourDImageSetStressOnly, 1));

                        app.currentSliceStressOnly = entrySliceNumber;
                        imageData(1:512, 1:512) = app.fourDImageSetStressOnly(app.currentSliceStressOnly,app.currentTimePointStressOnly,1:512,1:512);
                        updateimageStressOnly(app,imageData);

                        pointsEntryStressOnly = drawpoint(app.ImageAxesStressOnly);
                        app.pEntryStressOnly = [app.currentSliceStressOnly, app.currentTimePointStressOnly, round(pointsEntryStressOnly.Position(2)), round(pointsEntryStressOnly.Position(1))];
                        fittedCurvePEntryStressOnly = zeros(size(app.timePointsStressOnly));
                        entrySliceStressOnly = app.currentSliceStressOnly;
                        entryCurrentTimePointStressOnly = app.currentTimePointStressOnly;
                        [app.entryCurveStressOnly, entryInterpolatedSampledPointsStressOnly, entryInterpolatedTimePointsStressOnly, app.entrySxStressOnly, app.entrySyStressOnly, entryRxStressOnly, entryRyStressOnly] = getBestSample(app.pEntryStressOnly(3),app.pEntryStressOnly(4),app.searchROIMStressOnly,app.roiMStressOnly,app.roiNStressOnly,app.currentSliceStressOnly,app.fourDImageSetStressOnly,app.entryTimePointStressOnly);
                        segmentationWindowSize = 25;
                        [imageWithContour, radiusIncm, areaOfContourInCmSq] = getContour(app.pEntryStressOnly(3),app.pEntryStressOnly(4),segmentationWindowSize,app.currentSliceStressOnly, entryCurrentTimePointStressOnly, app.fourDImageSetStressOnly, app.dicomInfoStressOnly.PixelSpacing);
                        imageData(1:512, 1:512) = app.fourDImageSetStressOnly(app.currentSliceStressOnly,app.currentTimePointStressOnly,1:512,1:512);
                        imageWithContour = getROIOverlayed(imageData, app.entrySxStressOnly, app.entrySyStressOnly);
                        updateimageStressOnly(app,imageWithContour);
                        drawEntryCurveStressOnly(app);

                        app.SlicesSliderStressOnly.Value = exitSliceNumber;
                        event = struct('Value', exitSliceNumber);
                        SlicesSliderStressOnlyValueChanging(app, event);
                        app.SlicesSliderStressOnlyLabel.Text = sprintf('Slice %d/%d', exitSliceNumber, size(app.fourDImageSetStressOnly, 1));

                        app.currentSliceStressOnly = exitSlice(s);
                        imageData(1:512, 1:512) = app.fourDImageSetStressOnly(app.currentSliceStressOnly,app.currentTimePointStressOnly,1:512,1:512);
                        updateimageStressOnly(app,imageData);

                        pointsExitStressOnly = drawpoint(app.ImageAxesStressOnly);
                        app.pExitStressOnly = [app.currentSliceStressOnly, app.currentTimePointStressOnly, round(pointsExitStressOnly.Position(2)), round(pointsExitStressOnly.Position(1))];
                        fittedCurvePExitStressOnly = zeros(size(app.timePointsStressOnly));
                        exitSliceStressOnly = app.currentSliceStressOnly;
                        exitCurrentTimePointStressOnly = app.currentTimePointStressOnly;
                        [app.exitCurveStressOnly, exitInterpolatedSampledPointsStressOnly, exitInterpolatedTimePointsStressOnly, app.exitSxStressOnly, app.exitSyStressOnly, exitRxStressOnly, exitRyStressOnly] = getBestSample(app.pExitStressOnly(3),app.pExitStressOnly(4),app.searchROIMStressOnly,app.roiMStressOnly,app.roiNStressOnly,app.currentSliceStressOnly,app.fourDImageSetStressOnly,app.exitTimePointStressOnly);
                        segmentationWindowSize = 25;
                        [imageWithContour, radiusIncm, areaOfContourInCmSq] = getContour(app.pExitStressOnly(3), app.pExitStressOnly(4),segmentationWindowSize,app.currentSliceStressOnly, exitCurrentTimePointStressOnly, app.fourDImageSetStressOnly, app.dicomInfoStressOnly.PixelSpacing);
                        imageData(1:512, 1:512) = app.fourDImageSetStressOnly(app.currentSliceStressOnly,app.currentTimePointStressOnly,1:512,1:512);
                        imageWithContour = getROIOverlayed(imageData, app.exitSxStressOnly, app.exitSyStressOnly);
                        updateimageStressOnly(app,imageWithContour);
                        drawExitCurveStressOnly(app);

                        app.entryRoiObjectStressOnly = getROI(app.dicomInfoStressOnly.PatientName.FamilyName, size(app.fourDImageSetStressOnly,2), size(app.fourDImageSetStressOnly,1), app.pEntryStressOnly(3), app.pEntryStressOnly(4), app.pEntryStressOnly(1), app.pEntryStressOnly(2), app.entryCurveStressOnly, app.entryTimePointStressOnly, app.fittedCurveEntryStressOnly, app.fittedTimeEntryStressOnly, app.entrySxStressOnly, app.entrySyStressOnly, 0, 0, app.numberOfStenosis, app.rs1(end), app.rs2(end), app.rs3(end), app.entryQ(end), app.exitQ(end));
                        app.roiObjectStressOnly.entryRoiObject(s) = app.entryRoiObjectStressOnly;
                        app.exitRoiObjectStressOnly = getROI(app.dicomInfoStressOnly.PatientName.FamilyName, size(app.fourDImageSetStressOnly,2), size(app.fourDImageSetStressOnly,1), app.pExitStressOnly(3), app.pExitStressOnly(4), app.pExitStressOnly(1), app.pExitStressOnly(2), app.exitCurveStressOnly, app.exitTimePointStressOnly, app.fittedCurveExitStressOnly, app.fittedTimeExitStressOnly, app.exitSxStressOnly, app.exitSyStressOnly, 0, 0, app.numberOfStenosis, app.rs1(end), app.rs2(end), app.rs3(end), app.entryQ(end), app.exitQ(end));
                        app.roiObjectStressOnly.exitRoiObject(s) = app.exitRoiObjectStressOnly;

                        % Calculate the stenosis lenght 

                        stenosisheight= getRelativeHeight(entrySliceNumber, exitSliceNumber, app.dicomInfoStressOnly.SliceThickness, app.hAhBDiffNegative);
                        app.StenosisInfo(s).entrySlice=entrySliceNumber;
                        app.StenosisInfo(s).exitSlice=exitSliceNumber;
                        app.StenosisInfo(s).xEntry=app.pEntryStressOnly(4);
                        app.StenosisInfo(s).yEntry=app.pEntryStressOnly(3);
                        app.StenosisInfo(s).xExit=app.pExitStressOnly(4);
                        app.StenosisInfo(s).yExit=app.pExitStressOnly(3);
                        app.StenosisInfo(s).stenosisLenght=getDistanceBetweenSlicesAB(app.pEntryStressOnly(4),app.pEntryStressOnly(3),app.pExitStressOnly(4),app.pExitStressOnly(3),[],stenosisheight, app.dicomInfoStressOnly.PixelSpacing);
                        

                        if (app.numberOfStenosis > 1)

                            app.currentStenosisNumber = s;
                            FitCurvesButtonStressOnlyPushed(app);

                            app.currentStenosisNumber = s;
                            FitCurvesButtonStressOnly_2Pushed(app);
                        end
                    else
                        if (s > max(size(app.previousStenosisInfo.aucAEntryStressOnly)))
                            app.aucAEntryStressOnly(s) = 0;
                            app.aucBExitStressOnly(s) = 0;
                        else
                            app.aucAEntryStressOnly(s) = app.previousStenosisInfo.aucAEntryStressOnly(s);
                            app.aucBExitStressOnly(s) = app.previousStenosisInfo.aucBExitStressOnly(s);
                        end
                    end
                end
                saveRoiAsJson(app.roiObjectStressOnly);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Update Version
            updateVersion(app);

            % Load Logo/Icon:
            app.UIFigure.Icon = 'Icon\Icon.jpg';

            % Full Menu Selected as Default
            FullMenuSelected(app);

            % Remove older Log files
            removeOlderLogFiles();

            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');

            % Configure image axes rest
            app.ImageAxesRest.Visible = 'off';
            app.ImageAxesRest.Colormap = gray(256);
            axis(app.ImageAxesRest, 'image');

            % Configure image axes stress only
            app.ImageAxesStressOnly.Visible = 'off';
            app.ImageAxesStressOnly.Colormap = gray(256);
            axis(app.ImageAxesStressOnly, 'image');

            % Configure image axes 3D
            app.ImageAxes3D.Visible = 'off';
            app.ImageAxes3D.Colormap = gray(256);
            axis(app.ImageAxes3D, 'image');

            % Configure image axes 3D JPEG
            app.ImageAxes3DJPEG.Visible = 'off';
            app.ImageAxes3DJPEG.Colormap = gray(256);
            axis(app.ImageAxes3DJPEG, 'image');

            % Configure image axes CTA Stress Only
            app.ImageAxesCTAStressOnly.Visible = 'off';
            app.ImageAxesCTAStressOnly.Colormap = gray(256);
            axis(app.ImageAxesCTAStressOnly, 'image');

            % Configure FFR/Flow Velocity Plot
            yyaxis(app.FFRVsSliceLocation,'left');
            yyaxis(app.FFRVsSliceLocation,'right');
            ylabel(app.FFRVsSliceLocation, 'Flow Velocity (cm/s)');
            app.FFRVsSliceLocation.YLim = [0, 100];
            app.FFRVsSliceLocation.YAxis(1).Color = [0 0 0];
            app.FFRVsSliceLocation.YAxis(2).Color = [0 0 0];

            app.FFRVsSliceLocation.Title.Color = app.colorTitleFontNormalMode;
            app.paPlotAreaStressOnly.Title.Color = app.colorTitleFontNormalMode;
            app.pdPlotAreaStressOnly.Title.Color = app.colorTitleFontNormalMode;
            app.paPlotArea.Title.Color = app.colorTitleFontNormalMode;
            app.pdPlotArea.Title.Color = app.colorTitleFontNormalMode;
            % Update the image and histograms
            % Removed Initial Image Loading

            % pre/post lesion graph sizing
            % app.pdPlotAreaStressOnly.Units = 'normalized';
            % app.pdPlotAreaStressOnly.Position = [0.10 0.10 0.80 0.60];   % smaller
            % 
            % app.paPlotAreaStressOnly.Units = 'normalized';
            % app.paPlotAreaStressOnly.Position = [0.10 0.10 0.80 0.60];   % same size

            % % Make both axes use the full allotted space
            % app.ImageAxesCTAStressOnly.PlotBoxAspectRatioMode = 'auto';
            % app.ImageAxesStressOnly.PlotBoxAspectRatioMode    = 'auto';
            % 
            % app.ImageAxesCTAStressOnly.DataAspectRatioMode    = 'auto';
            % app.ImageAxesStressOnly.DataAspectRatioMode       = 'auto';
        
            % If you don't really need a Z label, kill it (it adds padding)
            % app.ImageAxesCTAStressOnly.ZLabel.String = '';
        
            % Remove the box outline if you don't want that frame look
            app.ImageAxesCTAStressOnly.Box = 'off';
            app.ImageAxesStressOnly.Box    = 'off';
        
            % Make axes fill their layout cell
            app.ImageAxesCTAStressOnly.ActivePositionProperty = 'outerposition';
            app.ImageAxesStressOnly.ActivePositionProperty    = 'outerposition';
            
            
        end

        % Callback function
        function DropDownValueChanged(app, event)

        end

        % Callback function
        function LoadStudiesButtonPushed(app, event)

        end

        % Callback function
        function SlicesSliderValueChanging(app, event)
            sliceLimit = size(app.fourDImageSet,1);
            if (round(event.Value) > sliceLimit)
                changingValue = sliceLimit;
            else
                if (round(event.Value) == 0)
                    changingValue = 1;
                else
                    changingValue = round(event.Value);
                end
            end
            app.currentSlice = changingValue;
            app.SlicesSlider_2Label.Text = sprintf('Slice %d/%d', app.currentSlice, size(app.fourDImageSet, 1));
            imageData(1:512, 1:512) = app.fourDImageSet(app.currentSlice,app.currentTimePoint,1:512,1:512);
            if (~isempty(app.pa) && app.currentSlice == app.pa(1))
                imageData = getROIOverlayed(imageData, app.sxPre, app.syPre);
            elseif (~isempty(app.pd) && app.currentSlice == app.pd(1))
                imageData = getROIOverlayed(imageData, app.sxPost, app.syPost);
            end
            updateimage(app, imageData);
        end

        % Callback function
        function TimePointsSliderValueChanging(app, event)
            timePointsLimit = size(app.fourDImageSet,2);
            if (round(event.Value) > timePointsLimit)
                changingValue = timePointsLimit;
            else
                if (round(event.Value) == 0)
                    changingValue = 1;
                else
                    changingValue = round(event.Value);
                end
            end
            app.currentTimePoint = changingValue;
            imageData(1:512, 1:512) = app.fourDImageSet(app.currentSlice,app.currentTimePoint,1:512,1:512);
            if (~isempty(app.pa) && app.currentSlice == app.pa(1))
                imageData = getROIOverlayed(imageData, app.sxPre, app.syPre);
            elseif (~isempty(app.pd) && app.currentSlice == app.pd(1))
                imageData = getROIOverlayed(imageData, app.sxPost, app.syPost);
            end
            updateimage(app, imageData);

        end

        % Callback function
        function SetPreLesionButtonPushed(app, event)
            zoom(app.ImageAxes, 'off');
            app.ptsA = drawpoint(app.ImageAxes);
            app.pa = [app.currentSlice, app.currentTimePoint, round(app.ptsA.Position(2)), round(app.ptsA.Position(1))];
            app.fittedCurvePa = zeros(size(app.timePoints));
            app.paSlice = app.currentSlice;
            app.paTimePoint = app.currentTimePoint;
            [app.paCurve, app.interpolatedSampledPointsPa, app.interpolatedTimePoints, app.sxPre, app.syPre, app.rxPre, app.ryPre] = getBestSample(app.pa(3),app.pa(4),app.searchROIM,app.roiM,app.roiN,app.currentSlice,app.fourDImageSet,app.timePoints);
            segmentationWindowSize = 25;
            [imageWithContour, radiusIncm, areaOfContourInCmSq] = getContour(app.pa(3),app.pa(4),segmentationWindowSize,app.currentSlice, app.paTimePoint, app.fourDImageSet, app.dicomInfo.PixelSpacing);
            imageData(1:512, 1:512) = app.fourDImageSet(app.currentSlice,app.currentTimePoint,1:512,1:512);
            imageWithContour = getROIOverlayed(imageData, app.sxPre, app.syPre);
            updateimage(app,imageWithContour);
            radiusIncm
            areaOfContourInCmSq
            drawPreLesionCurve(app);
        end

        % Callback function
        function SetPostLesionButtonPushed(app, event)
            zoom(app.ImageAxes, 'off');
            app.ptsB = drawpoint(app.ImageAxes);
            app.pd = [app.currentSlice, app.currentTimePoint, round(app.ptsB.Position(2)), round(app.ptsB.Position(1))];
            app.fittedCurvePd = zeros(size(app.timePoints));
            app.pdSlice = app.currentSlice;
            app.pdTimePoint = app.currentTimePoint;
            [app.pdCurve, app.interpolatedSampledPointsPd, app.interpolatedTimePoints, app.sxPost, app.syPost, app.rxPost, app.ryPost] = getBestSample(app.pd(3),app.pd(4),app.searchROIM,app.roiM,app.roiN,app.currentSlice,app.fourDImageSet,app.timePoints);
            segmentationWindowSize = 25;
            [imageWithContour, radiusIncm, areaOfContourInCmSq] = getContour(app.pd(3),app.pd(4),segmentationWindowSize,app.currentSlice, app.pdTimePoint, app.fourDImageSet, app.dicomInfo.PixelSpacing);
            imageData(1:512, 1:512) = app.fourDImageSet(app.currentSlice,app.currentTimePoint,1:512,1:512);
            imageWithContour = getROIOverlayed(imageData, app.sxPost, app.syPost);
            updateimage(app,imageWithContour);
            radiusIncm
            areaOfContourInCmSq
            drawPostLesionCurve(app);
        end

        % Callback function
        function SetWindowButtonPushed(app, event)
            level = str2double(app.SetWindowLevel.Value{1});
            width = str2double(app.SetWindowWidth.Value{1});
            app.minWindow = level - width / 2;
            app.maxWindow = level + width / 2;
            imagesc(app.ImageAxes, app.im, [app.minWindow, app.maxWindow]);
            % updateimage(app, app.im);
        end

        % Callback function
        function FitStressCurveButtonPushed(app, event)
            % This function now handles only pre-lesion cases:

            if isrow(app.paCurve)
                app.paCurve = app.paCurve';
            end
            app.manualBaselineValue = str2double(app.ManualBaselineInput.Value);
            app.manualWashoutValue = str2double(app.ManualWashoutInput.Value);
            if isnan(app.manualBaselineValue)
                app.manualBaselineValue = 0;
            else
                app.manualBaselineValue = str2double(app.ManualBaselineInput.Value);
            end
            if isnan(app.manualWashoutValue)
                app.manualWashoutValue = 0;
            else
                app.manualWashoutValue = str2double(app.ManualWashoutInput.Value);
            end
            manualBaselineValuePreLesion = app.manualBaselineValue;
            manualWashoutValuePreLesion = app.manualWashoutValue;

            try
                [app.fittedCurvePa, app.fittedTimePa, app.paCurve, app.rmseA, app.kA, app.alphaA, app.betaA] = getFittedCurve(app.paCurve, app.plotX, manualBaselineValuePreLesion, manualWashoutValuePreLesion);
                app.aucA = getAreaUnderCurve(app.fittedCurvePa, app.fittedTimePa);
                if isrow(app.pdCurve)
                    app.pdCurve = app.pdCurve';
                end

                % Strees Pre and Strees Post
                if (strcmp(app.viewMode,'Dark'))
                    plot(app.paPlotArea, app.plotX, app.paCurve, 'yo', app.fittedTimePa, app.fittedCurvePa, 'y-');
                    legend(app.paPlotArea,'Pre-Lesion Sampled','Pre-Lesion Fitted', 'TextColor','white');
                    if (~all(app.fittedCurvePd) == 0)
                        plot(app.pdPlotArea, app.plotX, app.pdCurve, 'yo', app.fittedTimePd, app.fittedCurvePd, 'y-');
                        legend(app.pdPlotArea,'Post-Lesion Sampled','Post-Lesion Fitted', 'TextColor','white');
                    end

                    if(~isempty(app.paCurveRest))
                        if(~isempty(app.fittedCurvePaRest))
                            plot(app.paPlotArea, app.plotX, app.paCurve, 'yo', app.fittedTimePa, app.fittedCurvePa, 'y-', app.plotXRest, app.paCurveRest, 'ws', app.fittedTimePaRest, app.fittedCurvePaRest, 'w-');
                            legend(app.paPlotArea,'Pre-Lesion Sampled','Pre-Lesion Fitted','Pre-Rest','Pre-Rest-Fitted', 'TextColor','white');
                        else
                            plot(app.paPlotArea, app.plotX, app.paCurve, 'yo', app.fittedTimePa, app.fittedCurvePa, 'y-', app.plotXRest, app.paCurveRest, 'ws');
                            legend(app.paPlotArea,'Pre-Lesion Sampled','Pre-Lesion Fitted','Pre-Rest', 'TextColor','white');
                        end
                    end

                    if(~isempty(app.pdCurveRest))
                        if(~isempty(app.fittedCurvePdRest))
                            plot(app.pdPlotArea, app.plotX, app.pdCurve, 'yo', app.fittedTimePd, app.fittedCurvePd, 'y-', app.plotXRest, app.pdCurveRest, 'ws', app.fittedTimePdRest, app.fittedCurvePdRest, 'w-');
                            legend(app.pdPlotArea,'Post-Lesion Sampled','Post-Lesion Fitted','Post-Rest','Post-Rest-Fitted', 'TextColor','white');
                        else
                            plot(app.pdPlotArea, app.plotX, app.pdCurve, 'yo', app.fittedTimePd, app.fittedCurvePd, 'y-', app.plotXRest, app.pdCurveRest, 'ws');
                            legend(app.pdPlotArea,'Post-Lesion Sampled','Post-Lesion Fitted','Post-Rest', 'TextColor','white');
                        end
                    end
                else
                    plot(app.paPlotArea, app.plotX, app.paCurve, 'mo', app.fittedTimePa, app.fittedCurvePa, 'm-');
                    legend(app.paPlotArea,'Pre-Lesion Sampled','Pre-Lesion Fitted', 'TextColor','black');
                    if (~all(app.fittedCurvePd) == 0)
                        plot(app.pdPlotArea, app.plotX, app.pdCurve, 'mo', app.fittedTimePd, app.fittedCurvePd, 'm-');
                        legend(app.pdPlotArea,'Post-Lesion Sampled','Post-Lesion Fitted', 'TextColor','black');
                    end

                    if(~isempty(app.paCurveRest))
                        if(~isempty(app.fittedCurvePaRest))
                            plot(app.paPlotArea, app.plotX, app.paCurve, 'mo', app.fittedTimePa, app.fittedCurvePa, 'm-', app.plotXRest, app.paCurveRest, 'bs', app.fittedTimePaRest, app.fittedCurvePaRest, 'b-');
                            legend(app.paPlotArea,'Pre-Lesion Sampled','Pre-Lesion Fitted','Pre-Rest','Pre-Rest-Fitted', 'TextColor','black');
                        else
                            plot(app.paPlotArea, app.plotX, app.paCurve, 'mo', app.fittedTimePa, app.fittedCurvePa, 'm-', app.plotXRest, app.paCurveRest, 'bs');
                            legend(app.paPlotArea,'Pre-Lesion Sampled','Pre-Lesion Fitted','Pre-Rest', 'TextColor','black');
                        end
                    end

                    if(~isempty(app.pdCurveRest))
                        if(~isempty(app.fittedCurvePdRest))
                            plot(app.pdPlotArea, app.plotX, app.pdCurve, 'mo', app.fittedTimePd, app.fittedCurvePd, 'm-', app.plotXRest, app.pdCurveRest, 'bs', app.fittedTimePdRest, app.fittedCurvePdRest, 'b-');
                            legend(app.pdPlotArea,'Post-Lesion Sampled','Post-Lesion Fitted','Post-Rest','Post-Rest-Fitted', 'TextColor','black');
                        else
                            plot(app.pdPlotArea, app.plotX, app.pdCurve, 'mo', app.fittedTimePd, app.fittedCurvePd, 'm-', app.plotXRest, app.pdCurveRest, 'bs');
                            legend(app.pdPlotArea,'Post-Lesion Sampled','Post-Lesion Fitted','Post-Rest', 'TextColor','black');
                        end
                    end
                end
            catch ME
                msg = strcat('Problem in Curve Fitting:', ME.message);
                errordlg(msg);
            end
        end

        % Callback function
        function ComputeButtonPushed(app, event)
            % Q has the unit mL * mg/mL = mg
            if strcmp(app.arteryType, 'Coronary')
                if (app.PrelesionTracerPercentageEditField.Value == "")
                    app.preTracerPercentage = 0.02;
                else
                    app.preTracerPercentage = str2double(app.PrelesionTracerPercentageEditField.Value)/100;
                end
                app.preQ = app.preTracerPercentage * str2double(app.ContrastVolumemLEditField.Value) * str2double(app.ContrastConcentrationmglmLEditField.Value);

                if (app.PostlesionTracerPercentageEditField.Value == "")
                    app.postTracerPercentage = 0.02;
                else
                    app.postTracerPercentage = str2double(app.PostlesionTracerPercentageEditField.Value)/100;
                end
                app.postQ = app.postTracerPercentage * str2double(app.ContrastVolumemLEditField.Value) * str2double(app.ContrastConcentrationmglmLEditField.Value);

                % Converting HUs to (mg/mL)s
                tempAucA = app.aucA / str2double(app.ConversionFactorEditField.Value);
                tempAucB = app.aucB / str2double(app.ConversionFactorEditField.Value);
            end

            if strcmp(app.arteryType, 'Aorta')
                if (app.PrelesionTracerPercentageEditField.Value == "")
                    app.preTracerPercentage = 0.94;
                else
                    app.preTracerPercentage = str2double(app.PrelesionTracerPercentageEditField.Value)/100;
                end
                app.preQ = app.preTracerPercentage * str2double(app.ContrastVolumemLEditField.Value) * str2double(app.ContrastConcentrationmglmLEditField.Value);

                if (app.PostlesionTracerPercentageEditField.Value == "")
                    app.postTracerPercentage = 0.94;
                else
                    app.postTracerPercentage = str2double(app.PostlesionTracerPercentageEditField.Value)/100;
                end
                app.postQ = app.postTracerPercentage * str2double(app.ContrastVolumemLEditField.Value) * str2double(app.ContrastConcentrationmglmLEditField.Value);
                % In which case it will be 98% - Aaron to send me a document describing it.
                % app.Q = 0.98 * str2double(app.ContrastVolumemLEditField.Value) * str2double(app.ContrastConcentrationmglmLEditField.Value);

                % Converting HUs to (mg/mL)s
                tempAucA = app.aucA / str2double(app.ConversionFactorEditField.Value);
                tempAucB = app.aucB / str2double(app.ConversionFactorEditField.Value);
            end

            if (~isempty(app.hBText))
                app.hB = app.hBText;
            else
                app.hB = getRelativeHeight(app.pa(1), app.pd(1), app.dicomInfo.SliceThickness, app.hAhBDiffNegative);
            end
            if (~isempty(app.LText))
                app.L = app.LText;
            else
                app.L = getDistanceBetweenSlicesAB(app.pa(3), app.pa(4), app.pd(3), app.pd(4),app.hB, app.dicomInfo.PixelSpacing);
            end

            if (app.ArterialRoughness.Value == "")
                app.ArterialRoughness.Value = '0';
            end

            % Radius values are in cm
            [app.FFR, app.VA, app.VB, app.shearStressA, app.shearStressB, app.pLE, app.pLF, app.Re, app.pB, app.frictionFactor, app.Vs1, app.Vs2, app.Vs3, app.pLC, app.pLS] = calculateFFR(tempAucA, tempAucB, app.preQ, app.postQ, app.hB, app.L, str2double(app.PrelesionLumenRadiuscmEditField.Value), str2double(app.PostlesionLumenRadiuscmEditField.Value), str2double(app.AorticBloodPressuremmHgEditField.Value), str2double(app.ArterialRoughness.Value), app.fMethod, 0);

            % Calculating CFR (if rest values are empty then CFR are also
            % empty)
            if isempty(app.VARest)
                app.preCFR = 0;
            else
                app.preCFR = app.VA/app.VARest;
            end
            if isempty(app.VBRest)
                app.postCFR = 0;
            else
                app.postCFR = app.VB/app.VBRest;
            end

            % Displaying output values
            app.FFREditField.Value = sprintf('%.4f',app.FFR);
            app.PrelesionFlowVelocitycmsEditField.Value = sprintf('%.4f',app.VA);
            app.PostlesionFlowVelocitycmsEditField.Value = sprintf('%.4f',app.VB);
            app.PrelesionShearStressPaEditField.Value = sprintf('%.4f',app.shearStressA);
            app.PostlesionShearStressPaEditField.Value = sprintf('%.4f',app.shearStressB);
            app.PrelesionCFREditField.Value = sprintf('%.4f',app.preCFR);
            app.PostlesionCFREditField.Value = sprintf('%.4f',app.postCFR);
            app.StressLabel.Visible = 'on';

            % Adding result to csvWriteMatrix
            stringForAllData = sprintf('%s,%s,%d,%d,(%d %d),(%d %d),%.4f,%.4f,%.4f,%.4f,%.4f,%d,%d,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f\n', datestr(now,'mm-dd-yyyy HH-MM-SS'),'Stress',app.pa(1),app.pd(1),app.pa(3),app.pa(4),app.pd(3),app.pd(4),app.FFR,app.VA,app.VB,app.shearStressA,app.shearStressB,app.aucA,app.aucB,app.alphaA,app.betaA,app.kA,app.alphaB,app.betaB,app.kB,app.preCFR,app.postCFR,app.pLE,app.pLF,app.Re,app.pB,app.frictionFactor,app.Vs1,app.Vs2,app.Vs3,app.pLC,app.pLS);
            app.allResultsForCSVWrite = append(app.allResultsForCSVWrite, newline, stringForAllData);

            stringForCoordinates = sprintf('%d,%d,%d,%d\n',app.pa(3),app.pa(4),app.pa(1),app.pa(2));
            app.coOrdinatesForCSVWrite = append(app.coOrdinatesForCSVWrite,newline,stringForCoordinates);
            stringForCoordinates = sprintf('%d,%d,%d,%d\n',app.pd(3),app.pd(4),app.pd(1),app.pd(2));
            app.coOrdinatesForCSVWrite = append(app.coOrdinatesForCSVWrite,newline,stringForCoordinates);

            saveCoordiantes(app);
        end

        % Callback function
        function ClearInputsButtonPushed(app, event)
            % Clear all fields
            app.ContrastVolumemLEditField.Value = '';
            app.ContrastConcentrationmglmLEditField.Value = '';
            app.PrelesionLumenRadiuscmEditField.Value = '';
            app.PostlesionLumenRadiuscmEditField.Value = '';
            app.AorticBloodPressuremmHgEditField.Value = '';
            app.PrelesionTracerPercentageEditField.Value = '';
            app.PostlesionTracerPercentageEditField.Value = '';
            app.ConversionFactorEditField.Value = '';
            app.ArterialRoughness.Value = '';
        end

        % Callback function
        function FrictionFactorButtonGroupSelectionChanged(app, event)
            selectedButton = app.FrictionFactorButtonGroup.SelectedObject;
            app.fMethod = selectedButton.Text;
        end

        % Callback function
        function SaveResultsButtonPushed(app, event)

        end

        % Callback function
        function LoadPreLesionCurveButtonPushed(app, event)
            filterspec = {'*.txt','Text Files';'*.xlsx','Excel Files'};
            [f, p] = uigetfile(filterspec);
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);
            set(gcf, 'Pointer','arrow');

            logDAI('Checking chosen file type:');
            [~, name, ext] = fileparts(f);

            if (strcmp(ext,'.xlsx'))
                logDAI('File type is excel, reading excel file.');
                fName = strcat(p, f);
                data = readtable(fName);
                time = data{1:end, 1};
                roi = data{1:end, 2};
            end

            if (strcmp(ext,'.txt'))
                logDAI('File type is txt, reading txt file.');
                % Read text files:
                fileName = strcat(p,f);
                [time, roi] = readDataFile(fileName);
                if time(2) >= 500
                    time = time / 1000;
                end
            end

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                logDAI('Plotting the curve');
                app.paCurve = roi;
                app.plotX = time;
                app.paPlotArea.YLim = [app.yAxisLimitMin app.yAxisLimitMax];
                interval = getPlotInterval(app.yAxisLimitMin, app.yAxisLimitMax);
                app.paPlotArea.YTick = [app.yAxisLimitMin:interval:app.yAxisLimitMax];
                app.paPlotArea.XLim = [1 max(time)];
                app.paPlotArea.XTick = [1:max(time)/10:max(time)];
                app.paPlotArea.XTick = cast(app.paPlotArea.XTick, 'int16');
                plot(app.paPlotArea, app.plotX, app.paCurve, 'mo');
                app.preTextRead = true;
                logDAI('Plotting the curve is successful');
            end

            if (app.preTextRead == true && app.postTextRead == true)
                app.RelativeHeightcmEditField.Enable = true;
                app.PrePostDistancecmEditField.Enable = true;
            end

        end

        % Callback function
        function LoadPostLesionCurveButtonPushed(app, event)
            filterspec = {'*.txt','Text Files';'*.xlsx','Excel Files'};
            [f, p] = uigetfile(filterspec);
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);
            set(gcf, 'Pointer','arrow');

            logDAI('Checking chosen file type:');
            [~, name, ext] = fileparts(f);

            if (strcmp(ext,'.xlsx'))
                logDAI('File type is excel, reading excel file.');
                fName = strcat(p, f);
                data = readtable(fName);
                time = data{1:end, 1};
                roi = data{1:end, 3};
            end

            if (strcmp(ext,'.txt'))
                logDAI('File type is txt, reading txt file.');
                % Read text files:
                fileName = strcat(p,f);
                [time, roi] = readDataFile(fileName);
                if time(2) >= 500
                    time = time / 1000;
                end
            end

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                logDAI('Plotting the curve');
                app.pdCurve = roi;
                app.plotX = time;
                app.pdPlotArea.YLim = [app.yAxisLimitMin app.yAxisLimitMax];
                interval = getPlotInterval(app.yAxisLimitMin, app.yAxisLimitMax);
                app.pdPlotArea.YTick = [app.yAxisLimitMin:interval:app.yAxisLimitMax];
                app.pdPlotArea.XLim = [1 max(time)];
                app.pdPlotArea.XTick = [1:max(time)/10:max(time)];
                app.pdPlotArea.XTick = cast(app.pdPlotArea.XTick, 'int16');
                plot(app.pdPlotArea, app.plotX, app.pdCurve, 'mo');
                app.postTextRead = true;
                logDAI('Plotting the curve is successful');
            end

            if (app.preTextRead == true && app.postTextRead == true)
                app.RelativeHeightcmEditField.Enable = true;
                app.PrePostDistancecmEditField.Enable = true;
            end
        end

        % Callback function
        function ArteryTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ArteryTypeButtonGroup.SelectedObject;
            app.arteryType = selectedButton.Text;
        end

        % Callback function
        function paPlotAreaButtonDown(app, event)
            %pts = drawpoint(app.paPlotArea, 'Color','r');
            %dataPoint = round(pts.Position(1))

            % One way is to get the cursor position through event
            % event.IntersectionPoint

        end

        % Callback function
        function pdPlotAreaButtonDown(app, event)
            %pts = drawpoint(app.pdPlotArea, 'Color','b');
            %dataPoint = round(pts.Position(1))

        end

        % Callback function
        function ScreenCaptureButtonPushed(app, event)
            saveScreen(app);
        end

        % Callback function
        function ManualBaselineWashoutstressCheckBoxValueChanged(app, event)
            app.manualBaselineCheck = app.ManualBaselineWashoutstressCheckBox.Value;
            if (app.manualBaselineCheck == 1)
                app.ManualBaselineInput.Enable = true;
                app.ManualWashoutInput.Enable = true;
                app.PreLesionCheckBox.Enable = true;
                app.PostLesionCheckBox.Enable = true;
            end
            if (app.manualBaselineCheck == 0)
                app.ManualBaselineInput.Enable = false;
                app.ManualBaselineInput.Value = '';
                app.ManualWashoutInput.Enable = false;
                app.ManualWashoutInput.Value = '';
                app.PreLesionCheckBox.Enable = false;
                app.PostLesionCheckBox.Enable = false;
            end
        end

        % Callback function
        function LoadStudiesButtonRestPushed(app, event)

        end

        % Callback function
        function TimePointsSliderRestValueChanging(app, event)
            timePointsLimit = size(app.restImageSet,2);
            if (round(event.Value) > timePointsLimit)
                changingValue = timePointsLimit;
            else
                if (round(event.Value) == 0)
                    changingValue = 1;
                else
                    changingValue = round(event.Value);
                end
            end
            app.currentTimePointRest = changingValue;
            imageData(1:512, 1:512) = app.restImageSet(app.currentSliceRest,app.currentTimePointRest,1:512,1:512);
            if (~isempty(app.paRest) && app.currentSliceRest == app.paRest(1))
                imageData = getROIOverlayed(imageData, app.sxPreRest, app.syPreRest);
            elseif (~isempty(app.pdRest) && app.currentSliceRest == app.pdRest(1))
                imageData = getROIOverlayed(imageData, app.sxPostRest, app.syPostRest);
            end
            updateImageRest(app, imageData);
        end

        % Callback function
        function SlicesSliderRestValueChanging(app, event)
            sliceLimit = size(app.restImageSet,1);
            if (round(event.Value) > sliceLimit)
                changingValue = sliceLimit;
            else
                if (round(event.Value) == 0)
                    changingValue = 1;
                else
                    changingValue = round(event.Value);
                end
            end
            app.currentSliceRest = changingValue;
            app.SlicesSliderLabelRest.Text = sprintf('Slice %d/%d', app.currentSliceRest, size(app.fourDImageSet, 1));
            imageData(1:512, 1:512) = app.restImageSet(app.currentSliceRest,app.currentTimePointRest,1:512,1:512);
            if (~isempty(app.paRest) && app.currentSliceRest == app.paRest(1))
                imageData = getROIOverlayed(imageData, app.sxPreRest, app.syPreRest);
            elseif (~isempty(app.pdRest) && app.currentSliceRest == app.pdRest(1))
                imageData = getROIOverlayed(imageData, app.sxPostRest, app.syPostRest);
            end
            updateImageRest(app, imageData);
        end

        % Callback function
        function SetWindowButtonRestPushed(app, event)
            level = str2double(app.SetWindowLevelRest.Value{1});
            width = str2double(app.SetWindowWidthRest.Value{1});
            app.minWindowRest = level - width / 2;
            app.maxWindowRest = level + width / 2;
            imagesc(app.ImageAxesRest, app.imRest, [app.minWindowRest, app.maxWindowRest]);
        end

        % Callback function
        function ClearInputsButtonRestPushed(app, event)
            % Clear all fields
            app.ContrastVolumemLEditFieldRest.Value = '';
            app.ConcentrationofContrastmglmLEditFieldRest.Value = '';
            app.RadiusPreLesioncmEditFieldRest.Value = '';
            app.RadiusPostLesioncmEditFieldRest.Value = '';
            app.SystolicBloodPressuremmHgEditFieldRest.Value = '';
            app.PreTracerPercentageEditFieldRest.Value = '';
            app.PostTracerPercentageEditFieldRest.Value = '';
            app.ConversionFactorEditFieldRest.Value = '';
            app.ArterialRoughnessRest.Value = '';
        end

        % Callback function
        function ClearAllButtonPushed(app, event)
            ClearInputsButtonRestPushed(app, event);
            ClearInputsButtonPushed(app, event);
            ClearOutputsButtonPushed(app, event);
        end

        % Callback function
        function ClearOutputsButtonPushed(app, event)
            % Stress Output Clear:
            app.PostlesionShearStressPaEditField.Value = '';
            app.PrelesionShearStressPaEditField.Value = '';
            app.PostlesionFlowVelocitycmsEditField.Value = '';
            app.PrelesionFlowVelocitycmsEditField.Value = '';
            app.FFREditField.Value = '';
            app.PrelesionCFREditField.Value = '';
            app.PostlesionCFREditField.Value = '';
            app.StressLabel.Visible = 'off';

            % Rest Output Clear:
            app.PostShearStressPaEditFieldRest.Value = '';
            app.PreShearStressPaEditFieldRest.Value = '';
            app.PostLesionVelocitycmsEditFieldRest.Value = '';
            app.PreLesionVelocitycmsEditFieldRest.Value = '';
            app.FFREditFieldRest.Value = '';
            app.RestLabel.Visible = 'off';
        end

        % Callback function
        function SetPreLesionButtonRestPushed(app, event)
            zoom(app.ImageAxesRest, 'off');
            app.ptsARest = drawpoint(app.ImageAxesRest);
            app.paRest = [app.currentSliceRest, app.currentTimePointRest, round(app.ptsARest.Position(2)), round(app.ptsARest.Position(1))];
            app.fittedCurvePaRest = zeros(size(app.timePointsRest));
            app.paSliceRest = app.currentSliceRest;
            app.paTimePointRest = app.currentTimePointRest;
            [app.paCurveRest, app.interpolatedSampledPointsPaRest, app.interpolatedTimePointsRest, app.sxPreRest, app.syPreRest, app.rxPreRest, app.ryPreRest] = getBestSample(app.paRest(3), app.paRest(4), app.searchROIMRest, app.roiMRest, app.roiNRest, app.currentSliceRest, app.restImageSet, app.timePointsRest);
            segmentationWindowSize = 25;
            [imageWithContour, radiusIncm, areaOfContourInCmSq] = getContour(app.paRest(3),app.paRest(4), segmentationWindowSize, app.currentSliceRest, app.paTimePointRest, app.restImageSet, app.dicomInfoRest.PixelSpacing);
            imageData(1:512, 1:512) = app.restImageSet(app.currentSliceRest,app.currentTimePointRest,1:512,1:512);
            imageWithContour = getROIOverlayed(imageData, app.sxPreRest, app.syPreRest);
            updateImageRest(app,imageWithContour);
            radiusIncm
            areaOfContourInCmSq
            drawPreLesionCurveRest(app);
        end

        % Callback function
        function SetPostLesionButtonRestPushed(app, event)
            zoom(app.ImageAxesRest, 'off');
            app.ptsBRest = drawpoint(app.ImageAxesRest);
            app.pdRest = [app.currentSliceRest, app.currentTimePointRest, round(app.ptsBRest.Position(2)), round(app.ptsBRest.Position(1))];
            app.fittedCurvePdRest = zeros(size(app.timePointsRest));
            app.pdSliceRest = app.currentSliceRest;
            app.pdTimePointRest = app.currentTimePointRest;
            [app.pdCurveRest, app.interpolatedSampledPointsPdRest, app.interpolatedTimePointsRest, app.sxPostRest, app.syPostRest, app.rxPostRest, app.ryPostRest] = getBestSample(app.pdRest(3),app.pdRest(4), app.searchROIMRest, app.roiMRest, app.roiNRest, app.currentSliceRest, app.restImageSet, app.timePointsRest);
            segmentationWindowSize = 25;
            [imageWithContour, radiusIncm, areaOfContourInCmSq] = getContour(app.pdRest(3),app.pdRest(4), segmentationWindowSize, app.currentSliceRest, app.pdTimePointRest, app.restImageSet, app.dicomInfoRest.PixelSpacing);
            imageData(1:512, 1:512) = app.restImageSet(app.currentSliceRest,app.currentTimePointRest,1:512,1:512);
            imageWithContour = getROIOverlayed(imageData, app.sxPostRest, app.syPostRest);
            updateImageRest(app,imageWithContour);
            radiusIncm
            areaOfContourInCmSq
            drawPostLesionCurveRest(app);
        end

        % Callback function
        function OutputButtonRestPushed(app, event)
            % Q has the unit mL * mg/mL = mg
            if strcmp(app.arteryTypeRest, 'Coronary')
                if (app.PreTracerPercentageEditFieldRest.Value == "")
                    app.preTracerPercentageRest = 0.02;
                else
                    app.preTracerPercentageRest = str2double(app.PreTracerPercentageEditFieldRest.Value)/100;
                end
                app.preQRest = app.preTracerPercentageRest * str2double(app.ContrastVolumemLEditFieldRest.Value) * str2double(app.ConcentrationofContrastmglmLEditFieldRest.Value);

                if (app.PostTracerPercentageEditFieldRest.Value == "")
                    app.postTracerPercentageRest = 0.02;
                else
                    app.postTracerPercentageRest = str2double(app.PostTracerPercentageEditFieldRest.Value)/100;
                end
                app.postQRest = app.postTracerPercentageRest * str2double(app.ContrastVolumemLEditFieldRest.Value) * str2double(app.ConcentrationofContrastmglmLEditFieldRest.Value);

                % Converting HUs to mg/mL
                tempAucA = app.aucARest / str2double(app.ConversionFactorEditFieldRest.Value);
                tempAucB = app.aucBRest / str2double(app.ConversionFactorEditFieldRest.Value);
            end

            if strcmp(app.arteryTypeRest, 'Aorta')
                if (app.PreTracerPercentageEditFieldRest.Value == "")
                    app.preTracerPercentageRest = 0.94;
                else
                    app.preTracerPercentageRest = str2double(app.PreTracerPercentageEditFieldRest.Value)/100;
                end
                app.preQRest = app.preTracerPercentageRest * str2double(app.ContrastVolumemLEditFieldRest.Value) * str2double(app.ConcentrationofContrastmglmLEditFieldRest.Value);

                if (app.PostTracerPercentageEditFieldRest.Value == "")
                    app.postTracerPercentageRest = 0.94;
                else
                    app.postTracerPercentageRest = str2double(app.PostTracerPercentageEditFieldRest.Value)/100;
                end
                app.postQRest = app.postTracerPercentageRest * str2double(app.ContrastVolumemLEditFieldRest.Value) * str2double(app.ConcentrationofContrastmglmLEditFieldRest.Value);
                % In which case it will be 98% - Aaron to send me a document describing it.
                % app.Q = 0.98 * str2double(app.ContrastVolumemLEditField.Value) * str2double(app.ContrastConcentrationmglmLEditField.Value);

                % Converting HUs to mg/mL
                tempAucA = app.aucARest / str2double(app.ConversionFactorEditFieldRest.Value);
                tempAucB = app.aucBRest / str2double(app.ConversionFactorEditFieldRest.Value);
            end

            app.hBRest = getRelativeHeight(app.paRest(1), app.pdRest(1), app.dicomInfoRest.SliceThickness, app.hAhBRestDiffNegative);
            app.LRest = getDistanceBetweenSlicesAB(app.paRest(3), app.paRest(4), app.pdRest(3), app.pdRest(4), app.hBRest, app.dicomInfoRest.PixelSpacing);

            if (app.ArterialRoughnessRest.Value == "")
                app.ArterialRoughnessRest.Value = '0';
            end

            % Radius values are in cm
            [app.FFRRest, app.VARest, app.VBRest, app.shearStressARest, app.shearStressBRest, app.pLERest, app.pLFRest, app.ReRest, app.pBRest, app.frictionFactorRest, app.Vs1Rest, app.Vs2Rest, app.Vs3Rest, app.pLCRest, app.pLSRest] = calculateFFR(tempAucA, tempAucB, app.preQRest, app.postQRest, app.hBRest, app.LRest, str2double(app.RadiusPreLesioncmEditFieldRest.Value), str2double(app.RadiusPostLesioncmEditFieldRest.Value), str2double(app.SystolicBloodPressuremmHgEditFieldRest.Value), str2double(app.ArterialRoughnessRest.Value), app.fMethodRest, 0);

            % Calculating CFR (if stress values are empty then CFR are also
            % empty)
            if isempty(app.VA)
                app.preCFR = 0;
            else
                app.preCFR = app.VA/app.VARest;
            end
            if isempty(app.VB)
                app.postCFR = 0;
            else
                app.postCFR = app.VB/app.VBRest;
            end

            % Displaying output values
            app.FFREditFieldRest.Value = sprintf('%.4f',app.FFRRest);
            app.PreLesionVelocitycmsEditFieldRest.Value = sprintf('%.4f',app.VARest);
            app.PostLesionVelocitycmsEditFieldRest.Value = sprintf('%.4f',app.VBRest);
            app.PreShearStressPaEditFieldRest.Value = sprintf('%.4f',app.shearStressARest);
            app.PostShearStressPaEditFieldRest.Value = sprintf('%.4f',app.shearStressBRest);
            app.PrelesionCFREditField.Value = sprintf('%.4f',app.preCFR);
            app.PostlesionCFREditField.Value = sprintf('%.4f',app.postCFR);
            app.RestLabel.Visible = 'on';

            % Adding result to csvWriteMatrix
            stringForAllData = sprintf('%s,%s,%d,%d,(%d %d),(%d %d),%.4f,%.4f,%.4f,%.4f,%.4f,%d,%d,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f\n', datestr(now,'mm-dd-yyyy HH-MM-SS'),'Rest',app.paRest(1),app.pdRest(1),app.paRest(3),app.paRest(4),app.pdRest(3),app.pdRest(4),app.FFRRest,app.VARest,app.VBRest,app.shearStressARest,app.shearStressBRest,app.aucARest,app.aucBRest,app.alphaARest,app.betaARest,app.kARest,app.alphaBRest,app.betaBRest,app.kBRest,app.preCFR,app.postCFR,app.pLERest,app.pLFRest,app.ReRest,app.pBRest,app.frictionFactorRest,app.Vs1Rest,app.Vs2Rest,app.Vs3Rest,app.pLCRest, app.pLSRest);
            app.allResultsForCSVWrite = append(app.allResultsForCSVWrite, newline, stringForAllData);

            stringForCoordinates = sprintf('%d,%d,%d,%d\n',app.paRest(3),app.paRest(4),app.paRest(1),app.paRest(2));
            app.coOrdinatesForCSVWriteRest = append(app.coOrdinatesForCSVWriteRest,newline,stringForCoordinates);
            stringForCoordinates = sprintf('%d,%d,%d,%d\n',app.pdRest(3),app.pdRest(4),app.pdRest(1),app.pdRest(2));
            app.coOrdinatesForCSVWriteRest = append(app.coOrdinatesForCSVWriteRest,newline,stringForCoordinates);

            saveCoordiantes(app);
        end

        % Callback function
        function FitCurvesButtonRestPushed(app, event)
            % This function now handles only pre-lesion cases:

            if isrow(app.paCurveRest)
                app.paCurveRest = app.paCurveRest';
            end
            app.manualBaselineValueRest = str2double(app.ManualBaselineInputRest.Value);
            app.manualWashoutValueRest = str2double(app.ManualWashoutInputRest.Value);
            if isnan(app.manualBaselineValueRest)
                app.manualBaselineValueRest = 0;
            else
                app.manualBaselineValueRest = str2double(app.ManualBaselineInputRest.Value);
            end
            if isnan(app.manualWashoutValueRest)
                app.manualWashoutValueRest = 0;
            else
                app.manualWashoutValueRest = str2double(app.ManualWashoutInputRest.Value);
            end
            manualBaselineValuePreLesionRest = app.manualBaselineValueRest;
            manualWashoutValuePreLesionRest = app.manualBaselineValueRest;

            try
                [app.fittedCurvePaRest, app.fittedTimePaRest, app.paCurveRest, app.rmseARest, app.kARest, app.alphaARest, app.betaARest] = getFittedCurve(app.paCurveRest, app.plotXRest, manualBaselineValuePreLesionRest, manualWashoutValuePreLesionRest);
                app.aucARest = getAreaUnderCurve(app.fittedCurvePaRest, app.fittedTimePaRest);
                if isrow(app.pdCurveRest)
                    app.pdCurveRest = app.pdCurveRest';
                end

                % Rest Pre and Rest Post
                if (strcmp(app.viewMode,'Dark'))
                    plot(app.paPlotArea, app.plotXRest, app.paCurveRest, 'ws', app.fittedTimePaRest, app.fittedCurvePaRest, 'w-');
                    legend(app.paPlotArea,'Pre-Rest','Pre-Rest-Fitted', 'TextColor','white');
                    if (~all(app.fittedCurvePdRest) == 0)
                        plot(app.pdPlotArea, app.plotXRest, app.pdCurveRest, 'ws', app.fittedTimePdRest, app.fittedCurvePdRest, 'w-');
                        legend(app.pdPlotArea,'Post-Rest','Post-Rest-Fitted', 'TextColor','white');
                    end

                    if(~isempty(app.paCurve))
                        if(~isempty(app.fittedCurvePa))
                            plot(app.paPlotArea, app.plotX, app.paCurve, 'yo', app.fittedTimePa, app.fittedCurvePa, 'y-', app.plotXRest, app.paCurveRest, 'ws', app.fittedTimePaRest, app.fittedCurvePaRest, 'w-');
                            legend(app.paPlotArea,'Pre-Lesion Sampled', 'Pre-Lesion Fitted', 'Pre-Rest','Pre-Rest-Fitted', 'TextColor','white');
                        else
                            plot(app.paPlotArea, app.plotX, app.paCurve, 'yo', app.plotXRest, app.paCurveRest, 'ws', app.fittedTimePaRest, app.fittedCurvePaRest, 'w-');
                            legend(app.paPlotArea,'Pre-Lesion Sampled','Pre-Rest','Pre-Rest-Fitted', 'TextColor','white');
                        end
                    end

                    if(~isempty(app.pdCurve))
                        if(~isempty(app.fittedCurvePd))
                            plot(app.pdPlotArea, app.plotX, app.pdCurve, 'yo', app.fittedTimePd, app.fittedCurvePd, 'y-', app.plotXRest, app.pdCurveRest, 'ws', app.fittedTimePdRest, app.fittedCurvePdRest, 'w-');
                            legend(app.pdPlotArea,'Post-Lesion Sampled','Post-Lesion Fitted', 'Post-Rest', 'Post-Rest-Fitted', 'TextColor','white');
                        else
                            plot(app.pdPlotArea, app.plotX, app.pdCurve, 'yo', app.plotXRest, app.pdCurveRest, 'ws', app.fittedTimePdRest, app.fittedCurvePdRest, 'w-');
                            legend(app.pdPlotArea,'Post-Lesion Sampled','Post-Rest','Post-Rest-Fitted', 'TextColor','white');
                        end
                    end
                else
                    plot(app.paPlotArea, app.plotXRest, app.paCurveRest, 'bs', app.fittedTimePaRest, app.fittedCurvePaRest, 'b-');
                    legend(app.paPlotArea,'Pre-Rest','Pre-Rest-Fitted', 'TextColor','black');
                    if (~all(app.fittedCurvePdRest) == 0)
                        plot(app.pdPlotArea, app.plotXRest, app.pdCurveRest, 'bs', app.fittedTimePdRest, app.fittedCurvePdRest, 'b-');
                        legend(app.pdPlotArea,'Post-Rest','Post-Rest-Fitted', 'TextColor','black');
                    end

                    if(~isempty(app.paCurve))
                        if(~isempty(app.fittedCurvePa))
                            plot(app.paPlotArea, app.plotX, app.paCurve, 'mo', app.fittedTimePa, app.fittedCurvePa, 'm-', app.plotXRest, app.paCurveRest, 'bs', app.fittedTimePaRest, app.fittedCurvePaRest, 'b-');
                            legend(app.paPlotArea,'Pre-Lesion Sampled', 'Pre-Lesion Fitted', 'Pre-Rest','Pre-Rest-Fitted', 'TextColor','black');
                        else
                            plot(app.paPlotArea, app.plotX, app.paCurve, 'mo', app.plotXRest, app.paCurveRest, 'bs', app.fittedTimePaRest, app.fittedCurvePaRest, 'b-');
                            legend(app.paPlotArea,'Pre-Lesion Sampled','Pre-Rest','Pre-Rest-Fitted', 'TextColor','black');
                        end
                    end

                    if(~isempty(app.pdCurve))
                        if(~isempty(app.fittedCurvePd))
                            plot(app.pdPlotArea, app.plotX, app.pdCurve, 'mo', app.fittedTimePd, app.fittedCurvePd, 'm-', app.plotXRest, app.pdCurveRest, 'bs', app.fittedTimePdRest, app.fittedCurvePdRest, 'b-');
                            legend(app.pdPlotArea,'Post-Lesion Sampled','Post-Lesion Fitted', 'Post-Rest', 'Post-Rest-Fitted', 'TextColor','black');
                        else
                            plot(app.pdPlotArea, app.plotX, app.pdCurve, 'mo', app.plotXRest, app.pdCurveRest, 'bs', app.fittedTimePdRest, app.fittedCurvePdRest, 'b-');
                            legend(app.pdPlotArea,'Post-Lesion Sampled','Post-Rest','Post-Rest-Fitted', 'TextColor','black');
                        end
                    end
                end
            catch ME
                msg = strcat('Problem in Curve Fitting:', ME.message);
                errordlg(msg);
            end
        end

        % Callback function
        function CopyRestInputsButtonPushed(app, event)
            app.ContrastVolumemLEditField.Value = app.ContrastVolumemLEditFieldRest.Value;
            app.ContrastConcentrationmglmLEditField.Value = app.ConcentrationofContrastmglmLEditFieldRest.Value;
            app.PrelesionLumenRadiuscmEditField.Value = app.RadiusPreLesioncmEditFieldRest.Value;
            app.PostlesionLumenRadiuscmEditField.Value = app.RadiusPostLesioncmEditFieldRest.Value;
            app.AorticBloodPressuremmHgEditField.Value = app.SystolicBloodPressuremmHgEditFieldRest.Value;
            app.PrelesionTracerPercentageEditField.Value = app.PreTracerPercentageEditFieldRest.Value;
            app.PostlesionTracerPercentageEditField.Value = app.PostTracerPercentageEditFieldRest.Value;
            app.ConversionFactorEditField.Value = app.ConversionFactorEditFieldRest.Value;
            app.ArterialRoughness.Value = app.ArterialRoughnessRest.Value;
        end

        % Callback function
        function CopyStressInputsButtonPushed(app, event)
            app.ContrastVolumemLEditFieldRest.Value = app.ContrastVolumemLEditField.Value;
            app.ConcentrationofContrastmglmLEditFieldRest.Value = app.ContrastConcentrationmglmLEditField.Value;
            app.RadiusPreLesioncmEditFieldRest.Value = app.PrelesionLumenRadiuscmEditField.Value;
            app.RadiusPostLesioncmEditFieldRest.Value = app.PostlesionLumenRadiuscmEditField.Value;
            app.SystolicBloodPressuremmHgEditFieldRest.Value = app.AorticBloodPressuremmHgEditField.Value;
            app.PreTracerPercentageEditFieldRest.Value = app.PrelesionTracerPercentageEditField.Value;
            app.PostTracerPercentageEditFieldRest.Value = app.PostlesionTracerPercentageEditField.Value;
            app.ConversionFactorEditFieldRest.Value = app.ConversionFactorEditField.Value;
            app.ArterialRoughnessRest.Value = app.ArterialRoughness.Value;
        end

        % Callback function
        function ManualBaselineWashoutrestCheckBoxValueChanged(app, event)
            app.manualBaselineCheckRest = app.ManualBaselineWashoutrestCheckBox.Value;
            if (app.manualBaselineCheckRest == 1)
                app.ManualBaselineInputRest.Enable = true;
                app.ManualWashoutInputRest.Enable = true;
                app.PreLesionCheckBoxRest.Enable = true;
                app.PostLesionCheckBoxRest.Enable = true;
            end
            if (app.manualBaselineCheckRest == 0)
                app.ManualBaselineInputRest.Enable = false;
                app.ManualBaselineInputRest.Value = '';
                app.ManualWashoutInputRest.Enable = false;
                app.ManualWashoutInputRest.Value = '';
                app.PreLesionCheckBoxRest.Enable = false;
                app.PostLesionCheckBoxRest.Enable = false;
            end
        end

        % Callback function
        function RelativeHeightcmEditFieldValueChanged(app, event)
            app.hBText = str2double(app.RelativeHeightcmEditField.Value);
        end

        % Callback function
        function PrePostDistancecmEditFieldValueChanged(app, event)
            app.LText = str2double(app.PrePostDistancecmEditField.Value);
        end

        % Callback function
        function ManualZoomingCheckBoxValueChanged(app, event)
            value = app.ManualZoomingCheckBox.Value;
            if (value == 1)
                app.ImageAxes.Interactions = zoomInteraction;
            end
            if (value == 0)
                zoom(app.ImageAxes, 'off');
            end
        end

        % Callback function
        function ManualZoomingRestCheckBoxValueChanged(app, event)
            value = app.ManualZoomingRestCheckBox.Value;
            if (value == 1)
                app.ImageAxesRest.Interactions = zoomInteraction;
            end
            if (value == 0)
                zoom(app.ImageAxesRest, 'off');
            end
        end

        % Callback function
        function SamplingROIDropDownValueChanged(app, event)
            value = app.SamplingROIDropDown.Value;
            app.roiM = str2double(value(1));
            app.roiN = str2double(value(3));
        end

        % Callback function
        function ROIDropDownRestValueChanged(app, event)
            value = app.ROIDropDownRest.Value;
            app.roiMRest = str2double(value(1));
            app.roiNRest = str2double(value(3));
        end

        % Callback function
        function veHeightCheckBoxValueChanged(app, event)
            value = app.veHeightCheckBox.Value;
            if (value == 1)
                app.hAhBDiffNegative = false;
            end
            if (value == 0)
                app.hAhBDiffNegative = true;
            end
        end

        % Callback function
        function veHeightRestCheckBoxValueChanged(app, event)
            value = app.veHeightRestCheckBox.Value;
            if (value == 1)
                app.hAhBRestDiffNegative = false;
            end
            if (value == 0)
                app.hAhBRestDiffNegative = true;
            end
        end

        % Callback function
        function SearchROIDropDownValueChanged(app, event)
            value = app.SearchROIDropDown.Value;
            if (value(2) == 'x')
                app.searchROIM = str2double(value(1));
                app.searchROIN = str2double(value(3));
            elseif (value(3) == 'x')
                app.searchROIM = str2double(value(1:2));
                app.searchROIN = str2double(value(4:5));
            end
        end

        % Callback function
        function RestSearchROIDropDownValueChanged(app, event)
            value = app.RestSearchROIDropDown.Value;
            if (value(2) == 'x')
                app.searchROIMRest = str2double(value(1));
                app.searchROINRest = str2double(value(3));
            elseif (value(3) == 'x')
                app.searchROIMRest = str2double(value(1:2));
                app.searchROINRest = str2double(value(4:5));
            end
        end

        % Callback function
        function InterpolateCurrentSliceWithDropDownValueChanged(app, event)
            value = convertCharsToStrings(app.InterpolateCurrentSliceWithDropDown.Value);
            if (strcmp(value,'Previous Slice'))
                if (app.currentSlice > 1)
                    app.sliceToBeInterpolatedWith = app.currentSlice - 1;
                    interpolatedSliceForAllTimePoints = getInterpolatedSlice(app.currentSlice, app.sliceToBeInterpolatedWith, app.fourDImageSet);
                else
                    errordlg('Current Slice Cannot Have Previous Slice','Slice Choose Error');
                end
            elseif (strcmp(value,'Next Slice'))
                if (app.currentSlice < size(app.fourDImageSet,1))
                    app.sliceToBeInterpolatedWith = app.currentSlice + 1;
                    interpolatedSliceForAllTimePoints = getInterpolatedSlice(app.currentSlice, app.sliceToBeInterpolatedWith, app.fourDImageSet);
                else
                    errordlg('Current Slice Cannot Have Next Slice','Slice Choose Error');
                end
            elseif ((strcmp(value,'None')))
                % Do nothing
            end

            tempFourDImageSet = app.fourDImageSet;
            tempFourDImageSet(app.currentSlice,:,:,:) = interpolatedSliceForAllTimePoints;
            if (app.currentSlice == app.pa(1))
                % copy pre slice's coordinate
                [app.paCurve, app.interpolatedSampledPointsPa, app.interpolatedTimePoints, app.sxPre, app.syPre, app.rxPre, app.ryPre] = getBestSample(app.pa(3),app.pa(4),app.searchROIM,app.roiM,app.roiN,app.currentSlice,tempFourDImageSet,app.timePoints);
                drawPreLesionCurve(app);
            elseif (app.currentSlice == app.pd(1))
                % copy post slice's coordinate
                [app.pdCurve, app.interpolatedSampledPointsPd, app.interpolatedTimePoints, app.sxPost, app.syPost, app.rxPost, app.ryPost] = getBestSample(app.pd(3),app.pd(4),app.searchROIM,app.roiM,app.roiN,app.currentSlice,tempFourDImageSet,app.timePoints);
                drawPostLesionCurve(app);
            end
        end

        % Callback function
        function InterpolateCurrentSliceWithDropDownRestValueChanged(app, event)
            value = convertCharsToStrings(app.InterpolateCurrentSliceWithDropDownRest.Value);
            if (strcmp(value,'Previous Slice'))
                if (app.currentSliceRest > 1)
                    app.sliceToBeInterpolatedWithRest = app.currentSliceRest - 1;
                    interpolatedSliceForAllTimePoints = getInterpolatedSlice(app.currentSliceRest, app.sliceToBeInterpolatedWithRest, app.restImageSet);
                else
                    errordlg('Current Slice Cannot Have Previous Slice','Slice Choose Error');
                end
            elseif (strcmp(value,'Next Slice'))
                if (app.currentSliceRest < size(app.restImageSet,1))
                    app.sliceToBeInterpolatedWithRest = app.currentSliceRest + 1;
                    interpolatedSliceForAllTimePoints = getInterpolatedSlice(app.currentSliceRest, app.sliceToBeInterpolatedWithRest, app.restImageSet);
                else
                    errordlg('Current Slice Cannot Have Next Slice','Slice Choose Error');
                end
            elseif ((strcmp(value,'None')))
                % Do nothing
            end

            tempRestImageSet = app.restDImageSet;
            tempRestImageSet(app.currentSliceRest,:,:,:) = interpolatedSliceForAllTimePoints;
            if (app.currentSliceRest == app.paRest(1))
                % copy pre slice rest's coordinate
                [app.paCurveRest, app.interpolatedSampledPointsPaRest, app.interpolatedTimePointsRest, app.sxPreRest, app.syPreRest, app.rxPreRest, app.ryPreRest] = getBestSample(app.paRest(3), app.paRest(4), app.searchROIMRest, app.roiMRest, app.roiNRest, app.currentSliceRest, tempRestImageSet, app.timePointsRest);
                drawPreLesionCurveRest(app);
            elseif (app.currentSliceRest == app.pdRest(1))
                % copy post slice rest's coordinate
                [app.pdCurveRest, app.interpolatedSampledPointsPdRest, app.interpolatedTimePointsRest, app.sxPostRest, app.syPostRest, app.rxPostRest, app.ryPostRest] = getBestSample(app.pdRest(3),app.pdRest(4), app.searchROIMRest, app.roiMRest, app.roiNRest, app.currentSliceRest, tempRestImageSet, app.timePointsRest);
                drawPostLesionCurveRest(app);
            end
        end

        % Callback function
        function ExportCurveButtonPushed(app, event)
            if (~isempty(app.paCurve))
                if (isempty(app.ConversionFactorEditField))
                    tempAucA = app.aucA;
                    tempAucB = app.aucB;
                else
                    tempAucA = app.aucA / str2double(app.ConversionFactorEditField.Value);
                    tempAucB = app.aucB / str2double(app.ConversionFactorEditField.Value);
                end
                exportCurves(app.paCurve, app.pdCurve, app.fittedCurvePa, app.fittedCurvePd, app.timePoints, app.fittedTimePa, tempAucA, tempAucB, app.dicomInfo.PatientName.FamilyName, 'Stress');
            end
            if (~isempty(app.paCurveRest))
                if (isempty(app.ConversionFactorEditField))
                    tempAucA = app.aucARest;
                    tempAucB = app.aucBRest;
                else
                    tempAucA = app.aucARest / str2double(app.ConversionFactorEditFieldRest.Value);
                    tempAucB = app.aucBRest / str2double(app.ConversionFactorEditFieldRest.Value);
                end
                exportCurves(app.paCurveRest, app.pdCurveRest, app.fittedCurvePaRest, app.fittedCurvePdRest, app.timePointsRest, app.fittedTimePaRest, tempAucA, tempAucB, app.dicomInfoRest.PatientName.FamilyName, 'Rest');
            end
        end

        % Callback function
        function ExportStressStudiesButtonPushed(app, event)
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);

            writeDicomVolume(app.fourDImageSet, app.allDicomInfo, 'Stress');

            set(gcf, 'Pointer','arrow');
        end

        % Callback function
        function ExportRestStudiesButtonPushed(app, event)
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);

            writeDicomVolume(app.restImageSet, app.allDicomInfoRest, 'Rest');

            set(gcf, 'Pointer','arrow');
        end

        % Callback function
        function Load3DVolumeStressButtonPushed(app, event)

        end

        % Callback function
        function Load3DCTAVolumeButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.*','All Files'};
            [f, p] = uigetfile(filterspec);
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);

            try
                % Read 3D volume:
                [app.threeDVolume,sp] = dicomreadVolume(p);
                app.threeDVolume = squeeze(app.threeDVolume);

                logDAI('Converting dicomVolume to jpegVolume');
                app.volumeJPEG = getJPEGVolumeFromDicomVolume(app.threeDVolume, 3);

                app.labelImageDirectory = p;

                app.SlicesSlider3D.Limits = [1 size(app.threeDVolume,3)];

                set(gcf, 'Pointer','arrow');

                level = 500;
                width = 1500;
                app.minWindow3D = level - width / 2;
                app.maxWindow3D = level + width / 2;

                % Make sure user didn't cancel uigetfile dialog
                if (ischar(p))
                    firstImage = app.threeDVolume(:,:,1);
                    updateimage3D(app,firstImage);

                    firstImageJPEG(:,:,:) = app.volumeJPEG(:,:,1,:);
                    updateimage3DJPEG(app,firstImageJPEG);
                end
            catch ME
                set(gcf, 'Pointer','arrow');
                msg = strcat('The DAI did not fail, there seems to be an issue with this dataset in particular: ', ME.message);
                errordlg(msg);
            end
        end

        % Callback function
        function ApplyDefaultThresholdsCheckBoxValueChanged(app, event)

        end

        % Callback function
        function DefaultThresholdsButtonGroupSelectionChanged(app, event)

        end

        % Callback function
        function ApplyThresholdButtonPushed(app, event)

        end

        % Callback function
        function LoadTrainingDataButtonPushed(app, event)
            app.trainDirectory = uigetdir;
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);
            set(gcf, 'Pointer','arrow');
        end

        % Callback function
        function LoadTestingDataButtonPushed(app, event)
            app.testDirectory = uigetdir;
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);
            set(gcf, 'Pointer','arrow');
        end

        % Callback function
        function LoadValidationDataButtonPushed(app, event)
            app.validationDirectory = uigetdir;
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);
            set(gcf, 'Pointer','arrow');
        end

        % Callback function
        function StartTrainingButtonPushed(app, event)

            % Get Data Ready:
            trainingDS = getData(app.trainDirectory);
            validationDS = getData(app.validationDirectory);

            % Training Option:
            if strcmp(app.NetworkOptionButtonGroup.SelectedObject.Text, 'CNN')
                trainingOption = 'CNN';
            end

            if strcmp(app.NetworkOptionButtonGroup.SelectedObject.Text, 'UNET')
                trainingOption = 'UNET';
            end

            app.trainedNetwork = getTraining(trainingDS, validationDS, trainingOption);
            networkName = sprintf('%s/%s', 'TrainedNetwork', datestr(now,'yyyymmddTHHMMSSFFF'));
            save(networkName, app.trainedNetwork);

        end

        % Callback function
        function ClassifyRCACheckBoxValueChanged(app, event)

        end

        % Callback function
        function ClassifyLADCheckBoxValueChanged(app, event)

        end

        % Callback function
        function LoadSavedNetworkButtonPushed(app, event)
            [f, p] = uigetfile();
            app.savedNetWork = coder.loadDeepLearningNetwork(p);
            app.trainedNetwork = app.savedNetwork;
        end

        % Callback function
        function ClassifyButtonPushed(app, event)
            testingDS = getData(app.testDirectory, 0, 0, labelName);
            predictedData = classify(app.trainedNetwork, testingDS);
        end

        % Callback function
        function SetRCAPointButtonPushed(app, event)
            ptsValue = drawpoint(app.ImageAxes3D);
            app.ptsRCA(end+1) = app.currentSlice3D;
            app.ptsRCA(end+1) = round(ptsValue.Position(2));
            app.ptsRCA(end+1) = round(ptsValue.Position(1));
            % Quadruplet of (Slice #, X, Y, PixelValue)
            app.supervisedLearningLabeledPoints{end+1} = 'rca';
            logDAI('RCA point adding completed.');
            logDAI('Putting point on JPEG volume');
            img = app.threeDVolume(:,:,app.currentSlice3D);
            imgJPEG(:,:,:) = app.volumeJPEG(:,:,app.currentSlice3D,:);
            app.ptsRCA(end+1) = imgJPEG(round(ptsValue.Position(2)), round(ptsValue.Position(1)));
            imgJPEG = getColoredPointForMarkedCoordinate(imgJPEG, round(ptsValue.Position(2)), round(ptsValue.Position(1)), [255.0, 0.0, 0.0]);
            app.volumeJPEG(:,:,app.currentSlice3D,:) = imgJPEG(:,:,:);
            updateimage3D(app, img);
            updateimage3DJPEG(app,imgJPEG);
        end

        % Callback function
        function SlicesSlider3DValueChanging(app, event)
            sliceLimit = size(app.threeDVolume,3);
            if (round(event.Value) > sliceLimit)
                changingValue = sliceLimit;
            else
                if (round(event.Value) == 0)
                    changingValue = 1;
                else
                    changingValue = round(event.Value);
                end
            end
            app.currentSlice3D = changingValue;
            app.SlicesSlider3DLabel.Text = sprintf('Slice %d/%d', app.currentSlice3D, size(app.threeDVolume, 3));
            imageData(1:512, 1:512) = app.threeDVolume(1:512,1:512,app.currentSlice3D);
            imageDataJPEG(1:512, 1:512, :) = app.volumeJPEG(1:512,1:512,app.currentSlice3D,:);

            updateimage3D(app, imageData);
            updateimage3DJPEG(app, imageDataJPEG);
        end

        % Callback function
        function LoadStudiesButtonStressOnlyPushed(app, event)

        end

        % Callback function
        function SlicesSliderStressOnlyValueChanging(app, event)
            sliceLimit = size(app.fourDImageSetStressOnly,1);
            if (round(event.Value) > sliceLimit)
                changingValue = sliceLimit;
            else
                if (round(event.Value) == 0)
                    changingValue = 1;
                else
                    changingValue = round(event.Value);
                end
            end
            app.currentSliceStressOnly = changingValue;
            app.SlicesSliderStressOnlyLabel.Text = sprintf('Slice %d/%d', app.currentSliceStressOnly, size(app.fourDImageSetStressOnly, 1));
            imageData(1:512, 1:512) = app.fourDImageSetStressOnly(app.currentSliceStressOnly,app.currentTimePointStressOnly,1:512,1:512);
            if (~isempty(app.paStressOnly) && app.currentSliceStressOnly == app.paStressOnly(1))
                imageData = getROIOverlayed(imageData, app.sxPreStressOnly, app.syPreStressOnly);
            elseif (~isempty(app.pdStressOnly) && app.currentSliceStressOnly == app.pdStressOnly(1))
                imageData = getROIOverlayed(imageData, app.sxPostStressOnly, app.syPostStressOnly);
            end
            updateimageStressOnly(app, imageData);
        end

        % Callback function
        function TimePointsSliderStressOnlyValueChanging(app, event)
            timePointsLimit = size(app.fourDImageSetStressOnly,2);
            if (round(event.Value) > timePointsLimit)
                changingValue = timePointsLimit;
            else
                if (round(event.Value) == 0)
                    changingValue = 1;
                else
                    changingValue = round(event.Value);
                end
            end
            app.currentTimePointStressOnly = changingValue;
            imageData(1:512, 1:512) = app.fourDImageSetStressOnly(app.currentSliceStressOnly,app.currentTimePointStressOnly,1:512,1:512);
            if (~isempty(app.paStressOnly) && app.currentSliceStressOnly == app.paStressOnly(1))
                imageData = getROIOverlayed(imageData, app.sxPreStressOnly, app.syPreStressOnly);
            elseif (~isempty(app.pdStressOnly) && app.currentSliceStressOnly == app.pdStressOnly(1))
                imageData = getROIOverlayed(imageData, app.sxPostStressOnly, app.syPostStressOnly);
            end
            updateimageStressOnly(app, imageData);
        end

        % Callback function
        function SetWindowButtonStressOnlyPushed(app, event)
            level = str2double(app.SetWindowLevelStressOnly.Value{1});
            width = str2double(app.SetWindowWidthStressOnly.Value{1});
            app.minWindowStressOnly = level - width / 2;
            app.maxWindowStressOnly = level + width / 2;
            imagesc(app.ImageAxesStressOnly, app.imStressOnly, [app.minWindowStressOnly, app.maxWindowStressOnly]);
        end

        % Callback function
        function SetPreLesionButtonStressOnlyPushed(app, event)
            zoom(app.ImageAxesStressOnly, 'off');
            

            app.paSliceStressOnly = app.currentSliceStressOnly;
            app.paCurrentTimePointStressOnly = app.currentTimePointStressOnly;
            segmentationWindowSize = 25;

            if (strcmp(app.SamplingROIDropDownStressOnly.Value, 'Freehand'))
                app.ptsAStressOnly = drawfreehand(app.ImageAxesStressOnly);
                app.ptsAStressOnly.Position = round(app.ptsAStressOnly.Position);
                app.paStressOnly = [app.currentSliceStressOnly, app.currentTimePointStressOnly, round(app.ptsAStressOnly.Position(1,:)), round(app.ptsAStressOnly.Position(2,:))];
                % FreeHand Writing Case:
                % The order of getting contour is flipped in this case, as
                % we first need to get contour and then calculate the
                % sample.
                [imageWithContour, radiusIncm, areaOfContourInCmSq, app.sxPreStressOnly, app.syPreStressOnly] = getContour(app.ptsAStressOnly.Position(:,2),app.ptsAStressOnly.Position(:,1),segmentationWindowSize,app.currentSliceStressOnly, app.paCurrentTimePointStressOnly, app.fourDImageSetStressOnly, app.dicomInfoStressOnly.PixelSpacing);
                [app.paCurveStressOnly, app.interpolatedSampledPointsPaStressOnly, app.interpolatedTimePointsStressOnly, app.sxPreStressOnly, app.syPreStressOnly, app.rxPreStressOnly, app.ryPreStressOnly] = getBestSample(app.sxPreStressOnly, app.syPreStressOnly,app.searchROIMStressOnly,app.roiMStressOnly,app.roiNStressOnly,app.currentSliceStressOnly,app.fourDImageSetStressOnly,app.paTimePointsStressOnly);
            else
                app.ptsAStressOnly = drawpoint(app.ImageAxesStressOnly);
                app.paStressOnly = [app.currentSliceStressOnly, app.currentTimePointStressOnly, round(app.ptsAStressOnly.Position(2)), round(app.ptsAStressOnly.Position(1))];
                app.fittedCurvePaStressOnly = zeros(size(app.timePointsStressOnly));
                [app.paCurveStressOnly, app.interpolatedSampledPointsPaStressOnly, app.interpolatedTimePointsStressOnly, app.sxPreStressOnly, app.syPreStressOnly, app.rxPreStressOnly, app.ryPreStressOnly] = getBestSample(app.paStressOnly(3),app.paStressOnly(4),app.searchROIMStressOnly,app.roiMStressOnly,app.roiNStressOnly,app.currentSliceStressOnly,app.fourDImageSetStressOnly,app.paTimePointsStressOnly);
                [imageWithContour, radiusIncm, areaOfContourInCmSq] = getContour(app.paStressOnly(3),app.paStressOnly(4),segmentationWindowSize,app.currentSliceStressOnly, app.paCurrentTimePointStressOnly, app.fourDImageSetStressOnly, app.dicomInfoStressOnly.PixelSpacing);
            end

            app.preRoiObjectStressOnly = getROI(app.dicomInfoStressOnly.PatientName.FamilyName, size(app.fourDImageSetStressOnly,2), size(app.fourDImageSetStressOnly,1), app.paStressOnly(3), app.paStressOnly(4), app.paStressOnly(1), app.paStressOnly(2), app.paCurveStressOnly, app.paTimePointsStressOnly, app.fittedCurvePaStressOnly, app.fittedTimePaStressOnly, app.sxPreStressOnly, app.syPreStressOnly, 0, 0, app.numberOfStenosis, 0, 0, 0, 0, 0);
            app.roiObjectStressOnly.preRoiObject = app.preRoiObjectStressOnly;
            saveRoiAsJson(app.roiObjectStressOnly);


            imageData(1:512, 1:512) = app.fourDImageSetStressOnly(app.currentSliceStressOnly,app.currentTimePointStressOnly,1:512,1:512);
            imageWithContour = getROIOverlayed(imageData, app.sxPreStressOnly, app.syPreStressOnly);
            updateimageStressOnly(app,imageWithContour);

            % radiusIncm
            % areaOfContourInCmSq
            
            drawPreLesionCurveStressOnly(app);
        end

        % Callback function
        function SetPostLesionStressOnlyButtonPushed(app, event)
            zoom(app.ImageAxesStressOnly, 'off');

            app.pdSliceStressOnly = app.currentSliceStressOnly;
            app.pdCurrentTimePointStressOnly = app.currentTimePointStressOnly;
            segmentationWindowSize = 25;

            if (strcmp(app.SamplingROIDropDownStressOnly.Value, 'Freehand'))
                app.ptsBStressOnly = drawfreehand(app.ImageAxesStressOnly);
                app.ptsBStressOnly.Position = round(app.ptsBStressOnly.Position);
                app.pdStressOnly = [app.currentSliceStressOnly, app.currentTimePointStressOnly, round(app.ptsBStressOnly.Position(1,:)), round(app.ptsBStressOnly.Position(2,:))];
                % FreeHand Writing Case:
                % The order of getting contour is flipped in this case, as
                % we first need to get contour and then calculate the
                % sample.
                [imageWithContour, radiusIncm, areaOfContourInCmSq, app.sxPostStressOnly, app.syPostStressOnly] = getContour(app.ptsBStressOnly.Position(:,2),app.ptsBStressOnly.Position(:,1),segmentationWindowSize,app.currentSliceStressOnly, app.pdCurrentTimePointStressOnly, app.fourDImageSetStressOnly, app.dicomInfoStressOnly.PixelSpacing);
                [app.pdCurveStressOnly, app.interpolatedSampledPointsPdStressOnly, app.interpolatedTimePointsStressOnly, app.sxPostStressOnly, app.syPostStressOnly, app.rxPostStressOnly, app.ryPostStressOnly] = getBestSample(app.sxPostStressOnly, app.syPostStressOnly, app.searchROIMStressOnly,app.roiMStressOnly,app.roiNStressOnly,app.currentSliceStressOnly,app.fourDImageSetStressOnly,app.pdTimePointsStressOnly);
            else
                app.ptsBStressOnly = drawpoint(app.ImageAxesStressOnly);
                app.pdStressOnly = [app.currentSliceStressOnly, app.currentTimePointStressOnly, round(app.ptsBStressOnly.Position(2)), round(app.ptsBStressOnly.Position(1))];
                app.fittedCurvePdStressOnly = zeros(size(app.timePointsStressOnly));
                [app.pdCurveStressOnly, app.interpolatedSampledPointsPdStressOnly, app.interpolatedTimePointsStressOnly, app.sxPostStressOnly, app.syPostStressOnly, app.rxPostStressOnly, app.ryPostStressOnly] = getBestSample(app.pdStressOnly(3),app.pdStressOnly(4),app.searchROIMStressOnly,app.roiMStressOnly,app.roiNStressOnly,app.currentSliceStressOnly,app.fourDImageSetStressOnly,app.pdTimePointsStressOnly);
                [imageWithContour, radiusIncm, areaOfContourInCmSq] = getContour(app.pdStressOnly(3),app.pdStressOnly(4),segmentationWindowSize,app.currentSliceStressOnly, app.pdCurrentTimePointStressOnly, app.fourDImageSetStressOnly, app.dicomInfoStressOnly.PixelSpacing);
            end
            app.postRoiObjectStressOnly = getROI(app.dicomInfoStressOnly.PatientName.FamilyName, size(app.fourDImageSetStressOnly,2), size(app.fourDImageSetStressOnly,1), app.pdStressOnly(3), app.pdStressOnly(4), app.pdStressOnly(1), app.pdStressOnly(2), app.pdCurveStressOnly, app.pdTimePointsStressOnly, app.fittedCurvePdStressOnly, app.fittedTimePdStressOnly, app.sxPostStressOnly, app.syPostStressOnly, 0, 0, app.numberOfStenosis, 0, 0, 0, 0, 0);
            app.roiObjectStressOnly.postRoiObject = app.postRoiObjectStressOnly;
            saveRoiAsJson(app.roiObjectStressOnly);
            imageData(1:512, 1:512) = app.fourDImageSetStressOnly(app.currentSliceStressOnly,app.currentTimePointStressOnly,1:512,1:512);
            imageWithContour = getROIOverlayed(imageData, app.sxPostStressOnly, app.syPostStressOnly);
            updateimageStressOnly(app,imageWithContour);
            % radiusIncm
            % areaOfContourInCmSq
            drawPostLesionCurveStressOnly(app);

        end

        % Callback function
        function veHeightCheckBoxStressOnlyValueChanged(app, event)
            value = app.veHeightCheckBoxStressOnly.Value;
            if (value == 1)
                app.hAhBDiffNegativeStressOnly = false;
            end
            if (value == 0)
                app.hAhBDiffNegativeStressOnly = true;
            end
        end

        % Callback function
        function SamplingROIDropDownStressOnlyValueChanged(app, event)
            value = app.SamplingROIDropDownStressOnly.Value;
            if (value(2) == 'x')
                app.roiMStressOnly = str2double(value(1));
                app.roiNStressOnly = str2double(value(3));
            elseif (value(3) == 'x')
                app.roiMStressOnly = str2double(value(1:2));
                app.roiNStressOnly = str2double(value(4:5));
            end
        end

        % Callback function
        function SearchROIDropDownStressOnlyValueChanged(app, event)
            value = app.SearchROIDropDownStressOnly.Value;
            if (value(2) == 'x')
                app.searchROIMStressOnly = str2double(value(1));
                app.searchROINStressOnly = str2double(value(3));
            elseif (value(3) == 'x')
                app.searchROIMStressOnly = str2double(value(1:2));
                app.searchROINStressOnly = str2double(value(4:5));
            end
        end

        % Callback function
        function InterpolateCurrentSliceWithDropDownStressOnlyValueChanged(app, event)
            value = convertCharsToStrings(app.InterpolateCurrentSliceWithDropDownStressOnly.Value);
            if (strcmp(value,'Previous Slice'))
                if (app.currentSliceStressOnly > 1)
                    app.sliceToBeInterpolatedWithStressOnly = app.currentSliceStressOnly - 1;
                    interpolatedSliceForAllTimePoints = getInterpolatedSlice(app.currentSliceStressOnly, app.sliceToBeInterpolatedWithStressOnly, app.fourDImageSetStressOnly);
                else
                    errordlg('Current Slice Cannot Have Previous Slice','Slice Choose Error');
                end
            elseif (strcmp(value,'Next Slice'))
                if (app.currentSliceStressOnly < size(app.fourDImageSetStressOnly,1))
                    app.sliceToBeInterpolatedWithStressOnly = app.currentSliceStressOnly + 1;
                    interpolatedSliceForAllTimePoints = getInterpolatedSlice(app.currentSliceStressOnly, app.sliceToBeInterpolatedWithStressOnly, app.fourDImageSetStressOnly);
                else
                    errordlg('Current Slice Cannot Have Next Slice','Slice Choose Error');
                end
            elseif ((strcmp(value,'None')))
                % Do nothing
            end

            tempFourDImageSet = app.fourDImageSetStressOnly;
            tempFourDImageSet(app.currentSliceStressOnly,:,:,:) = interpolatedSliceForAllTimePoints;
            if (app.currentSliceStressOnly == app.paStressOnly(1))
                % copy pre slice's coordinate
                [app.paCurveStressOnly, app.interpolatedSampledPointsPaStressOnly, app.interpolatedTimePointsStressOnly, app.sxPreStressOnly, app.syPreStressOnly, app.rxPreStressOnly, app.ryPreStressOnly] = getBestSample(app.paStressOnly(3),app.paStressOnly(4),app.searchROIMStressOnly,app.roiMStressOnly,app.roiNStressOnly,app.currentSliceStressOnly,tempFourDImageSet,app.timePointsStressOnly);
                drawPreLesionCurveStressOnly(app);
            elseif (app.currentSliceStressOnly == app.pdStressOnly(1))
                % copy post slice's coordinate
                [app.pdCurveStressOnly, app.interpolatedSampledPointsPdStressOnly, app.interpolatedTimePointsStressOnly, app.sxPostStressOnly, app.syPostStressOnly, app.rxPostStressOnly, app.ryPostStressOnly] = getBestSample(app.pdStressOnly(3),app.pdStressOnly(4),app.searchROIMStressOnly,app.roiMStressOnly,app.roiNStressOnly,app.currentSliceStressOnly,tempFourDImageSet,app.timePointsStressOnly);
                drawPostLesionCurveStressOnly(app);
            end
        end

        % Callback function
        function ClearInputsButtonStressOnlyPushed(app, event)
            % Clear all fields
            app.ContrastVolumemLEditFieldStressOnly.Value = '';
            app.ContrastConcentrationmglmLEditFieldStressOnly.Value = '';
            app.PrelesionLumenRadiuscmEditFieldStressOnly.Value = '';
            app.PostlesionLumenRadiuscmEditFieldStressOnly.Value = '';
            app.SystolicBloodPressuremmHgEditFieldStressOnly.Value = '';
            app.MarkStenosisCheckBox.Value = false;
            app.MarkBreakerCheckBox.Value = false;
            app.XrayTubeVoltagekVDropDown.Value = app.xRayTubeVoltages(1);
            app.RestorStressConditionDropDown.Value = 'Rest';
            app.CoronaryArteryDropDown.Value = app.arterySelections(1);
            app.CoronaryDominanceDropDown.Value = app.dominanceTypes(1);
        end

        % Callback function
        function OutputButtonStressOnlyPushed(app, event)
            if app.f == 1
                maxVAVB= 50;
            else
                maxVAVB = 120;
            end


            % Get conversion factor based on scanner type
            conversionFactor = getConversionFactor(app);

            % Get preLesion TracerMass based on dominance and coronary
            % artery type
            app.mTotal = str2double(app.ContrastVolumemLEditFieldStressOnly.Value) * str2double(app.ContrastConcentrationmglmLEditFieldStressOnly.Value);
            tracerMass = getTracerMass(app);
            

            % app.preQStressOnly = tracerMass(app.arterySelectedIndex);

             
            if (app.arterySelectedIndex <= 4)
                app.preQStressOnly = tracerMass(app.arterySelectedIndex);
                app.postQinitial= app.preQStressOnly;
            end

            if(app.arterySelectedIndex == 5)
                 app.preQStressOnly = tracerMass(2);               
                 app.postQinitial= tracerMass(3);
   
            end
            if (app.arterySelectedIndex == 6)
                 app.preQStressOnly = tracerMass(2);  
                 app.postQinitial= tracerMass(4);
                 
            end

             if (app.arterySelectedIndex == 7)
                 app.preQStressOnly = tracerMass(2)-tracerMass(3)-tracerMass(4);  
                 app.postQinitial=app.preQStressOnly;
             end
             if (app.arterySelectedIndex == 8)
                 app.preQStressOnly = tracerMass(2);
                 app.postQinitial=tracerMass(2)-tracerMass(3)-tracerMass(4);
            end

           tempVA=maxVAVB;
           tempVB=maxVAVB;
           tempPreQ= app.preQStressOnly;
           tempPostQ = app.postQinitial;
           fprintf('\nInitial Prelesion Tracer Mass : %f\n',app.preQStressOnly)
           fprintf('\nInitial PostLesion Tracer Mass : %f\n',app.postQinitial)
           for i = 1:-0.05:0.1
               if tempVA >= maxVAVB || tempVB >= maxVAVB
                   app.preQStressOnly= tempPreQ*i;
                   app.postQinitial = tempPostQ*i;


            % Converting HUs to (mg/mL)s
            tempAucA = app.aucAStressOnly / conversionFactor;
            tempAucB = app.aucBStressOnly / conversionFactor;

            % Get tracer mass at every branch, if there is any.
            % If there is none, postQ is same as preQ.
            % getTracerMassAtEverySideBranch checks if there is any branch
            % defined and calculates tracer loss at each branch.
            [app.tracerMassAtBranches, app.tracerMassAtTrunkAfterEachSideBranch] = getTracerMassAtEverySideBranch(app);
            app.postQStressOnly = app.tracerMassAtTrunkAfterEachSideBranch(end);

            % Check if there is stenosis defined and if so, then
            % check if there is branches involved in stenoses.
            % If there are branches, entryQ and exitQ should be
            % correctly populated.
            if (app.numberOfStenosis > 0)
                for s = 1 : app.maxStenosisSupported
                    if (app.checkedStenosis(s) == true)
                        numBranchesBefore = app.stenosisData(s).NumberOfBranchBeforeEntry;
                        numBranchesAfter = app.stenosisData(s).NumberOfBranchBeforeEntry;
                        if (numBranchesBefore > 0)
                            app.entryQ(s) = app.tracerMassAtTrunkAfterEachSideBranch(numBranchesBefore);
                        else
                            app.entryQ(s) = app.preQStressOnly;
                        end
                        if (numBranchesAfter > 0)
                            app.exitQ(s) = app.tracerMassAtTrunkAfterEachSideBranch(numBranchesAfter);
                        else
                            app.exitQ(s) = app.postQinitial;
                        end
                        tempaucAEntry(s) = app.aucAEntryStressOnly(s) / conversionFactor;
                        tempaucBExit(s) = app.aucBExitStressOnly(s) / conversionFactor;
                    else
                        app.entryQ(s) = 0;
                        app.exitQ(s) = 0;
                        tempaucAEntry(s) = 0;
                        tempaucBExit(s) = 0;
                    end
                end
            end

            if (~isempty(app.hBTextStressOnly))
                app.hBStressOnly = app.hBTextStressOnly;
            else
              
                app.hBStressOnly = getRelativeHeight(app.paStressOnly(1), app.pdStressOnly(1), app.dicomInfoStressOnly.SliceThickness, app.hAhBDiffNegativeStressOnly);
            end
            if (~isempty(app.LTextStressOnly))

                app.LStressOnly = app.LTextStressOnly;
            else
                % app.LStressOnly = getDistanceBetweenSlicesAB(app.paStressOnly(3), app.paStressOnly(4), app.pdStressOnly(3), app.pdStressOnly(4), app.allExtraPointsForDistanceInStressOnly, app.hBStressOnly, app.dicomInfoStressOnly.PixelSpacing);
                % fprintf('\nLenght : %f\n',app.LStressOnly)
                app.LStressOnly = getDistanceBetweenSlicesWithBreakerPoints(app.paStressOnly(3), app.paStressOnly(4),app.paStressOnly(1),app.pdStressOnly(3), app.pdStressOnly(4),app.pdStressOnly(1),...
                    app.allExtraPointsForDistanceInStressOnly,app.dicomInfoStressOnly.SliceThickness, app.hAhBDiffNegativeStressOnly,app.dicomInfoStressOnly.PixelSpacing);

            end

            % Will check if stenosis lenght is above 20mm separately
            for i = 1:size(app.StenosisInfo(app.checkedStenosis),2)
                disp(app.StenosisInfo(i))
            end


            logDAI('Calling calculateFFR()');
            % Radius values are in cm
            if (app.numberOfStenosis >= 1)
                tempaucAEntry = tempaucAEntry(tempaucAEntry ~= 0);
                tempaucBExit = tempaucBExit(tempaucBExit ~= 0);
                temprs1 = app.rs1(app.checkedStenosis);
                temprs2 = app.rs2(app.checkedStenosis);
                temprs3 = app.rs3(app.checkedStenosis);
                tempentryQ = app.entryQ(app.entryQ ~= 0);
                tempexitQ = app.exitQ(app.exitQ ~= 0);
                [app.FFRStressOnly, app.VAStressOnly, app.VBStressOnly, app.shearStressAStressOnly, app.shearStressBStressOnly, app.pLEStressOnly, app.pLFStresOnly, app.ReStressOnly, app.pBStressOnly, app.frictionFactorStressOnly, app.Vs1StressOnly, app.Vs2StressOnly, app.Vs3StressOnly, app.pLCStressOnly, app.pLSStressOnly, app.FAStressOnly, app.FBStressOnly] = calculateFFR(tempAucA, tempAucB, app.preQStressOnly, app.postQStressOnly, app.hBStressOnly, app.LStressOnly, str2double(app.PrelesionLumenRadiuscmEditFieldStressOnly.Value), str2double(app.PostlesionLumenRadiuscmEditFieldStressOnly.Value), str2double(app.SystolicBloodPressuremmHgEditFieldStressOnly.Value), 0, app.fMethodStressOnly, 1, tempaucAEntry, tempaucBExit, temprs1, temprs2, temprs3, tempentryQ, tempexitQ, app.numberOfStenosis);
                

                for i = 1 : size(app.stenosisData(app.checkedStenosis),2) % Modification done from Carlos Mendes : When Branch(es) are between entry and exit

                if app.stenosisData(i).NumberOfBranchBeforeExit - app.stenosisData(i).NumberOfBranchBeforeEntry > 0 
                    for j = app.stenosisData(i).NumberOfBranchBeforeEntry +1 : app.stenosisData(i).NumberOfBranchBeforeExit
                    
                    if app.branchGuiData.branches(j).aucOfSideBranch ~= 0
                       Fs4= app.tracerMassAtBranches(j)/app.branchGuiData.branches(j).aucOfSideBranch;
                       app.Vs2StressOnly(i) = app.Vs2StressOnly(i)- Fs4/(pi*app.rs2(i)^2);
                    end
                    end
     
                end
                end
                
                % Modification done from Carlos Mendes : Verify if stenosis
                % is diffuse and obtain distances to calculate pressure
                % loss to friction

                rPre=str2double(app.PrelesionLumenRadiuscmEditFieldStressOnly.Value);
                rPost=str2double(app.PostlesionLumenRadiuscmEditFieldStressOnly.Value);

                fprintf('\n PLF before stenosis correction : %f\n',app.pLFStresOnly)
                fprintf('\n FFR before stenosis correction : %f\n',app.FFRStressOnly)
                [DiffuseFFR,DiffusepLF]=calculateDiffusePL(app.StenosisInfo(app.checkedStenosis),app.paStressOnly,app.pdStressOnly,app.dicomInfoStressOnly.SliceThickness,app.hAhBDiffNegativeStressOnly,app.dicomInfoStressOnly.PixelSpacing,...
                app.Vs1StressOnly,app.Vs2StressOnly,app.Vs3StressOnly,app.VAStressOnly, app.VBStressOnly,temprs1,temprs2,temprs3,rPre,rPost,0,...
                app.fMethodStressOnly,str2double(app.SystolicBloodPressuremmHgEditFieldStressOnly.Value), app.pLSStressOnly);

                if DiffusepLF ~=0
                    app.FFRStressOnly=DiffuseFFR;
                    app.pLFStresOnly=DiffusepLF;

                    
                end


            else
                if (app.MarkStenosisCheckBox.Value == 1)
                    isStenosis = 1;
                else
                    isStenosis = 0;
                end
                [app.FFRStressOnly, app.VAStressOnly, app.VBStressOnly, app.shearStressAStressOnly, app.shearStressBStressOnly, app.pLEStressOnly, app.pLFStresOnly, app.ReStressOnly, app.pBStressOnly, app.frictionFactorStressOnly, app.Vs1StressOnly, app.Vs2StressOnly, app.Vs3StressOnly, app.pLCStressOnly, app.pLSStressOnly, app.FAStressOnly, app.FBStressOnly] = calculateFFR(tempAucA, tempAucB, app.preQStressOnly, app.postQStressOnly, app.hBStressOnly, app.LStressOnly, str2double(app.PrelesionLumenRadiuscmEditFieldStressOnly.Value), str2double(app.PostlesionLumenRadiuscmEditFieldStressOnly.Value), str2double(app.SystolicBloodPressuremmHgEditFieldStressOnly.Value), 0, app.fMethodStressOnly, isStenosis, 0, 0, app.rs1, app.rs2, app.rs3, app.entryQ, app.exitQ, app.numberOfStenosis);
            end
            
               end
           tempVA=app.VAStressOnly;
           tempVB=app.VBStressOnly;
           fprintf('\nVA: %f\n',tempVA);
           fprintf('\nVB: %f\n',tempVB);

           end
           fprintf('\nPrelesion Tracer Mass : %f\n',app.preQStressOnly)
           fprintf('\nPostLesion Tracer Mass : %f\n',app.postQStressOnly)


            logDAI('FFR Calculation Successful');
            logDAI('Populating output parameters');

            % Displaying output values
            app.FFREditFieldStressOnly.Value = sprintf('%.4f',app.FFRStressOnly);
            app.PrelesionFlowVelocitycmsEditFieldStressOnly.Value = sprintf('%.4f',app.VAStressOnly);
            app.PostlesionFlowVelocitycmsEditFieldStressOnly.Value = sprintf('%.4f',app.VBStressOnly);
            app.PrelesionShearStressPaEditFieldStressOnly.Value = sprintf('%.4f',app.shearStressAStressOnly);
            app.PostlesionShearStressPaEditFieldStressOnly.Value = sprintf('%.4f',app.shearStressBStressOnly);

            logDAI('Populating output parameters Successful');

            % Adding result to csvWriteMatrix
            logDAI('Adding result to csvWriteMatrix');

            % Currently we are limited to writing only one value in the csv
            % if there is multiple values
            if (max(size(app.pLEStressOnly)) > 1)
                % app.allResultsForCSVWrite = [];
                % app.allResultsForCSVWrite = 'TimeStamp,RestOrStress,PreLesionSlice,PostLesionSlice,PreLesionCooridnate,PostLesionCoordinate,Prelesion AUC,Postlesion AUC,Prelesion Alpha,Prelesion Beta,Prelesion K,Postlesion Alpha,Postlesion Beta,Postlesion K,PreVolumetricFlowRate(mL/s),PostVolumetricFlowRate(mL/s),PreVelocity(cm/s),PostVelocity(cm/s),PreShearStress(Pa),PostShearStress(Pa),pLE(mmHg),pLF(mmHg),Re,Darcy Friction Factor(f),pB(mmHg),Vs1(cm/s),Vs2(cm/s),Vs3(cm/s),pLC(mmHg),pLS(mmHg),FFR\n';
                for i = 1:max(size(app.pLEStressOnly))
                    % if (i == 1)
                        stringForAllData = sprintf('%s,%s,%d,%d,(%d %d),(%d %d),%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%d,%d,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f\n',...
                        datestr(now,'mm-dd-yyyy HH-MM-SS'),'StressOnly',app.paStressOnly(1),app.pdStressOnly(1),app.paStressOnly(3),app.paStressOnly(4),app.pdStressOnly(3),app.pdStressOnly(4),app.aucAStressOnly,app.aucBStressOnly,...
                        app.alphaAStressOnly,app.betaAStressOnly,app.kAStressOnly,app.alphaBStressOnly,app.betaBStressOnly,app.kBStressOnly,...
                        app.FAStressOnly,app.FBStressOnly,app.VAStressOnly,app.VBStressOnly,app.shearStressAStressOnly,app.shearStressBStressOnly,...
                        app.pLEStressOnly(i),app.pLFStresOnly,app.ReStressOnly,app.frictionFactorStressOnly,app.pBStressOnly,app.Vs1StressOnly(i),app.Vs2StressOnly(i),app.Vs3StressOnly(i),app.pLCStressOnly(i),app.pLSStressOnly(i),app.FFRStressOnly);
                    % else
                        % stringForAllData = sprintf('%s,%s,%d,%d,%d %d,%d %d,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%d,%d,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f\n',...
                        %     '','','','','','','','','','','','','','','','','','','','','','','','','',app.pLEStressOnly(i),'','','','',app.Vs1StressOnly(i),app.Vs2StressOnly(i),app.Vs3StressOnly(i),app.pLCStressOnly(i),app.pLSStressOnly(i));
                    % end
                    app.allResultsForCSVWrite = append(app.allResultsForCSVWrite, newline, stringForAllData);
                end
                
            else
                stringForAllData = sprintf('%s,%s,%d,%d,(%d %d),(%d %d),%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%d,%d,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f\n',...
                datestr(now,'mm-dd-yyyy HH-MM-SS'),'StressOnly',app.paStressOnly(1),app.pdStressOnly(1),app.paStressOnly(3),app.paStressOnly(4),...
                app.pdStressOnly(3),app.pdStressOnly(4),app.aucAStressOnly,app.aucBStressOnly,app.alphaAStressOnly,app.betaAStressOnly,app.kAStressOnly,app.alphaBStressOnly,app.betaBStressOnly,app.FAStressOnly,app.FBStressOnly,...
                app.VAStressOnly,app.VBStressOnly,app.shearStressAStressOnly,app.shearStressBStressOnly,...
                app.kBStressOnly,app.pLEStressOnly,app.pLFStresOnly,app.ReStressOnly,app.frictionFactorStressOnly,app.pBStressOnly,app.Vs1StressOnly,app.Vs2StressOnly,app.Vs3StressOnly,app.pLCStressOnly, app.pLSStressOnly,app.FFRStressOnly);
                app.allResultsForCSVWrite = append(app.allResultsForCSVWrite, newline, stringForAllData);
            end

            stringForCoordinates = sprintf('%d,%d,%d,%d\n',app.paStressOnly(3),app.paStressOnly(4),app.paStressOnly(1),app.paStressOnly(2));
            app.coOrdinatesForCSVWriteStressOnly = append(app.coOrdinatesForCSVWriteStressOnly,newline,stringForCoordinates);
            stringForCoordinates = sprintf('%d,%d,%d,%d\n',app.pdStressOnly(3),app.pdStressOnly(4),app.pdStressOnly(1),app.pdStressOnly(2));
            app.coOrdinatesForCSVWriteStressOnly = append(app.coOrdinatesForCSVWriteStressOnly,newline,stringForCoordinates);
            logDAI('Adding result to csvWriteMatrix Successful');

            logDAI('Saving coordinates');
            saveCoordiantes(app);

            % Plot for Slice Location vs FFR
            logDAI('Generating data for FFRvsSliceLocation Plot');

            %{ this part of the code is intended to be modified, to check
            %if error persist, the plot in this completely wrong,
            %generating a weird behaviour.
            %}

            % this condition is satisfied when the first datapoint is
            % created. The FFR for datapoint 1 is equal to 1.
            if isempty(app.allFFRStressOnlySliceLocations)
                
                app.allFFRStressOnly = [1.0, app.FFRStressOnly];
                app.allFlowVelocitiesStressOnly = [app.VAStressOnly, app.VBStressOnly];
                app.allFFRStressOnlySliceLocations = [app.paStressOnly(1), app.pdStressOnly(1)];
                app.FFRlastCalculated= 1.0;            
                
           % condition when both prelesion and postlesion are selected
            elseif isempty(find(app.allFlowVelocitiesStressOnly==app.VAStressOnly,1)) && isempty(find(app.allFFRStressOnlySliceLocations==app.paStressOnly(1),1))
                
                app.allFFRStressOnly = [app.allFFRStressOnly,app.FFRlastCalculated, app.FFRStressOnly];    
                app.allFlowVelocitiesStressOnly =  [app.allFlowVelocitiesStressOnly,app.VAStressOnly,app.VBStressOnly];
                app.allFFRStressOnlySliceLocations = [app.allFFRStressOnlySliceLocations,app.paStressOnly(1), app.pdStressOnly(1)];
                app.FFRlastCalculated= app.FFRStressOnly;
            elseif isempty(find(app.allFlowVelocitiesStressOnly==app.VBStressOnly,1)) && isempty(find(app.allFFRStressOnlySliceLocations==app.pdStressOnly(1),1))
               
                %when only postlesion is selected and the condition avoid
                %when user click compute more than once              
                app.allFFRStressOnly = [app.allFFRStressOnly, app.FFRStressOnly];    
                app.allFlowVelocitiesStressOnly =  [app.allFlowVelocitiesStressOnly,app.VBStressOnly];
                app.allFFRStressOnlySliceLocations = [app.allFFRStressOnlySliceLocations, app.pdStressOnly(1)];
                app.FFRlastCalculated= app.FFRStressOnly;
            end


            tempData = [app.allFFRStressOnlySliceLocations.', app.allFFRStressOnly.', app.allFlowVelocitiesStressOnly.'];
            sortedData = sortrows(tempData, 1);
            sortedData= unique(sortedData,'rows');
            app.allFFRStressOnlySliceLocations = sortedData(:, 1)';
            app.allFFRStressOnly = sortedData(:, 2)';
            app.allFlowVelocitiesStressOnly = sortedData(:, 3)';


            if (isfield(app.allDicomInfoStressOnly, 'SliceLocation'))
                app.allFFRStressOnlyDicomSliceNumbers(end+1) = app.allDicomInfoStressOnly{app.currentSliceStressOnly}.SliceLocation;
            else
                app.allFFRStressOnlyDicomSliceNumbers(end+1) = app.pdStressOnly(1);
            end
            logDAI('Data Appeneded for FFRvsSliceLocation Plot');
            logDAI('Generating Plot');
            logDAI('Setting YLim between 0 and 1.2');
            app.FFRVsSliceLocation.YLim = [0, 1.2];

            drawFFRCurve(app);
            
            logDAI('Plot Generation Successful');

        end

        % Callback function
        function ClearOutputsButtonStressOnlyPushed(app, event)
            % Stress Output Clear:
            app.PostlesionShearStressPaEditFieldStressOnly.Value = '';
            app.PrelesionShearStressPaEditFieldStressOnly.Value = '';
            app.PostlesionFlowVelocitycmsEditFieldStressOnly.Value = '';
            app.PrelesionFlowVelocitycmsEditFieldStressOnly.Value = '';
            app.FFREditFieldStressOnly.Value = '';
        end

        % Callback function
        function ClearAllButtonStressOnlyPushed(app, event)
            ClearInputsButtonStressOnlyPushed(app, event);
            ClearOutputsButtonStressOnlyPushed(app, event);
        end

        % Callback function
        function LoadPreLesionCurveButtonStressOnlyPushed(app, event)
            filterspec = {'*.txt','Text Files';'*.xlsx','Excel Files'};
            [f, p] = uigetfile(filterspec);
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);
            set(gcf, 'Pointer','arrow');

            logDAI('Checking chosen file type:');
            [~, name, ext] = fileparts(f);

            if (strcmp(ext,'.xlsx'))
                logDAI('File type is excel, reading excel file.');
                fName = strcat(p, f);
                data = readtable(fName);
                time = data.TimePoints;
                roi = data.PreLesionCurve;
            end

            if (strcmp(ext,'.txt'))
                logDAI('File type is txt, reading txt file.');
                % Read text files:
                fileName = strcat(p,f);
                [time, roi] = readDataFile(fileName);
                if time(2) >= 500
                    time = time / 1000;
                end
            end


            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                logDAI('Plotting the curve');
                app.paCurveStressOnly = roi;
                app.plotXPreStressOnly = time;
                app.paPlotAreaStressOnly.YLim = [app.yAxisLimitMin app.yAxisLimitMax];
                app.paPlotAreaStressOnly.YTick = getSliderTicks(app.yAxisLimitMin, app.yAxisLimitMax, app.maxYAxisInterval);
                app.paPlotAreaStressOnly.XLim = [1 max(time)];
                app.paPlotAreaStressOnly.XTick = [1:max(time)/10:max(time)];
                app.paPlotAreaStressOnly.XTick = cast(app.paPlotAreaStressOnly.XTick, 'int16');
                plot(app.paPlotAreaStressOnly, app.plotXPreStressOnly, app.paCurveStressOnly, 'mo');
                app.preTextReadStressOnly = true;
                logDAI('Plotting the curve is successful');
            end

            if (app.preTextReadStressOnly == true && app.postTextReadStressOnly == true)
                app.RelativeHeightcmEditFieldStressOnly.Enable = true;
                app.PrePostDistancecmEditFieldStressOnly.Enable = true;
            end
        end

        % Callback function
        function LoadPostLesionCurveButtonStressOnlyPushed(app, event)
            filterspec = {'*.txt','Text Files';'*.xlsx','Excel Files'};
            [f, p] = uigetfile(filterspec);
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);
            set(gcf, 'Pointer','arrow');

            logDAI('Checking chosen file type:');
            [~, name, ext] = fileparts(f);

            if (strcmp(ext,'.xlsx'))
                logDAI('File type is excel, reading excel file.');
                fName = strcat(p, f);
                data = readtable(fName);
                time = data.TimePoints;
                roi = data.PreLesionCurve;
            end

            if (strcmp(ext,'.txt'))
                logDAI('File type is txt, reading txt file.');
                % Read text files:
                fileName = strcat(p,f);
                [time, roi] = readDataFile(fileName);
                if time(2) >= 500
                    time = time / 1000;
                end
            end

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                logDAI('Plotting the curve');
                app.pdCurveStressOnly = roi;
                app.plotXPostStressOnly = time;
                app.pdPlotAreaStressOnly.YLim = [app.yAxisLimitMin app.yAxisLimitMax];
                app.pdPlotAreaStressOnly.YTick = getSliderTicks(app.yAxisLimitMin, app.yAxisLimitMax, app.maxYAxisInterval);
                app.pdPlotAreaStressOnly.XLim = [1 max(time)];
                app.pdPlotAreaStressOnly.XTick = [1:max(time)/10:max(time)];
                app.pdPlotAreaStressOnly.XTick = cast(app.pdPlotAreaStressOnly.XTick, 'int16');
                plot(app.pdPlotAreaStressOnly, app.plotXPostStressOnly, app.pdCurveStressOnly, 'mo');
                app.postTextReadStressOnly = true;
                logDAI('Plotting the curve is successful');
            end

            if (app.preTextReadStressOnly == true && app.postTextReadStressOnly == true)
                app.RelativeHeightcmEditFieldStressOnly.Enable = true;
                app.PrePostDistancecmEditFieldStressOnly.Enable = true;
            end
        end

        % Callback function
        function RelativeHeightcmEditFieldStressOnlyValueChanged(app, event)
            app.hBTextStressOnly = str2double(app.RelativeHeightcmEditFieldStressOnly.Value);
        end

        % Callback function
        function PrePostDistancecmEditFieldStressOnlyValueChanged(app, event)
            app.LTextStressOnly = str2double(app.PrePostDistancecmEditFieldStressOnly.Value);
        end

        % Callback function
        function ScreenCaptureButtonStressOnlyPushed(app, event)

        end

        % Callback function
        function ManualBaselineWashoutstressCheckBoxStressOnlyValueChanged(app, event)
            app.manualBaselineCheckStressOnly = app.ManualBaselineWashoutstressCheckBoxStressOnly.Value;
            if (app.manualBaselineCheckStressOnly == 1)
                app.ManualBaselineInputStressOnly.Enable = true;
                app.ManualWashoutInputStressOnly.Enable = true;
                app.PreLesionCheckBoxStressOnly.Enable = true;
                app.PostLesionCheckBoxStressOnly.Enable = true;
            end
            if (app.manualBaselineCheckStressOnly == 0)
                app.ManualBaselineInputStressOnly.Enable = false;
                app.ManualBaselineInputStressOnly.Value = '';
                app.ManualWashoutInputStressOnly.Enable = false;
                app.ManualWashoutInputStressOnly.Value = '';
                app.PreLesionCheckBoxStressOnly.Enable = false;
                app.PostLesionCheckBoxStressOnly.Enable = false;
            end
        end

        % Callback function
        function FitCurvesButtonStressOnlyPushed(app, event)
            choice = questdlg('Which curve to fit?', 'Curve Fitting', 'Pre-lesion curve','Lesion entry curve','');
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);
            switch choice
                case 'Pre-lesion curve'
                    app.SetPreLesionButtonStressOnly.Enable="off";
                    if isrow(app.paCurveStressOnly)
                        app.paCurveStressOnly = app.paCurveStressOnly';
                    end

                    if isnan(str2double(app.ManualBaselineInputStressOnly.Value))
                        manualBaselineValuePreLesion = 0;
                    else
                        manualBaselineValuePreLesion = str2double(app.ManualBaselineInputStressOnly.Value);
                    end
                    if isnan(str2double(app.ManualWashoutInputStressOnly.Value))
                        manualWashoutValuePreLesion = 0;
                    else
                        manualWashoutValuePreLesion = str2double(app.ManualWashoutInputStressOnly.Value);
                    end

                    try
                        [app.fittedCurvePaStressOnly, app.fittedTimePaStressOnly, app.paCurveStressOnly, app.rmseAStressOnly, app.kAStressOnly, app.alphaAStressOnly, app.betaAStressOnly] = getFittedCurve(app.paCurveStressOnly, app.plotXPreStressOnly, manualBaselineValuePreLesion, manualWashoutValuePreLesion);
                        app.aucAStressOnly = getAreaUnderCurve(app.fittedCurvePaStressOnly, app.fittedTimePaStressOnly);

                        drawPreLesionCurveStressOnly(app);

                        app.paStressOnly = [app.roiObjectStressOnly.preRoiObject.curveData.z, app.roiObjectStressOnly.preRoiObject.curveData.t, app.roiObjectStressOnly.preRoiObject.curveData.x, app.roiObjectStressOnly.preRoiObject.curveData.y];
                        app.preRoiObjectStressOnly = getROI(app.dicomInfoStressOnly.PatientName.FamilyName, size(app.fourDImageSetStressOnly,2), size(app.fourDImageSetStressOnly,1), app.paStressOnly(3), app.paStressOnly(4), app.paStressOnly(1), app.paStressOnly(2), app.paCurveStressOnly, app.paTimePointsStressOnly, app.fittedCurvePaStressOnly, app.fittedTimePaStressOnly, app.sxPreStressOnly, app.syPreStressOnly, app.aucAStressOnly, app.allExtraPointsForDistanceInStressOnly, app.numberOfStenosis, 0, 0, 0, 0, 0);
                        app.roiObjectStressOnly.preRoiObject = app.preRoiObjectStressOnly;
                        saveRoiAsJson(app.roiObjectStressOnly);

                    catch ME
                        msg = strcat('Problem in Curve Fitting:', ME.message);
                        errordlg(msg);
                        pause(5);
                        set(gcf, 'Pointer','arrow');
                    end


                case 'Lesion entry curve'
                    if isrow(app.entryCurveStressOnly)
                        app.entryCurveStressOnly = app.entryCurveStressOnly';
                    end

                    if isnan(str2double(app.ManualBaselineInputStressOnly.Value))
                        manualBaselineValuePreLesion = 0;
                    else
                        manualBaselineValuePreLesion = str2double(app.ManualBaselineInputStressOnly.Value);
                    end
                    if isnan(str2double(app.ManualWashoutInputStressOnly.Value))
                        manualWashoutValuePreLesion = 0;
                    else
                        manualWashoutValuePreLesion = str2double(app.ManualWashoutInputStressOnly.Value);
                    end

                    try
                        [app.fittedCurveEntryStressOnly, app.fittedTimeEntryStressOnly, app.entryCurveStressOnly, app.rmseAStressOnly, app.kAStressOnly, app.alphaAStressOnly, app.betaAStressOnly] = getFittedCurve(app.entryCurveStressOnly, app.plotXEntryStressOnly, manualBaselineValuePreLesion, manualWashoutValuePreLesion);
                        app.aucAEntryStressOnly(app.currentStenosisID) = getAreaUnderCurve(app.fittedCurveEntryStressOnly, app.fittedTimeEntryStressOnly);
                        %                         if (isempty(app.ConversionFactorEditFieldStressOnly.Value) == false)
                        %                             app.aucAEntryStressOnly(end) = app.aucAEntryStressOnly(end) / str2double(app.ConversionFactorEditFieldStressOnly.Value);
                        %                         end

                        drawEntryCurveStressOnly(app);

                        if (app.currentStenosisNumber == -1)
                            app.pEntryStressOnly = [app.roiObjectStressOnly.entryRoiObject.curveData.z, app.roiObjectStressOnly.entryRoiObject.curveData.t, app.roiObjectStressOnly.entryRoiObject.curveData.x, app.roiObjectStressOnly.entryRoiObject.curveData.y];
                            app.entryRoiObjectStressOnly = getROI(app.dicomInfoStressOnly.PatientName.FamilyName, size(app.fourDImageSetStressOnly,2), size(app.fourDImageSetStressOnly,1), app.pEntryStressOnly(3), app.pEntryStressOnly(4), app.pEntryStressOnly(1), app.pEntryStressOnly(2), app.entryCurveStressOnly, app.entryTimePointStressOnly, app.fittedCurveEntryStressOnly, app.fittedTimeEntryStressOnly, app.entrySxStressOnly, app.entrySyStressOnly, app.aucAEntryStressOnly(end), 0, app.numberOfStenosis, app.rs1(end), app.rs2(end), app.rs3(end), app.entryQ(end), app.exitQ(end));
                            app.roiObjectStressOnly.entryRoiObject = app.entryRoiObjectStressOnly;
                        else
                            app.pEntryStressOnly = [app.roiObjectStressOnly.entryRoiObject(app.currentStenosisNumber).curveData.z, app.roiObjectStressOnly.entryRoiObject(app.currentStenosisNumber).curveData.t, app.roiObjectStressOnly.entryRoiObject(app.currentStenosisNumber).curveData.x, app.roiObjectStressOnly.entryRoiObject(app.currentStenosisNumber).curveData.y];
                            app.entryRoiObjectStressOnly = getROI(app.dicomInfoStressOnly.PatientName.FamilyName, size(app.fourDImageSetStressOnly,2), size(app.fourDImageSetStressOnly,1), app.pEntryStressOnly(3), app.pEntryStressOnly(4), app.pEntryStressOnly(1), app.pEntryStressOnly(2), app.entryCurveStressOnly, app.entryTimePointStressOnly, app.fittedCurveEntryStressOnly, app.fittedTimeEntryStressOnly, app.entrySxStressOnly, app.entrySyStressOnly, app.aucAEntryStressOnly(end), 0, app.numberOfStenosis, app.rs1(end), app.rs2(end), app.rs3(end), app.entryQ(end), app.exitQ(end));
                            app.roiObjectStressOnly.entryRoiObject(app.currentStenosisNumber) = app.entryRoiObjectStressOnly;
                            app.currentStenosisNumber = -1;
                        end
                        saveRoiAsJson(app.roiObjectStressOnly);

                    catch ME
                        msg = strcat('Problem in Curve Fitting:', ME.message);
                        errordlg(msg);
                        pause(5);
                        set(gcf, 'Pointer','arrow');
                    end
            end
            set(gcf, 'Pointer','arrow');
        end

        % Callback function
        function SaveLabelledDataButtonPushed(app, event)
            % Get Label Ready:

            logDAI('Converting images to JPEG and putting them into JPEGData Directory');
            convertImagesToJpeg(app.labelImageDirectory, app.threeDVolume, 'JPEGData');
            logDAI('Images are converted to JPEG');
            labeledDataRCA = getLabel(app.ptsRCA, size(app.threeDVolume), 1);
            labeledDataLAD = getLabel(app.ptsLAD, size(app.threeDVolume), 2);
            labeledDataLCx = getLabel(app.ptsLCx, size(app.threeDVolume), 3);
            labeledDataApex = getLabel(app.ptsApex, size(app.threeDVolume), 4);

            labeledData = labeledDataRCA + labeledDataLAD + labeledDataLCx + labeledDataApex;

            newDirectory = convertCharsToStrings(app.labelImageDirectory) + 'LabeledData\';
            logDAI('LabeledData directory is: ');
            logDAI(newDirectory);
            logDAI('LabeledData directory is being created');
            mkdir (newDirectory);
            logDAI('LabeledData directory is successfully created');
            logDAI('Writing labeled data to LabeledData directory');
            for i = 1:size(labeledData, 3)
                currentImage = labeledData(:,:,i);
                fName = newDirectory + num2str(i) + '.jpg';
                imwrite(currentImage, fName);
            end
            logDAI('Writing labeled data to LabeledData directory is successful');
        end

        % Callback function
        function RemovePointCheckBoxValueChanged(app, event)

        end

        % Callback function
        function RemovePointCheckBoxStressOnlyValueChanged(app, event)

        end

        % Callback function
        function RemovePointPreCheckBoxStressOnlyValueChanged(app, event)
            value = app.RemovePointPreCheckBoxStressOnly.Value;
            if(value == 1)

                f = msgbox('Select a plot by clicking on it','Plot select', 'modal');
                uiwait(f);

                w = waitforbuttonpress;
                app.SetYaxisMaxMenu.Visible = 'off';
                if (strcmp(app.plotToRemovePoint, 'pre'))

                    if (~isempty(app.fittedCurvePaStressOnly))
                        app.fittedCurvePaStressOnly = [];
                        drawPreLesionCurveStressOnly(app);
                    end

                    pts = getpts(app.paPlotAreaStressOnly);
                    data = app.xDataPreStressOnly;
                    distance = round(data - pts(1));
                    idx = find(distance == 0);
                    dataArray = app.paCurveStressOnly;
                    dataArray(idx) = [];
                    app.paCurveStressOnly = dataArray;
                    timeArray = app.paTimePointsStressOnly;
                    timeArray(idx) = [];
                    app.paTimePointsStressOnly = timeArray;
                    drawPreLesionCurveStressOnly(app);

                elseif (contains(app.plotToRemovePoint, 'entry'))

                    logDAI('Entered point removal for entry curve.');
                    plotID = str2double(app.plotToRemovePoint(end));
                    s = sprintf('plotID Selected = %d', plotID);
                    logDAI(s);
                    temporaryStenosisID = app.currentStenosisID;
                    s = sprintf('temporaryStenosisID and currentStenosisID = %d', temporaryStenosisID);
                    logDAI(s);
                    temporaryStenosisNumber = app.currentStenosisNumber;
                    s = sprintf('temporaryStenosisNumber and currentStenosisNumber = %d', temporaryStenosisNumber);
                    logDAI(s);
                    app.currentStenosisID = plotID;
                    app.currentStenosisNumber = plotID;

                    pts = getpts(app.paPlotAreaStressOnly);

                    s = sprintf('point selected = %d', pts(1));
                    logDAI(s);
                    data = app.roiObjectStressOnly.entryRoiObject(plotID).curveData.timePoints;
                    s = sprintf('data = %d', data);
                    logDAI(s);
                    distance = round(data - pts(1));
                    idx = find(distance == 0);
                    s = sprintf('idx = %d', idx);
                    logDAI(s);

                    app.entryCurveStressOnly = app.roiObjectStressOnly.entryRoiObject(plotID).curveData.curve;
                    app.entryTimePointStressOnly = app.roiObjectStressOnly.entryRoiObject(plotID).curveData.timePoints;

                    dataArray = app.entryCurveStressOnly;
                    s = sprintf('dataArrayBeforeRemovalOfPoint = %d', dataArray);
                    logDAI(s);
                    dataArray(idx) = [];
                    s = sprintf('dataArrayAfterRemovalOfPoint = %d', dataArray);
                    logDAI(s);
                    app.entryCurveStressOnly = dataArray;
                    timeArray = app.entryTimePointStressOnly;
                    s = sprintf('timeArrayBeforeRemovalOfPoint = %d', timeArray);
                    logDAI(s);
                    timeArray(idx) = [];
                    s = sprintf('timeArrayAfterRemovalOfPoint = %d', timeArray);
                    logDAI(s);
                    app.entryTimePointStressOnly = timeArray;
                    app.fittedCurveEntryStressOnly = [];
                    logDAI('Calling Draw Curve.');
                    drawEntryCurveStressOnly(app);
                    logDAI('Calling Curve Fitting.');
                    FitCurvesButtonStressOnlyPushed(app);

                    app.currentStenosisID = temporaryStenosisID;
                    app.currentStenosisNumber = temporaryStenosisNumber;
                end

                app.RemovePointPreCheckBoxStressOnly.Value = 0;
                app.SetYaxisMaxMenu.Visible = 'on';
            end
        end

        % Callback function
        function RemovePointPostCheckBoxStressOnlyValueChanged(app, event)
            value = app.RemovePointPostCheckBoxStressOnly.Value;
            if(value == 1)

                f = msgbox('Select a plot by clicking on it','Plot select', 'modal');
                uiwait(f);

                w = waitforbuttonpress;
                app.SetYaxisMaxMenu_2.Visible = 'off';

                if (strcmp(app.plotToRemovePoint, 'post'))

                    if (~isempty(app.fittedCurvePdStressOnly))
                        app.fittedCurvePdStressOnly = [];
                        drawPostLesionCurveStressOnly(app);
                    end

                    pts = getpts(app.pdPlotAreaStressOnly);
                    data = app.xDataPostStressOnly;
                    distance = round(data - pts(1));
                    idx = find(distance == 0);
                    dataArray = app.pdCurveStressOnly;
                    dataArray(idx) = [];
                    app.pdCurveStressOnly = dataArray;
                    timeArray = app.pdTimePointsStressOnly;
                    timeArray(idx) = [];
                    app.pdTimePointsStressOnly = timeArray;
                    drawPostLesionCurveStressOnly(app);

                elseif (contains(app.plotToRemovePoint, 'exit'))

                    logDAI('Entered point removal for exit curve.');
                    plotID = str2double(app.plotToRemovePoint(end));
                    s = sprintf('plotID Selected = %d', plotID);
                    logDAI(s);
                    temporaryStenosisID = app.currentStenosisID;
                    s = sprintf('temporaryStenosisID and currentStenosisID = %d', temporaryStenosisID);
                    logDAI(s);
                    temporaryStenosisNumber = app.currentStenosisNumber;
                    s = sprintf('temporaryStenosisNumber and currentStenosisNumber = %d', temporaryStenosisNumber);
                    logDAI(s);
                    app.currentStenosisID = plotID;
                    app.currentStenosisNumber = plotID;

                    pts = getpts(app.pdPlotAreaStressOnly);

                    s = sprintf('point selected = %d', pts(1));
                    logDAI(s);
                    data = app.roiObjectStressOnly.exitRoiObject(plotID).curveData.timePoints;
                    s = sprintf('data = %d', data);
                    logDAI(s);
                    distance = round(data - pts(1));
                    idx = find(distance == 0);
                    s = sprintf('idx = %d', idx);
                    logDAI(s);

                    app.exitCurveStressOnly = app.roiObjectStressOnly.exitRoiObject(plotID).curveData.curve;
                    app.exitTimePointStressOnly = app.roiObjectStressOnly.exitRoiObject(plotID).curveData.timePoints;

                    dataArray = app.exitCurveStressOnly;
                    s = sprintf('dataArrayBeforeRemovalOfPoint = %d', dataArray);
                    logDAI(s);
                    dataArray(idx) = [];
                    s = sprintf('dataArrayAfterRemovalOfPoint = %d', dataArray);
                    logDAI(s);
                    app.exitCurveStressOnly = dataArray;
                    timeArray = app.exitTimePointStressOnly;
                    s = sprintf('timeArrayBeforeRemovalOfPoint = %d', timeArray);
                    logDAI(s);
                    timeArray(idx) = [];
                    s = sprintf('timeArrayAfterRemovalOfPoint = %d', timeArray);
                    logDAI(s);
                    app.exitTimePointStressOnly = timeArray;
                    app.fittedCurveExitStressOnly = [];
                    logDAI('Calling Draw Curve.');
                    drawExitCurveStressOnly(app);
                    logDAI('Calling Curve Fitting.');
                    FitCurvesButtonStressOnly_2Pushed(app);

                    app.currentStenosisID = temporaryStenosisID;
                    app.currentStenosisNumber = temporaryStenosisNumber;
                end

                app.RemovePointPostCheckBoxStressOnly.Value = 0;
                app.SetYaxisMaxMenu_2.Visible = 'on';
            end
        end

        % Callback function
        function SetLADPointButtonPushed(app, event)
            ptsValue = drawpoint(app.ImageAxes3D);
            app.ptsLAD(end+1) = app.currentSlice3D;
            app.ptsLAD(end+1) = round(ptsValue.Position(2));
            app.ptsLAD(end+1) = round(ptsValue.Position(1));
            % Quadruplet of (Slice #, X, Y, PixelValue)
            app.supervisedLearningLabeledPoints{end+1} = 'lad';
            logDAI('LAD point adding completed.');
            logDAI('Putting point on JPEG volume');
            img = app.threeDVolume(:,:,app.currentSlice3D);
            imgJPEG(:,:,:) = app.volumeJPEG(:,:,app.currentSlice3D,:);
            app.ptsLAD(end+1) = imgJPEG(round(ptsValue.Position(2)), round(ptsValue.Position(1)));
            imgJPEG = getColoredPointForMarkedCoordinate(imgJPEG, round(ptsValue.Position(2)), round(ptsValue.Position(1)), [0.0, 255.0, 0.0]);
            app.volumeJPEG(:,:,app.currentSlice3D,:) = imgJPEG(:,:,:);
            updateimage3D(app, img);
            updateimage3DJPEG(app,imgJPEG);
        end

        % Callback function
        function SetLCxPointButtonPushed(app, event)
            ptsValue = drawpoint(app.ImageAxes3D);
            app.ptsLCx(end+1) = app.currentSlice3D;
            app.ptsLCx(end+1) = round(ptsValue.Position(2));
            app.ptsLCx(end+1) = round(ptsValue.Position(1));
            % Quadruplet of (Slice #, X, Y, PixelValue)
            app.supervisedLearningLabeledPoints{end+1} = 'lcx';
            logDAI('LCx point adding completed.');
            logDAI('Putting point on JPEG volume');
            img = app.threeDVolume(:,:,app.currentSlice3D);
            imgJPEG(:,:,:) = app.volumeJPEG(:,:,app.currentSlice3D,:);
            app.ptsLCx(end+1) = imgJPEG(round(ptsValue.Position(2)), round(ptsValue.Position(1)));
            imgJPEG = getColoredPointForMarkedCoordinate(imgJPEG, round(ptsValue.Position(2)), round(ptsValue.Position(1)), [0.0, 0.0, 255.0]);
            app.volumeJPEG(:,:,app.currentSlice3D,:) = imgJPEG(:,:,:);
            updateimage3D(app, img);
            updateimage3DJPEG(app,imgJPEG);
        end

        % Callback function
        function SetApexPointButtonPushed(app, event)
            ptsValue = drawpoint(app.ImageAxes3D);
            app.ptsApex(end+1) = app.currentSlice3D;
            app.ptsApex(end+1) = round(ptsValue.Position(2));
            app.ptsApex(end+1) = round(ptsValue.Position(1));
            % Triplet of (Slice #, X, Y, PixelValue)
            app.supervisedLearningLabeledPoints{end+1} = 'apex';
            logDAI('Apex point adding completed.');
            logDAI('Putting point on JPEG volume');
            img = app.threeDVolume(:,:,app.currentSlice3D);
            imgJPEG(:,:,:) = app.volumeJPEG(:,:,app.currentSlice3D,:);
            app.ptsApex(end+1) = imgJPEG(round(ptsValue.Position(2)), round(ptsValue.Position(1)));
            imgJPEG = getColoredPointForMarkedCoordinate(imgJPEG, round(ptsValue.Position(2)), round(ptsValue.Position(1)), [255.0, 0.0, 255.0]);
            app.volumeJPEG(:,:,app.currentSlice3D,:) = imgJPEG(:,:,:);
            updateimage3D(app, img);
            updateimage3DJPEG(app,imgJPEG);
        end

        % Callback function
        function ViewModeButtonGroupSelectionChanged(app, event)

        end

        % Callback function
        function LoadTimeInfoStudiesButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.*','All Files'};
            [f, p] = uigetfile(filterspec);
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);

            % Read dicom files:
            app.timePoints = readTimeInfoOnlyFromImages(p);
            app.interpolatedTimePoints = app.timePoints;
            set(gcf, 'Pointer','arrow');

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                % Generate the First Image, update sliders' positions and Send it for displaying
                firstImage(1:512,1:512) = app.fourDImageSet(1,1,1:512,1:512);
                app.SlicesSlider.Limits = [1 size(app.fourDImageSet,1)];
                tickValues = getSliderTicks(1, size(app.fourDImageSet,1));
                app.SlicesSlider.MajorTicks = tickValues;
                app.TimePointsSlider.Limits = [1 size(app.fourDImageSet,2)];
                app.TimePointsSlider.MajorTicks = [1:size(app.fourDImageSet,2)];
                app.TimePointsSlider.MajorTickLabels = arrayfun(@num2str, app.TimePointsSlider.MajorTicks, 'UniformOutput', 0);
                app.TimePointsSlider.MinorTicks = [1:size(app.fourDImageSet,2)];
                level = 500;
                width = 1500;
                app.minWindow = level - width / 2;
                app.maxWindow = level + width / 2;
                updateimage(app, firstImage);
            end

            % Set Initial y axes limits
            app.paPlotArea.YLim = [-50 500];
            app.pdPlotArea.YLim = [-50 500];
        end

        % Callback function
        function GraphAppearanceDropDownValueChanged(app, event)
            value = app.GraphAppearanceDropDown.Value;
            if (strcmp(value, 'Smooth Line'))
                tempallFFRStressOnlySliceLocations = app.allFFRStressOnlySliceLocations;
                tempallFFRStressOnly = app.allFFRStressOnly;
                Xi = app.allFFRStressOnlySliceLocations(1):0.1:max(app.allFFRStressOnlySliceLocations(:));
                Yi = pchip(app.allFFRStressOnlySliceLocations,app.allFFRStressOnly,Xi);

                app.allFFRStressOnlySliceLocations = Xi;
                app.allFFRStressOnly = Yi;

                tempallFlowVelocitiesSliceLocationsStressOnly = app.allFlowVelocitiesSliceLocationsStressOnly;
                tempallFlowVelocitiesStressOnly = app.allFlowVelocitiesStressOnly;
                Xi = [];
                Yi = [];

                Xi = app.allFlowVelocitiesSliceLocationsStressOnly(1):0.1:max(app.allFlowVelocitiesSliceLocationsStressOnly(:));
                Yi = pchip(app.allFlowVelocitiesSliceLocationsStressOnly,app.allFlowVelocitiesStressOnly,Xi);
                app.allFlowVelocitiesSliceLocationsStressOnly = Xi;
                app.allFlowVelocitiesStressOnly = Yi;

                drawFFRCurve(app);

                app.allFFRStressOnlySliceLocations = tempallFFRStressOnlySliceLocations;
                app.allFFRStressOnly = tempallFFRStressOnly;
                app.allFlowVelocitiesSliceLocationsStressOnly = tempallFlowVelocitiesSliceLocationsStressOnly;
                app.allFlowVelocitiesStressOnly = tempallFlowVelocitiesStressOnly;

            elseif (strcmp(value, 'Without Marker'))
                drawFFRCurve(app, '-');
            elseif (strcmp(value, 'With Marker'))
                drawFFRCurve(app);
            elseif (strcmp(value, 'Default'))
                drawFFRCurve(app);
            end
        end

        % Callback function
        function FrictionFactorButtonGroupRestSelectionChanged(app, event)
            selectedButton = app.FrictionFactorButtonGroupRest.SelectedObject;
            app.fMethodRest = selectedButton.Text;
        end

        % Callback function
        function FrictionFactorButtonGroupStressOnlySelectionChanged(app, event)
            selectedButton = app.FrictionFactorButtonGroupStressOnly.SelectedObject;
            app.fMethodStressOnly = selectedButton.Text;
        end

        % Callback function
        function ArteryTypeButtonGroupRestSelectionChanged(app, event)
            selectedButton = app.ArteryTypeButtonGroupRest.SelectedObject;
            app.arteryTypeRest = selectedButton.Text;
        end

        % Callback function
        function ArteryTypeButtonGroupStressOnlySelectionChanged(app, event)
            selectedButton = app.ArteryTypeButtonGroupStressOnly.SelectedObject;
            app.arteryTypeStressOnly = selectedButton.Text;
        end

        % Callback function
        function FlipSliceNumbersCheckBoxValueChanged(app, event)
            value = app.FlipSliceNumbersCheckBox.Value;
            if (value == 1)
                logDAI('Flip Slice Number Checkbox checked.');
                app.reversedALLFFRStressOnly = true;
                logDAI('Reversing allFFRStressOnly vector done.');
            else
                logDAI('Flip Slice Number Checkbox unchecked.');
                app.reversedALLFFRStressOnly = false;
            end

            drawFFRCurve(app);

        end

        % Callback function
        function SetWindowButtonSupervisedPushed(app, event)
            logDAI('Getting new level and width from input field');
            level = str2double(app.SetWindowLevelSupervised.Value{1});
            width = str2double(app.SetWindowWidthSupervised.Value{1});
            logDAI('Calculating new level and width');
            app.minWindow3D = level - width / 2;
            app.maxWindow3D = level + width / 2;
            logDAI('New level and width calculated');
            logDAI('Applying new level and width');
            imagesc(app.ImageAxes3D, app.im, [app.minWindow3D, app.maxWindow3D]);
            logDAI('New level and width applied');
        end

        % Callback function
        function ViewOptionButtonGroupSelectionChanged(app, event)

        end

        % Callback function
        function ScannerDropDownValueChanged(app, event)
            value = app.ScannerDropDown.Value;
            app.scannerType = value;
        end

        % Callback function
        function ScannerDropDownRestValueChanged(app, event)

        end

        % Callback function
        function SlicesSliderCTAStressOnlyValueChanging(app, event)
            sliceLimit = size(app.ctaVolume,3);
            if (round(event.Value) > sliceLimit)
                changingValue = sliceLimit;
            else
                if (round(event.Value) == 0)
                    changingValue = 1;
                else
                    changingValue = round(event.Value);
                end
            end
            app.currentSliceCTA = changingValue;
            app.SlicesSliderCTAStressOnlyLabel.Text = sprintf('Slice %d/%d', app.currentSliceCTA, size(app.ctaVolume, 3));
            imageData(1:512, 1:512) = app.ctaVolume(1:512,1:512,app.currentSliceCTA);

            updateimageCTA(app, imageData);
        end

        % Callback function
        function ViewModeButtonGroupStressOnlySelectionChanged(app, event)

        end

        % Callback function
        function ViewOptionButtonStressOnlySelectionChanged(app, event)

        end

        % Callback function
        function SetWindowButtonCTAStressOnlyPushed(app, event)
            level = str2double(app.SetWindowLevelCTAStressOnly.Value{1});
            width = str2double(app.SetWindowWidthCTAStressOnly.Value{1});
            app.minWindowCTA = level - width / 2;
            app.maxWindowCTA = level + width / 2;
            imagesc(app.ImageAxesCTAStressOnly, app.imCTA, [app.minWindowCTA, app.maxWindowCTA]);
        end

        % Menu selected function: UserGuideMenu
        function UserGuideMenuSelected(app, event)
            docxFileDirectory = pwd;
            docxFileName = strcat(docxFileDirectory, '\Documents\User Manual.docx');
            pdfFileName = convertDocxToPdf(docxFileDirectory, docxFileName);
            open(pdfFileName);
        end

        % Menu selected function: BrightMenu
        function BrightMenuSelected(app, event)
            app.viewMode = 'Normal';
            changeViewMode(app, 'Normal');
            app.BrightMenu.Checked = true;
            app.DarkMenu.Checked = false;
        end

        % Menu selected function: DarkMenu
        function DarkMenuSelected(app, event)
            app.viewMode = 'Dark';
            changeViewMode(app, 'Dark');
            app.BrightMenu.Checked = false;
            app.DarkMenu.Checked = true;
        end

        % Menu selected function: FullMenu
        function FullMenuSelected(app, event)
            logDAI('View Option in Full Mode');
            app.FrictionFactorButtonGroup.Visible = true;
            app.ArteryTypeButtonGroup.Visible = true;
            app.FrictionFactorButtonGroupRest.Visible = true;
            app.ArteryTypeButtonGroupRest.Visible = true;
            app.FrictionFactorButtonGroupStressOnly.Visible = false;
            app.ArteryTypeButtonGroupStressOnly.Visible = false;

            app.ClearInputsButton.Visible = true;
            app.CopyRestInputsButton.Visible = true;
            app.ClearInputsButtonRest.Visible = true;
            app.CopyStressInputsButton.Visible = true;
            app.ClearInputsButtonStressOnly.Visible = true;

            app.ArterialRoughness.Visible = true;
            app.SystolicBloodPressuremmHgEditField_2Label_2.Text = 'Arterial Roughness (cm)';

            app.ArterialRoughnessRest.Visible = true;
            app.SystolicBloodPressuremmHgEditField_2Label_3.Text = 'Arterial Roughness (cm)';

            app.MarkStenosisCheckBox.Visible = true;
            app.MarkBreakerCheckBox.Visible = true;

            logDAI('View Option Succesfully Changed in Full Mode');

            app.FullMenu.Checked = true;
            app.BasicMenu.Checked = false;
        end

        % Menu selected function: BasicMenu
        function BasicMenuSelected(app, event)
            logDAI('View Option in Basic Mode');
            app.FrictionFactorButtonGroup.Visible = false;
            app.ArteryTypeButtonGroup.Visible = false;
            app.FrictionFactorButtonGroupRest.Visible = false;
            app.ArteryTypeButtonGroupRest.Visible = false;
            app.FrictionFactorButtonGroupStressOnly.Visible = false;
            app.ArteryTypeButtonGroupStressOnly.Visible = false;

            app.ClearInputsButton.Visible = false;
            app.CopyRestInputsButton.Visible = false;
            app.ClearInputsButtonRest.Visible = false;
            app.CopyStressInputsButton.Visible = false;
            app.ClearInputsButtonStressOnly.Visible = false;

            app.ArterialRoughness.Visible = false;
            app.SystolicBloodPressuremmHgEditField_2Label_2.Text = '';

            app.ArterialRoughnessRest.Visible = false;
            app.SystolicBloodPressuremmHgEditField_2Label_3.Text = '';

            app.SystolicBloodPressuremmHgEditField_2Label_4.Text = '';

            app.MarkStenosisCheckBox.Visible = false;
            app.MarkBreakerCheckBox.Visible = false;
            logDAI('View Option Succesfully Changed in Basic Mode');

            app.FullMenu.Checked = false;
            app.BasicMenu.Checked = true;
        end

        % Menu selected function: AboutDAIVeraMenu
        function AboutDAIVeraMenuSelected(app, event)
            msgbox('DAI Vera is a software developed to analyse dynamic contrast-enhanced CT images for functional assessment of cardiovascular diseases. It is a proprietary property of the So''s lab at Lawson Health Research Institute and Western University. All rights reserved.');
        end

        % Menu selected function: DCECTImagesMenu
        function DCECTImagesMenuSelected(app, event)

        end

        % Menu selected function: CTAImagesMenu
        function CTAImagesMenuSelected(app, event)
            % Display uigetfile dialog
            filterspec = {'*.*','All Files'};
            [f, p] = uigetfile(filterspec);
            if (p == 0)
                % Do Nothing
                figure(app.UIFigure);
            else
                set(gcf, 'Pointer','watch');
                drawnow;
                figure(app.UIFigure);

                try
                    % Read 3D volume:

                    [app.ctaVolume,sp] = dicomreadVolume(p);
                    app.ctaVolume = squeeze(app.ctaVolume);
                    listings = dir(p);
                    fname = strcat(p,listings(3).name);
                    app.ctaInfo = dicominfo(fname);
                    app.ctaVolume = app.ctaVolume * app.ctaInfo.RescaleSlope + app.ctaInfo.RescaleIntercept;

                    app.SlicesSliderCTAStressOnly.Limits = [1 size(app.ctaVolume,3)];
                    tickValues = getSliderTicks(1, size(app.ctaVolume,3));
                    app.SlicesSliderCTAStressOnly.MajorTicks = tickValues;

                    set(gcf, 'Pointer','arrow');

                    minValue = min(app.ctaVolume(:));
                    maxValue = max(app.ctaVolume(:));
                    width = maxValue - minValue;
                    level = (maxValue + minValue) / 2;
                    app.minWindowCTA = level - width / 2;
                    app.maxWindowCTA = level + width / 2;

                    % Make sure user didn't cancel uigetfile dialog
                    if (ischar(p))
                        firstImage = app.ctaVolume(:,:,1);
                        updateimageCTA(app,firstImage);
                    end
                catch ME
                    try
                        [app.ctaVolume, app.ctaInfo] = readRegularCTAVolume(p);

                        app.SlicesSliderCTAStressOnly.Limits = [1 size(app.ctaVolume,3)];
                        tickValues = getSliderTicks(1, size(app.ctaVolume,3));
                        app.SlicesSliderCTAStressOnly.MajorTicks = tickValues;

                        set(gcf, 'Pointer','arrow');

                        minValue = min(app.ctaVolume(:));
                        maxValue = max(app.ctaVolume(:));
                        width = maxValue - minValue;
                        level = (maxValue + minValue) / 2;
                        app.minWindowCTA = level - width / 2;
                        app.maxWindowCTA = level + width / 2;

                        % Make sure user didn't cancel uigetfile dialog
                        if (ischar(p))
                            firstImage = app.ctaVolume(:,:,1);
                            updateimageCTA(app,firstImage);
                        end
                    catch ME
                        set(gcf, 'Pointer','arrow');
                        msg = strcat('The DAI did not fail, there seems to be an issue with this dataset in particular: ', ME.message);
                        errordlg(msg);
                    end
                end
            end
        end

        % Menu selected function: DCECTImagesMenu_2
        function DCECTImagesMenu_2Selected(app, event)
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);

            writeDicomVolume(app.fourDImageSetStressOnly, app.allDicomInfoStressOnly, 'Stress');

            set(gcf, 'Pointer','arrow');
        end

        % Menu selected function: ResultsMenu
        function ResultsMenuSelected(app, event)
            generateReport(app);
            generateDetailsReport(app);
        end

        % Menu selected function: FittedCurvesMenu
        function FittedCurvesMenuSelected(app, event)
            if (~isempty(app.paCurveStressOnly))
                    tempAucA = app.aucAStressOnly;
                    tempAucB = app.aucBStressOnly;

                % if (isempty(app.ConversionFactorEditFieldStressOnly))
                %     tempAucA = app.aucAStressOnly;
                %     tempAucB = app.aucBStressOnly;
                % else
                %     tempAucA = app.aucAStressOnly / str2double(app.ConversionFactorEditFieldStressOnly.Value);
                %     tempAucB = app.aucBStressOnly / str2double(app.ConversionFactorEditFieldStressOnly.Value);
                % end
                exportCurves(app.paCurveStressOnly, app.pdCurveStressOnly, app.fittedCurvePaStressOnly, app.fittedCurvePdStressOnly, app.timePointsStressOnly, app.fittedTimePaStressOnly, tempAucA, tempAucB, app.dicomInfoStressOnly.PatientName.FamilyName, 'StressOnly');
            end
        end

        % Menu selected function: FFRValuesMenu
        function FFRValuesMenuSelected(app, event)
            logDAI('Calling exportFFRCurve()');
            exportFFRCurve(app.allFFRStressOnlySliceLocations, app.allFFRStressOnlyDicomSliceNumbers, app.allFFRStressOnly, app.paStressOnly(1), app.allFlowVelocitiesStressOnly, app.dicomInfoStressOnly.PatientName.FamilyName);
            logDAI('FFRExport Successful');
        end

        % Menu selected function: ScreenCaptureMenu
        function ScreenCaptureMenuSelected(app, event)
            saveScreen(app);
        end

        % Menu selected function: StressImagesMenu_4
        function StressImagesMenu_4Selected(app, event)

        end

        % Menu selected function: RestImagesMenu_2
        function RestImagesMenu_2Selected(app, event)

        end

        % Menu selected function: TimeInfoStudyStressMenu
        function TimeInfoStudyStressMenuSelected(app, event)
            % Display uigetfile dialog
            filterspec = {'*.*','All Files'};
            [f, p] = uigetfile(filterspec);
            if (p == 0)
                % Do nothing
                figure(app.UIFigure);
            else
                set(gcf, 'Pointer','watch');
                drawnow;
                figure(app.UIFigure);

                % Read dicom files:
                app.timePoints = readTimeInfoOnlyFromImages(p);
                app.interpolatedTimePoints = app.timePoints;
                set(gcf, 'Pointer','arrow');

                % Make sure user didn't cancel uigetfile dialog
                if (ischar(p))
                    % Generate the First Image, update sliders' positions and Send it for displaying
                    firstImage(1:512,1:512) = app.fourDImageSet(1,1,1:512,1:512);
                    app.SlicesSlider.Limits = [1 size(app.fourDImageSet,1)];
                    tickValues = getSliderTicks(1, size(app.fourDImageSet,1));
                    app.SlicesSlider.MajorTicks = tickValues;
                    app.TimePointsSlider.Limits = [1 size(app.fourDImageSet,2)];
                    app.TimePointsSlider.MajorTicks = [1:size(app.fourDImageSet,2)];
                    app.TimePointsSlider.MajorTickLabels = arrayfun(@num2str, app.TimePointsSlider.MajorTicks, 'UniformOutput', 0);
                    app.TimePointsSlider.MinorTicks = [1:size(app.fourDImageSet,2)];
                    level = 500;
                    width = 1500;
                    app.minWindow = level - width / 2;
                    app.maxWindow = level + width / 2;
                    updateimage(app, firstImage);
                end

                % Set Initial y axes limits
                app.paPlotArea.YLim = [-50 500];
                app.pdPlotArea.YLim = [-50 500];
            end
        end

        % Menu selected function: TimeInfoStudyRestMenu
        function TimeInfoStudyRestMenuSelected(app, event)
            % Display uigetfile dialog
            filterspec = {'*.*','All Files'};
            [f, p] = uigetfile(filterspec);
            if (p == 0)
                % Do nothing
                figure(app.UIFigure);
            else
                set(gcf, 'Pointer','watch');
                drawnow;
                figure(app.UIFigure);

                % Read dicom files:
                app.timePointsRest = readTimeInfoOnlyFromImages(p);
                app.interpolatedTimePointsRest = app.timePointsRest;
                set(gcf, 'Pointer','arrow');

                % Make sure user didn't cancel uigetfile dialog
                if (ischar(p))
                    % Generate the First Image, update sliders' positions and Send it for displaying
                    firstImage(1:512,1:512) = app.restImageSet(1,1,1:512,1:512);
                    app.SlicesSliderRest.Limits = [1 size(app.restImageSet,1)];
                    tickValues = getSliderTicks(1, size(app.restImageSet,1));
                    app.SlicesSliderRest.MajorTicks = tickValues;
                    app.TimePointsSliderRest.Limits = [1 size(app.restImageSet,2)];
                    app.TimePointsSliderRest.MajorTicks = [1:size(app.restImageSet,2)];
                    app.TimePointsSliderRest.MajorTickLabels = arrayfun(@num2str, app.TimePointsSlider.MajorTicks, 'UniformOutput', 0);
                    app.TimePointsSliderRest.MinorTicks = [1:size(app.restImageSet,2)];
                    level = 500;
                    width = 1500;
                    app.minWindowRest = level - width / 2;
                    app.maxWindowRest = level + width / 2;
                    updateImageRest(app, firstImage);
                end

                % Set Initial y axes limits
                app.paPlotArea.YLim = [-50 500];
                app.pdPlotArea.YLim = [-50 500];
            end
        end

        % Menu selected function: StressImagesMenu_3
        function StressImagesMenu_3Selected(app, event)
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);

            writeDicomVolume(app.fourDImageSet, app.allDicomInfo, 'Stress');

            set(gcf, 'Pointer','arrow');
        end

        % Menu selected function: RestImagesMenu
        function RestImagesMenuSelected(app, event)
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);

            writeDicomVolume(app.restImageSet, app.allDicomInfoRest, 'Rest');

            set(gcf, 'Pointer','arrow');
        end

        % Menu selected function: PreLesionCurveMenu_2
        function PreLesionCurveMenu_2Selected(app, event)
            filterspec = {'*.txt','Text Files';'*.xlsx','Excel Files'};
            [f, p] = uigetfile(filterspec);
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);
            set(gcf, 'Pointer','arrow');

            logDAI('Checking chosen file type:');
            [~, name, ext] = fileparts(f);

            if (strcmp(ext,'.xlsx'))
                logDAI('File type is excel, reading excel file.');
                fName = strcat(p, f);
                data = readtable(fName);
                time = data{1:end, 1};
                roi = data{1:end, 2};
            end

            if (strcmp(ext,'.txt'))
                logDAI('File type is txt, reading txt file.');
                % Read text files:
                fileName = strcat(p,f);
                [time, roi] = readDataFile(fileName);
                if time(2) >= 500
                    time = time / 1000;
                end
            end

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                logDAI('Plotting the curve');
                app.paCurve = roi;
                app.plotX = time;
                app.paPlotArea.YLim = [app.yAxisLimitMin app.yAxisLimitMax];
                interval = getPlotInterval(app.yAxisLimitMin, app.yAxisLimitMax);
                app.paPlotArea.YTick = [app.yAxisLimitMin:interval:app.yAxisLimitMax];
                app.paPlotArea.XLim = [1 max(time)];
                app.paPlotArea.XTick = [1:max(time)/10:max(time)];
                app.paPlotArea.XTick = cast(app.paPlotArea.XTick, 'int16');
                plot(app.paPlotArea, app.plotX, app.paCurve, 'mo');
                app.preTextRead = true;
                logDAI('Plotting the curve is successful');
            end

            if (app.preTextRead == true && app.postTextRead == true)
                prompt = {'Relative Height(cm):','Pre-Post Distance(cm):'};
                dlgtitle = 'Enter';
                dims = [1 35];
                definput = {'0','0'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                app.hBText = str2double(answer{1});
                app.LText = str2double(answer{2});
                figure(app.UIFigure);
            end
        end

        % Menu selected function: FittedCurveMenu_2
        function FittedCurveMenu_2Selected(app, event)
            if (~isempty(app.paCurve))
                if (isempty(app.ConversionFactorEditField))
                    tempAucA = app.aucA;
                    tempAucB = app.aucB;
                else
                    tempAucA = app.aucA / str2double(app.ConversionFactorEditField.Value);
                    tempAucB = app.aucB / str2double(app.ConversionFactorEditField.Value);
                end
                exportCurves(app.paCurve, app.pdCurve, app.fittedCurvePa, app.fittedCurvePd, app.timePoints, app.fittedTimePa, tempAucA, tempAucB, app.dicomInfo.PatientName.FamilyName, 'Stress');
            end
            if (~isempty(app.paCurveRest))
                if (isempty(app.ConversionFactorEditField))
                    tempAucA = app.aucARest;
                    tempAucB = app.aucBRest;
                else
                    tempAucA = app.aucARest / str2double(app.ConversionFactorEditFieldRest.Value);
                    tempAucB = app.aucBRest / str2double(app.ConversionFactorEditFieldRest.Value);
                end
                exportCurves(app.paCurveRest, app.pdCurveRest, app.fittedCurvePaRest, app.fittedCurvePdRest, app.timePointsRest, app.fittedTimePaRest, tempAucA, tempAucB, app.dicomInfoRest.PatientName.FamilyName, 'Rest');
            end
        end

        % Menu selected function: ResultsMenu_2
        function ResultsMenu_2Selected(app, event)
            generateReport(app);
            generateDetailsReport(app);
        end

        % Menu selected function: DCTAVolumeMenu
        function DCTAVolumeMenuSelected(app, event)
            
            % % Display uigetfile dialog
            % filterspec = {'*.*','All Files'};
            % [f, p] = uigetfile(filterspec);
            % if (p == 0)
            %     % Do nothing
            %     figure(app.UIFigure);
            % else
            %     set(gcf, 'Pointer','watch');
            %     drawnow;
            %     figure(app.UIFigure);
            % 
            %     try
            %         % Read 3D volume:
            %         [app.threeDVolume,sp] = dicomreadVolume(p);
            %         app.threeDVolume = squeeze(app.threeDVolume);
            % 
            %         logDAI('Converting dicomVolume to jpegVolume');
            %         app.volumeJPEG = getJPEGVolumeFromDicomVolume(app.threeDVolume, 3);
            % 
            %         app.labelImageDirectory = p;
            % 
            %         app.SlicesSlider3D.Limits = [1 size(app.threeDVolume,3)];
            %         tickValues = getSliderTicks(1, size(app.threeDVolume,3));
            %         app.SlicesSlider3D.MajorTicks = tickValues;
            % 
            %         set(gcf, 'Pointer','arrow');
            % 
            %         level = 500;
            %         width = 1500;
            %         app.minWindow3D = level - width / 2;
            %         app.maxWindow3D = level + width / 2;
            % 
            %         % Make sure user didn't cancel uigetfile dialog
            %         if (ischar(p))
            %             firstImage = app.threeDVolume(:,:,1);
            %             updateimage3D(app,firstImage);
            % 
            %             firstImageJPEG(:,:,:) = app.volumeJPEG(:,:,1,:);
            %             updateimage3DJPEG(app,firstImageJPEG);
            %         end
            %     catch ME
            %         set(gcf, 'Pointer','arrow');
            %         msg = strcat('The DAI did not fail, there seems to be an issue with this dataset in particular: ', ME.message);
            %         errordlg(msg);
            %     end
            % end
        end

        % Menu selected function: LabelledDataMenu
        function LabelledDataMenuSelected(app, event)
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);

            % Delete previous temp files if saveCount = 1
            if (app.supervisedLearningSaveCount == 1)
                files = dir(fullfile(app.labelImageDirectory, '*.txt'));
                for i = 1:numel(files)
                    file = fullfile(app.labelImageDirectory, files(i).name);
                    delete(file);
                end
            end
            % Check if file exists for points:
            app.ptsRCA = readPreviousDataPointsFromFile(app, 'RCA.txt', 'RCA');
            app.ptsLAD = readPreviousDataPointsFromFile(app, 'LAD.txt', 'LAD');
            app.ptsLCx = readPreviousDataPointsFromFile(app, 'LCx.txt', 'LCx');
            app.ptsApex = readPreviousDataPointsFromFile(app, 'Apex.txt', 'Apex');

            % Get Label Ready:

            logDAI('Converting images to JPEG and putting them into JPEGData Directory');
            convertImagesToJpeg(app.labelImageDirectory, app.threeDVolume, 'JPEGData');
            logDAI('Images are converted to JPEG');
            labeledDataRCA = getLabel(app.ptsRCA, size(app.threeDVolume), 1);
            labeledDataLAD = getLabel(app.ptsLAD, size(app.threeDVolume), 2);
            labeledDataLCx = getLabel(app.ptsLCx, size(app.threeDVolume), 3);
            labeledDataApex = getLabel(app.ptsApex, size(app.threeDVolume), 4);

            % Clear the data points:
            logDAI('Clearing the data points');
            app.ptsRCA = [];
            app.ptsLAD = [];
            app.ptsLCx = [];
            app.ptsApex = [];

            labeledData = labeledDataRCA + labeledDataLAD + labeledDataLCx + labeledDataApex;

            newDirectory = convertCharsToStrings(app.labelImageDirectory) + 'LabeledData\';
            logDAI('LabeledData directory is: ');
            logDAI(newDirectory);
            logDAI('LabeledData directory is being created');
            mkdir (newDirectory);
            logDAI('LabeledData directory is successfully created');
            logDAI('Writing labeled data to LabeledData directory');
            for i = 1:size(labeledData, 3)
                currentImage = labeledData(:,:,i);
                fName = newDirectory + num2str(i) + '.jpg';
                imwrite(currentImage, fName);
            end
            logDAI('Writing labeled data to LabeledData directory is successful');

            app.supervisedLearningSaveCount = app.supervisedLearningSaveCount + 1;

            set(gcf, 'Pointer','arrow');
        end

        % Callback function
        function SiemensMenu_2Selected(app, event)
            app.scannerType = convertStringsToChars(app.scannerName(1));

            app.SiemensMenu_2.Checked = true;
            app.CanonMenu_2.Checked = false;
            app.GEMenu_2.Checked = false;
        end

        % Callback function
        function CanonMenu_2Selected(app, event)
            app.scannerType = convertStringsToChars(app.scannerName(2));

            app.SiemensMenu_2.Checked = false;
            app.CanonMenu_2.Checked = true;
            app.GEMenu_2.Checked = false;
        end

        % Callback function
        function SiemensMenuSelected(app, event)
            app.scannerTypeStressOnly = convertStringsToChars(app.scannerName(1));

            app.SiemensMenu.Checked = true;
            app.CanonMenu.Checked = false;
            app.GEMenu.Checked = false;
        end

        % Callback function
        function CanonMenuSelected(app, event)
            app.scannerTypeStressOnly = convertStringsToChars(app.scannerName(2));

            app.SiemensMenu.Checked = false;
            app.CanonMenu.Checked = true;
            app.GEMenu.Checked = false;
        end

        % Menu selected function: SetRCAPointMenu
        function SetRCAPointMenuSelected(app, event)
            ptsValue = drawpoint(app.ImageAxes3D);x
            app.ptsRCA(end+1) = app.currentSlice3D;
            app.ptsRCA(end+1) = round(ptsValue.Position(2));
            app.ptsRCA(end+1) = round(ptsValue.Position(1));
            % Quadruplet of (Slice #, X, Y, PixelValue)
            app.supervisedLearningLabeledPoints{end+1} = 'rca';
            logDAI('RCA point adding completed.');
            logDAI('Putting point on JPEG volume');
            img = app.threeDVolume(:,:,app.currentSlice3D);
            imgJPEG(:,:,:) = app.volumeJPEG(:,:,app.currentSlice3D,:);
            app.ptsRCA(end+1) = imgJPEG(round(ptsValue.Position(2)), round(ptsValue.Position(1)));
            imgJPEG = getColoredPointForMarkedCoordinate(imgJPEG, round(ptsValue.Position(2)), round(ptsValue.Position(1)), [255.0, 0.0, 0.0]);
            app.volumeJPEG(:,:,app.currentSlice3D,:) = imgJPEG(:,:,:);
            updateimage3D(app, img);
            updateimage3DJPEG(app,imgJPEG);
        end

        % Menu selected function: SetLADPointMenu
        function SetLADPointMenuSelected(app, event)
            ptsValue = drawpoint(app.ImageAxes3D);
            app.ptsLAD(end+1) = app.currentSlice3D;
            app.ptsLAD(end+1) = round(ptsValue.Position(2));
            app.ptsLAD(end+1) = round(ptsValue.Position(1));
            % Quadruplet of (Slice #, X, Y, PixelValue)
            app.supervisedLearningLabeledPoints{end+1} = 'lad';
            logDAI('LAD point adding completed.');
            logDAI('Putting point on JPEG volume');
            img = app.threeDVolume(:,:,app.currentSlice3D);
            imgJPEG(:,:,:) = app.volumeJPEG(:,:,app.currentSlice3D,:);
            app.ptsLAD(end+1) = imgJPEG(round(ptsValue.Position(2)), round(ptsValue.Position(1)));
            imgJPEG = getColoredPointForMarkedCoordinate(imgJPEG, round(ptsValue.Position(2)), round(ptsValue.Position(1)), [0.0, 255.0, 0.0]);
            app.volumeJPEG(:,:,app.currentSlice3D,:) = imgJPEG(:,:,:);
            updateimage3D(app, img);
            updateimage3DJPEG(app,imgJPEG);
        end

        % Menu selected function: SetLCxPointMenu
        function SetLCxPointMenuSelected(app, event)
            ptsValue = drawpoint(app.ImageAxes3D);
            app.ptsLCx(end+1) = app.currentSlice3D;
            app.ptsLCx(end+1) = round(ptsValue.Position(2));
            app.ptsLCx(end+1) = round(ptsValue.Position(1));
            % Quadruplet of (Slice #, X, Y, PixelValue)
            app.supervisedLearningLabeledPoints{end+1} = 'lcx';
            logDAI('LCx point adding completed.');
            logDAI('Putting point on JPEG volume');
            img = app.threeDVolume(:,:,app.currentSlice3D);
            imgJPEG(:,:,:) = app.volumeJPEG(:,:,app.currentSlice3D,:);
            app.ptsLCx(end+1) = imgJPEG(round(ptsValue.Position(2)), round(ptsValue.Position(1)));
            imgJPEG = getColoredPointForMarkedCoordinate(imgJPEG, round(ptsValue.Position(2)), round(ptsValue.Position(1)), [0.0, 0.0, 255.0]);
            app.volumeJPEG(:,:,app.currentSlice3D,:) = imgJPEG(:,:,:);
            updateimage3D(app, img);
            updateimage3DJPEG(app,imgJPEG);
        end

        % Menu selected function: SetApexPointMenu
        function SetApexPointMenuSelected(app, event)
            ptsValue = drawpoint(app.ImageAxes3D);
            app.ptsApex(end+1) = app.currentSlice3D;
            app.ptsApex(end+1) = round(ptsValue.Position(2));
            app.ptsApex(end+1) = round(ptsValue.Position(1));
            % Triplet of (Slice #, X, Y, PixelValue)
            app.supervisedLearningLabeledPoints{end+1} = 'apex';
            logDAI('Apex point adding completed.');
            logDAI('Putting point on JPEG volume');
            img = app.threeDVolume(:,:,app.currentSlice3D);
            imgJPEG(:,:,:) = app.volumeJPEG(:,:,app.currentSlice3D,:);
            app.ptsApex(end+1) = imgJPEG(round(ptsValue.Position(2)), round(ptsValue.Position(1)));
            imgJPEG = getColoredPointForMarkedCoordinate(imgJPEG, round(ptsValue.Position(2)), round(ptsValue.Position(1)), [255.0, 0.0, 255.0]);
            app.volumeJPEG(:,:,app.currentSlice3D,:) = imgJPEG(:,:,:);
            updateimage3D(app, img);
            updateimage3DJPEG(app,imgJPEG);
        end

        % Menu selected function: StartTrainingMenu
        function StartTrainingMenuSelected(app, event)

            % Get Data Ready:
            trainingDS = getData(app.trainDirectory);
            validationDS = getData(app.validationDirectory);

            % Training Option:
            trainingOption = app.selectedTrainingOption;

            app.trainedNetwork = getTraining(trainingDS, validationDS, trainingOption);
            networkName = sprintf('%s/%s', 'TrainedNetwork', datestr(now,'yyyymmddTHHMMSSFFF'));
            save(networkName, app.trainedNetwork);
        end

        % Menu selected function: CNNMenu
        function CNNMenuSelected(app, event)
            app.selectedTrainingOption = convertStringsToChars(trainingOptions(1));

            app.CNNMenu.Checked = true;
            app.UNETMenu.Checked = false;
        end

        % Menu selected function: UNETMenu
        function UNETMenuSelected(app, event)
            app.selectedTrainingOption = convertStringsToChars(trainingOptions(2));

            app.CNNMenu.Checked = false;
            app.UNETMenu.Checked = true;
        end

        % Menu selected function: TrainingDataMenu
        function TrainingDataMenuSelected(app, event)
            app.trainDirectory = uigetdir;
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);
            set(gcf, 'Pointer','arrow');
        end

        % Menu selected function: ValidationDataMenu
        function ValidationDataMenuSelected(app, event)
            app.validationDirectory = uigetdir;
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);
            set(gcf, 'Pointer','arrow');
        end

        % Menu selected function: ClassifyMenu
        function ClassifyMenuSelected(app, event)
            testingDS = getData(app.testDirectory, 0, 0, labelName);
            predictedData = classify(app.trainedNetwork, testingDS);
        end

        % Menu selected function: TestDataMenu
        function TestDataMenuSelected(app, event)
            app.testDirectory = uigetdir;
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);
            set(gcf, 'Pointer','arrow');
        end

        % Menu selected function: SavedNetworkMenu
        function SavedNetworkMenuSelected(app, event)
            [f, p] = uigetfile();
            app.savedNetWork = coder.loadDeepLearningNetwork(p);
            app.trainedNetwork = app.savedNetwork;
        end

        % Menu selected function: PreLesionCurveMenu
        function PreLesionCurveMenuSelected(app, event)
            filterspec = {'*.txt','Text Files';'*.xlsx','Excel Files'};
            [f, p] = uigetfile(filterspec);
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);
            set(gcf, 'Pointer','arrow');

            logDAI('Checking chosen file type:');
            [~, name, ext] = fileparts(f);

            if (strcmp(ext,'.xlsx'))
                logDAI('File type is excel, reading excel file.');
                fName = strcat(p, f);
                data = readtable(fName);
                time = data.TimePoints;
                roi = data.PreLesionCurve;
            end

            if (strcmp(ext,'.txt'))
                logDAI('File type is txt, reading txt file.');
                % Read text files:
                fileName = strcat(p,f);
                [time, roi] = readDataFile(fileName);
                if time(2) >= 500
                    time = time / 1000;
                end
            end


            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                logDAI('Plotting the curve');
                app.paCurveStressOnly = roi;
                app.timePointsStressOnly = time;
                app.paTimePointsStressOnly = app.timePointsStressOnly;
                app.paPlotAreaStressOnly.YLim = [app.yAxisLimitMin app.yAxisLimitMax];
                app.paPlotAreaStressOnly.YTick = getSliderTicks(app.yAxisLimitMin, app.yAxisLimitMax, app.maxYAxisInterval);
                app.paPlotAreaStressOnly.XLim = [1 max(time)];
                app.paPlotAreaStressOnly.XTick = [1:max(time)/10:max(time)];
                app.paPlotAreaStressOnly.XTick = cast(app.paPlotAreaStressOnly.XTick, 'int16');
                % plot(app.paPlotAreaStressOnly, app.plotXPreStressOnly, app.paCurveStressOnly, 'mo');
                drawPreLesionCurveStressOnly(app);
                app.preTextReadStressOnly = true;
                logDAI('Plotting the curve is successful');
            end

            if (app.preTextReadStressOnly == true && app.postTextReadStressOnly == true)
                prompt = {'Relative Height(cm):','Pre-Post Distance(cm):'};
                dlgtitle = 'Enter';
                dims = [1 35];
                definput = {'0','0'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                app.hBTextStressOnly = str2double(answer{1});
                app.LTextStressOnly = str2double(answer{2});
                figure(app.UIFigure);
            end
        end

        % Menu selected function: PostLesionCurveMenu
        function PostLesionCurveMenuSelected(app, event)
            filterspec = {'*.txt','Text Files';'*.xlsx','Excel Files'};
            [f, p] = uigetfile(filterspec);
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);
            set(gcf, 'Pointer','arrow');

            logDAI('Checking chosen file type:');
            [~, name, ext] = fileparts(f);

            if (strcmp(ext,'.xlsx'))
                logDAI('File type is excel, reading excel file.');
                fName = strcat(p, f);
                data = readtable(fName);
                time = data.TimePoints;
                roi = data.PostLesionCurve;
            end

            if (strcmp(ext,'.txt'))
                logDAI('File type is txt, reading txt file.');
                % Read text files:
                fileName = strcat(p,f);
                [time, roi] = readDataFile(fileName);
                if time(2) >= 500
                    time = time / 1000;
                end
            end

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                logDAI('Plotting the curve');
                app.pdCurveStressOnly = roi;
                app.timePointsStressOnly = time;
                app.pdTimePointsStressOnly = app.timePointsStressOnly;
                app.pdPlotAreaStressOnly.YLim = [app.yAxisLimitMin app.yAxisLimitMax];
                app.pdPlotAreaStressOnly.YTick = getSliderTicks(app.yAxisLimitMin, app.yAxisLimitMax, app.maxYAxisInterval);
                app.pdPlotAreaStressOnly.XLim = [1 max(time)];
                app.pdPlotAreaStressOnly.XTick = [1:max(time)/10:max(time)];
                app.pdPlotAreaStressOnly.XTick = cast(app.pdPlotAreaStressOnly.XTick, 'int16');
                % plot(app.pdPlotAreaStressOnly, app.plotXPostStressOnly, app.pdCurveStressOnly, 'mo');
                drawPostLesionCurveStressOnly(app);
                app.postTextReadStressOnly = true;
                logDAI('Plotting the curve is successful');
            end

            if (app.preTextReadStressOnly == true && app.postTextReadStressOnly == true)
                prompt = {'Relative Height(cm):','Pre-Post Distance(cm):'};
                dlgtitle = 'Enter';
                dims = [1 35];
                definput = {'0','0'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                app.hBTextStressOnly = str2double(answer{1});
                app.LTextStressOnly = str2double(answer{2});
                figure(app.UIFigure);
            end
        end

        % Menu selected function: PostLesionCurveMenu_2
        function PostLesionCurveMenu_2Selected(app, event)
            filterspec = {'*.txt','Text Files';'*.xlsx','Excel Files'};
            [f, p] = uigetfile(filterspec);
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);
            set(gcf, 'Pointer','arrow');

            logDAI('Checking chosen file type:');
            [~, name, ext] = fileparts(f);

            if (strcmp(ext,'.xlsx'))
                logDAI('File type is excel, reading excel file.');
                fName = strcat(p, f);
                data = readtable(fName);
                time = data{1:end, 1};
                roi = data{1:end, 3};
            end

            if (strcmp(ext,'.txt'))
                logDAI('File type is txt, reading txt file.');
                % Read text files:
                fileName = strcat(p,f);
                [time, roi] = readDataFile(fileName);
                if time(2) >= 500
                    time = time / 1000;
                end
            end

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                logDAI('Plotting the curve');
                app.pdCurve = roi;
                app.plotX = time;
                app.pdPlotArea.YLim = [app.yAxisLimitMin app.yAxisLimitMax];
                interval = getPlotInterval(app.yAxisLimitMin, app.yAxisLimitMax);
                app.pdPlotArea.YTick = [app.yAxisLimitMin:interval:app.yAxisLimitMax];
                app.pdPlotArea.XLim = [1 max(time)];
                app.pdPlotArea.XTick = [1:max(time)/10:max(time)];
                app.pdPlotArea.XTick = cast(app.pdPlotArea.XTick, 'int16');
                plot(app.pdPlotArea, app.plotX, app.pdCurve, 'mo');
                app.postTextRead = true;
                logDAI('Plotting the curve is successful');
            end

            if (app.preTextRead == true && app.postTextRead == true)
                prompt = {'Relative Height(cm):','Pre-Post Distance(cm):'};
                dlgtitle = 'Enter';
                dims = [1 35];
                definput = {'0','0'};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                app.hBText = str2double(answer{1});
                app.LText = str2double(answer{2});
                figure(app.UIFigure);
            end
        end

        % Menu selected function: TimesMenu
        function TimesMenuSelected(app, event)
            app.fontName = 'Times';

            changeFontsTo(app, app.fontName);

            app.TimesMenu.Checked = true;
            app.ArialMenu.Checked = false;
            app.PalatinoLinotypeMenu.Checked = false;
        end

        % Callback function
        function HelveticaMenuSelected(app, event)

        end

        % Menu selected function: ArialMenu
        function ArialMenuSelected(app, event)
            app.fontName = 'Arial';

            changeFontsTo(app, app.fontName);

            app.ArialMenu.Checked = true;
            app.TimesMenu.Checked = false;
            app.PalatinoLinotypeMenu.Checked = false;
        end

        % Callback function
        function GEMenuSelected(app, event)
            app.scannerTypeStressOnly = convertStringsToChars(app.scannerName(3));

            app.SiemensMenu.Checked = false;
            app.CanonMenu.Checked = false;
            app.GEMenu.Checked = true;
        end

        % Callback function
        function GEMenu_2Selected(app, event)
            app.scannerType = convertStringsToChars(app.scannerName(3));

            app.SiemensMenu_2.Checked = false;
            app.CanonMenu_2.Checked = false;
            app.GEMenu_2.Checked = true;
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            close all force;
        end

        % Menu selected function: AutoMenu
        function AutoMenuSelected(app, event)
            logDAI('Auto Scaling for Pre Lesion Curve Selected');
            app.paPlotAreaStressOnly.YLim = [app.yAxisLimitMin app.yAxisLimitMax];
            app.paPlotAreaStressOnly.YTick = getSliderTicks(app.yAxisLimitMin, app.yAxisLimitMax);

            app.ManualMenu.Checked = false;
            app.AutoMenu.Checked = true;
            logDAI('Auto Scaling for Pre Lesion Curve Applied');
        end

        % Menu selected function: ManualMenu
        function ManualMenuSelected(app, event)
            logDAI('Manual Scaling for Pre Lesion Curve Selected');
            prompt = {'Min Limit (HU)','Max Limit (HU)','Interval (HU)'};
            dlgtitle = 'Enter';
            dims = [1 35];
            definput = {'-50','500','10'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            localYAxisLimitMin = str2double(answer{1});
            localYAxisLimitMax = str2double(answer{2});
            fixedInterval = str2double(answer{3});

            app.paPlotAreaStressOnly.YLim = [localYAxisLimitMin localYAxisLimitMax];
            app.paPlotAreaStressOnly.YTick = getSliderTicks(localYAxisLimitMin, localYAxisLimitMax, fixedInterval);

            figure(app.UIFigure);

            app.AutoMenu.Checked = false;
            app.ManualMenu.Checked = true;
            logDAI('Manual Scaling for Pre Lesion Curve Applied');
        end

        % Menu selected function: AutoMenu_2
        function AutoMenu_2Selected(app, event)
            logDAI('Auto Scaling for Post Lesion Curve Selected');
            app.pdPlotAreaStressOnly.YLim = [app.yAxisLimitMin app.yAxisLimitMax];
            app.pdPlotAreaStressOnly.YTick = getSliderTicks(app.yAxisLimitMin, app.yAxisLimitMax);

            app.ManualMenu_2.Checked = false;
            app.AutoMenu_2.Checked = true;
            logDAI('Auto Scaling for Post Lesion Curve Applied');
        end

        % Menu selected function: ManualMenu_2
        function ManualMenu_2Selected(app, event)
            logDAI('Manual Scaling for Post Lesion Curve Selected');
            prompt = {'Min Limit (HU)','Max Limit (HU)','Interval (HU)'};
            dlgtitle = 'Enter';
            dims = [1 35];
            definput = {'-50','500','10'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            localYAxisLimitMin = str2double(answer{1});
            localYAxisLimitMax = str2double(answer{2});
            fixedInterval = str2double(answer{3});

            app.pdPlotAreaStressOnly.YLim = [localYAxisLimitMin localYAxisLimitMax];
            app.pdPlotAreaStressOnly.YTick = getSliderTicks(localYAxisLimitMin, localYAxisLimitMax, fixedInterval);

            figure(app.UIFigure);

            app.AutoMenu_2.Checked = false;
            app.ManualMenu_2.Checked = true;
            logDAI('Manual Scaling for Post Lesion Curve Applied');
        end

        % Menu selected function: AutoMenu_3
        function AutoMenu_3Selected(app, event)
            logDAI('Auto Scaling for Post Lesion Curve Selected');
            app.paPlotArea.YLim = [app.yAxisLimitMin app.yAxisLimitMax];
            interval = getPlotInterval(app.yAxisLimitMin, app.yAxisLimitMax);
            app.paPlotArea.YTick = [app.yAxisLimitMin:interval:app.yAxisLimitMax];

            app.ManualMenu_3.Checked = false;
            app.AutoMenu_3.Checked = true;
            logDAI('Auto Scaling for Post Lesion Curve Applied');
        end

        % Menu selected function: ManualMenu_3
        function ManualMenu_3Selected(app, event)
            logDAI('Manual Scaling for Post Lesion Curve Selected');
            prompt = {'Min Limit:','Max Limit:'};
            dlgtitle = 'Enter';
            dims = [1 35];
            definput = {'-50','500'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            localYAxisLimitMin = str2double(answer{1});
            localYAxisLimitMax = str2double(answer{2});

            app.paPlotArea.YLim = [localYAxisLimitMin localYAxisLimitMax];
            interval = getPlotInterval(localYAxisLimitMin, localYAxisLimitMax);
            app.paPlotArea.YTick = [localYAxisLimitMin:interval:localYAxisLimitMax];

            figure(app.UIFigure);

            app.AutoMenu_3.Checked = false;
            app.ManualMenu_3.Checked = true;
            logDAI('Manual Scaling for Post Lesion Curve Applied');
        end

        % Menu selected function: AutoMenu_4
        function AutoMenu_4Selected(app, event)
            logDAI('Auto Scaling for Post Lesion Curve Selected');
            app.pdPlotArea.YLim = [app.yAxisLimitMin app.yAxisLimitMax];
            interval = getPlotInterval(app.yAxisLimitMin, app.yAxisLimitMax);
            app.pdPlotArea.YTick = [app.yAxisLimitMin:interval:app.yAxisLimitMax];

            app.ManualMenu_4.Checked = false;
            app.AutoMenu_4.Checked = true;
            logDAI('Auto Scaling for Post Lesion Curve Applied');
        end

        % Menu selected function: ManualMenu_4
        function ManualMenu_4Selected(app, event)
            logDAI('Manual Scaling for Post Lesion Curve Selected');
            prompt = {'Min Limit:','Max Limit:'};
            dlgtitle = 'Enter';
            dims = [1 35];
            definput = {'-50','500'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            localYAxisLimitMin = str2double(answer{1});
            localYAxisLimitMax = str2double(answer{2});

            app.pdPlotArea.YLim = [localYAxisLimitMin localYAxisLimitMax];
            interval = getPlotInterval(localYAxisLimitMin, localYAxisLimitMax);
            app.pdPlotArea.YTick = [localYAxisLimitMin:interval:localYAxisLimitMax];

            figure(app.UIFigure);

            app.AutoMenu_4.Checked = false;
            app.ManualMenu_4.Checked = true;
            logDAI('Manual Scaling for Post Lesion Curve Applied');
        end

        % Menu selected function: SiemensMenu_3
        function SiemensMenu_3Selected(app, event)
            app.scannerTypeStressOnly = convertStringsToChars(app.scannerName(1));

            app.SiemensMenu_3.Checked = true;
            app.CanonMenu_3.Checked = false;
            app.GEMenu_3.Checked = false;

            StressImagesMenuLoader(app);
        end

        % Menu selected function: CanonMenu_3
        function CanonMenu_3Selected(app, event)
            app.scannerTypeStressOnly = convertStringsToChars(app.scannerName(2));

            app.SiemensMenu_3.Checked = false;
            app.CanonMenu_3.Checked = true;
            app.GEMenu_3.Checked = false;

            options = {'Enhanced', 'Standard'};
            dlgPrompt = 'Select image format:';
            dlgTitle = 'Canon image format selection';
            [idx,~] = listdlg('ListString', options, 'PromptString', dlgPrompt, 'SelectionMode', 'single', 'ListSize',[310,40], 'Name', dlgTitle);

            % Check the selected index
            if ~isempty(idx)
                choice = options{idx};
            else
                % Default is enhanced
                choice = options{1};
            end

            StressImagesMenuLoader(app, choice);
        end

        % Menu selected function: GEMenu_3
        function GEMenu_3Selected(app, event)
            app.scannerTypeStressOnly = convertStringsToChars(app.scannerName(3));

            app.SiemensMenu_3.Checked = false;
            app.CanonMenu_3.Checked = false;
            app.GEMenu_3.Checked = true;

            StressImagesMenuLoader(app);
        end

        % Menu selected function: SiemensMenu_4
        function SiemensMenu_4Selected(app, event)
            app.scannerType = convertStringsToChars(app.scannerName(1));

            app.SiemensMenu_4.Checked = true;
            app.CanonMenu_4.Checked = false;
            app.GEMenu_4.Checked = false;

            StreesImageLoaderInStressRestTab(app);
        end

        % Menu selected function: CanonMenu_4
        function CanonMenu_4Selected(app, event)
            app.scannerType = convertStringsToChars(app.scannerName(2));

            app.SiemensMenu_4.Checked = false;
            app.CanonMenu_4.Checked = true;
            app.GEMenu_4.Checked = false;

            StreesImageLoaderInStressRestTab(app);
        end

        % Menu selected function: GEMenu_4
        function GEMenu_4Selected(app, event)
            app.scannerType = convertStringsToChars(app.scannerName(3));

            app.SiemensMenu_4.Checked = false;
            app.CanonMenu_4.Checked = false;
            app.GEMenu_4.Checked = true;

            StreesImageLoaderInStressRestTab(app);
        end

        % Menu selected function: SiemensMenu_5
        function SiemensMenu_5Selected(app, event)
            app.scannerTypeRest = convertStringsToChars(app.scannerName(1));

            app.SiemensMenu_5.Checked = true;
            app.CanonMenu_5.Checked = false;
            app.GEMenu_5.Checked = false;

            RestImageLoaderInStressRestTab(app);
        end

        % Menu selected function: CanonMenu_5
        function CanonMenu_5Selected(app, event)
            app.scannerTypeRest = convertStringsToChars(app.scannerName(2));

            app.SiemensMenu_5.Checked = false;
            app.CanonMenu_5.Checked = true;
            app.GEMenu_5.Checked = false;

            RestImageLoaderInStressRestTab(app);
        end

        % Menu selected function: GEMenu_5
        function GEMenu_5Selected(app, event)
            app.scannerTypeRest = convertStringsToChars(app.scannerName(3));

            app.SiemensMenu_5.Checked = false;
            app.CanonMenu_5.Checked = false;
            app.GEMenu_5.Checked = true;

            RestImageLoaderInStressRestTab(app);
        end

        % Menu selected function: PalatinoLinotypeMenu
        function PalatinoLinotypeMenuSelected(app, event)
            app.fontName = 'Palatino Linotype';

            changeFontsTo(app, app.fontName);

            app.PalatinoLinotypeMenu.Checked = true;
            app.ArialMenu.Checked = false;
            app.TimesMenu.Checked = false;
        end

        % Menu selected function: RemoveLastPointMenu
        function RemoveLastPointMenuSelected(app, event)
            pointType = app.supervisedLearningLabeledPoints{end};
            switch pointType
                case 'apex'
                    imgJPEG(:,:,:) = app.volumeJPEG(:,:,app.ptsApex(end-3),:);
                    originalPixelValue = app.ptsApex(end);
                    imgJPEG = getColoredPointForMarkedCoordinate(imgJPEG, app.ptsApex(end-2), app.ptsApex(end-1), [originalPixelValue, originalPixelValue, originalPixelValue]);
                    %imgJPEG(app.ptsApex(end-2), app.ptsApex(end-1)) = originalPixelValue;
                    app.volumeJPEG(:,:,app.ptsApex(end-3),:) = imgJPEG(:,:,:);
                    updateimage3DJPEG(app,imgJPEG);
                    app.ptsApex(end-3:end) = [];
                case 'lcx'
                    imgJPEG(:,:,:) = app.volumeJPEG(:,:,app.ptsLCx(end-3),:);
                    originalPixelValue = app.ptsLCx(end);
                    imgJPEG = getColoredPointForMarkedCoordinate(imgJPEG, app.ptsLCx(end-2), app.ptsLCx(end-1), [originalPixelValue, originalPixelValue, originalPixelValue]);
                    %imgJPEG(app.ptsLCx(end-2), app.ptsLCx(end-1)) = originalPixelValue;
                    app.volumeJPEG(:,:,app.ptsLCx(end-3),:) = imgJPEG(:,:,:);
                    updateimage3DJPEG(app,imgJPEG);
                    app.ptsLCx(end-3:end) = [];
                case 'lad'
                    imgJPEG(:,:,:) = app.volumeJPEG(:,:,app.ptsLAD(end-3),:);
                    originalPixelValue = app.ptsLAD(end);
                    imgJPEG = getColoredPointForMarkedCoordinate(imgJPEG, app.ptsLAD(end-2), app.ptsLAD(end-1), [originalPixelValue, originalPixelValue, originalPixelValue]);
                    %imgJPEG(app.ptsLAD(end-2), app.ptsLAD(end-1)) = originalPixelValue;
                    app.volumeJPEG(:,:,app.ptsLAD(end-3),:) = imgJPEG(:,:,:);
                    updateimage3DJPEG(app,imgJPEG);
                    app.ptsLAD(end-3:end) = [];
                case 'rca'
                    imgJPEG(:,:,:) = app.volumeJPEG(:,:,app.ptsRCA(end-3),:);
                    originalPixelValue = app.ptsRCA(end);
                    imgJPEG = getColoredPointForMarkedCoordinate(imgJPEG, app.ptsRCA(end-2), app.ptsRCA(end-1), [originalPixelValue, originalPixelValue, originalPixelValue]);
                    %imgJPEG(app.ptsRCA(end-2), app.ptsRCA(end-1)) = originalPixelValue;
                    app.volumeJPEG(:,:,app.ptsRCA(end-3),:) = imgJPEG(:,:,:);
                    updateimage3DJPEG(app,imgJPEG);
                    app.ptsRCA(end-3:end) = [];
            end
            app.supervisedLearningLabeledPoints(end) = [];
        end

        % Callback function
        function FitCurvesButtonStressOnly_2Pushed(app, event)
            choice = questdlg('Which curve to fit?', 'Curve Fitting', 'Post-lesion curve','Lesion exit curve','');
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);
            switch choice
                case 'Post-lesion curve'
                    if isrow(app.pdCurveStressOnly)
                        app.pdCurveStressOnly = app.pdCurveStressOnly';
                    end

                    if isnan(str2double(app.ManualBaselineInputStressOnly_2.Value))
                        manualBaselineValuePostLesion = 0;
                    else
                        manualBaselineValuePostLesion = str2double(app.ManualBaselineInputStressOnly_2.Value);
                    end
                    if isnan(str2double(app.ManualWashoutInputStressOnly_2.Value))
                        manualWashoutValuePostLesion = 0;
                    else
                        manualWashoutValuePostLesion = str2double(app.ManualWashoutInputStressOnly_2.Value);
                    end

                    try
                        [app.fittedCurvePdStressOnly, app.fittedTimePdStressOnly, app.pdCurveStressOnly, app.rmseBStressOnly, app.kBStressOnly, app.alphaBStressOnly, app.betaBStressOnly] = getFittedCurve(app.pdCurveStressOnly, app.plotXPostStressOnly, manualBaselineValuePostLesion, manualWashoutValuePostLesion);
                        app.aucBStressOnly = getAreaUnderCurve(app.fittedCurvePdStressOnly, app.fittedTimePdStressOnly);

                        drawPostLesionCurveStressOnly(app);

                        app.pdStressOnly = [app.roiObjectStressOnly.postRoiObject.curveData.z, app.roiObjectStressOnly.postRoiObject.curveData.t, app.roiObjectStressOnly.postRoiObject.curveData.x, app.roiObjectStressOnly.postRoiObject.curveData.y];
                        app.postRoiObjectStressOnly = getROI(app.dicomInfoStressOnly.PatientName.FamilyName, size(app.fourDImageSetStressOnly,2), size(app.fourDImageSetStressOnly,1), app.pdStressOnly(3), app.pdStressOnly(4), app.pdStressOnly(1), app.pdStressOnly(2), app.pdCurveStressOnly, app.pdTimePointsStressOnly, app.fittedCurvePdStressOnly, app.fittedTimePdStressOnly, app.sxPostStressOnly, app.syPostStressOnly, app.aucBStressOnly, app.allExtraPointsForDistanceInStressOnly, app.numberOfStenosis, 0, 0, 0, 0, 0);
                        app.roiObjectStressOnly.postRoiObject = app.postRoiObjectStressOnly;
                        saveRoiAsJson(app.roiObjectStressOnly);

                    catch ME
                        msg = strcat('Problem in Curve Fitting:', ME.message);
                        errordlg(msg);
                        pause(5);
                        set(gcf, 'Pointer','arrow');
                    end
                case 'Lesion exit curve'
                    if isrow(app.exitCurveStressOnly)
                        app.exitCurveStressOnly = app.exitCurveStressOnly';
                    end

                    if isnan(str2double(app.ManualBaselineInputStressOnly_2.Value))
                        manualBaselineValuePostLesion = 0;
                    else
                        manualBaselineValuePostLesion = str2double(app.ManualBaselineInputStressOnly_2.Value);
                    end
                    if isnan(str2double(app.ManualWashoutInputStressOnly_2.Value))
                        manualWashoutValuePostLesion = 0;
                    else
                        manualWashoutValuePostLesion = str2double(app.ManualWashoutInputStressOnly_2.Value);
                    end

                    try
                        [app.fittedCurveExitStressOnly, app.fittedTimeExitStressOnly, app.exitCurveStressOnly, app.rmseBStressOnly, app.kBStressOnly, app.alphaBStressOnly, app.betaBStressOnly] = getFittedCurve(app.exitCurveStressOnly, app.plotXExitStressOnly, manualBaselineValuePostLesion, manualWashoutValuePostLesion);
                        app.aucBExitStressOnly(app.currentStenosisID) = getAreaUnderCurve(app.fittedCurveExitStressOnly, app.fittedTimeExitStressOnly);
                        %                         if (isempty(app.ConversionFactorEditFieldStressOnly.Value) == false)
                        %                             app.aucBExitStressOnly(end) = app.aucBExitStressOnly(end) / str2double(app.ConversionFactorEditFieldStressOnly.Value);
                        %                         end

                        drawExitCurveStressOnly(app);

                        if (app.currentStenosisNumber == -1)
                            app.pExitStressOnly = [app.roiObjectStressOnly.exitRoiObject.curveData.z, app.roiObjectStressOnly.exitRoiObject.curveData.t, app.roiObjectStressOnly.exitRoiObject.curveData.x, app.roiObjectStressOnly.exitRoiObject.curveData.y];
                            app.exitRoiObjectStressOnly = getROI(app.dicomInfoStressOnly.PatientName.FamilyName, size(app.fourDImageSetStressOnly,2), size(app.fourDImageSetStressOnly,1), app.pExitStressOnly(3), app.pExitStressOnly(4), app.pExitStressOnly(1), app.pExitStressOnly(2), app.exitCurveStressOnly, app.exitTimePointStressOnly, app.fittedCurveExitStressOnly, app.fittedTimeExitStressOnly, app.exitSxStressOnly, app.exitSyStressOnly, app.aucBExitStressOnly(end), 0, app.numberOfStenosis, app.rs1(end), app.rs2(end), app.rs3(end), app.entryQ(end), app.exitQ(end));
                            app.roiObjectStressOnly.exitRoiObject = app.exitRoiObjectStressOnly;
                        else
                            app.pExitStressOnly = [app.roiObjectStressOnly.exitRoiObject(app.currentStenosisNumber).curveData.z, app.roiObjectStressOnly.exitRoiObject(app.currentStenosisNumber).curveData.t, app.roiObjectStressOnly.exitRoiObject(app.currentStenosisNumber).curveData.x, app.roiObjectStressOnly.exitRoiObject(app.currentStenosisNumber).curveData.y];
                            app.exitRoiObjectStressOnly = getROI(app.dicomInfoStressOnly.PatientName.FamilyName, size(app.fourDImageSetStressOnly,2), size(app.fourDImageSetStressOnly,1), app.pExitStressOnly(3), app.pExitStressOnly(4), app.pExitStressOnly(1), app.pExitStressOnly(2), app.exitCurveStressOnly, app.exitTimePointStressOnly, app.fittedCurveExitStressOnly, app.fittedTimeExitStressOnly, app.exitSxStressOnly, app.exitSyStressOnly, app.aucBExitStressOnly(end), 0, app.numberOfStenosis, app.rs1(end), app.rs2(end), app.rs3(end), app.entryQ(end), app.exitQ(end));
                            app.roiObjectStressOnly.exitRoiObject(app.currentStenosisNumber) = app.exitRoiObjectStressOnly;
                            app.currentStenosisNumber = -1;
                        end
                        saveRoiAsJson(app.roiObjectStressOnly);

                    catch ME
                        msg = strcat('Problem in Curve Fitting:', ME.message);
                        errordlg(msg);
                        pause(5);
                        set(gcf, 'Pointer','arrow');
                    end
            end
            set(gcf, 'Pointer','arrow');
        end

        % Callback function
        function FitStressCurveButton_2Pushed(app, event)
            % This function now handles only post-lesion cases:

            if isrow(app.paCurve)
                app.paCurve = app.paCurve';
            end
            app.manualBaselineValue = str2double(app.ManualBaselineInput_2.Value);
            app.manualWashoutValue = str2double(app.ManualWashoutInput_2.Value);
            if isnan(app.manualBaselineValue)
                app.manualBaselineValue = 0;
            else
                app.manualBaselineValue = str2double(app.ManualBaselineInput_2.Value);
            end
            if isnan(app.manualWashoutValue)
                app.manualWashoutValue = 0;
            else
                app.manualWashoutValue = str2double(app.ManualWashoutInput_2.Value);
            end
            manualBaselineValuePostLesion = app.manualBaselineValue;
            manualWashoutValuePostLesion = app.manualWashoutValue;

            if isrow(app.pdCurve)
                app.pdCurve = app.pdCurve';
            end

            try
                [app.fittedCurvePd, app.fittedTimePd, app.pdCurve, app.rmseB, app.kB, app.alphaB, app.betaB] = getFittedCurve(app.pdCurve, app.plotX, manualBaselineValuePostLesion, manualWashoutValuePostLesion);
                app.aucB = getAreaUnderCurve(app.fittedCurvePd, app.fittedTimePd);

                % Strees Pre and Strees Post
                if (strcmp(app.viewMode,'Dark'))
                    if (~all(app.fittedCurvePa) == 0)
                        plot(app.paPlotArea, app.plotX, app.paCurve, 'yo', app.fittedTimePa, app.fittedCurvePa, 'y-');
                        legend(app.paPlotArea,'Pre-Lesion Sampled','Pre-Lesion Fitted', 'TextColor','white');
                    end
                    plot(app.pdPlotArea, app.plotX, app.pdCurve, 'yo', app.fittedTimePd, app.fittedCurvePd, 'y-');
                    legend(app.pdPlotArea,'Post-Lesion Sampled','Post-Lesion Fitted', 'TextColor','white');

                    if(~isempty(app.paCurveRest))
                        if(~isempty(app.fittedCurvePaRest))
                            plot(app.paPlotArea, app.plotX, app.paCurve, 'yo', app.fittedTimePa, app.fittedCurvePa, 'y-', app.plotXRest, app.paCurveRest, 'ws', app.fittedTimePaRest, app.fittedCurvePaRest, 'w-');
                            legend(app.paPlotArea,'Pre-Lesion Sampled','Pre-Lesion Fitted','Pre-Rest','Pre-Rest-Fitted', 'TextColor','white');
                        else
                            plot(app.paPlotArea, app.plotX, app.paCurve, 'yo', app.fittedTimePa, app.fittedCurvePa, 'y-', app.plotXRest, app.paCurveRest, 'ws');
                            legend(app.paPlotArea,'Pre-Lesion Sampled','Pre-Lesion Fitted','Pre-Rest', 'TextColor','white');
                        end
                    end

                    if(~isempty(app.pdCurveRest))
                        if(~isempty(app.fittedCurvePdRest))
                            plot(app.pdPlotArea, app.plotX, app.pdCurve, 'yo', app.fittedTimePd, app.fittedCurvePd, 'y-', app.plotXRest, app.pdCurveRest, 'ws', app.fittedTimePdRest, app.fittedCurvePdRest, 'w-');
                            legend(app.pdPlotArea,'Post-Lesion Sampled','Post-Lesion Fitted','Post-Rest','Post-Rest-Fitted', 'TextColor','white');
                        else
                            plot(app.pdPlotArea, app.plotX, app.pdCurve, 'yo', app.fittedTimePd, app.fittedCurvePd, 'y-', app.plotXRest, app.pdCurveRest, 'ws');
                            legend(app.pdPlotArea,'Post-Lesion Sampled','Post-Lesion Fitted','Post-Rest', 'TextColor','white');
                        end
                    end
                else
                    if (~all(app.fittedCurvePa) == 0)
                        plot(app.paPlotArea, app.plotX, app.paCurve, 'mo', app.fittedTimePa, app.fittedCurvePa, 'm-');
                        legend(app.paPlotArea,'Pre-Lesion Sampled','Pre-Lesion Fitted', 'TextColor','black');
                    end
                    plot(app.pdPlotArea, app.plotX, app.pdCurve, 'mo', app.fittedTimePd, app.fittedCurvePd, 'm-');
                    legend(app.pdPlotArea,'Post-Lesion Sampled','Post-Lesion Fitted', 'TextColor','black');

                    if(~isempty(app.paCurveRest))
                        if(~isempty(app.fittedCurvePaRest))
                            plot(app.paPlotArea, app.plotX, app.paCurve, 'mo', app.fittedTimePa, app.fittedCurvePa, 'm-', app.plotXRest, app.paCurveRest, 'bs', app.fittedTimePaRest, app.fittedCurvePaRest, 'b-');
                            legend(app.paPlotArea,'Pre-Lesion Sampled','Pre-Lesion Fitted','Pre-Rest','Pre-Rest-Fitted', 'TextColor','black');
                        else
                            plot(app.paPlotArea, app.plotX, app.paCurve, 'mo', app.fittedTimePa, app.fittedCurvePa, 'm-', app.plotXRest, app.paCurveRest, 'bs');
                            legend(app.paPlotArea,'Pre-Lesion Sampled','Pre-Lesion Fitted','Pre-Rest', 'TextColor','black');
                        end
                    end

                    if(~isempty(app.pdCurveRest))
                        if(~isempty(app.fittedCurvePdRest))
                            plot(app.pdPlotArea, app.plotX, app.pdCurve, 'mo', app.fittedTimePd, app.fittedCurvePd, 'm-', app.plotXRest, app.pdCurveRest, 'bs', app.fittedTimePdRest, app.fittedCurvePdRest, 'b-');
                            legend(app.pdPlotArea,'Post-Lesion Sampled','Post-Lesion Fitted','Post-Rest','Post-Rest-Fitted', 'TextColor','black');
                        else
                            plot(app.pdPlotArea, app.plotX, app.pdCurve, 'mo', app.fittedTimePd, app.fittedCurvePd, 'm-', app.plotXRest, app.pdCurveRest, 'bs');
                            legend(app.pdPlotArea,'Post-Lesion Sampled','Post-Lesion Fitted','Post-Rest', 'TextColor','black');
                        end
                    end
                end
            catch ME
                msg = strcat('Problem in Curve Fitting:', ME.message);
                errordlg(msg);
            end
        end

        % Callback function
        function FitCurvesButtonRest_2Pushed(app, event)
            % This function now handles only post-lesion cases:

            if isrow(app.paCurveRest)
                app.paCurveRest = app.paCurveRest';
            end
            app.manualBaselineValueRest = str2double(app.ManualBaselineInputRest_2.Value);
            app.manualWashoutValueRest = str2double(app.ManualWashoutInputRest_2.Value);
            if isnan(app.manualBaselineValueRest)
                app.manualBaselineValueRest = 0;
            else
                app.manualBaselineValueRest = str2double(app.ManualBaselineInputRest_2.Value);
            end
            if isnan(app.manualWashoutValueRest)
                app.manualWashoutValueRest = 0;
            else
                app.manualWashoutValueRest = str2double(app.ManualWashoutInputRest_2.Value);
            end
            manualBaselineValuePostLesionRest = app.manualBaselineValueRest;
            manualWashoutValuePostLesionRest = app.manualBaselineValueRest;

            if isrow(app.pdCurveRest)
                app.pdCurveRest = app.pdCurveRest';
            end

            try
                [app.fittedCurvePdRest, app.fittedTimePdRest, app.pdCurveRest, app.rmseBRest, app.kBRest, app.alphaBRest, app.betaBRest] = getFittedCurve(app.pdCurveRest, app.plotXRest, manualBaselineValuePostLesionRest, manualWashoutValuePostLesionRest);
                app.aucBRest = getAreaUnderCurve(app.fittedCurvePdRest, app.fittedTimePdRest);

                % Rest Pre and Rest Post
                if (strcmp(app.viewMode,'Dark'))
                    if (~all(app.fittedCurvePaRest) == 0)
                        plot(app.paPlotArea, app.plotXRest, app.paCurveRest, 'ws', app.fittedTimePaRest, app.fittedCurvePaRest, 'w-');
                        legend(app.paPlotArea,'Pre-Rest','Pre-Rest-Fitted', 'TextColor','white');
                    end
                    plot(app.pdPlotArea, app.plotXRest, app.pdCurveRest, 'ws', app.fittedTimePdRest, app.fittedCurvePdRest, 'w-');
                    legend(app.pdPlotArea,'Post-Rest','Post-Rest-Fitted', 'TextColor','white');

                    if(~isempty(app.paCurve))
                        if(~isempty(app.fittedCurvePa))
                            plot(app.paPlotArea, app.plotX, app.paCurve, 'yo', app.fittedTimePa, app.fittedCurvePa, 'y-', app.plotXRest, app.paCurveRest, 'ws', app.fittedTimePaRest, app.fittedCurvePaRest, 'w-');
                            legend(app.paPlotArea,'Pre-Lesion Sampled', 'Pre-Lesion Fitted', 'Pre-Rest','Pre-Rest-Fitted', 'TextColor','white');
                        else
                            plot(app.paPlotArea, app.plotX, app.paCurve, 'yo', app.plotXRest, app.paCurveRest, 'ws', app.fittedTimePaRest, app.fittedCurvePaRest, 'w-');
                            legend(app.paPlotArea,'Pre-Lesion Sampled','Pre-Rest','Pre-Rest-Fitted', 'TextColor','white');
                        end
                    end

                    if(~isempty(app.pdCurve))
                        if(~isempty(app.fittedCurvePd))
                            plot(app.pdPlotArea, app.plotX, app.pdCurve, 'yo', app.fittedTimePd, app.fittedCurvePd, 'y-', app.plotXRest, app.pdCurveRest, 'ws', app.fittedTimePdRest, app.fittedCurvePdRest, 'w-');
                            legend(app.pdPlotArea,'Post-Lesion Sampled','Post-Lesion Fitted', 'Post-Rest', 'Post-Rest-Fitted', 'TextColor','white');
                        else
                            plot(app.pdPlotArea, app.plotX, app.pdCurve, 'yo', app.plotXRest, app.pdCurveRest, 'ws', app.fittedTimePdRest, app.fittedCurvePdRest, 'w-');
                            legend(app.pdPlotArea,'Post-Lesion Sampled','Post-Rest','Post-Rest-Fitted', 'TextColor','white');
                        end
                    end
                else
                    if (~all(app.fittedCurvePaRest) == 0)
                        plot(app.paPlotArea, app.plotXRest, app.paCurveRest, 'bs', app.fittedTimePaRest, app.fittedCurvePaRest, 'b-');
                        legend(app.paPlotArea,'Pre-Rest','Pre-Rest-Fitted', 'TextColor','black');
                    end
                    plot(app.pdPlotArea, app.plotXRest, app.pdCurveRest, 'bs', app.fittedTimePdRest, app.fittedCurvePdRest, 'b-');
                    legend(app.pdPlotArea,'Post-Rest','Post-Rest-Fitted', 'TextColor','black');

                    if(~isempty(app.paCurve))
                        if(~isempty(app.fittedCurvePa))
                            plot(app.paPlotArea, app.plotX, app.paCurve, 'mo', app.fittedTimePa, app.fittedCurvePa, 'm-', app.plotXRest, app.paCurveRest, 'bs', app.fittedTimePaRest, app.fittedCurvePaRest, 'b-');
                            legend(app.paPlotArea,'Pre-Lesion Sampled', 'Pre-Lesion Fitted', 'Pre-Rest','Pre-Rest-Fitted', 'TextColor','black');
                        else
                            plot(app.paPlotArea, app.plotX, app.paCurve, 'mo', app.plotXRest, app.paCurveRest, 'bs', app.fittedTimePaRest, app.fittedCurvePaRest, 'b-');
                            legend(app.paPlotArea,'Pre-Lesion Sampled','Pre-Rest','Pre-Rest-Fitted', 'TextColor','black');
                        end
                    end

                    if(~isempty(app.pdCurve))
                        if(~isempty(app.fittedCurvePd))
                            plot(app.pdPlotArea, app.plotX, app.pdCurve, 'mo', app.fittedTimePd, app.fittedCurvePd, 'm-', app.plotXRest, app.pdCurveRest, 'bs', app.fittedTimePdRest, app.fittedCurvePdRest, 'b-');
                            legend(app.pdPlotArea,'Post-Lesion Sampled','Post-Lesion Fitted', 'Post-Rest', 'Post-Rest-Fitted', 'TextColor','black');
                        else
                            plot(app.pdPlotArea, app.plotX, app.pdCurve, 'mo', app.plotXRest, app.pdCurveRest, 'bs', app.fittedTimePdRest, app.fittedCurvePdRest, 'b-');
                            legend(app.pdPlotArea,'Post-Lesion Sampled','Post-Rest','Post-Rest-Fitted', 'TextColor','black');
                        end
                    end
                end
            catch ME
                msg = strcat('Problem in Curve Fitting:', ME.message);
                errordlg(msg);
            end
        end

        % Callback function
        function MarkStenosisCheckBoxValueChanged(app, event)
            value = app.MarkStenosisCheckBox.Value;
            app.entryQ = [];
            app.exitQ = [];
            app.entrySliceLocations = [];
            app.exitSliceLocations = [];
            app.stenosisPercentage = [];
            app.aucAEntryStressOnly = [];
            app.aucBExitStressOnly = [];

            if (value == 1)
                app.stenosisGuiData = STENOSISGUI(app);
                app.stenosisGuiData.addlistener('AppClosedEvent', @app.onStenosisGuiClosed);

                app.stenosisGuiData.LumenNarrowingEditField.Value = app.narowRatio(1);

                app.stenosisGuiData.EntrySliceNumberEditField.Value = app.entrySlicerNum(1);
                app.stenosisGuiData.ExitSliceNumberEditField.Value = app.exitSlicerNum(1);
                app.stenosisGuiData.EntryLumenRadiuscmEditField.Value = app.entrySliceThk(1)/10;
                app.stenosisGuiData.ExitLumenRadiuscmEditField.Value = app.exitSliceThk(1)/10;

                app.stenosisGuiData.LumenNarrowingEditField_2.Value = app.narowRatio(2);

                app.stenosisGuiData.EntrySliceNumberEditField_2.Value = app.entrySlicerNum(2);
                app.stenosisGuiData.ExitSliceNumberEditField_2.Value = app.exitSlicerNum(2);
                app.stenosisGuiData.EntryLumenRadiuscmEditField_2.Value = app.entrySliceThk(2)/10;
                app.stenosisGuiData.ExitLumenRadiuscmEditField_2.Value = app.exitSliceThk(2)/10;

                app.stenosisGuiData.LumenNarrowingEditField_3.Value = app.narowRatio(3);

                app.stenosisGuiData.EntrySliceNumberEditField_3.Value = app.entrySlicerNum(3);
                app.stenosisGuiData.ExitSliceNumberEditField_3.Value = app.exitSlicerNum(3);
                app.stenosisGuiData.EntryLumenRadiuscmEditField_3.Value = app.entrySliceThk(3)/10;
                app.stenosisGuiData.ExitLumenRadiuscmEditField_3.Value = app.exitSliceThk(3)/10;

                app.stenosisGuiData.LumenNarrowingEditField_4.Value = app.narowRatio(4);

                app.stenosisGuiData.EntrySliceNumberEditField_4.Value = app.entrySlicerNum(4);
                app.stenosisGuiData.ExitSliceNumberEditField_4.Value = app.exitSlicerNum(4);
                app.stenosisGuiData.EntryLumenRadiuscmEditField_4.Value = app.entrySliceThk(4)/10;
                app.stenosisGuiData.ExitLumenRadiuscmEditField_4.Value = app.exitSliceThk(4)/10;
                
                app.stenosisGuiData.LumenNarrowingEditField_4.Value = app.narowRatio(4);

                app.stenosisGuiData.EntrySliceNumberEditField_4.Value = app.entrySlicerNum(4);
                app.stenosisGuiData.ExitSliceNumberEditField_4.Value = app.exitSlicerNum(4);
                app.stenosisGuiData.EntryLumenRadiuscmEditField_4.Value = app.entrySliceThk(4)/10;
                app.stenosisGuiData.ExitLumenRadiuscmEditField_4.Value = app.exitSliceThk(4)/10;


            else
                app.stenosisGuiData.delete();
                app.numberOfStenosis = 0;

                app.entryCurveStressOnly = [];
                app.fittedCurveEntryStressOnly = [];
                app.exitCurveStressOnly = [];
                app.fittedCurveExitStressOnly = [];
                plotIDsToRemove = find(app.checkedStenosis);
                for i = 1:max(size(plotIDsToRemove))
                    app.plotIDsToRemoveFromPlotArea(end+1) = plotIDsToRemove(i) * 2 + 1;
                    app.plotIDsToRemoveFromPlotArea(end+1) = plotIDsToRemove(i) * 2 + 2;
                end
                app.plotIDsToRemoveFromPlotArea = unique(app.plotIDsToRemoveFromPlotArea);
                showPlot(app);
            end
        end

        % Callback function
        function ClearButtonPushed(app, event)
            cla(app.FFRVsSliceLocation, 'reset');
            app.FFRVsSliceLocation.XLim = [1 52]; % I added this command to avoid X axis change 0 to 1.
            app.allFFRStressOnlySliceLocations = [];
            app.allFFRStressOnly = [];
            app.allFlowVelocitiesSliceLocationsStressOnly = [];
            app.allFlowVelocitiesStressOnly = [];


        end

        % Callback function
        function ClearButton_2Pushed(app, event)
            legend(app.paPlotAreaStressOnly, 'hide');
            cla(app.paPlotAreaStressOnly);

            app.plotXPreStressOnly = [];
            app.paCurveStressOnly = [];
            app.fittedTimePaStressOnly = [];
            app.fittedCurvePaStressOnly = [];
            app.entryCurveStressOnly = [];
            app.fittedTimeEntryStressOnly = [];
            app.fittedCurveEntryStressOnly = [];
            app.plotXEntryStressOnly = [];
            app.plotData.pre = [];
            app.SetPreLesionButtonStressOnly.Enable="on";
        end

        % Callback function
        function ClearButton_3Pushed(app, event)
            legend(app.pdPlotAreaStressOnly, 'hide');
            cla(app.pdPlotAreaStressOnly);

            app.plotXPostStressOnly = [];
            app.pdCurveStressOnly = [];
            app.fittedTimePdStressOnly = [];
            app.fittedCurvePdStressOnly = [];
            app.exitCurveStressOnly = [];
            app.fittedTimeExitStressOnly = [];
            app.fittedCurveExitStressOnly = [];
            app.plotXExitStressOnly = [];
            app.plotData.post = [];
        end

        % Menu selected function: SampleROIMenu
        function SampleROIMenuSelected(app, event)
            filterspec = {'*.json','JSON Files'};
            [f, p] = uigetfile(filterspec);
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);
            set(gcf, 'Pointer','arrow');
            [~, name, ext] = fileparts(f);
            app.roiObjectStressOnly = loadRoiFromJson(p, f);

            if (isfield(app.roiObjectStressOnly, 'preRoiObject'))
                % Update Slice Axis
                app.currentSliceStressOnly = app.roiObjectStressOnly.preRoiObject.curveData.z;
                app.paCurrentTimePointStressOnly = app.roiObjectStressOnly.preRoiObject.curveData.t;
                app.SlicesSliderStressOnly.Value = app.roiObjectStressOnly.preRoiObject.curveData.z;
                event = struct('Value', app.roiObjectStressOnly.preRoiObject.curveData.z, 'PreviousValue', 0);
                SlicesSliderStressOnlyValueChanging(app, event);
                app.SlicesSliderStressOnlyLabel.Text = sprintf('Slice %d/%d', app.roiObjectStressOnly.preRoiObject.curveData.z, size(app.fourDImageSetStressOnly, 1));

                % Update Image
                imageData(1:512, 1:512) = app.fourDImageSetStressOnly(app.roiObjectStressOnly.preRoiObject.curveData.z, app.roiObjectStressOnly.preRoiObject.curveData.t, 1:512, 1:512);
                imageWithContour = getROIOverlayed(imageData, app.roiObjectStressOnly.preRoiObject.curveData.roiPoints.x, app.roiObjectStressOnly.preRoiObject.curveData.roiPoints.y);
                updateimageStressOnly(app,imageWithContour);
            end

            % Get ROI and Draw Plot
            if (isfield(app.roiObjectStressOnly, 'preRoiObject'))
                app.plotXPreStressOnly = app.roiObjectStressOnly.preRoiObject.curveData.timePoints;
                app.paCurveStressOnly = app.roiObjectStressOnly.preRoiObject.curveData.curve;
                app.fittedCurvePaStressOnly = app.roiObjectStressOnly.preRoiObject.curveData.FittedCurve;
                app.fittedTimePaStressOnly = app.roiObjectStressOnly.preRoiObject.curveData.FittedTimePoints;
                app.aucAStressOnly = app.roiObjectStressOnly.preRoiObject.curveData.FittedCurveAUC;
                app.paStressOnly = [app.roiObjectStressOnly.preRoiObject.curveData.z, app.roiObjectStressOnly.preRoiObject.curveData.t, app.roiObjectStressOnly.preRoiObject.curveData.x, app.roiObjectStressOnly.preRoiObject.curveData.y];
                app.allExtraPointsForDistanceInStressOnly = app.roiObjectStressOnly.preRoiObject.ExtraPointsForDistanceBreaker;
                app.numberOfStenosis = app.roiObjectStressOnly.preRoiObject.NumberOfStenosis;
                drawEntryCurveStressOnly(app);
            end
            if (isfield(app.roiObjectStressOnly, 'postRoiObject'))
                app.plotXPostStressOnly = app.roiObjectStressOnly.postRoiObject.curveData.timePoints;
                app.pdCurveStressOnly = app.roiObjectStressOnly.postRoiObject.curveData.curve;
                app.fittedCurvePdStressOnly = app.roiObjectStressOnly.postRoiObject.curveData.FittedCurve;
                app.fittedTimePdStressOnly = app.roiObjectStressOnly.postRoiObject.curveData.FittedTimePoints;
                app.aucBStressOnly = app.roiObjectStressOnly.postRoiObject.curveData.FittedCurveAUC;
                app.pdStressOnly = [app.roiObjectStressOnly.postRoiObject.curveData.z, app.roiObjectStressOnly.postRoiObject.curveData.t, app.roiObjectStressOnly.postRoiObject.curveData.x, app.roiObjectStressOnly.postRoiObject.curveData.y];
                app.allExtraPointsForDistanceInStressOnly = app.roiObjectStressOnly.postRoiObject.ExtraPointsForDistanceBreaker;
                app.numberOfStenosis = app.roiObjectStressOnly.postRoiObject.NumberOfStenosis;
                drawExitCurveStressOnly(app);
            end
            if (isfield(app.roiObjectStressOnly, 'entryRoiObject'))

                totalElementsInEntryROIObject = max(size(app.roiObjectStressOnly.entryRoiObject));
                for i = 1:totalElementsInEntryROIObject
                    app.plotXEntryStressOnly = app.roiObjectStressOnly.entryRoiObject(i).curveData.timePoints;
                    app.entryCurveStressOnly = app.roiObjectStressOnly.entryRoiObject(i).curveData.curve;
                    app.fittedCurveEntryStressOnly = app.roiObjectStressOnly.entryRoiObject(i).curveData.FittedCurve;
                    app.fittedTimeEntryStressOnly = app.roiObjectStressOnly.entryRoiObject(i).curveData.FittedTimePoints;
                    app.numberOfStenosis = app.roiObjectStressOnly.entryRoiObject(i).NumberOfStenosis;
                    app.aucAEntryStressOnly(i) = app.roiObjectStressOnly.entryRoiObject(i).curveData.FittedCurveAUC;
                    app.rs1(i) = app.roiObjectStressOnly.entryRoiObject(i).Radii.rs1;
                    app.rs2(i) = app.roiObjectStressOnly.entryRoiObject(i).Radii.rs2;
                    app.rs3(i) = app.roiObjectStressOnly.entryRoiObject(i).Radii.rs3;
                    app.entryQ(i) = app.roiObjectStressOnly.entryRoiObject(i).Q.entryQ;
                end
                drawEntryCurveStressOnly(app);
            end
            if (isfield(app.roiObjectStressOnly, 'exitRoiObject'))

                totalElementsInExitROIObject = max(size(app.roiObjectStressOnly.exitRoiObject));
                for i = 1:totalElementsInExitROIObject
                    app.plotXExitStressOnly = app.roiObjectStressOnly.exitRoiObject(i).curveData.timePoints;
                    app.exitCurveStressOnly = app.roiObjectStressOnly.exitRoiObject(i).curveData.curve;
                    app.fittedCurveExitStressOnly = app.roiObjectStressOnly.exitRoiObject(i).curveData.FittedCurve;
                    app.fittedTimeExitStressOnly = app.roiObjectStressOnly.exitRoiObject(i).curveData.FittedTimePoints;
                    app.numberOfStenosis = app.roiObjectStressOnly.exitRoiObject(i).NumberOfStenosis;
                    app.aucBExitStressOnly(i) = app.roiObjectStressOnly.exitRoiObject(i).curveData.FittedCurveAUC;
                    app.exitQ(i) = app.roiObjectStressOnly.exitRoiObject(i).Q.exitQ;
                end
                drawExitCurveStressOnly(app);
            end
        end

        % Callback function
        function EditPointCheckBoxValueChanged(app, event)
            value = app.EditPointCheckBox.Value;
            if(value == 1)
                if (isVectorEmptyOrZero(app.entryCurveStressOnly))
                    % If pre-sampled curve is drawn, then update point.
                    if (~isVectorEmptyOrZero(app.paCurveStressOnly))

                        if (app.paPlotAreaPointModifyCounter < 3)

                            app.HUTextArea.Visible = true;
                            set(app.UIFigure, 'WindowButtonMotionFcn', @(src, event) updateCoordinates(app, 'pre'));
                            roi = drawpoint(app.paPlotAreaStressOnly);
                            app.HUTextArea.Visible = false;

                            data = app.xDataPreStressOnly;
                            distance = round(data - roi.Position(1));
                            idx = find(distance == 0);

                            dataArray = app.paCurveStressOnly;
                            dataArray(idx) = roi.Position(2);
                            app.paCurveStressOnly = dataArray;

                            app.paPlotAreaPointModifyCounter = app.paPlotAreaPointModifyCounter + 1;

                            drawPreLesionCurveStressOnly(app);
                        else
                            warningMessage = sprintf('maximum number of HU Correction supported is %d.', app.maxHUCorrectionSupported);
                            waitfor(warndlg(warningMessage, 'HU Correction info'));
                        end
                    end
                else
                    prompt = {'Stenosis # (1-5):','Point #:','New HU Value:'};
                    dlgtitle = 'Edit Point';
                    dims = [1 35];
                    definput = {'1','1','50'};
                    answer = inputdlg(prompt,dlgtitle,dims,definput);
                    stenosisID = str2double(answer{1});
                    pointNumber = str2double(answer{2});
                    newHUValue = str2double(answer{3});
                    figure(app.UIFigure);

                    tempStenosisID = app.currentStenosisID;
                    tempStenosisNumber = app.currentStenosisNumber;
                    app.currentStenosisID = stenosisID;
                    app.currentStenosisNumber = stenosisID;
                    app.entryCurveStressOnly = app.roiObjectStressOnly.entryRoiObject(stenosisID).curveData.curve;
                    app.entryCurveStressOnly(pointNumber) = newHUValue;
                    drawEntryCurveStressOnly(app);
                    app.currentStenosisID = tempStenosisID;
                    app.currentStenosisNumber = tempStenosisNumber;
                end
                app.EditPointCheckBox.Value = 0;
            end

        end

        % Callback function
        function EditPointCheckBox_2ValueChanged(app, event)
            value = app.EditPointCheckBox_2.Value;
            if(value == 1)
                if (isVectorEmptyOrZero(app.exitCurveStressOnly))
                    % If pre-sampled curve is drawn, then update point.
                    if (~isVectorEmptyOrZero(app.pdCurveStressOnly))

                        if (app.pdPlotAreaPointModifyCounter < 3)

                            app.HUTextArea_2.Visible = true;
                            set(app.UIFigure, 'WindowButtonMotionFcn', @(src, event) updateCoordinates(app, 'post'));
                            roi = drawpoint(app.pdPlotAreaStressOnly);
                            app.HUTextArea_2.Visible = false;

                            data = app.xDataPostStressOnly;
                            distance = round(data - roi.Position(1));
                            idx = find(distance == 0);

                            dataArray = app.pdCurveStressOnly;
                            dataArray(idx) = roi.Position(2);
                            app.pdCurveStressOnly = dataArray;

                            app.pdPlotAreaPointModifyCounter = app.pdPlotAreaPointModifyCounter + 1;

                            drawPostLesionCurveStressOnly(app);
                        else
                            warningMessage = sprintf('maximum number of HU Correction supported is %d.', app.maxHUCorrectionSupported);
                            waitfor(warndlg(warningMessage, 'HU Correction info'));
                        end
                    end
                else
                    prompt = {'Stenosis # (1-5):','Point #:','New HU Value:'};
                    dlgtitle = 'Edit Point';
                    dims = [1 35];
                    definput = {'1','1','50'};
                    answer = inputdlg(prompt,dlgtitle,dims,definput);
                    stenosisID = str2double(answer{1});
                    pointNumber = str2double(answer{2});
                    newHUValue = str2double(answer{3});
                    figure(app.UIFigure);

                    tempStenosisID = app.currentStenosisID;
                    tempStenosisNumber = app.currentStenosisNumber;
                    app.currentStenosisID = stenosisID;
                    app.currentStenosisNumber = stenosisID;
                    app.exitCurveStressOnly = app.roiObjectStressOnly.exitRoiObject(stenosisID).curveData.curve;
                    app.exitCurveStressOnly(pointNumber) = newHUValue;
                    drawExitCurveStressOnly(app);
                    app.currentStenosisID = tempStenosisID;
                    app.currentStenosisNumber = tempStenosisNumber;
                end
                app.EditPointCheckBox_2.Value = 0;
            end
        end

        % Menu selected function: CTAImagesMenu_2
        function CTAImagesMenu_2Selected(app, event)
            set(gcf, 'Pointer','watch');
            drawnow;
            figure(app.UIFigure);

            writeRegularDicomVolumeFromEnhancedCTA(app.ctaVolume, app.ctaInfo);

            set(gcf, 'Pointer','arrow');

        end

        % Menu selected function: CTPTimeInfoMenu
        function CTPTimeInfoMenuSelected(app, event)
            % Display uigetfile dialog
            p = uigetdir;
            if (p == 0)
                % Do Nothing
                figure(app.UIFigure);
            else
                set(gcf, 'Pointer','watch');
                drawnow;
                figure(app.UIFigure);
                try
                    [uniqueAcquisitionTime, uniqueContentTime] = readTimeInfoOnlyFromCTPImages(p);
                    saveTimeInfo(uniqueAcquisitionTime, uniqueContentTime);
                    set(gcf, 'Pointer','arrow');
                catch ME
                    set(gcf, 'Pointer','arrow');
                    msg = strcat('The DAI did not fail, there seems to be an issue with this dataset in particular: ', ME.message);
                    errordlg(msg);
                end
            end
        end

        % Menu selected function: ApplyCTPTimeInfoMenu
        function ApplyCTPTimeInfoMenuSelected(app, event)
            callCustomInputDialog();
        end

        % Callback function
        function MarkBreakerCheckBoxValueChanged(app, event)
            value = app.MarkBreakerCheckBox.Value;
            if (value == 1)
                app.breakerGuiData = BREAKERGUI(app);
                app.breakerGuiData.addlistener('AppClosedEvent', @app.onBreakerGuiClosed);
            else
                app.breakerGuiData.delete();
                app.MarkBreakerCheckBox.Value = false;
            end
        end

        % Menu selected function: SetYaxisMaxMenu
        function SetYaxisMaxMenuSelected(app, event)
            prompt = {'Max HU:'};
            dlgtitle = 'Set Y-axis Max';
            dims = [1 40];
            definput = {'0'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if (isempty(answer))
                figure(app.UIFigure);
            else
                answer = str2double(answer{1});
                localYAxisLimitMax = answer;
                app.paPlotAreaStressOnly.YLim = [app.yAxisLimitMin localYAxisLimitMax];
                app.paPlotAreaStressOnly.YTick = getSliderTicks(app.yAxisLimitMin, localYAxisLimitMax, app.maxYAxisInterval);
            end
        end

        % Menu selected function: SetYaxisMaxMenu_2
        function SetYaxisMaxMenu_2Selected(app, event)
            prompt = {'Max HU:'};
            dlgtitle = 'Set Y-axis Max';
            dims = [1 40];
            definput = {'0'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if (isempty(answer))
                figure(app.UIFigure);
            else
                answer = str2double(answer{1});
                localYAxisLimitMax = answer;
                app.pdPlotAreaStressOnly.YLim = [app.yAxisLimitMin localYAxisLimitMax];
                app.pdPlotAreaStressOnly.YTick = getSliderTicks(app.yAxisLimitMin, localYAxisLimitMax, app.maxYAxisInterval);
            end
        end

        % Callback function
        function StressTestMenuSelected(app, event)
            app.StressStudyLabelStressOnly.Text = app.StressTestMenu.Text;
            app.StressTestMenu.Checked = 'on';
            app.RestTestMenu.Checked = 'off';
        end

        % Callback function
        function RestTestMenuSelected(app, event)
            app.StressStudyLabelStressOnly.Text = app.RestTestMenu.Text;
            app.RestTestMenu.Checked = 'on';
            app.StressTestMenu.Checked = 'off';
        end

        % Callback function
        function MButtonPushed(app, event)
            if (app.movieStop == false)
                app.movieStop = true;
            else
                app.movieStop = false;
            end

            numberOfTimePoints = max(size(app.timePointsStressOnly));
            i = app.currentTimePointStressOnly;
            while(app.movieStop)
                currentImage(1:512,1:512) = app.fourDImageSetStressOnly(app.currentSliceStressOnly, i, 1:512,1:512);
                app.TimePointsSliderStressOnly.Value = i;
                if (~isempty(app.paStressOnly) && app.currentSliceStressOnly == app.paStressOnly(1))
                    currentImage = getROIOverlayed(currentImage, app.sxPreStressOnly, app.syPreStressOnly);
                elseif (~isempty(app.pdStressOnly) && app.currentSliceStressOnly == app.pdStressOnly(1))
                    currentImage = getROIOverlayed(currentImage, app.sxPostStressOnly, app.syPostStressOnly);
                end
                updateimageStressOnly(app, currentImage);
                pause(app.movieFrameDuration);
                i = i + 1;
                if (i == numberOfTimePoints)
                    i = 1;
                end
                app.currentTimePointStressOnly = i;
            end

        end

        % Callback function
        function XrayTubeVoltagekVDropDownValueChanged(app, event)
            app.xRayTubeVoltageIndex = find(strcmp(app.XrayTubeVoltagekVDropDown.Value, app.xRayTubeVoltages));
        end

        % Callback function
        function RestorStressConditionDropDownValueChanged(app, event)
            value = app.RestorStressConditionDropDown.Value;
            if (strcmp(value, 'Rest'))
                app.f = 1;
            elseif (strcmp(value, 'Stress'))
                app.f = 2.25;
            end
        end

        % Callback function
        function CoronaryArteryDropDownValueChanged(app, event)
            value = app.CoronaryArteryDropDown.Value;
            app.arterySelectedIndex = find(strcmp(value, app.arterySelections));
            
        end

        % Callback function
        function MarkBranchCheckBoxValueChanged(app, event)
            value = app.MarkBranchCheckBox.Value;
            if (value == 1)
                app.branchGuiData = BRANCHGUI(app);
                app.branchGuiData.addlistener('AppClosedEvent', @app.onBranchGuiClosed);
            else
                % app.branchGuiData.delete();
                app.MarkBranchCheckBox.Value = false;
            end
        end

        % Callback function
        function CoronaryDominanceDropDownValueChanged(app, event)
            value = app.CoronaryDominanceDropDown.Value;
            index = find(strcmp(value, app.dominanceTypes));
            app.dominanceType = app.dominanceTypes(index);
        end

        % Callback function
        function ComputeAUCButtonPushed(app, event)

            
            switch app.CoronaryDropDown.Value
                case 'LAD'
                    cor_idx = 0;
                case 'LCX'
                    cor_idx = 1;
                case 'RCA'
                    cor_idx = 2;
            end
            switch app.StenosisTypeEditField.Value
                case 'Focal'
                    stenosis_idx = 0;
                case 'Serial'
                    stenosis_idx = 1;
                case 'Diffuse'
                    stenosis_idx = 2;
                case 'Diffuse + Serial'
                    stenosis_idx = 3;
            end
            switch app.SexDropDown.Value
                case 'Male'
                    sex=0;
                case 'Female'
                    sex=1;
             
            end

            %Load the neural network developed using Deep Network toolbox
            [net_preauc,net_postauc] = neuralNetwork;
            
            %set a single set to feed the neural network, with the required
            % parameters.
            paramsNetwork= [cor_idx, app.PrelesionSegmentNumberEditField.Value, app.paStressOnly(1),app.pdStressOnly(1),app.aucAStressOnly,app.aucBStressOnly, app.Calcificationmm3EditField.Value,...
                app.CalcificationEditField.Value, app.StenosisLengthmmEditField.Value,...
                app.PostlesionSegmentNumberEditField.Value,stenosis_idx,sex, app.AgeyrEditField.Value, app.BodyweightkgEditField.Value,...
                app.HeightcmEditField.Value,app.ContrastVolumemlEditField.Value,app.ContrastConcentrationmgmlEditField.Value,...
                app.KVEditField.Value,app.HeartRatebpmEditField.Value,app.SystolicBloodPressuremmHgEditField.Value,app.DiastolicBloodPressuremmHgEditField.Value, app.CTcalScoreEditField.Value];

            % Values must be converted previously to Float number
            auc_preauc= double(predict(net_preauc,paramsNetwork));
            auc_postauc= double(predict(net_postauc,paramsNetwork));
            
            % Values are updated on the Edit label (Predicted values)
            app.PredictedPrelesionAUCatStressHUsEditField.Value= auc_preauc;
            app.PredictedPostlesionAUCatStressHUsEditField.Value= auc_postauc;
            
            % Values are updated on the Edit label (True values)
            app.MeasuredPrelesionAUCatRestHUsEditField.Value= app.aucAStressOnly;
            app.MeasuredPostlesionAUCatRestHUsEditField.Value= app.aucBStressOnly;
            
            
        end

        % Callback function
        function PredictpeakenhancementButtonPushed(app, event)


            [V,spatial,dim] = dicomreadVolume(app.dicomFolder);
            V= squeeze(V);
            timepoints= 10;
            Slices=size(V,3)/timepoints;
            Slices= floor(Slices);
            Half_slice= floor(Slices/2);
            V= reshape(V,512,512,timepoints,Slices); % convert to a 4D matrix
            FOV_reducer = 80;

            % Since All the files has different sizes, I will pick a ROI from the
            % Volume
            V = V(:,:,1:10,Half_slice+1:Half_slice+20);
            [x y t z] = size(V);
            V = reshape(V,x,y,t*z);

            % I will try to reduce the FOV
            V(1:FOV_reducer,:,:)=[];
            V(end-FOV_reducer+1:end,:,:)=[];
            V(:,1:FOV_reducer,:,:)=[];
            V(:,end-FOV_reducer+1:end,:,:)=[];

            z= size(V,3);
            V= reshape(V,352,352,10,z/10);
            average_slices= floor(z/80);
            V= double(V);
            avg_Volume= zeros(size(V,1),size(V,2),10,average_slices);

            for i = 1:average_slices
                avg_Volume(:,:,:,i)= mean(V(:,:,:,8*(i-1)+1:8*i),4);
            end
            avg_Volume= reshape(avg_Volume, 352,352, []);

            V= avg_Volume;
            V= rescale(V,0,1);
            V= imresize3(V,[128,128,size(V,3)]);
            % volshow(V);
            net_peak = neuralNetwork_peak;
            peak= double(classify(net_peak,V));
            app.PredictedTimepointEditField.Value=peak;


        end

        % Callback function
        function LevelSliderValueChanging(app, event)
            app.SetWindowLevelStressOnly.Value = string(event.Value);
            app.SetWindowWidthStressOnly.Value = string(app.WidthSlider.Value);
            app.SetWindowButtonStressOnlyPushed;

            
        end

        % Callback function
        function WidthSliderValueChanging(app, event)
            app.SetWindowWidthStressOnly.Value = string(event.Value);
            app.SetWindowLevelStressOnly.Value = string(app.LevelSlider.Value);
            app.SetWindowButtonStressOnlyPushed;
            
        end

        % Callback function
        function SetWindowLevelStressOnlyValueChanged(app, event)
         
            app.LevelSlider.Value = str2double(app.SetWindowLevelStressOnly.Value{1});
            app.SetWindowButtonStressOnlyPushed;
            
        end

        % Callback function
        function SetWindowWidthStressOnlyValueChanged(app, event)
            app.WidthSlider.Value = str2double(app.SetWindowWidthStressOnly.Value{1});
            app.SetWindowButtonStressOnlyPushed;
            
        end

        % Callback function
        function ImportLesionButtonPushed(app, event)
            ImportLesionButton_2Pushed(app, event)
            global answerPre;
            global answerPost;
        
            % Default values in case globals are not defined or empty
            defaultPre  = {'120', '200', '10', '5'};
            defaultPost = {'130', '210', '10', '5'};
            
            % Use previous values if available
            if isempty(answerPre) || numel(answerPre) < 4
                defInputPre = defaultPre;
            else
                defInputPre = { ...
                    num2str(answerPre{1}), ...
                    num2str(answerPre{2}), ...
                    num2str(answerPre{3}), ...
                    num2str(answerPre{4}) ...
                };
            end
            
            % Prompt for pre-lesion point
            promptPre   = {'Pre X:', 'Pre Y:', 'Pre Slice:', 'Pre Time Point:'};
            dlgTitlePre = 'Enter Pre-Lesion Point';
            dims = [1 35];
            % answerPre = inputdlg(promptPre, dlgTitlePre, dims, defInputPre);
            if isempty(answerPre)
                return; % User canceled
            end
            
            % Parse pre-lesion input
            xPre     = str2double(answerPre{1});
            yPre     = str2double(answerPre{2});
            slicePre = str2double(answerPre{3});
            timePre  = str2double(answerPre{4});
            
            % Use previous values if available
            if isempty(answerPost) || numel(answerPost) < 4
                defInputPost = defaultPost;
            else
                defInputPost = { ...
                    num2str(answerPost{1}), ...
                    num2str(answerPost{2}), ...
                    num2str(answerPost{3}), ...
                    num2str(answerPost{4}) ...
                };
            end
            
            % Prompt for post-lesion point
            promptPost   = {'Post X:', 'Post Y:', 'Post Slice:', 'Post Time Point:'};
            dlgTitlePost = 'Enter Post-Lesion Point';
            % answerPost = inputdlg(promptPost, dlgTitlePost, dims, defInputPost);
            if isempty(answerPost)
                return; % User canceled
            end
            
            % Parse post-lesion input
            xPost     = str2double(answerPost{1});
            yPost     = str2double(answerPost{2});
            slicePost = str2double(answerPost{3});
            timePost  = str2double(answerPost{4});
            
            global P_stress_all P_cta_all
            
            % Build 3D affine transform ctaToStress if enough points
            if size(P_cta_all,1) >= 3
                try
                    X = [P_cta_all, ones(size(P_cta_all,1),1)]; % N×4
                    Y = P_stress_all;                           % N×3
                    T = X \ Y;                                  % 4×3 matrix
                    ctaToStress = @(p) [p,1] * T;
                catch ME
                    ctaToStress = @(p) p;
                    % uialert(app.UIFigure, ...
                    %     sprintf('Error while computing 3D transform:\n%s\nUsing identity mapping instead.', ME.message), ...
                    %     'Transform Error', ...
                    %     'Icon', 'error');
                end
            else
                ctaToStress = @(p) p;
                uialert(app.UIFigure, ...
                    ['Not enough point pairs to compute 3D transformation.' newline ...
                     'Using identity mapping instead.'], ...
                    'Warning: Identity Transform', ...
                    'Icon','warning');
            end
            
            % Apply the transform
            stressPre  = ctaToStress([xPre,  yPre,  slicePre ]);
            stressPost = ctaToStress([xPost, yPost, slicePost]);


             % --- Build z mapping function ---
            % Only do this if we have at least 2 point pairs
            if size(P_stress_all,1) >= 2
                z_cta    = P_cta_all(:,3);
                z_stress = P_stress_all(:,3);
    
                % Interpolant: maps CTA z -> Stress z
                % Uses linear interpolation, extrapolate for values outside
                ctaToStressZ = @(z) interp1(z_cta, z_stress, z, 'linear', 'extrap');
    
                % Wrap in full function to return [x,y,z]
                ctaToStress = @(p) [p(1), p(2), ctaToStressZ(p(3))];
            else
                % Default identity until we have pairs
                ctaToStress = @(p) p;
            end
                        % Translate all entry slicer numbers
            for i = 1:length(app.entrySlicerNum)
                original = app.entrySlicerNum(i);
                try
                    tmp = ctaToStress([0, 0, original]);
                    translated = round(tmp(3));
            
                    % UI box showing before/after
                    % uialert(app.UIFigure, ...
                    %     sprintf('Entry %d:\nOriginal = %g\nTranslated = %g', ...
                    %             i, original, translated), ...
                    %     'Entry Translation');
            
                    app.entrySlicerNum(i) = translated;
            
                catch err
                    % Improved error message
                    % uialert(app.UIFigure, ...
                    %     sprintf(['Failed to translate entry %d\n', ...
                    %              'Original Z = %g\n', ...
                    %              'Error: %s\n\n', ...
                    %              'Using identity transform instead.'], ...
                    %              i, original, err.message), ...
                    %     'Warning: Translation Error', ...
                    %     'Icon','warning');
            
                    % Fallback (identity)
                    translated = original;
                    app.entrySlicerNum(i) = translated;
                end
            end

            
            % Translate all exit slicer numbers
            for i = 1:length(app.exitSlicerNum)
                original = app.exitSlicerNum(i);
               
                try
                    tmp = ctaToStress([0, 0, original]);
                    translated = round(tmp(3));
                catch
                    
                    % uialert(app.UIFigure, ...
                    % ['Bad Translation'], ...
                    % 'Warning: Identity Transform', ...
                    % 'Icon','warning');
                end
                
                % UI box showing before/after
                % uialert(app.UIFigure, ...
                %     sprintf('Exit %d:\nOriginal = %g\nTranslated = %g', ...
                %             i, original, translated), ...
                %     'Exit Translation');
                
                app.exitSlicerNum(i) = translated;
            end

            
            % Integer voxel targets
            tgtPreX   = floor(stressPre(1));
            tgtPreY   = floor(stressPre(2));
            tgtPreZ   = floor(stressPre(3));
            tgtPostX  = floor(stressPost(1));
            tgtPostY  = floor(stressPost(2));
            tgtPostZ  = floor(stressPost(3));
            
            % Optional: summary

            % try
            %     uialert(app.UIFigure, ...
            %         sprintf(['Added points:' newline ...
            %                  'Pre  -> [x=%d, y=%d, z=%d], t=%d' newline ...
            %                  'Post -> [x=%d, y=%d, z=%d], t=%d'], ...
            %                  preX,  preY,  tgtPreZ,  round(timePre), ...
            %                  postX, postY, tgtPostZ, round(timePost)), ...
            %         'Points Added', 'Icon','info');
            % catch
            %     uialert(app.UIFigure, ...
            %         "Failed to translate points. Please check your input.", ...
            %         'Error', 'Icon','error');
            % end
            
            % PRE: navigate -> drawpoint -> wait Ctrl+S -> setPre
            navigateStressSlice(tgtPreZ, round(timePre));
            [preX, preY] = placeAndConfirmPoint(tgtPreX, tgtPreY, 'Adjust PRE point, then press Ctrl+S to confirm');
            
            % POST: navigate -> drawpoint -> wait Ctrl+S -> setPost
            navigateStressSlice(tgtPostZ, round(timePost));
            [postX, postY] = placeAndConfirmPoint(tgtPostX, tgtPostY, 'Adjust POST point, then press Ctrl+S to confirm');

             try
                uialert(app.UIFigure, ...
                    sprintf(['Added points:' newline ...
                             'Pre  -> [x=%d, y=%d, z=%d], t=%d' newline ...
                             'Post -> [x=%d, y=%d, z=%d], t=%d'], ...
                             preX,  preY,  tgtPreZ,  round(timePre), ...
                             postX, postY, tgtPostZ, round(timePost)), ...
                    'Points Added', 'Icon','info');
            catch
                uialert(app.UIFigure, ...
                    "Failed to translate points. Please check your input.", ...
                    'Error', 'Icon','error');
            end


            setPre(app, preX, preY, tgtPreZ, timePre);
            setPost(app, postX, postY, tgtPostZ, timePost);


            % ------- local helpers -------
            
            function navigateStressSlice(sliceIdx, timeIdx)
                % Clamp to data bounds
                nSlices = size(app.fourDImageSetStressOnly,1);
                nTimes  = size(app.fourDImageSetStressOnly,2);
                sliceIdx = max(1, min(nSlices, sliceIdx));
                timeIdx  = max(1, min(nTimes,  timeIdx));
            
                % Update app state
                app.currentSliceStressOnly       = sliceIdx;
                app.paSliceStressOnly            = sliceIdx;
                app.currentTimePointStressOnly   = timeIdx;
                app.paCurrentTimePointStressOnly = timeIdx;
            
                % Display refresh
                try
                    img = squeeze(app.fourDImageSetStressOnly(sliceIdx, timeIdx, :, :));
                    if isprop(app, 'ImageHandleStressOnly') && ~isempty(app.ImageHandleStressOnly) && isvalid(app.ImageHandleStressOnly)
                        set(app.ImageHandleStressOnly, 'CData', img);
                    else
                        imagesc(app.ImageAxesStressOnly, img);
                        colormap(app.ImageAxesStressOnly, 'gray');
                        axis(app.ImageAxesStressOnly, 'image');
                    end
                    % Ensure axes are ready for interactive ROI
                    if isprop(app.ImageAxesStressOnly, 'Interactions')
                        disableDefaultInteractivity(app.ImageAxesStressOnly);
                    end
                catch
                    % If you have a custom refresh method, call it instead:
                    % updateimageStressOnly(app);
                end
            end
            
            function [finalX, finalY] = placeAndConfirmPoint(initX, initY, labelText)
                % Clamp to current image dimensions
                frame = squeeze(app.fourDImageSetStressOnly(app.currentSliceStressOnly, app.currentTimePointStressOnly, :, :));
                [H, W] = size(frame);
                initX = max(1, min(W, initX));
                initY = max(1, min(H, initY));
            
                % Draw an adjustable point at the intended position
                roi = drawpoint(app.ImageAxesStressOnly, 'Position', [double(initX), double(initY)]);
                try
                    roi.Label = ''; % keep clean
                catch
                end
            
                % Guide user and wait for Ctrl+S
                waitForCtrlS(sprintf('%s | Slice %d, Time %d', labelText, app.currentSliceStressOnly, app.currentTimePointStressOnly));
            
                % Read back position; use modified if changed, else original
                pos = roi.Position; % [x y]
                if ~isempty(pos) && numel(pos) == 2
                    candX = round(pos(1));
                    candY = round(pos(2));
                else
                    candX = initX;
                    candY = initY;
                end
            
                % Clamp and compare
                candX = max(1, min(W, candX));
                candY = max(1, min(H, candY));
            
                if candX ~= initX || candY ~= initY
                    finalX = candX;
                    finalY = candY;
                else
                    finalX = initX;
                    finalY = initY;
                end
            
                % Clean up ROI
                if isvalid(roi)
                    delete(roi);
                end
            end
            
            function waitForCtrlS(promptText)
                % Save existing handler and title
                prevFcn  = app.UIFigure.WindowKeyPressFcn;
                prevName = app.UIFigure.Name;
            
                % Update title to guide the user
                try
                    app.UIFigure.Name = promptText;
                catch
                end
            
                % Install temporary keypress handler
                app.UIFigure.WindowKeyPressFcn = @onKeyPress;
            
                % Block here (UI remains responsive)
                try
                    uiwait(app.UIFigure);
                catch
                end
            
                % Restore previous handler and title
                if isvalid(app.UIFigure)
                    app.UIFigure.WindowKeyPressFcn = prevFcn;
                    try
                        app.UIFigure.Name = prevName;
                    catch
                    end
                end
            
                function onKeyPress(~, evt)
                    if any(strcmpi(evt.Modifier, 'control')) && strcmpi(evt.Key, 's')
                        try
                            uiresume(app.UIFigure);
                        catch
                        end
                    end
                end
            end
        end

        % Callback function
        function ImportLesionButton_2Pushed(app, event)
% IMPORTLESIONPOINTSBUTTONPUSHED_LOCKBASED Handle lesion points import with lock-based external program execution
            % 
            % This function processes lesion points and coordinates external program execution
            % through a lock-based system with the parent PowerShell process, rather than
            % directly executing Slicer and InVesalius.
            
            try
                % Request Slicer execution through lock system
                fprintf('Requesting Slicer execution from parent process...\n');
                requestSlicerExecution('dai_slicer_lock.txt');
                
                % Wait for user to complete Slicer workflow
                uialert(app.UIFigure, 'Slicer has been launched. Please complete your workflow in Slicer and then click OK to continue.', 'Slicer Workflow', 'Icon', 'info');
                
                % File selection for DICOM load file
                [file, path] = uigetfile({'*.nrrd*', '.nrrd'}, 'Select dicom load file');
                if isequal(file,0) || isequal(path,0)
                    uialert(app.UIFigure, 'No file selected.', 'Error');
                    return;
                end
                selectedFile = fullfile(path, file);
                
                % Look for Centerline VTK files
                files = dir(fullfile(path, '*Centerline*.vtk'));
                if isempty(files)
                    uialert(app.UIFigure, 'No Centerline VTK files found.', 'Error');
                    return;
                end
                
                % Extract numbers from filenames and find the highest
                nums = zeros(1, numel(files));
                for i = 1:numel(files)
                    tokens = regexp(files(i).name, '_(\d+)\.vtk$', 'tokens');
                    if ~isempty(tokens)
                        nums(i) = str2double(tokens{1}{1});
                    else
                        nums(i) = 0; % If no number, treat as 0
                    end
                end
                
                [~, idx] = max(nums);
                vtkFile = fullfile(path, files(idx).name);
                
                % Process the VTK file to extract points and radii
                fprintf('Processing VTK file: %s\n', vtkFile);
                extract_sl(vtkFile);
                
                % Load the processed data
                userHome = char(java.lang.System.getProperty('user.home'));
                outfile = fullfile(userHome, 'output.mat');
                data = load(outfile);
                points = data.points;
                radii = data.radius;
                
                % Ensure correct orientation
                if size(points,1) ~= numel(radii)
                    error('Number of points and radii do not match.');
                end
                
                % Merge into cell array of {[x y z], r}
                merged = cell(numel(radii), 1);
                for i = 1:numel(radii)
                    merged{i} = {points(i,:), radii(i)};
                end
                
                % Get point mapping
                [points, points_inv] = pointMapUtil(path, selectedFile);
                radii = [];

                fprintf('len %d', length(points))
                
                % Match points to radii
                for i = 1:size(points, 1)
                    xyz = points(i, :);
                    xyz_comp = xyz;
                    xyz_comp(2); %= -xyz_comp(2); % Negate y for comparison only
                    for j = 1:numel(merged)
                        fprintf('xyz: [%f, %f, %f]\n', xyz);
                        fprintf('merged{%d}{1}: [%f, %f, %f]\n', j, merged{j}{1});
                        % Check if all coordinates are within 0.8
                        delta = abs(merged{j}{1} - xyz_comp);
                        fprintf('Delta: [%.3f, %.3f, %.3f]\n', delta(1), delta(2), delta(3));

                        if all(abs(merged{j}{1} - xyz_comp) <= 1.5)
                            radii = [radii; merged{j}{2}];
                            break; % Only take the first matching radius
                        end
                    end
                end
                
                app.entrySliceThk = [];
                app.exitSliceThk  = [];
                app.entrySlicerNum  = [];
                app.exitSlicerNum = [];
                
                for k = 3:length(radii)
                    if mod(k,2) == 1
                        % odd index -> entry

                        app.entrySliceThk(end+1) = radii(k);
                        app.entrySlicerNum(end+1) = points_inv(k,3);
                        fprintf('entry r%d %f, %d\n', k, app.entrySliceThk(end), app.entrySlicerNum(end));
                    else
                        % even index -> exit
                        app.exitSliceThk(end+1) = radii(k);
                        app.exitSlicerNum(end+1) = points_inv(k,3);
                        fprintf('exit r%d %f, %d \n', k, app.exitSliceThk(end),  app.exitSlicerNum(end));
                    end
                end

                fprintf('Matched radii: %s\n', mat2str(radii));
                

                if numel(radii) >= 1
                    app.PostlesionLumenRadiuscmEditFieldStressOnly.Value = num2str(radii(2)/10);
                    fprintf('Post-lesion radius set to: %f cm\n', radii(2)/10);
                end
                % Update text fields with the first two radii (if available)
                if numel(radii) >= 2
                    app.PrelesionLumenRadiuscmEditFieldStressOnly.Value = num2str(radii(1)/10);
                    fprintf('Pre-lesion radius set to: %f cm\n', radii(1)/10);
                end
                if numel(radii) == 0
                    uialert(app.UIFigure, 'No Radii merged', 'Error');
                end
                

                % pre = ['[[' num2str(points_inv(1,1)) ',' num2str(points_inv(1,2)) ',' num2str(points_inv(1,3)) ']]'];
                % post = ['[[' num2str(points_inv(2,1)) ',' num2str(points_inv(2,2)) ',' num2str(points_inv(2,3)) ']]'];
                % top = ['[[' num2str(points_inv(3,1)) ',' num2str(points_inv(3,2)) ',' num2str(points_inv(3,3)) ']]'];
                % bottom = ['[[' num2str(points_inv(4,1)) ',' num2str(points_inv(4,2)) ',' num2str(points_inv(4,3)) ']]'];


                % Extract the values from points_inv
                preX = points_inv(1,1);
                preY = points_inv(1,2);
                preZ = points_inv(1,3);
                preT = 0;  % Assuming time point is 0
                
                postX = points_inv(2,1);
                postY = points_inv(2,2);
                postZ = points_inv(2,3);
                postT = 0;  % Assuming time point is 0
                
                % Convert to string cell arrays
                preStrings = {num2str(floor(preX)), num2str(floor(preY)), num2str(floor(preZ)), num2str(floor(preT))};
                postStrings = {num2str(floor(postX)), num2str(floor(postY)), num2str(floor(postZ)), num2str(floor(postT))};
                
                % Call the unified function
                global answerPre;
                global answerPost;

                % Save to global variables for use in default dialogs
                answerPre = preStrings;
                answerPost = postStrings;

                try
                    app.narowRatio = 100 * calculateStenosisRatio(path);
                    % uialert(app.UIFigure, ...
                    %     sprintf('Stenosis ratio set to %.2f', calculateStenosisRatio(path)), ...
                    %     'Success');
                catch ME
                    uialert(app.UIFigure, ...
                        sprintf(['Failed to update stenosis ratio.' newline ...
                                 'Error: %s'], ME.message), ...
                        'Update Error');
                end


            catch ME
                % Build stack trace string
                stackMsg = sprintf('Error: %s\n\nStack trace:\n', ME.message);
                for k = 1:length(ME.stack)
                    stackMsg = sprintf('%sFile: %s (line %d)\n', stackMsg, ME.stack(k).file, ME.stack(k).line);
                end
            
                % Show detailed error in UI
                uialert(app.UIFigure, stackMsg, 'Error');
            
                % Also print to console for debugging
                fprintf('%s\n', stackMsg);
            
                % Rethrow for potential higher-level handlers
                rethrow(ME);
            end 
        end

        % Callback function
        function SetTranslationButtonPushed(app, event)
            global ctaToStress
            global P_stress_all P_cta_all
        
            % Initialize globals if first time
            if isempty(P_stress_all); P_stress_all = []; end
            if isempty(P_cta_all);    P_cta_all    = []; end
        
            try
                % Ask user to place one pair of points (2D)
                hPointStress = drawpoint(app.ImageAxesStressOnly);
                hPointCTA    = drawpoint(app.ImageAxesCTAStressOnly);
        
                posStress2D = hPointStress.Position; % [x,y]
                posCTA2D    = hPointCTA.Position;    % [x,y]
        
                % Validate
                if isempty(posStress2D) || isempty(posCTA2D)
                    error('No valid point received.');
                end
        
                % Append z-coordinate from current slice variables
                posStress = [posStress2D, app.currentSliceStressOnly];
                posCTA    = [posCTA2D,    app.currentSliceCTA];
        
                % Append to stored pairs
                P_stress_all = [P_stress_all; posStress];
                P_cta_all    = [P_cta_all;    posCTA];
        
                % Build alert message
                msg = sprintf('Stress = [%.3f, %.3f, %.3f]\nCTA    = [%.3f, %.3f, %.3f]', ...
                              posStress(1), posStress(2), posStress(3), ...
                              posCTA(1),    posCTA(2),    posCTA(3));
        
                % Show confirmation
                uialert(app.UIFigure, msg, ...
                        sprintf('Point Pair %d Stored', size(P_stress_all,1)), ...
                        'Icon', 'info');
        
                % Always default to identity until 3 pairs are placed
                ctaToStress = @(p) p;
        
            catch ME
                % Error handling
                uialert(app.UIFigure, ...
                    sprintf('Error while placing points:\n%s', ME.message), ...
                    'Point Placement Error', ...
                    'Icon', 'error');
            end
        end

        % Callback function
        function level(app, event)
            
            
        end

        % Callback function
        function levelslider_2valuechanging(app, event)
  
            
        end

        % Callback function
        function LevelSliderCTAValueChanging(app, event)
            app.SetWindowLevelCTAStressOnly.Value = {num2str(event.Value)};
            app.SetWindowWidthCTAStressOnly.Value = {num2str(app.WidthSliderCTA.Value)};
            app.SetWindowButtonCTAStressOnlyPushed;
            
        end

        % Callback function
        function WidthSliderCTAValueChanging(app, event)
            app.SetWindowWidthCTAStressOnly.Value = {num2str(event.Value)};
            app.SetWindowLevelCTAStressOnly.Value = {num2str(app.LevelSliderCTA.Value)};
            app.SetWindowButtonCTAStressOnlyPushed;
            
        end

        % Callback function
        function SetWindowLevelCTAStressOnlyValueChanged(app, event)
            app.LevelSliderCTA.Value = str2double(app.SetWindowLevelCTAStressOnly.Value{1});
            app.SetWindowButtonCTAStressOnlyPushed;            
        end

        % Callback function
        function SetWindowWidthCTAStressOnlyValueChanged(app, event)
            app.WidthSliderCTA.Value = str2double(app.SetWindowWidthCTAStressOnly.Value{1});
            app.SetWindowButtonCTAStressOnlyPushed;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'DAI - Vera';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.Text = 'Load';
            app.DCECTAnalysisMenu = uimenu(app.LoadMenu);
            app.DCECTAnalysisMenu.Text = 'DCE CT Analysis';
            app.DCECTImagesMenu = uimenu(app.DCECTAnalysisMenu);
            app.DCECTImagesMenu.MenuSelectedFcn = createCallbackFcn(app, @DCECTImagesMenuSelected, true);
            app.DCECTImagesMenu.Text = 'DCE CT Images';
            app.ScannerModelMenu = uimenu(app.DCECTImagesMenu);
            app.ScannerModelMenu.Text = 'Scanner Model';
            app.SiemensMenu_3 = uimenu(app.ScannerModelMenu);
            app.SiemensMenu_3.MenuSelectedFcn = createCallbackFcn(app, @SiemensMenu_3Selected, true);
            app.SiemensMenu_3.Text = 'Siemens';
            app.CanonMenu_3 = uimenu(app.ScannerModelMenu);
            app.CanonMenu_3.MenuSelectedFcn = createCallbackFcn(app, @CanonMenu_3Selected, true);
            app.CanonMenu_3.Text = 'Canon';
            app.GEMenu_3 = uimenu(app.ScannerModelMenu);
            app.GEMenu_3.MenuSelectedFcn = createCallbackFcn(app, @GEMenu_3Selected, true);
            app.GEMenu_3.Text = 'GE';
            app.CTAImagesMenu = uimenu(app.DCECTAnalysisMenu);
            app.CTAImagesMenu.MenuSelectedFcn = createCallbackFcn(app, @CTAImagesMenuSelected, true);
            app.CTAImagesMenu.Text = 'CTA Images';
            app.PreLesionCurveMenu = uimenu(app.DCECTAnalysisMenu);
            app.PreLesionCurveMenu.MenuSelectedFcn = createCallbackFcn(app, @PreLesionCurveMenuSelected, true);
            app.PreLesionCurveMenu.Visible = 'off';
            app.PreLesionCurveMenu.Text = 'Pre-Lesion Curve';
            app.PostLesionCurveMenu = uimenu(app.DCECTAnalysisMenu);
            app.PostLesionCurveMenu.MenuSelectedFcn = createCallbackFcn(app, @PostLesionCurveMenuSelected, true);
            app.PostLesionCurveMenu.Visible = 'off';
            app.PostLesionCurveMenu.Text = 'Post-Lesion Curve';
            app.SampleROIMenu = uimenu(app.DCECTAnalysisMenu);
            app.SampleROIMenu.MenuSelectedFcn = createCallbackFcn(app, @SampleROIMenuSelected, true);
            app.SampleROIMenu.Text = 'Sample ROI';
            app.CTPTimeInfoMenu = uimenu(app.DCECTAnalysisMenu);
            app.CTPTimeInfoMenu.MenuSelectedFcn = createCallbackFcn(app, @CTPTimeInfoMenuSelected, true);
            app.CTPTimeInfoMenu.Text = 'CTP Time Info';
            app.ApplyCTPTimeInfoMenu = uimenu(app.DCECTAnalysisMenu);
            app.ApplyCTPTimeInfoMenu.MenuSelectedFcn = createCallbackFcn(app, @ApplyCTPTimeInfoMenuSelected, true);
            app.ApplyCTPTimeInfoMenu.Text = 'Apply CTP Time Info';
            app.StressandRestAnalysisMenu = uimenu(app.LoadMenu);
            app.StressandRestAnalysisMenu.Visible = 'off';
            app.StressandRestAnalysisMenu.Text = 'Stress and Rest Analysis';
            app.StressImagesMenu_4 = uimenu(app.StressandRestAnalysisMenu);
            app.StressImagesMenu_4.MenuSelectedFcn = createCallbackFcn(app, @StressImagesMenu_4Selected, true);
            app.StressImagesMenu_4.Text = 'Stress Images';
            app.ScannerModelMenu_2 = uimenu(app.StressImagesMenu_4);
            app.ScannerModelMenu_2.Text = 'Scanner Model';
            app.SiemensMenu_4 = uimenu(app.ScannerModelMenu_2);
            app.SiemensMenu_4.MenuSelectedFcn = createCallbackFcn(app, @SiemensMenu_4Selected, true);
            app.SiemensMenu_4.Text = 'Siemens';
            app.CanonMenu_4 = uimenu(app.ScannerModelMenu_2);
            app.CanonMenu_4.MenuSelectedFcn = createCallbackFcn(app, @CanonMenu_4Selected, true);
            app.CanonMenu_4.Text = 'Canon';
            app.GEMenu_4 = uimenu(app.ScannerModelMenu_2);
            app.GEMenu_4.MenuSelectedFcn = createCallbackFcn(app, @GEMenu_4Selected, true);
            app.GEMenu_4.Text = 'GE';
            app.TimeInfoStudyStressMenu = uimenu(app.StressandRestAnalysisMenu);
            app.TimeInfoStudyStressMenu.MenuSelectedFcn = createCallbackFcn(app, @TimeInfoStudyStressMenuSelected, true);
            app.TimeInfoStudyStressMenu.Text = 'Time-Info Study (Stress)';
            app.RestImagesMenu_2 = uimenu(app.StressandRestAnalysisMenu);
            app.RestImagesMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RestImagesMenu_2Selected, true);
            app.RestImagesMenu_2.Text = 'Rest Images';
            app.ScannerModelMenu_3 = uimenu(app.RestImagesMenu_2);
            app.ScannerModelMenu_3.Text = 'Scanner Model';
            app.SiemensMenu_5 = uimenu(app.ScannerModelMenu_3);
            app.SiemensMenu_5.MenuSelectedFcn = createCallbackFcn(app, @SiemensMenu_5Selected, true);
            app.SiemensMenu_5.Text = 'Siemens';
            app.CanonMenu_5 = uimenu(app.ScannerModelMenu_3);
            app.CanonMenu_5.MenuSelectedFcn = createCallbackFcn(app, @CanonMenu_5Selected, true);
            app.CanonMenu_5.Text = 'Canon';
            app.GEMenu_5 = uimenu(app.ScannerModelMenu_3);
            app.GEMenu_5.MenuSelectedFcn = createCallbackFcn(app, @GEMenu_5Selected, true);
            app.GEMenu_5.Text = 'GE';
            app.TimeInfoStudyRestMenu = uimenu(app.StressandRestAnalysisMenu);
            app.TimeInfoStudyRestMenu.MenuSelectedFcn = createCallbackFcn(app, @TimeInfoStudyRestMenuSelected, true);
            app.TimeInfoStudyRestMenu.Text = 'Time-Info Study (Rest)';
            app.PreLesionCurveMenu_2 = uimenu(app.StressandRestAnalysisMenu);
            app.PreLesionCurveMenu_2.MenuSelectedFcn = createCallbackFcn(app, @PreLesionCurveMenu_2Selected, true);
            app.PreLesionCurveMenu_2.Text = 'Pre-Lesion Curve';
            app.PostLesionCurveMenu_2 = uimenu(app.StressandRestAnalysisMenu);
            app.PostLesionCurveMenu_2.MenuSelectedFcn = createCallbackFcn(app, @PostLesionCurveMenu_2Selected, true);
            app.PostLesionCurveMenu_2.Text = 'Post-Lesion Curve';
            app.SupervisedLearningMenu_2 = uimenu(app.LoadMenu);
            app.SupervisedLearningMenu_2.Visible = 'off';
            app.SupervisedLearningMenu_2.Text = 'Supervised Learning';
            app.DCTAVolumeMenu = uimenu(app.SupervisedLearningMenu_2);
            app.DCTAVolumeMenu.MenuSelectedFcn = createCallbackFcn(app, @DCTAVolumeMenuSelected, true);
            app.DCTAVolumeMenu.Text = '3D CTA Volume';
            app.TrainingMenu_2 = uimenu(app.SupervisedLearningMenu_2);
            app.TrainingMenu_2.Text = 'Training';
            app.TrainingDataMenu = uimenu(app.TrainingMenu_2);
            app.TrainingDataMenu.MenuSelectedFcn = createCallbackFcn(app, @TrainingDataMenuSelected, true);
            app.TrainingDataMenu.Text = 'Training Data';
            app.ValidationDataMenu = uimenu(app.TrainingMenu_2);
            app.ValidationDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ValidationDataMenuSelected, true);
            app.ValidationDataMenu.Text = 'Validation Data';
            app.TestingMenu_2 = uimenu(app.SupervisedLearningMenu_2);
            app.TestingMenu_2.Text = 'Testing';
            app.TestDataMenu = uimenu(app.TestingMenu_2);
            app.TestDataMenu.MenuSelectedFcn = createCallbackFcn(app, @TestDataMenuSelected, true);
            app.TestDataMenu.Text = 'Test Data';
            app.SavedNetworkMenu = uimenu(app.TestingMenu_2);
            app.SavedNetworkMenu.MenuSelectedFcn = createCallbackFcn(app, @SavedNetworkMenuSelected, true);
            app.SavedNetworkMenu.Text = 'Saved Network';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.DCECTAnalysisMenu_2 = uimenu(app.SaveMenu);
            app.DCECTAnalysisMenu_2.Text = 'DCE CT Analysis';
            app.ResultsMenu = uimenu(app.DCECTAnalysisMenu_2);
            app.ResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @ResultsMenuSelected, true);
            app.ResultsMenu.Text = 'Results';
            app.StressandRestAnalysisMenu_2 = uimenu(app.SaveMenu);
            app.StressandRestAnalysisMenu_2.Visible = 'off';
            app.StressandRestAnalysisMenu_2.Text = 'Stress and Rest Analysis';
            app.ResultsMenu_2 = uimenu(app.StressandRestAnalysisMenu_2);
            app.ResultsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ResultsMenu_2Selected, true);
            app.ResultsMenu_2.Text = 'Results';
            app.SupervisedLearningMenu = uimenu(app.SaveMenu);
            app.SupervisedLearningMenu.Visible = 'off';
            app.SupervisedLearningMenu.Text = 'Supervised Learning';
            app.LabelledDataMenu = uimenu(app.SupervisedLearningMenu);
            app.LabelledDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LabelledDataMenuSelected, true);
            app.LabelledDataMenu.Text = 'Labelled Data';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Text = 'Export';
            app.DCECTAnalysisMenu_3 = uimenu(app.ExportMenu);
            app.DCECTAnalysisMenu_3.Text = 'DCE CT Analysis';
            app.DCECTImagesMenu_2 = uimenu(app.DCECTAnalysisMenu_3);
            app.DCECTImagesMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DCECTImagesMenu_2Selected, true);
            app.DCECTImagesMenu_2.Text = 'DCE CT Images';
            app.CTAImagesMenu_2 = uimenu(app.DCECTAnalysisMenu_3);
            app.CTAImagesMenu_2.MenuSelectedFcn = createCallbackFcn(app, @CTAImagesMenu_2Selected, true);
            app.CTAImagesMenu_2.Text = 'CTA Images';
            app.FittedCurvesMenu = uimenu(app.DCECTAnalysisMenu_3);
            app.FittedCurvesMenu.MenuSelectedFcn = createCallbackFcn(app, @FittedCurvesMenuSelected, true);
            app.FittedCurvesMenu.Text = 'Fitted Curves';
            app.FFRValuesMenu = uimenu(app.DCECTAnalysisMenu_3);
            app.FFRValuesMenu.MenuSelectedFcn = createCallbackFcn(app, @FFRValuesMenuSelected, true);
            app.FFRValuesMenu.Text = 'FFR Values';
            app.StressandRestAnalysisMenu_3 = uimenu(app.ExportMenu);
            app.StressandRestAnalysisMenu_3.Visible = 'off';
            app.StressandRestAnalysisMenu_3.Text = 'Stress and Rest Analysis';
            app.StressImagesMenu_3 = uimenu(app.StressandRestAnalysisMenu_3);
            app.StressImagesMenu_3.MenuSelectedFcn = createCallbackFcn(app, @StressImagesMenu_3Selected, true);
            app.StressImagesMenu_3.Text = 'Stress Images';
            app.RestImagesMenu = uimenu(app.StressandRestAnalysisMenu_3);
            app.RestImagesMenu.MenuSelectedFcn = createCallbackFcn(app, @RestImagesMenuSelected, true);
            app.RestImagesMenu.Text = 'Rest Images';
            app.FittedCurveMenu_2 = uimenu(app.StressandRestAnalysisMenu_3);
            app.FittedCurveMenu_2.MenuSelectedFcn = createCallbackFcn(app, @FittedCurveMenu_2Selected, true);
            app.FittedCurveMenu_2.Text = 'Fitted Curve';
            app.WorkflowMenu = uimenu(app.FileMenu);
            app.WorkflowMenu.Visible = 'off';
            app.WorkflowMenu.Text = 'Workflow';
            app.SupervisedLearningMenu_4 = uimenu(app.WorkflowMenu);
            app.SupervisedLearningMenu_4.Text = 'Supervised Learning';
            app.TrainingMenu = uimenu(app.SupervisedLearningMenu_4);
            app.TrainingMenu.Text = 'Training';
            app.NetworkOptionMenu = uimenu(app.TrainingMenu);
            app.NetworkOptionMenu.Text = 'Network Option';
            app.CNNMenu = uimenu(app.NetworkOptionMenu);
            app.CNNMenu.MenuSelectedFcn = createCallbackFcn(app, @CNNMenuSelected, true);
            app.CNNMenu.Checked = 'on';
            app.CNNMenu.Text = 'CNN';
            app.UNETMenu = uimenu(app.NetworkOptionMenu);
            app.UNETMenu.MenuSelectedFcn = createCallbackFcn(app, @UNETMenuSelected, true);
            app.UNETMenu.Text = 'UNET';
            app.StartTrainingMenu = uimenu(app.TrainingMenu);
            app.StartTrainingMenu.MenuSelectedFcn = createCallbackFcn(app, @StartTrainingMenuSelected, true);
            app.StartTrainingMenu.Text = 'Start Training';
            app.TestingMenu = uimenu(app.SupervisedLearningMenu_4);
            app.TestingMenu.Text = 'Testing';
            app.ClassifyMenu = uimenu(app.TestingMenu);
            app.ClassifyMenu.MenuSelectedFcn = createCallbackFcn(app, @ClassifyMenuSelected, true);
            app.ClassifyMenu.Text = 'Classify';
            app.DataLabelingMenu = uimenu(app.SupervisedLearningMenu_4);
            app.DataLabelingMenu.Text = 'Data Labeling';
            app.SetApexPointMenu = uimenu(app.DataLabelingMenu);
            app.SetApexPointMenu.MenuSelectedFcn = createCallbackFcn(app, @SetApexPointMenuSelected, true);
            app.SetApexPointMenu.Text = 'Set Apex Point';
            app.SetLADPointMenu = uimenu(app.DataLabelingMenu);
            app.SetLADPointMenu.MenuSelectedFcn = createCallbackFcn(app, @SetLADPointMenuSelected, true);
            app.SetLADPointMenu.Text = 'Set LAD Point';
            app.SetLCxPointMenu = uimenu(app.DataLabelingMenu);
            app.SetLCxPointMenu.MenuSelectedFcn = createCallbackFcn(app, @SetLCxPointMenuSelected, true);
            app.SetLCxPointMenu.Text = 'Set LCx Point';
            app.SetRCAPointMenu = uimenu(app.DataLabelingMenu);
            app.SetRCAPointMenu.MenuSelectedFcn = createCallbackFcn(app, @SetRCAPointMenuSelected, true);
            app.SetRCAPointMenu.Text = 'Set RCA Point';
            app.RemoveLastPointMenu = uimenu(app.DataLabelingMenu);
            app.RemoveLastPointMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveLastPointMenuSelected, true);
            app.RemoveLastPointMenu.Text = 'Remove Last Point';
            app.ScreenCaptureMenu = uimenu(app.FileMenu);
            app.ScreenCaptureMenu.MenuSelectedFcn = createCallbackFcn(app, @ScreenCaptureMenuSelected, true);
            app.ScreenCaptureMenu.Text = 'Screen Capture';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Enable = 'off';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.DCECTAnalysisMenu_4 = uimenu(app.EditMenu);
            app.DCECTAnalysisMenu_4.Text = 'DCE CT Analysis';
            app.SetLevelWidthforStressTestMenu = uimenu(app.DCECTAnalysisMenu_4);
            app.SetLevelWidthforStressTestMenu.Visible = 'off';
            app.SetLevelWidthforStressTestMenu.Text = 'Set Level/Width for Stress Test';
            app.SetLevelWidthforCTATestMenu = uimenu(app.DCECTAnalysisMenu_4);
            app.SetLevelWidthforCTATestMenu.Visible = 'off';
            app.SetLevelWidthforCTATestMenu.Text = 'Set Level/Width for CTA Test';
            app.YaxisScalePreLesionMenu = uimenu(app.DCECTAnalysisMenu_4);
            app.YaxisScalePreLesionMenu.Text = 'Y-axis Scale (Pre Lesion)';
            app.AutoMenu = uimenu(app.YaxisScalePreLesionMenu);
            app.AutoMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoMenuSelected, true);
            app.AutoMenu.Checked = 'on';
            app.AutoMenu.Text = 'Auto';
            app.ManualMenu = uimenu(app.YaxisScalePreLesionMenu);
            app.ManualMenu.MenuSelectedFcn = createCallbackFcn(app, @ManualMenuSelected, true);
            app.ManualMenu.Text = 'Manual';
            app.YaxisScalePostLesionMenu = uimenu(app.DCECTAnalysisMenu_4);
            app.YaxisScalePostLesionMenu.Text = 'Y-axis Scale (Post Lesion)';
            app.AutoMenu_2 = uimenu(app.YaxisScalePostLesionMenu);
            app.AutoMenu_2.MenuSelectedFcn = createCallbackFcn(app, @AutoMenu_2Selected, true);
            app.AutoMenu_2.Checked = 'on';
            app.AutoMenu_2.Text = 'Auto';
            app.ManualMenu_2 = uimenu(app.YaxisScalePostLesionMenu);
            app.ManualMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ManualMenu_2Selected, true);
            app.ManualMenu_2.Text = 'Manual';
            app.StressandRestAnalysisMenu_4 = uimenu(app.EditMenu);
            app.StressandRestAnalysisMenu_4.Visible = 'off';
            app.StressandRestAnalysisMenu_4.Text = 'Stress and Rest Analysis';
            app.SetLevelWidthforStressTestMenu_2 = uimenu(app.StressandRestAnalysisMenu_4);
            app.SetLevelWidthforStressTestMenu_2.Text = 'Set Level/Width for Stress Test';
            app.SetLevelWidthforRestTestMenu = uimenu(app.StressandRestAnalysisMenu_4);
            app.SetLevelWidthforRestTestMenu.Text = 'Set Level/Width for Rest Test';
            app.YaxisScalePreLesionMenu_2 = uimenu(app.StressandRestAnalysisMenu_4);
            app.YaxisScalePreLesionMenu_2.Text = 'Y-axis Scale (Pre Lesion)';
            app.AutoMenu_3 = uimenu(app.YaxisScalePreLesionMenu_2);
            app.AutoMenu_3.MenuSelectedFcn = createCallbackFcn(app, @AutoMenu_3Selected, true);
            app.AutoMenu_3.Checked = 'on';
            app.AutoMenu_3.Text = 'Auto';
            app.ManualMenu_3 = uimenu(app.YaxisScalePreLesionMenu_2);
            app.ManualMenu_3.MenuSelectedFcn = createCallbackFcn(app, @ManualMenu_3Selected, true);
            app.ManualMenu_3.Text = 'Manual';
            app.YaxisScalePostLesionMenu_2 = uimenu(app.StressandRestAnalysisMenu_4);
            app.YaxisScalePostLesionMenu_2.Text = 'Y-axis Scale (Post Lesion)';
            app.AutoMenu_4 = uimenu(app.YaxisScalePostLesionMenu_2);
            app.AutoMenu_4.MenuSelectedFcn = createCallbackFcn(app, @AutoMenu_4Selected, true);
            app.AutoMenu_4.Checked = 'on';
            app.AutoMenu_4.Text = 'Auto';
            app.ManualMenu_4 = uimenu(app.YaxisScalePostLesionMenu_2);
            app.ManualMenu_4.MenuSelectedFcn = createCallbackFcn(app, @ManualMenu_4Selected, true);
            app.ManualMenu_4.Text = 'Manual';
            app.SupervisedLearningMenu_3 = uimenu(app.EditMenu);
            app.SupervisedLearningMenu_3.Visible = 'off';
            app.SupervisedLearningMenu_3.Text = 'Supervised Learning';
            app.SetWindowLevelMenu = uimenu(app.SupervisedLearningMenu_3);
            app.SetWindowLevelMenu.Text = 'Set Window/Level';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.Enable = 'off';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.OptionMenu = uimenu(app.ViewMenu);
            app.OptionMenu.Visible = 'off';
            app.OptionMenu.Text = 'Option';
            app.BasicMenu = uimenu(app.OptionMenu);
            app.BasicMenu.MenuSelectedFcn = createCallbackFcn(app, @BasicMenuSelected, true);
            app.BasicMenu.Text = 'Basic';
            app.FullMenu = uimenu(app.OptionMenu);
            app.FullMenu.MenuSelectedFcn = createCallbackFcn(app, @FullMenuSelected, true);
            app.FullMenu.Checked = 'on';
            app.FullMenu.Text = 'Full';
            app.BackgroundMenu = uimenu(app.ViewMenu);
            app.BackgroundMenu.Text = 'Background';
            app.BrightMenu = uimenu(app.BackgroundMenu);
            app.BrightMenu.MenuSelectedFcn = createCallbackFcn(app, @BrightMenuSelected, true);
            app.BrightMenu.Checked = 'on';
            app.BrightMenu.Text = 'Bright';
            app.DarkMenu = uimenu(app.BackgroundMenu);
            app.DarkMenu.MenuSelectedFcn = createCallbackFcn(app, @DarkMenuSelected, true);
            app.DarkMenu.Text = 'Dark';
            app.FontMenu = uimenu(app.ViewMenu);
            app.FontMenu.Text = 'Font';
            app.ArialMenu = uimenu(app.FontMenu);
            app.ArialMenu.MenuSelectedFcn = createCallbackFcn(app, @ArialMenuSelected, true);
            app.ArialMenu.Checked = 'on';
            app.ArialMenu.Text = 'Arial';
            app.PalatinoLinotypeMenu = uimenu(app.FontMenu);
            app.PalatinoLinotypeMenu.MenuSelectedFcn = createCallbackFcn(app, @PalatinoLinotypeMenuSelected, true);
            app.PalatinoLinotypeMenu.Text = 'Palatino Linotype';
            app.TimesMenu = uimenu(app.FontMenu);
            app.TimesMenu.MenuSelectedFcn = createCallbackFcn(app, @TimesMenuSelected, true);
            app.TimesMenu.Text = 'Times';
            app.Menu_3 = uimenu(app.UIFigure);
            app.Menu_3.Enable = 'off';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutDAIVeraMenu = uimenu(app.HelpMenu);
            app.AboutDAIVeraMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutDAIVeraMenuSelected, true);
            app.AboutDAIVeraMenu.Text = 'About DAI Vera';
            app.UserGuideMenu = uimenu(app.HelpMenu);
            app.UserGuideMenu.MenuSelectedFcn = createCallbackFcn(app, @UserGuideMenuSelected, true);
            app.UserGuideMenu.Text = 'User Guide';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StressOnly = uitab(app.TabGroup);
            app.StressOnly.Title = 'DCE CT Analysis';
            app.GridLayout = uigridlayout(app.StressOnly);
            app.FFRVsSliceLocation = uiaxes(app.GridLayout);
            title(app.FFRVsSliceLocation, 'FFR / Flow Velocity Graph')
            xlabel(app.FFRVsSliceLocation, 'Slice Number')
            ylabel(app.FFRVsSliceLocation, 'FFR')
            zlabel(app.FFRVsSliceLocation, 'Z')
            app.FFRVsSliceLocation.XLim = [1 52];
            app.ImageAxesStressOnly = uiaxes(app.GridLayout);
            app.ImageAxesStressOnly.XTick = [];
            app.ImageAxesStressOnly.XTickLabel = {'[ ]'};
            app.ImageAxesStressOnly.YTick = [];
            app.paPlotAreaStressOnly = uiaxes(app.GridLayout);
            title(app.paPlotAreaStressOnly, 'Pre-lesion / Entry Curve')
            xlabel(app.paPlotAreaStressOnly, 'Time (s)')
            ylabel(app.paPlotAreaStressOnly, 'Enhancement (HU)')
            app.paPlotAreaStressOnly.XLim = [1 10];
            app.paPlotAreaStressOnly.YLim = [-50 500];
            app.paPlotAreaStressOnly.XTick = [1 2 3 4 5 6 7 8 9 10];
            app.paPlotAreaStressOnly.YTick = [-50 50 150 250 350 450];
            app.ImageAxesCTAStressOnly = uiaxes(app.GridLayout);
            zlabel(app.ImageAxesCTAStressOnly, 'Z')
            app.ImageAxesCTAStressOnly.XTick = [];
            app.ImageAxesCTAStressOnly.YTick = [];
            app.pdPlotAreaStressOnly = uiaxes(app.GridLayout);
            title(app.pdPlotAreaStressOnly, 'Post-lesion / Exit Curve')
            xlabel(app.pdPlotAreaStressOnly, 'Time (s)')
            ylabel(app.pdPlotAreaStressOnly, 'Enhancement (HU)')
            app.pdPlotAreaStressOnly.XLim = [1 10];
            app.pdPlotAreaStressOnly.YLim = [-50 500];
            app.pdPlotAreaStressOnly.XTick = [1 2 3 4 5 6 7 8 9 10];
            app.pdPlotAreaStressOnly.YTick = [-50 50 150 250 350 450];
            app.BaselineLabel = uilabel(app.GridLayout);
            app.BaselineLabel.Text = 'Baseline';
            app.WashoutLabel = uilabel(app.GridLayout);
            app.WashoutLabel.Text = 'Washout';
            app.EditPointCheckBox = uicheckbox(app.GridLayout);
            app.EditPointCheckBox.Text = 'Edit Point';
            app.DynamicAngiographicImagingDAIVeraPanel_2 = uipanel(app.GridLayout);
            app.DynamicAngiographicImagingDAIVeraPanel_2.Title = 'Dynamic Angiographic Imaging (DAI) - Vera ';
            app.UnauthorizedUseisProhibitedLabelStressOnly = uilabel(app.DynamicAngiographicImagingDAIVeraPanel_2);
            app.UnauthorizedUseisProhibitedLabelStressOnly.Text = 'Unauthorized Use is Prohibited';
            app.ClearButton_2 = uibutton(app.GridLayout, 'push');
            app.ClearButton_2.Text = 'Clear';
            app.FitCurvesButtonStressOnly = uibutton(app.GridLayout, 'push');
            app.FitCurvesButtonStressOnly.Text = {'Fit'; 'Curves'};
            app.ManualBaselineInputStressOnly = uitextarea(app.GridLayout);
            app.SetLabel = uilabel(app.GridLayout);
            app.SetLabel.Text = 'Set';
            app.ManualWashoutInputStressOnly = uitextarea(app.GridLayout);
            app.RemovePointPreCheckBoxStressOnly = uicheckbox(app.GridLayout);
            app.RemovePointPreCheckBoxStressOnly.Text = 'Remove Point';
            app.StressStudyLabelStressOnly = uilabel(app.GridLayout);
            app.StressStudyLabelStressOnly.Text = 'DCE CT Images';
            app.CTAStudyLabelStressOnly = uilabel(app.GridLayout);
            app.CTAStudyLabelStressOnly.Text = 'CTA Images';
            app.ManualZoomingCheckBoxStressOnly = uicheckbox(app.GridLayout);
            app.ManualZoomingCheckBoxStressOnly.Visible = 'off';
            app.ManualZoomingCheckBoxStressOnly.Text = 'Manual Zooming';
            app.SlicesSliderStressOnly = uislider(app.GridLayout);
            app.SlicesSliderStressOnly.Limits = [1 50];
            app.SlicesSliderStressOnly.MajorTicks = [1 5 10 15 20 25 30 35 40 45 50];
            app.SlicesSliderStressOnly.Value = 1;
            app.SlicesSliderCTAStressOnly = uislider(app.GridLayout);
            app.SlicesSliderCTAStressOnly.Limits = [1 100];
            app.SlicesSliderCTAStressOnly.MajorTicks = [1 11 21 31 41 51 61 71 81 91 100];
            app.SlicesSliderCTAStressOnly.Value = 1;
            app.BaselineLabel_2 = uilabel(app.GridLayout);
            app.BaselineLabel_2.Text = 'Baseline';
            app.WashoutLabel_2 = uilabel(app.GridLayout);
            app.WashoutLabel_2.Text = 'Washout';
            app.EditPointCheckBox_2 = uicheckbox(app.GridLayout);
            app.EditPointCheckBox_2.Text = 'Edit Point';
            app.ClearButton_3 = uibutton(app.GridLayout, 'push');
            app.ClearButton_3.Text = 'Clear';
            app.FitCurvesButtonStressOnly_2 = uibutton(app.GridLayout, 'push');
            app.FitCurvesButtonStressOnly_2.Text = {'Fit'; 'Curves'};
            app.ManualBaselineInputStressOnly_2 = uitextarea(app.GridLayout);
            app.SetLabel_2 = uilabel(app.GridLayout);
            app.SetLabel_2.Text = 'Set';
            app.ManualWashoutInputStressOnly_2 = uitextarea(app.GridLayout);
            app.RemovePointPostCheckBoxStressOnly = uicheckbox(app.GridLayout);
            app.RemovePointPostCheckBoxStressOnly.Text = 'Remove Point';
            app.HUTextArea_2 = uitextarea(app.GridLayout);
            app.HUTextArea_2.Visible = 'off';
            app.SlicesSliderCTAStressOnlyLabel = uilabel(app.GridLayout);
            app.SlicesSliderCTAStressOnlyLabel.Text = 'Slices';
            app.SlicesSliderStressOnlyLabel = uilabel(app.GridLayout);
            app.SlicesSliderStressOnlyLabel.Text = 'Slices';
            app.TimePointsSliderStressOnly = uislider(app.GridLayout);
            app.TimePointsSliderStressOnly.Limits = [1 10];
            app.TimePointsSliderStressOnly.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.TimePointsSliderStressOnly.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.TimePointsSliderStressOnly.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4 4.1 4.2 4.3 4.4 4.5 4.6 4.7 4.8 4.9 5 5.1 5.2 5.3 5.4 5.5 5.6 5.7 5.8 5.9 6 6.1 6.2 6.3 6.4 6.5 6.6 6.7 6.8 6.9 7 7.1 7.2 7.3 7.4 7.5 7.6 7.7 7.8 7.9 8 8.1 8.2 8.3 8.4 8.5 8.6 8.7 8.8 8.9 9 9.1 9.2 9.3 9.4 9.5 9.6 9.7 9.8 9.9 10];
            app.TimePointsSliderStressOnly.Value = 1;
            app.TimePointsSliderStressOnlyLabel = uilabel(app.GridLayout);
            app.TimePointsSliderStressOnlyLabel.Text = {'Time '; 'Points'};
            app.SetWindowLevelCTAStressOnly = uitextarea(app.GridLayout);
            app.SetWindowLevelCTAStressOnly.Placeholder = 'Level';
            app.SetWindowWidthCTAStressOnly = uitextarea(app.GridLayout);
            app.SetWindowWidthCTAStressOnly.Placeholder = 'Width';
            app.SetWindowButtonCTAStressOnly = uibutton(app.GridLayout, 'push');
            app.SetWindowButtonCTAStressOnly.Text = 'Set Window';
            app.SetWindowLevelStressOnly = uitextarea(app.GridLayout);
            app.SetWindowLevelStressOnly.Placeholder = 'Level';
            app.SetWindowWidthStressOnly = uitextarea(app.GridLayout);
            app.SetWindowWidthStressOnly.Placeholder = 'Width';
            app.SetWindowButtonStressOnly = uibutton(app.GridLayout, 'push');
            app.SetWindowButtonStressOnly.Text = 'Set Window';
            app.SetPreLesionButtonStressOnly = uibutton(app.GridLayout, 'push');
            app.SetPreLesionButtonStressOnly.Text = 'Pre Lesion';
            app.SetPostLesionStressOnly = uibutton(app.GridLayout, 'push');
            app.SetPostLesionStressOnly.Text = 'Post Lesion';
            app.veHeightCheckBoxStressOnly = uicheckbox(app.GridLayout);
            app.veHeightCheckBoxStressOnly.Text = 'Height (+ve)';
            app.MButton = uibutton(app.GridLayout, 'push');
            app.MButton.Text = 'M';
            app.SampleROILabel = uilabel(app.GridLayout);
            app.SampleROILabel.Text = {'Sample '; 'ROI'};
            app.SamplingROIDropDownStressOnly = uidropdown(app.GridLayout);
            app.SamplingROIDropDownStressOnly.Items = {'1x1', '2x2', '3x3', '1x2', '2x1', '20x20', '30x30', '40x40', 'Freehand'};
            app.SamplingROIDropDownStressOnly.Value = '2x2';
            app.SearchROIDropDown_2Label_2 = uilabel(app.GridLayout);
            app.SearchROIDropDown_2Label_2.Text = {'Search '; 'ROI'};
            app.SearchROIDropDownStressOnly = uidropdown(app.GridLayout);
            app.SearchROIDropDownStressOnly.Items = {'6x6', '8x8', '10x10', '12x12', '16x16', '20x20', '30x30', '40x40'};
            app.SearchROIDropDownStressOnly.Value = '6x6';
            app.InterpolateCurrentSliceWithDropDown_2Label_2 = uilabel(app.GridLayout);
            app.InterpolateCurrentSliceWithDropDown_2Label_2.Text = 'Interpolate Current Slice With';
            app.InterpolateCurrentSliceWithDropDownStressOnly = uidropdown(app.GridLayout);
            app.InterpolateCurrentSliceWithDropDownStressOnly.Items = {'None', 'Previous Slice', 'Next Slice'};
            app.InterpolateCurrentSliceWithDropDownStressOnly.Value = 'None';
            app.InputParametersforStressStudyPanelStressOnly = uipanel(app.GridLayout);
            app.InputParametersforStressStudyPanelStressOnly.Title = 'Input Parameters';
            app.CoronaryArteryDropDownLabel = uilabel(app.InputParametersforStressStudyPanelStressOnly);
            app.CoronaryArteryDropDownLabel.Text = 'Coronary Artery';
            app.CoronaryArteryDropDown = uidropdown(app.InputParametersforStressStudyPanelStressOnly);
            app.CoronaryArteryDropDown.Items = {'RCA', 'LM', 'LAD', 'LCX', 'LM-LAD', 'LM-LCX', 'IB', 'LM-IB'};
            app.CoronaryArteryDropDown.Value = 'RCA';
            app.OutputButtonStressOnly = uibutton(app.InputParametersforStressStudyPanelStressOnly, 'push');
            app.OutputButtonStressOnly.Text = 'Compute';
            app.CoronaryDominanceDropDownLabel = uilabel(app.InputParametersforStressStudyPanelStressOnly);
            app.CoronaryDominanceDropDownLabel.Text = 'Coronary Dominance';
            app.CoronaryDominanceDropDown = uidropdown(app.InputParametersforStressStudyPanelStressOnly);
            app.CoronaryDominanceDropDown.Items = {'Right', 'Left', 'Co'};
            app.CoronaryDominanceDropDown.Value = 'Right';
            app.ClearInputsButtonStressOnly = uibutton(app.InputParametersforStressStudyPanelStressOnly, 'push');
            app.ClearInputsButtonStressOnly.Text = 'Clear Inputs';
            app.RestorStressConditionDropDownLabel = uilabel(app.InputParametersforStressStudyPanelStressOnly);
            app.RestorStressConditionDropDownLabel.Text = 'Rest or Stress Condition';
            app.RestorStressConditionDropDown = uidropdown(app.InputParametersforStressStudyPanelStressOnly);
            app.RestorStressConditionDropDown.Items = {'Rest', 'Stress'};
            app.RestorStressConditionDropDown.Value = 'Rest';
            app.FrictionFactorButtonGroupStressOnly = uibuttongroup(app.InputParametersforStressStudyPanelStressOnly);
            app.FrictionFactorButtonGroupStressOnly.Title = 'Friction Factor';
            app.ChurchillButtonStressOnly = uiradiobutton(app.FrictionFactorButtonGroupStressOnly);
            app.ChurchillButtonStressOnly.Text = 'Churchill';
            app.ColebrookButtonStressOnly = uiradiobutton(app.FrictionFactorButtonGroupStressOnly);
            app.ColebrookButtonStressOnly.Text = 'Colebrook';
            app.ColebrookButtonStressOnly.Value = true;
            app.XrayTubeVoltagekVDropDownLabel = uilabel(app.InputParametersforStressStudyPanelStressOnly);
            app.XrayTubeVoltagekVDropDownLabel.Text = 'X-ray Tube Voltage (kV)';
            app.XrayTubeVoltagekVDropDown = uidropdown(app.InputParametersforStressStudyPanelStressOnly);
            app.XrayTubeVoltagekVDropDown.Items = {'80', '100', '120'};
            app.XrayTubeVoltagekVDropDown.Value = '80';
            app.ArterialBloodPressuremmHgLabel = uilabel(app.InputParametersforStressStudyPanelStressOnly);
            app.ArterialBloodPressuremmHgLabel.Text = 'Arterial Blood Pressure (mmHg)';
            app.SystolicBloodPressuremmHgEditFieldStressOnly = uieditfield(app.InputParametersforStressStudyPanelStressOnly, 'text');
            app.ArteryTypeButtonGroupStressOnly = uibuttongroup(app.InputParametersforStressStudyPanelStressOnly);
            app.ArteryTypeButtonGroupStressOnly.Title = 'Artery Type';
            app.CoronaryButtonStressOnly = uiradiobutton(app.ArteryTypeButtonGroupStressOnly);
            app.CoronaryButtonStressOnly.Text = 'Coronary';
            app.CoronaryButtonStressOnly.Value = true;
            app.AortaButtonStressOnly = uiradiobutton(app.ArteryTypeButtonGroupStressOnly);
            app.AortaButtonStressOnly.Text = 'Aorta';
            app.ContrastVolumemLEditField_2Label = uilabel(app.InputParametersforStressStudyPanelStressOnly);
            app.ContrastVolumemLEditField_2Label.Text = 'Contrast Volume (mL)';
            app.ContrastVolumemLEditFieldStressOnly = uieditfield(app.InputParametersforStressStudyPanelStressOnly, 'text');
            app.ContrastConcentrationmgmLLabel = uilabel(app.InputParametersforStressStudyPanelStressOnly);
            app.ContrastConcentrationmgmLLabel.Text = 'Contrast Concentration (mg/mL)';
            app.ContrastConcentrationmglmLEditFieldStressOnly = uieditfield(app.InputParametersforStressStudyPanelStressOnly, 'text');
            app.MarkBranchCheckBox = uicheckbox(app.InputParametersforStressStudyPanelStressOnly);
            app.MarkBranchCheckBox.Text = 'Mark Branch';
            app.PrelesionLumenRadiuscmLabel_2 = uilabel(app.InputParametersforStressStudyPanelStressOnly);
            app.PrelesionLumenRadiuscmLabel_2.Text = 'Pre-lesion Lumen Radius (cm)';
            app.PrelesionLumenRadiuscmEditFieldStressOnly = uieditfield(app.InputParametersforStressStudyPanelStressOnly, 'text');
            app.MarkStenosisCheckBox = uicheckbox(app.InputParametersforStressStudyPanelStressOnly);
            app.MarkStenosisCheckBox.Text = 'Mark Stenosis';
            app.PostlesionLumenRadiuscmEditField_2Label = uilabel(app.InputParametersforStressStudyPanelStressOnly);
            app.PostlesionLumenRadiuscmEditField_2Label.Text = 'Post-lesion Lumen Radius (cm)';
            app.PostlesionLumenRadiuscmEditFieldStressOnly = uieditfield(app.InputParametersforStressStudyPanelStressOnly, 'text');
            app.MarkBreakerCheckBox = uicheckbox(app.InputParametersforStressStudyPanelStressOnly);
            app.MarkBreakerCheckBox.Text = 'Mark Breaker';
            app.IntermediatebranchDropDownLabel = uilabel(app.InputParametersforStressStudyPanelStressOnly);
            app.IntermediatebranchDropDownLabel.Text = 'Intermediate branch';
            app.IntermediatebranchDropDown = uidropdown(app.InputParametersforStressStudyPanelStressOnly);
            app.IntermediatebranchDropDown.Items = {'No', 'Yes – 1 LCX segment', 'Yes – 2 LCX segments', 'Yes – 3 LCX segments', 'Yes – 1 LAD segments', 'Yes – 2 LAD segments', 'Yes – 3 LAD segments'};
            app.IntermediatebranchDropDown.Value = 'No';
            app.OutputParametersPanelStressOnly = uipanel(app.GridLayout);
            app.OutputParametersPanelStressOnly.Title = 'Output Parameters                                  ';
            app.StressLabelStressOnly = uilabel(app.OutputParametersPanelStressOnly);
            app.StressLabelStressOnly.Visible = 'off';
            app.StressLabelStressOnly.Text = 'Stress';
            app.FFREditField_2Label = uilabel(app.OutputParametersPanelStressOnly);
            app.FFREditField_2Label.Text = 'FFR';
            app.FFREditFieldStressOnly = uieditfield(app.OutputParametersPanelStressOnly, 'text');
            app.PrelesionFlowVelocitycmsEditField_2Label = uilabel(app.OutputParametersPanelStressOnly);
            app.PrelesionFlowVelocitycmsEditField_2Label.Text = 'Pre-lesion Flow Velocity (cm/s)';
            app.PrelesionFlowVelocitycmsEditFieldStressOnly = uieditfield(app.OutputParametersPanelStressOnly, 'text');
            app.PostlesionFlowVelocitycmsEditField_2Label = uilabel(app.OutputParametersPanelStressOnly);
            app.PostlesionFlowVelocitycmsEditField_2Label.Text = 'Post-lesion Flow Velocity (cm/s)';
            app.PostlesionFlowVelocitycmsEditFieldStressOnly = uieditfield(app.OutputParametersPanelStressOnly, 'text');
            app.PrelesionShearStressPaEditField_2Label = uilabel(app.OutputParametersPanelStressOnly);
            app.PrelesionShearStressPaEditField_2Label.Text = 'Pre-lesion Shear Stress (Pa)';
            app.PrelesionShearStressPaEditFieldStressOnly = uieditfield(app.OutputParametersPanelStressOnly, 'text');
            app.PostlesionShearStressPaEditField_2Label = uilabel(app.OutputParametersPanelStressOnly);
            app.PostlesionShearStressPaEditField_2Label.Text = 'Post-lesion Shear Stress (Pa)';
            app.PostlesionShearStressPaEditFieldStressOnly = uieditfield(app.OutputParametersPanelStressOnly, 'text');
            app.ClearOutputsButtonStressOnly = uibutton(app.OutputParametersPanelStressOnly, 'push');
            app.ClearOutputsButtonStressOnly.Text = 'Clear Outputs';
            app.GraphAppearanceDropDownLabel = uilabel(app.GridLayout);
            app.GraphAppearanceDropDownLabel.Text = 'Graph Appearance';
            app.GraphAppearanceDropDown = uidropdown(app.GridLayout);
            app.GraphAppearanceDropDown.Items = {'Straight Line', 'Smooth Line', 'With Marker', 'Without Marker'};
            app.GraphAppearanceDropDown.Value = 'Straight Line';
            app.ClearButton = uibutton(app.GridLayout, 'push');
            app.ClearButton.Text = 'Clear';
            app.FlipSliceNumbersCheckBox = uicheckbox(app.GridLayout);
            app.FlipSliceNumbersCheckBox.Text = 'Flip Slice Numbers';
            app.LevelSlider = uislider(app.GridLayout);
            app.LevelSlider.Limits = [1 1000];
            app.LevelSlider.MajorTicks = [1 250 500 750 1000];
            app.LevelSlider.MajorTickLabels = {'1', '250', '500', '750', '1000'};
            app.LevelSlider.MinorTicks = [1 41 81 121 161 201 241 281 321 361 401 441 481 521 561 601 641 681 721 761 801 841 881 921 961 1001 1041 1081 1121 1161 1201 1241 1281 1321 1361 1401 1441 1481 1521 1561 1601 1641 1681 1721 1761 1801 1841 1881 1921 1961 2000];
            app.LevelSlider.Value = 1;
            app.SetTranslationButton = uibutton(app.GridLayout, 'push');
            app.SetTranslationButton.Text = 'Set Translation';
            app.ImportLesionButton = uibutton(app.GridLayout, 'push');
            app.ImportLesionButton.Text = 'Import Lesion';
            app.HUTextArea_4 = uitextarea(app.GridLayout);
            app.HUTextArea_4.Visible = 'off';
            app.LevelSliderCTA = uislider(app.GridLayout);
            app.LevelSliderCTA.Limits = [1 1000];
            app.LevelSliderCTA.MajorTicks = [1 250 500 750 1000];
            app.LevelSliderCTA.Value = 1;
            app.WidthSliderCTA = uislider(app.GridLayout);
            app.WidthSliderCTA.Limits = [1 1000];
            app.WidthSliderCTA.MajorTicks = [1 250 500 750 1000];
            app.WidthSliderCTA.Value = 1;
            app.WidthSlider = uislider(app.GridLayout);
            app.WidthSlider.Limits = [1 1000];
            app.WidthSlider.MajorTicks = [1 250 500 750 1000];
            app.WidthSlider.Value = 1;
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.SetYaxisMaxMenu = uimenu(app.ContextMenu);
            app.SetYaxisMaxMenu.MenuSelectedFcn = createCallbackFcn(app, @SetYaxisMaxMenuSelected, true);
            app.SetYaxisMaxMenu.Text = 'Set Y-axis Max';
            % Assign app.ContextMenu
            app.paPlotAreaStressOnly.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.SetYaxisMaxMenu_2 = uimenu(app.ContextMenu2);
            app.SetYaxisMaxMenu_2.MenuSelectedFcn = createCallbackFcn(app, @SetYaxisMaxMenu_2Selected, true);
            app.SetYaxisMaxMenu_2.Text = 'Set Y-axis Max';
            % Assign app.ContextMenu2
            app.pdPlotAreaStressOnly.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DCTFFRGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end