classdef STENOSISGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MarkStenosisUIFigure            matlab.ui.Figure
        MarkstenosisbetweenPrelesionandPostlesionSitesLabel  matlab.ui.control.Label
        ExitLabel_5                     matlab.ui.control.Label
        EditField_5                     matlab.ui.control.NumericEditField
        EntryLabel_5                    matlab.ui.control.Label
        NumberofbranchbetweenprelesionandstenosisEditField_5  matlab.ui.control.NumericEditField
        NumberofbranchbetweenprelesionandstenosisEditField_5Label  matlab.ui.control.Label
        ExitLabel_4                     matlab.ui.control.Label
        EditField_4                     matlab.ui.control.NumericEditField
        EntryLabel_4                    matlab.ui.control.Label
        NumberofbranchbetweenprelesionandstenosisEditField_4  matlab.ui.control.NumericEditField
        NumberofbranchbetweenprelesionandstenosisEditField_4Label  matlab.ui.control.Label
        ExitLabel_3                     matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        EntryLabel_3                    matlab.ui.control.Label
        NumberofbranchbetweenprelesionandstenosisEditField_3  matlab.ui.control.NumericEditField
        NumberofbranchbetweenprelesionandstenosisEditField_3Label  matlab.ui.control.Label
        ExitLabel_2                     matlab.ui.control.Label
        EditField_2                     matlab.ui.control.NumericEditField
        EntryLabel_2                    matlab.ui.control.Label
        NumberofbranchbetweenprelesionandstenosisEditField_2  matlab.ui.control.NumericEditField
        NumberofbranchbetweenprelesionandstenosisEditField_2Label  matlab.ui.control.Label
        ExitLabel                       matlab.ui.control.Label
        EntryLabel                      matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        NumberofbranchbetweenprelesionandstenosisEditField  matlab.ui.control.NumericEditField
        NumberofbranchbetweenprelesionandstenosisEditFieldLabel  matlab.ui.control.Label
        EnterButton                     matlab.ui.control.Button
        ClearButton_5                   matlab.ui.control.Button
        ExitLumenRadiuscmEditField_5    matlab.ui.control.NumericEditField
        ExitLumenRadiuscmEditField_5Label  matlab.ui.control.Label
        EntryLumenRadiuscmEditField_5   matlab.ui.control.NumericEditField
        EntryLumenRadiuscmEditField_5Label  matlab.ui.control.Label
        LumenNarrowingEditField_5       matlab.ui.control.NumericEditField
        LumenNarrowingEditField_5Label  matlab.ui.control.Label
        ExitSliceNumberEditField_5      matlab.ui.control.NumericEditField
        ExitSliceNumberEditField_5Label  matlab.ui.control.Label
        EntrySliceNumberEditField_5     matlab.ui.control.NumericEditField
        EntrySliceNumberEditField_5Label  matlab.ui.control.Label
        Stenosis5CheckBox               matlab.ui.control.CheckBox
        ClearButton_4                   matlab.ui.control.Button
        ExitLumenRadiuscmEditField_4    matlab.ui.control.NumericEditField
        ExitLumenRadiuscmEditField_4Label  matlab.ui.control.Label
        EntryLumenRadiuscmEditField_4   matlab.ui.control.NumericEditField
        EntryLumenRadiuscmEditField_4Label  matlab.ui.control.Label
        LumenNarrowingEditField_4       matlab.ui.control.NumericEditField
        LumenNarrowingEditField_4Label  matlab.ui.control.Label
        ExitSliceNumberEditField_4      matlab.ui.control.NumericEditField
        ExitSliceNumberEditField_4Label  matlab.ui.control.Label
        EntrySliceNumberEditField_4     matlab.ui.control.NumericEditField
        EntrySliceNumberEditField_4Label  matlab.ui.control.Label
        Stenosis4CheckBox               matlab.ui.control.CheckBox
        ClearButton_3                   matlab.ui.control.Button
        ExitLumenRadiuscmEditField_3    matlab.ui.control.NumericEditField
        ExitLumenRadiuscmEditField_3Label  matlab.ui.control.Label
        EntryLumenRadiuscmEditField_3   matlab.ui.control.NumericEditField
        EntryLumenRadiuscmEditField_3Label  matlab.ui.control.Label
        LumenNarrowingEditField_3       matlab.ui.control.NumericEditField
        LumenNarrowingEditField_3Label  matlab.ui.control.Label
        ExitSliceNumberEditField_3      matlab.ui.control.NumericEditField
        ExitSliceNumberEditField_3Label  matlab.ui.control.Label
        EntrySliceNumberEditField_3     matlab.ui.control.NumericEditField
        EntrySliceNumberEditField_3Label  matlab.ui.control.Label
        Stenosis3CheckBox               matlab.ui.control.CheckBox
        ClearButton_2                   matlab.ui.control.Button
        ExitLumenRadiuscmEditField_2    matlab.ui.control.NumericEditField
        ExitLumenRadiuscmEditField_2Label  matlab.ui.control.Label
        EntryLumenRadiuscmEditField_2   matlab.ui.control.NumericEditField
        EntryLumenRadiuscmEditField_2Label  matlab.ui.control.Label
        LumenNarrowingEditField_2       matlab.ui.control.NumericEditField
        LumenNarrowingEditField_2Label  matlab.ui.control.Label
        ExitSliceNumberEditField_2      matlab.ui.control.NumericEditField
        ExitSliceNumberEditField_2Label  matlab.ui.control.Label
        EntrySliceNumberEditField_2     matlab.ui.control.NumericEditField
        EntrySliceNumberEditField_2Label  matlab.ui.control.Label
        Stenosis2CheckBox               matlab.ui.control.CheckBox
        ClearButton                     matlab.ui.control.Button
        ExitLumenRadiuscmEditField      matlab.ui.control.NumericEditField
        ExitLumenRadiuscmLabel          matlab.ui.control.Label
        EntryLumenRadiuscmEditField     matlab.ui.control.NumericEditField
        EntryLumenRadiuscmEditFieldLabel  matlab.ui.control.Label
        LumenNarrowingEditField         matlab.ui.control.NumericEditField
        LumenNarrowingEditFieldLabel    matlab.ui.control.Label
        ExitSliceNumberEditField        matlab.ui.control.NumericEditField
        ExitSliceNumberEditFieldLabel   matlab.ui.control.Label
        EntrySliceNumberEditField       matlab.ui.control.NumericEditField
        EntrySliceNumberEditFieldLabel  matlab.ui.control.Label
        Stenosis1CheckBox               matlab.ui.control.CheckBox
    end


    properties (Access = private)
        DCTFFRGUI;
        BRANCHCURVE;
    end

    events
        AppClosedEvent;
    end

    properties (Access = public)
        numberOfStenosis = 0;
        plotToRemove = [];
        colorBackgroundNormalMode = [0.0 0.0 0.0];
        colorBackgroundDarkMode = [0.0 0.0 0.0];
        colorTextFieldNormalMode = [0.07,0.07,0.07];
        colorTextFieldDarkMode = [0.07,0.07,0.07];
        colorRegularFontDarkMode = [1.0 1.0 1.0];
        colorRegularFontNormalMode = [1.0 1.0 1.0];
    end

    methods (Access = public)

        function deletePlotIDFromRemovalList(app, plotID)
            app.plotToRemove = app.plotToRemove(app.plotToRemove ~= plotID);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DCTFFRGUI)
            % Center the app
            screenSize = get(0, 'ScreenSize');
            appWidth = 1081;
            appHeight =  331;
            appX = (screenSize(3) - appWidth) / 2;
            appY = (screenSize(4) - appHeight) / 2;
            app.MarkStenosisUIFigure.Position = [appX, appY, appWidth, appHeight];
            
            % Connecting with Main App
            app.DCTFFRGUI = DCTFFRGUI;
            % Change View Mode
            sChangeViewMode(app, app.DCTFFRGUI.viewMode);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.ExitLumenRadiuscmEditField.Value = 0;
            app.EntryLumenRadiuscmEditField.Value = 0;
            app.LumenNarrowingEditField.Value = 0;
            app.ExitSliceNumberEditField.Value = 0;
            app.EntrySliceNumberEditField.Value = 0;
            app.NumberofbranchbetweenprelesionandstenosisEditField.Value = 0;
            app.EditField.Value = 0;
            deletePlotIDFromRemovalList(app, 1);
        end

        % Value changed function: Stenosis1CheckBox
        function Stenosis1CheckBoxValueChanged(app, event)
            valuesPresentInTheEditFields = false;
            if (app.EntrySliceNumberEditField.Value > 0 || ...
                    app.ExitSliceNumberEditField.Value > 0 || ...
                    app.LumenNarrowingEditField.Value > 0 || ...
                    app.EntryLumenRadiuscmEditField.Value > 0 || ...
                    app.ExitLumenRadiuscmEditField.Value > 0)
                valuesPresentInTheEditFields = true;
            end
            value = app.Stenosis1CheckBox.Value;
            if (value == true)
                app.ExitLumenRadiuscmEditField.Enable = true;
                app.EntryLumenRadiuscmEditField.Enable = true;
                app.LumenNarrowingEditField.Enable = true;
                app.ExitSliceNumberEditField.Enable = true;
                app.EntrySliceNumberEditField.Enable = true;
                app.ExitLumenRadiuscmLabel.Enable = true;
                app.EntryLumenRadiuscmEditFieldLabel.Enable = true;
                app.LumenNarrowingEditFieldLabel.Enable = true;
                app.ExitSliceNumberEditFieldLabel.Enable = true;
                app.EntrySliceNumberEditFieldLabel.Enable = true;
                app.ClearButton.Enable = true;
                app.NumberofbranchbetweenprelesionandstenosisEditFieldLabel.Enable = true;
                app.NumberofbranchbetweenprelesionandstenosisEditField.Enable = true;
                app.EditField.Enable = true;
                app.EntryLabel.Enable = true;
                app.ExitLabel.Enable = true;
                app.numberOfStenosis = app.numberOfStenosis + 1;
                if (valuesPresentInTheEditFields)
                    deletePlotIDFromRemovalList(app, 1);
                end
            else
                app.ExitLumenRadiuscmEditField.Enable = false;
                app.EntryLumenRadiuscmEditField.Enable = false;
                app.LumenNarrowingEditField.Enable = false;
                app.ExitSliceNumberEditField.Enable = false;
                app.EntrySliceNumberEditField.Enable = false;
                app.ExitLumenRadiuscmLabel.Enable = false;
                app.EntryLumenRadiuscmEditFieldLabel.Enable = false;
                app.LumenNarrowingEditFieldLabel.Enable = false;
                app.ExitSliceNumberEditFieldLabel.Enable = false;
                app.EntrySliceNumberEditFieldLabel.Enable = false;
                app.ClearButton.Enable = false;
                app.NumberofbranchbetweenprelesionandstenosisEditFieldLabel.Enable = false;
                app.NumberofbranchbetweenprelesionandstenosisEditField.Enable = false;
                app.EditField.Enable = false;
                app.EntryLabel.Enable = false;
                app.ExitLabel.Enable = false;
                app.numberOfStenosis = app.numberOfStenosis - 1;
                if (valuesPresentInTheEditFields)
                    app.plotToRemove(end + 1) = 1;
                    app.plotToRemove= unique(app.plotToRemove);
                end
            end
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            
            CheckBranchBetweenNumber = [app.EditField.Value-app.NumberofbranchbetweenprelesionandstenosisEditField.Value,app.EditField_2.Value-app.NumberofbranchbetweenprelesionandstenosisEditField_2.Value,...
                app.EditField_3.Value-app.NumberofbranchbetweenprelesionandstenosisEditField_3.Value,app.EditField_4.Value-app.NumberofbranchbetweenprelesionandstenosisEditField_4.Value,app.EditField_5.Value-app.NumberofbranchbetweenprelesionandstenosisEditField_5.Value];
            

            for i = 1: size(CheckBranchBetweenNumber,1)
                if CheckBranchBetweenNumber(i) < 0
                    errorMessage = sprintf('"Number Of Entry and Exit Branches for Stenosis %d Are invalid! Exit Branches must be equal or greater than Entry Branches"',i);
                    msgbox(errorMessage,"Error","error");
                else
                getStenosisDataFromStenosisGUI(app.DCTFFRGUI);
                end
            end
        end

        % Value changed function: Stenosis2CheckBox
        function Stenosis2CheckBoxValueChanged(app, event)
            valuesPresentInTheEditFields = false;
            if (app.EntrySliceNumberEditField_2.Value > 0 || ...
                    app.ExitSliceNumberEditField_2.Value > 0 || ...
                    app.LumenNarrowingEditField_2.Value > 0 || ...
                    app.EntryLumenRadiuscmEditField_2.Value > 0 || ...
                    app.ExitLumenRadiuscmEditField_2.Value > 0)
                valuesPresentInTheEditFields = true;
            end
            value = app.Stenosis2CheckBox.Value;
            if (value == true)
                app.ExitLumenRadiuscmEditField_2.Enable = true;
                app.EntryLumenRadiuscmEditField_2.Enable = true;
                app.LumenNarrowingEditField_2.Enable = true;
                app.ExitSliceNumberEditField_2.Enable = true;
                app.EntrySliceNumberEditField_2.Enable = true;
                app.ExitLumenRadiuscmEditField_2Label.Enable = true;
                app.EntryLumenRadiuscmEditField_2Label.Enable = true;
                app.LumenNarrowingEditField_2Label.Enable = true;
                app.ExitSliceNumberEditField_2Label.Enable = true;
                app.EntrySliceNumberEditField_2Label.Enable = true;
                app.ClearButton_2.Enable = true;
                app.NumberofbranchbetweenprelesionandstenosisEditField_2Label.Enable = true;
                app.NumberofbranchbetweenprelesionandstenosisEditField_2.Enable = true;
                app.EditField_2.Enable = true;
                app.EntryLabel_2.Enable = true;
                app.ExitLabel_2.Enable = true;
                app.numberOfStenosis = app.numberOfStenosis + 1;
                if (valuesPresentInTheEditFields)
                    deletePlotIDFromRemovalList(app, 2);
                end
            else
                app.ExitLumenRadiuscmEditField_2.Enable = false;
                app.EntryLumenRadiuscmEditField_2.Enable = false;
                app.LumenNarrowingEditField_2.Enable = false;
                app.ExitSliceNumberEditField_2.Enable = false;
                app.EntrySliceNumberEditField_2.Enable = false;
                app.ExitLumenRadiuscmEditField_2Label.Enable = false;
                app.EntryLumenRadiuscmEditField_2Label.Enable = false;
                app.LumenNarrowingEditField_2Label.Enable = false;
                app.ExitSliceNumberEditField_2Label.Enable = false;
                app.EntrySliceNumberEditField_2Label.Enable = false;
                app.ClearButton_2.Enable = false;
                app.NumberofbranchbetweenprelesionandstenosisEditField_2Label.Enable = false;
                app.NumberofbranchbetweenprelesionandstenosisEditField_2.Enable = false;
                app.EditField_2.Enable = false;
                app.EntryLabel_2.Enable = false;
                app.ExitLabel_2.Enable = false;
                app.numberOfStenosis = app.numberOfStenosis - 1;
                if (valuesPresentInTheEditFields)
                    app.plotToRemove(end + 1) = 2;
                    app.plotToRemove= unique(app.plotToRemove);
                end
            end
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
            app.ExitLumenRadiuscmEditField_2.Value = 0;
            app.EntryLumenRadiuscmEditField_2.Value = 0;
            app.LumenNarrowingEditField_2.Value = 0;
            app.ExitSliceNumberEditField_2.Value = 0;
            app.EntrySliceNumberEditField_2.Value = 0;
            app.NumberofbranchbetweenprelesionandstenosisEditField_2.Value = 0;
            app.EditField_2.Value = 0;
            deletePlotIDFromRemovalList(app, 2);
        end

        % Callback function
        function EnterButton_2Pushed(app, event)
            % getStenosisDataFromStenosisGUI(app.DCTFFRGUI);
            % delete(app);
        end

        % Value changed function: Stenosis3CheckBox
        function Stenosis3CheckBoxValueChanged(app, event)
            valuesPresentInTheEditFields = false;
            if (app.EntrySliceNumberEditField_3.Value > 0 || ...
                    app.ExitSliceNumberEditField_3.Value > 0 || ...
                    app.LumenNarrowingEditField_3.Value > 0 || ...
                    app.EntryLumenRadiuscmEditField_3.Value > 0 || ...
                    app.ExitLumenRadiuscmEditField_3.Value > 0)
                valuesPresentInTheEditFields = true;
            end
            value = app.Stenosis3CheckBox.Value;
            if (value == true)
                app.ExitLumenRadiuscmEditField_3.Enable = true;
                app.EntryLumenRadiuscmEditField_3.Enable = true;
                app.LumenNarrowingEditField_3.Enable = true;
                app.ExitSliceNumberEditField_3.Enable = true;
                app.EntrySliceNumberEditField_3.Enable = true;
                app.ExitLumenRadiuscmEditField_3Label.Enable = true;
                app.EntryLumenRadiuscmEditField_3Label.Enable = true;
                app.LumenNarrowingEditField_3Label.Enable = true;
                app.ExitSliceNumberEditField_3Label.Enable = true;
                app.EntrySliceNumberEditField_3Label.Enable = true;
                app.ClearButton_3.Enable = true;
                app.NumberofbranchbetweenprelesionandstenosisEditField_3Label.Enable = true;
                app.NumberofbranchbetweenprelesionandstenosisEditField_3.Enable = true;
                app.EditField_3.Enable = true;
                app.EntryLabel_3.Enable = true;
                app.ExitLabel_3.Enable = true;
                app.numberOfStenosis = app.numberOfStenosis + 1;
                if (valuesPresentInTheEditFields)
                    deletePlotIDFromRemovalList(app, 3);
                end
            else
                app.ExitLumenRadiuscmEditField_3.Enable = false;
                app.EntryLumenRadiuscmEditField_3.Enable = false;
                app.LumenNarrowingEditField_3.Enable = false;
                app.ExitSliceNumberEditField_3.Enable = false;
                app.EntrySliceNumberEditField_3.Enable = false;
                app.ExitLumenRadiuscmEditField_3Label.Enable = false;
                app.EntryLumenRadiuscmEditField_3Label.Enable = false;
                app.LumenNarrowingEditField_3Label.Enable = false;
                app.ExitSliceNumberEditField_3Label.Enable = false;
                app.EntrySliceNumberEditField_3Label.Enable = false;
                app.ClearButton_3.Enable = false;
                app.NumberofbranchbetweenprelesionandstenosisEditField_3Label.Enable = false;
                app.NumberofbranchbetweenprelesionandstenosisEditField_3.Enable = false;
                app.EditField_3.Enable = false;
                app.EntryLabel_3.Enable = false;
                app.ExitLabel_3.Enable = false;
                app.numberOfStenosis = app.numberOfStenosis - 1;
                if (valuesPresentInTheEditFields)
                    app.plotToRemove(end + 1) = 3;
                    app.plotToRemove= unique(app.plotToRemove);
                end
            end
        end

        % Button pushed function: ClearButton_3
        function ClearButton_3Pushed(app, event)
            app.ExitLumenRadiuscmEditField_3.Value = 0;
            app.EntryLumenRadiuscmEditField_3.Value = 0;
            app.LumenNarrowingEditField_3.Value = 0;
            app.ExitSliceNumberEditField_3.Value = 0;
            app.EntrySliceNumberEditField_3.Value = 0;
            app.NumberofbranchbetweenprelesionandstenosisEditField_3.Value = 0;
            app.EditField_3.Value = 0;
            deletePlotIDFromRemovalList(app, 3);
        end

        % Value changed function: Stenosis4CheckBox
        function Stenosis4CheckBoxValueChanged(app, event)
            valuesPresentInTheEditFields = false;
            if (app.EntrySliceNumberEditField_4.Value > 0 || ...
                    app.ExitSliceNumberEditField_4.Value > 0 || ...
                    app.LumenNarrowingEditField_4.Value > 0 || ...
                    app.EntryLumenRadiuscmEditField_4.Value > 0 || ...
                    app.ExitLumenRadiuscmEditField_4.Value > 0)
                valuesPresentInTheEditFields = true;
            end
            value = app.Stenosis4CheckBox.Value;
            if (value == true)
                app.ExitLumenRadiuscmEditField_4.Enable = true;
                app.EntryLumenRadiuscmEditField_4.Enable = true;
                app.LumenNarrowingEditField_4.Enable = true;
                app.ExitSliceNumberEditField_4.Enable = true;
                app.EntrySliceNumberEditField_4.Enable = true;
                app.ExitLumenRadiuscmEditField_4Label.Enable = true;
                app.EntryLumenRadiuscmEditField_4Label.Enable = true;
                app.LumenNarrowingEditField_4Label.Enable = true;
                app.ExitSliceNumberEditField_4Label.Enable = true;
                app.EntrySliceNumberEditField_4Label.Enable = true;
                app.ClearButton_4.Enable = true;
                app.NumberofbranchbetweenprelesionandstenosisEditField_4Label.Enable = true;
                app.NumberofbranchbetweenprelesionandstenosisEditField_4.Enable = true;
                app.EditField_4.Enable = true;
                app.EntryLabel_4.Enable = true;
                app.ExitLabel_4.Enable = true;
                app.numberOfStenosis = app.numberOfStenosis + 1;
                if (valuesPresentInTheEditFields)
                    deletePlotIDFromRemovalList(app, 4);
                end
            else
                app.ExitLumenRadiuscmEditField_4.Enable = false;
                app.EntryLumenRadiuscmEditField_4.Enable = false;
                app.LumenNarrowingEditField_4.Enable = false;
                app.ExitSliceNumberEditField_4.Enable = false;
                app.EntrySliceNumberEditField_4.Enable = false;
                app.ExitLumenRadiuscmEditField_4Label.Enable = false;
                app.EntryLumenRadiuscmEditField_4Label.Enable = false;
                app.LumenNarrowingEditField_4Label.Enable = false;
                app.ExitSliceNumberEditField_4Label.Enable = false;
                app.EntrySliceNumberEditField_4Label.Enable = false;
                app.ClearButton_4.Enable = false;
                app.NumberofbranchbetweenprelesionandstenosisEditField_4Label.Enable = false;
                app.NumberofbranchbetweenprelesionandstenosisEditField_4.Enable = false;
                app.EditField_4.Enable = false;
                app.EntryLabel_4.Enable = false;
                app.ExitLabel_4.Enable = false;
                app.numberOfStenosis = app.numberOfStenosis - 1;
                if (valuesPresentInTheEditFields)
                    app.plotToRemove(end + 1) = 4;
                    app.plotToRemove= unique(app.plotToRemove);
                end
            end
        end

        % Button pushed function: ClearButton_4
        function ClearButton_4Pushed(app, event)
            app.ExitLumenRadiuscmEditField_4.Value = 0;
            app.EntryLumenRadiuscmEditField_4.Value = 0;
            app.LumenNarrowingEditField_4.Value = 0;
            app.ExitSliceNumberEditField_4.Value = 0;
            app.EntrySliceNumberEditField_4.Value = 0;
            app.NumberofbranchbetweenprelesionandstenosisEditField_4.Value = 0;
            app.EditField_4.Value = 0;
            deletePlotIDFromRemovalList(app, 4);
        end

        % Value changed function: Stenosis5CheckBox
        function Stenosis5CheckBoxValueChanged(app, event)
            valuesPresentInTheEditFields = false;
            if (app.EntrySliceNumberEditField_5.Value > 0 || ...
                    app.ExitSliceNumberEditField_5.Value > 0 || ...
                    app.LumenNarrowingEditField_5.Value > 0 || ...
                    app.EntryLumenRadiuscmEditField_5.Value > 0 || ...
                    app.ExitLumenRadiuscmEditField_5.Value > 0)
                valuesPresentInTheEditFields = true;
            end
            value = app.Stenosis5CheckBox.Value;
            if (value == true)
                app.ExitLumenRadiuscmEditField_5.Enable = true;
                app.EntryLumenRadiuscmEditField_5.Enable = true;
                app.LumenNarrowingEditField_5.Enable = true;
                app.ExitSliceNumberEditField_5.Enable = true;
                app.EntrySliceNumberEditField_5.Enable = true;
                app.ExitLumenRadiuscmEditField_5Label.Enable = true;
                app.EntryLumenRadiuscmEditField_5Label.Enable = true;
                app.LumenNarrowingEditField_5Label.Enable = true;
                app.ExitSliceNumberEditField_5Label.Enable = true;
                app.EntrySliceNumberEditField_5Label.Enable = true;
                app.ClearButton_5.Enable = true;
                app.NumberofbranchbetweenprelesionandstenosisEditField_5Label.Enable = true;
                app.NumberofbranchbetweenprelesionandstenosisEditField_5.Enable = true;
                app.EditField_5.Enable = true;
                app.EntryLabel_5.Enable = true;
                app.ExitLabel_5.Enable = true;
                app.numberOfStenosis = app.numberOfStenosis + 1;
                if (valuesPresentInTheEditFields)
                    deletePlotIDFromRemovalList(app, 5);
                end
            else
                app.ExitLumenRadiuscmEditField_5.Enable = false;
                app.EntryLumenRadiuscmEditField_5.Enable = false;
                app.LumenNarrowingEditField_5.Enable = false;
                app.ExitSliceNumberEditField_5.Enable = false;
                app.EntrySliceNumberEditField_5.Enable = false;
                app.ExitLumenRadiuscmEditField_5Label.Enable = false;
                app.EntryLumenRadiuscmEditField_5Label.Enable = false;
                app.LumenNarrowingEditField_5Label.Enable = false;
                app.ExitSliceNumberEditField_5Label.Enable = false;
                app.EntrySliceNumberEditField_5Label.Enable = false;
                app.ClearButton_5.Enable = false;
                app.NumberofbranchbetweenprelesionandstenosisEditField_5Label.Enable = false;
                app.NumberofbranchbetweenprelesionandstenosisEditField_5.Enable = false;
                app.EditField_5.Enable = false;
                app.EntryLabel_5.Enable = false;
                app.ExitLabel_5.Enable = false;
                app.numberOfStenosis = app.numberOfStenosis - 1;
                if (valuesPresentInTheEditFields)
                    app.plotToRemove(end + 1) = 5;
                    app.plotToRemove= unique(app.plotToRemove);
                end
            end
        end

        % Button pushed function: ClearButton_5
        function ClearButton_5Pushed(app, event)
            app.ExitLumenRadiuscmEditField_5.Value = 0;
            app.EntryLumenRadiuscmEditField_5.Value = 0;
            app.LumenNarrowingEditField_5.Value = 0;
            app.ExitSliceNumberEditField_5.Value = 0;
            app.EntrySliceNumberEditField_5.Value = 0;
            app.NumberofbranchbetweenprelesionandstenosisEditField_5.Value = 0;
            app.EditField_5.Value = 0;
            deletePlotIDFromRemovalList(app, 5);
        end

        % Callback function
        function EnterButton_3Pushed(app, event)
            % getStenosisDataFromStenosisGUI(app.DCTFFRGUI);
            % delete(app);
        end

        % Callback function
        function EnterButton_4Pushed(app, event)
            % getStenosisDataFromStenosisGUI(app.DCTFFRGUI);
            % delete(app);
        end

        % Callback function
        function EnterButton_5Pushed(app, event)
            % getStenosisDataFromStenosisGUI(app.DCTFFRGUI);
            % delete(app);
        end

        % Close request function: MarkStenosisUIFigure
        function MarkStenosisUIFigureCloseRequest(app, event)
            notify(app, 'AppClosedEvent');
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MarkStenosisUIFigure = uifigure('Visible', 'off');
            app.MarkStenosisUIFigure.Name = 'Mark Stenosis';
            app.MarkStenosisUIFigure.CloseRequestFcn = createCallbackFcn(app, @MarkStenosisUIFigureCloseRequest, true);
            app.Stenosis1CheckBox = uicheckbox(app.MarkStenosisUIFigure);
            app.Stenosis1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Stenosis1CheckBoxValueChanged, true);
            app.Stenosis1CheckBox.Text = 'Stenosis 1';
            app.EntrySliceNumberEditFieldLabel = uilabel(app.MarkStenosisUIFigure);
            app.EntrySliceNumberEditFieldLabel.Enable = 'off';
            app.EntrySliceNumberEditFieldLabel.Text = 'Entry Slice Number';
            app.EntrySliceNumberEditField = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.EntrySliceNumberEditField.Enable = 'off';
            app.ExitSliceNumberEditFieldLabel = uilabel(app.MarkStenosisUIFigure);
            app.ExitSliceNumberEditFieldLabel.Enable = 'off';
            app.ExitSliceNumberEditFieldLabel.Text = 'Exit Slice Number';
            app.ExitSliceNumberEditField = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.ExitSliceNumberEditField.Enable = 'off';
            app.LumenNarrowingEditFieldLabel = uilabel(app.MarkStenosisUIFigure);
            app.LumenNarrowingEditFieldLabel.Enable = 'off';
            app.LumenNarrowingEditFieldLabel.Text = 'Lumen Narrowing (%)';
            app.LumenNarrowingEditField = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.LumenNarrowingEditField.Enable = 'off';
            app.EntryLumenRadiuscmEditFieldLabel = uilabel(app.MarkStenosisUIFigure);
            app.EntryLumenRadiuscmEditFieldLabel.Enable = 'off';
            app.EntryLumenRadiuscmEditFieldLabel.Text = 'Entry Lumen Radius (cm)';
            app.EntryLumenRadiuscmEditField = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.EntryLumenRadiuscmEditField.Enable = 'off';
            app.ExitLumenRadiuscmLabel = uilabel(app.MarkStenosisUIFigure);
            app.ExitLumenRadiuscmLabel.Enable = 'off';
            app.ExitLumenRadiuscmLabel.Text = 'Exit Lumen Radius (cm)';
            app.ExitLumenRadiuscmEditField = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.ExitLumenRadiuscmEditField.Enable = 'off';
            app.ClearButton = uibutton(app.MarkStenosisUIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Enable = 'off';
            app.ClearButton.Visible = 'off';
            app.ClearButton.Text = 'Clear';
            app.Stenosis2CheckBox = uicheckbox(app.MarkStenosisUIFigure);
            app.Stenosis2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Stenosis2CheckBoxValueChanged, true);
            app.Stenosis2CheckBox.Text = 'Stenosis 2';
            app.EntrySliceNumberEditField_2Label = uilabel(app.MarkStenosisUIFigure);
            app.EntrySliceNumberEditField_2Label.Enable = 'off';
            app.EntrySliceNumberEditField_2Label.Text = 'Entry Slice Number';
            app.EntrySliceNumberEditField_2 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.EntrySliceNumberEditField_2.Enable = 'off';
            app.ExitSliceNumberEditField_2Label = uilabel(app.MarkStenosisUIFigure);
            app.ExitSliceNumberEditField_2Label.Enable = 'off';
            app.ExitSliceNumberEditField_2Label.Text = 'Exit Slice Number';
            app.ExitSliceNumberEditField_2 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.ExitSliceNumberEditField_2.Enable = 'off';
            app.LumenNarrowingEditField_2Label = uilabel(app.MarkStenosisUIFigure);
            app.LumenNarrowingEditField_2Label.Enable = 'off';
            app.LumenNarrowingEditField_2Label.Text = 'Lumen Narrowing (%)';
            app.LumenNarrowingEditField_2 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.LumenNarrowingEditField_2.Enable = 'off';
            app.EntryLumenRadiuscmEditField_2Label = uilabel(app.MarkStenosisUIFigure);
            app.EntryLumenRadiuscmEditField_2Label.Enable = 'off';
            app.EntryLumenRadiuscmEditField_2Label.Text = 'Entry Lumen Radius (cm)';
            app.EntryLumenRadiuscmEditField_2 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.EntryLumenRadiuscmEditField_2.Enable = 'off';
            app.ExitLumenRadiuscmEditField_2Label = uilabel(app.MarkStenosisUIFigure);
            app.ExitLumenRadiuscmEditField_2Label.Enable = 'off';
            app.ExitLumenRadiuscmEditField_2Label.Text = 'Exit Lumen Radius (cm)';
            app.ExitLumenRadiuscmEditField_2 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.ExitLumenRadiuscmEditField_2.Enable = 'off';
            app.ClearButton_2 = uibutton(app.MarkStenosisUIFigure, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Enable = 'off';
            app.ClearButton_2.Visible = 'off';
            app.ClearButton_2.Text = 'Clear';
            app.Stenosis3CheckBox = uicheckbox(app.MarkStenosisUIFigure);
            app.Stenosis3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Stenosis3CheckBoxValueChanged, true);
            app.Stenosis3CheckBox.Text = 'Stenosis 3';
            app.EntrySliceNumberEditField_3Label = uilabel(app.MarkStenosisUIFigure);
            app.EntrySliceNumberEditField_3Label.Enable = 'off';
            app.EntrySliceNumberEditField_3Label.Text = 'Entry Slice Number';
            app.EntrySliceNumberEditField_3 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.EntrySliceNumberEditField_3.Enable = 'off';
            app.ExitSliceNumberEditField_3Label = uilabel(app.MarkStenosisUIFigure);
            app.ExitSliceNumberEditField_3Label.Enable = 'off';
            app.ExitSliceNumberEditField_3Label.Text = 'Exit Slice Number';
            app.ExitSliceNumberEditField_3 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.ExitSliceNumberEditField_3.Enable = 'off';
            app.LumenNarrowingEditField_3Label = uilabel(app.MarkStenosisUIFigure);
            app.LumenNarrowingEditField_3Label.Enable = 'off';
            app.LumenNarrowingEditField_3Label.Text = 'Lumen Narrowing (%)';
            app.LumenNarrowingEditField_3 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.LumenNarrowingEditField_3.Enable = 'off';
            app.EntryLumenRadiuscmEditField_3Label = uilabel(app.MarkStenosisUIFigure);
            app.EntryLumenRadiuscmEditField_3Label.Enable = 'off';
            app.EntryLumenRadiuscmEditField_3Label.Text = 'Entry Lumen Radius (cm)';
            app.EntryLumenRadiuscmEditField_3 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.EntryLumenRadiuscmEditField_3.Enable = 'off';
            app.ExitLumenRadiuscmEditField_3Label = uilabel(app.MarkStenosisUIFigure);
            app.ExitLumenRadiuscmEditField_3Label.Enable = 'off';
            app.ExitLumenRadiuscmEditField_3Label.Text = 'Exit Lumen Radius (cm)';
            app.ExitLumenRadiuscmEditField_3 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.ExitLumenRadiuscmEditField_3.Enable = 'off';
            app.ClearButton_3 = uibutton(app.MarkStenosisUIFigure, 'push');
            app.ClearButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_3Pushed, true);
            app.ClearButton_3.Enable = 'off';
            app.ClearButton_3.Visible = 'off';
            app.ClearButton_3.Text = 'Clear';
            app.Stenosis4CheckBox = uicheckbox(app.MarkStenosisUIFigure);
            app.Stenosis4CheckBox.ValueChangedFcn = createCallbackFcn(app, @Stenosis4CheckBoxValueChanged, true);
            app.Stenosis4CheckBox.Text = 'Stenosis 4';
            app.EntrySliceNumberEditField_4Label = uilabel(app.MarkStenosisUIFigure);
            app.EntrySliceNumberEditField_4Label.Enable = 'off';
            app.EntrySliceNumberEditField_4Label.Text = 'Entry Slice Number';
            app.EntrySliceNumberEditField_4 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.EntrySliceNumberEditField_4.Enable = 'off';
            app.ExitSliceNumberEditField_4Label = uilabel(app.MarkStenosisUIFigure);
            app.ExitSliceNumberEditField_4Label.Enable = 'off';
            app.ExitSliceNumberEditField_4Label.Text = 'Exit Slice Number';
            app.ExitSliceNumberEditField_4 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.ExitSliceNumberEditField_4.Enable = 'off';
            app.LumenNarrowingEditField_4Label = uilabel(app.MarkStenosisUIFigure);
            app.LumenNarrowingEditField_4Label.Enable = 'off';
            app.LumenNarrowingEditField_4Label.Text = 'Lumen Narrowing (%)';
            app.LumenNarrowingEditField_4 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.LumenNarrowingEditField_4.Enable = 'off';
            app.EntryLumenRadiuscmEditField_4Label = uilabel(app.MarkStenosisUIFigure);
            app.EntryLumenRadiuscmEditField_4Label.Enable = 'off';
            app.EntryLumenRadiuscmEditField_4Label.Text = 'Entry Lumen Radius (cm)';
            app.EntryLumenRadiuscmEditField_4 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.EntryLumenRadiuscmEditField_4.Enable = 'off';
            app.ExitLumenRadiuscmEditField_4Label = uilabel(app.MarkStenosisUIFigure);
            app.ExitLumenRadiuscmEditField_4Label.Enable = 'off';
            app.ExitLumenRadiuscmEditField_4Label.Text = 'Exit Lumen Radius (cm)';
            app.ExitLumenRadiuscmEditField_4 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.ExitLumenRadiuscmEditField_4.Enable = 'off';
            app.ClearButton_4 = uibutton(app.MarkStenosisUIFigure, 'push');
            app.ClearButton_4.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_4Pushed, true);
            app.ClearButton_4.Enable = 'off';
            app.ClearButton_4.Visible = 'off';
            app.ClearButton_4.Text = 'Clear';
            app.Stenosis5CheckBox = uicheckbox(app.MarkStenosisUIFigure);
            app.Stenosis5CheckBox.ValueChangedFcn = createCallbackFcn(app, @Stenosis5CheckBoxValueChanged, true);
            app.Stenosis5CheckBox.Text = 'Stenosis 5';
            app.EntrySliceNumberEditField_5Label = uilabel(app.MarkStenosisUIFigure);
            app.EntrySliceNumberEditField_5Label.Enable = 'off';
            app.EntrySliceNumberEditField_5Label.Text = 'Entry Slice Number';
            app.EntrySliceNumberEditField_5 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.EntrySliceNumberEditField_5.Enable = 'off';
            app.ExitSliceNumberEditField_5Label = uilabel(app.MarkStenosisUIFigure);
            app.ExitSliceNumberEditField_5Label.Enable = 'off';
            app.ExitSliceNumberEditField_5Label.Text = 'Exit Slice Number';
            app.ExitSliceNumberEditField_5 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.ExitSliceNumberEditField_5.Enable = 'off';
            app.LumenNarrowingEditField_5Label = uilabel(app.MarkStenosisUIFigure);
            app.LumenNarrowingEditField_5Label.Enable = 'off';
            app.LumenNarrowingEditField_5Label.Text = 'Lumen Narrowing (%)';
            app.LumenNarrowingEditField_5 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.LumenNarrowingEditField_5.Enable = 'off';
            app.EntryLumenRadiuscmEditField_5Label = uilabel(app.MarkStenosisUIFigure);
            app.EntryLumenRadiuscmEditField_5Label.Enable = 'off';
            app.EntryLumenRadiuscmEditField_5Label.Text = 'Entry Lumen Radius (cm)';
            app.EntryLumenRadiuscmEditField_5 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.EntryLumenRadiuscmEditField_5.Enable = 'off';
            app.ExitLumenRadiuscmEditField_5Label = uilabel(app.MarkStenosisUIFigure);
            app.ExitLumenRadiuscmEditField_5Label.Enable = 'off';
            app.ExitLumenRadiuscmEditField_5Label.Text = 'Exit Lumen Radius (cm)';
            app.ExitLumenRadiuscmEditField_5 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.ExitLumenRadiuscmEditField_5.Enable = 'off';
            app.ClearButton_5 = uibutton(app.MarkStenosisUIFigure, 'push');
            app.ClearButton_5.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_5Pushed, true);
            app.ClearButton_5.Enable = 'off';
            app.ClearButton_5.Visible = 'off';
            app.ClearButton_5.Text = 'Clear';
            app.EnterButton = uibutton(app.MarkStenosisUIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.NumberofbranchbetweenprelesionandstenosisEditFieldLabel = uilabel(app.MarkStenosisUIFigure);
            app.NumberofbranchbetweenprelesionandstenosisEditFieldLabel.Enable = 'off';
            app.NumberofbranchbetweenprelesionandstenosisEditFieldLabel.Text = {'Number of branch'; 'between prelesion'; 'and stenosis'};
            app.NumberofbranchbetweenprelesionandstenosisEditField = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.NumberofbranchbetweenprelesionandstenosisEditField.Enable = 'off';
            app.EditField = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.EditField.Enable = 'off';
            app.EntryLabel = uilabel(app.MarkStenosisUIFigure);
            app.EntryLabel.Enable = 'off';
            app.EntryLabel.Text = 'Entry';
            app.ExitLabel = uilabel(app.MarkStenosisUIFigure);
            app.ExitLabel.Enable = 'off';
            app.ExitLabel.Text = 'Exit';
            app.NumberofbranchbetweenprelesionandstenosisEditField_2Label = uilabel(app.MarkStenosisUIFigure);
            app.NumberofbranchbetweenprelesionandstenosisEditField_2Label.Enable = 'off';
            app.NumberofbranchbetweenprelesionandstenosisEditField_2Label.Text = {'Number of branch'; 'between prelesion'; 'and stenosis'};
            app.NumberofbranchbetweenprelesionandstenosisEditField_2 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.NumberofbranchbetweenprelesionandstenosisEditField_2.Enable = 'off';
            app.EntryLabel_2 = uilabel(app.MarkStenosisUIFigure);
            app.EntryLabel_2.Enable = 'off';
            app.EntryLabel_2.Text = 'Entry';
            app.EditField_2 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.EditField_2.Enable = 'off';
            app.ExitLabel_2 = uilabel(app.MarkStenosisUIFigure);
            app.ExitLabel_2.Enable = 'off';
            app.ExitLabel_2.Text = 'Exit';
            app.NumberofbranchbetweenprelesionandstenosisEditField_3Label = uilabel(app.MarkStenosisUIFigure);
            app.NumberofbranchbetweenprelesionandstenosisEditField_3Label.Enable = 'off';
            app.NumberofbranchbetweenprelesionandstenosisEditField_3Label.Text = {'Number of branch'; 'between prelesion'; 'and stenosis'};
            app.NumberofbranchbetweenprelesionandstenosisEditField_3 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.NumberofbranchbetweenprelesionandstenosisEditField_3.Enable = 'off';
            app.EntryLabel_3 = uilabel(app.MarkStenosisUIFigure);
            app.EntryLabel_3.Enable = 'off';
            app.EntryLabel_3.Text = 'Entry';
            app.EditField_3 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.EditField_3.Enable = 'off';
            app.ExitLabel_3 = uilabel(app.MarkStenosisUIFigure);
            app.ExitLabel_3.Enable = 'off';
            app.ExitLabel_3.Text = 'Exit';
            app.NumberofbranchbetweenprelesionandstenosisEditField_4Label = uilabel(app.MarkStenosisUIFigure);
            app.NumberofbranchbetweenprelesionandstenosisEditField_4Label.Enable = 'off';
            app.NumberofbranchbetweenprelesionandstenosisEditField_4Label.Text = {'Number of branch'; 'between prelesion'; 'and stenosis'};
            app.NumberofbranchbetweenprelesionandstenosisEditField_4 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.NumberofbranchbetweenprelesionandstenosisEditField_4.Enable = 'off';
            app.EntryLabel_4 = uilabel(app.MarkStenosisUIFigure);
            app.EntryLabel_4.Enable = 'off';
            app.EntryLabel_4.Text = 'Entry';
            app.EditField_4 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.EditField_4.Enable = 'off';
            app.ExitLabel_4 = uilabel(app.MarkStenosisUIFigure);
            app.ExitLabel_4.Enable = 'off';
            app.ExitLabel_4.Text = 'Exit';
            app.NumberofbranchbetweenprelesionandstenosisEditField_5Label = uilabel(app.MarkStenosisUIFigure);
            app.NumberofbranchbetweenprelesionandstenosisEditField_5Label.Enable = 'off';
            app.NumberofbranchbetweenprelesionandstenosisEditField_5Label.Text = {'Number of branch'; 'between prelesion'; 'and stenosis'};
            app.NumberofbranchbetweenprelesionandstenosisEditField_5 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.NumberofbranchbetweenprelesionandstenosisEditField_5.Enable = 'off';
            app.EntryLabel_5 = uilabel(app.MarkStenosisUIFigure);
            app.EntryLabel_5.Enable = 'off';
            app.EntryLabel_5.Text = 'Entry';
            app.EditField_5 = uieditfield(app.MarkStenosisUIFigure, 'numeric');
            app.EditField_5.Enable = 'off';
            app.ExitLabel_5 = uilabel(app.MarkStenosisUIFigure);
            app.ExitLabel_5.Enable = 'off';
            app.ExitLabel_5.Text = 'Exit';
            app.MarkstenosisbetweenPrelesionandPostlesionSitesLabel = uilabel(app.MarkStenosisUIFigure);
            app.MarkstenosisbetweenPrelesionandPostlesionSitesLabel.Text = 'Mark stenosis between Pre-lesion and Post-lesion Sites';
            % Show the figure after all components are created
            app.MarkStenosisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STENOSISGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MarkStenosisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MarkStenosisUIFigure)
        end
    end
end